classdef Change_Plot_Speed < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChannelSpacingSelectionUIFigure  matlab.ui.Figure
        TimetoJumpsPressEntertoConfirmEditField  matlab.ui.control.EditField
        TimetoJumpsPressEntertoConfirmEditFieldLabel  matlab.ui.control.Label
        TextArea_4  matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        Mainapp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.ChannelSpacingSelectionUIFigure, 'center');
            
            app.Mainapp = Mainapp;

            app = Utility_Change_Light_Dark_Mode(app,"ChangePlotSpeed");

            app.TimetoJumpsPressEntertoConfirmEditField.Value = num2str(app.Mainapp.MovieTimeToJump);
        end

        % Value changed function: TimetoJumpsPressEntertoConfirmEditField
        function TimetoJumpsPressEntertoConfirmEditFieldValueChanged(app, event)
            [CorrectedValue] = Utility_SimpleCheckInputs(app.TimetoJumpsPressEntertoConfirmEditField.Value,"One",'0.02',0,0);
            
            if ~strcmp(CorrectedValue,app.TimetoJumpsPressEntertoConfirmEditField.Value)
                msgbox("Error: Wrong format. Please enter a single number")
                return;
            end

            app.Mainapp.MovieTimeToJump = str2double(app.TimetoJumpsPressEntertoConfirmEditField.Value);
            
            msgbox(strcat("Time to jump changed to ",num2str(app.Mainapp.MovieTimeToJump)))

            app.Mainapp.ManuallyChangedPlotSpeed = 1;

            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChannelSpacingSelectionUIFigure = uifigure('Visible', 'off');
            app.ChannelSpacingSelectionUIFigure.Name = 'Channel Spacing Selection';
            app.ChannelSpacingSelectionUIFigure.Icon = 'Logo.png';
            app.TextArea_4 = uitextarea(app.ChannelSpacingSelectionUIFigure);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'The update speed of the main data plot is limited to 40 fps, but you can change the amount of time jumped per frame. Default is 0.02 '};
            app.TimetoJumpsPressEntertoConfirmEditFieldLabel = uilabel(app.ChannelSpacingSelectionUIFigure);
            app.TimetoJumpsPressEntertoConfirmEditFieldLabel.Text = {'Time to Jump [s] '; 'Press Enter to Confirm'};
            app.TimetoJumpsPressEntertoConfirmEditField = uieditfield(app.ChannelSpacingSelectionUIFigure, 'text');
            app.TimetoJumpsPressEntertoConfirmEditField.ValueChangedFcn = createCallbackFcn(app, @TimetoJumpsPressEntertoConfirmEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.ChannelSpacingSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Change_Plot_Speed(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ChannelSpacingSelectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ChannelSpacingSelectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChannelSpacingSelectionUIFigure)
        end
    end
end