classdef Figure_Property_Change_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure_Property_Change_WindowUIFigure  matlab.ui.Figure
        NeuromodToolboxLabel          matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        ChangeMaxTimeSpanWindowLabel  matlab.ui.control.Label
        EnteraValueLabel              matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
    end

    
    properties (Access = public)
        Figure = [] % Description
        Type = [] % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Figure, Type)
            movegui(app.Figure_Property_Change_WindowUIFigure, 'center');

            app.Figure = Figure;
            app.Type = Type;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
            updateUIAxesProperty(app.Figure, app.Type, value);

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure_Property_Change_WindowUIFigure = uifigure('Visible', 'off');
            app.Figure_Property_Change_WindowUIFigure.Name = 'Figure_Property_Change_Window';
            app.EditField = uieditfield(app.Figure_Property_Change_WindowUIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EnteraValueLabel = uilabel(app.Figure_Property_Change_WindowUIFigure);
            app.EnteraValueLabel.Text = 'Enter a Value';
            app.ChangeMaxTimeSpanWindowLabel = uilabel(app.Figure_Property_Change_WindowUIFigure);
            app.ChangeMaxTimeSpanWindowLabel.Text = 'Change Max. Time Span Window';
            app.Image = uiimage(app.Figure_Property_Change_WindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.Figure_Property_Change_WindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            % Show the figure after all components are created
            app.Figure_Property_Change_WindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Figure_Property_Change_Window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure_Property_Change_WindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure_Property_Change_WindowUIFigure)
        end
    end
end