classdef Manage_Modules_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManageModulesWindowUIFigure  matlab.ui.Figure
        SelectCheckBox_2             matlab.ui.control.CheckBox
        SelectCheckBox_3             matlab.ui.control.CheckBox
        SelectCheckBox_4             matlab.ui.control.CheckBox
        SelectCheckBox_5             matlab.ui.control.CheckBox
        SelecttoSwitchoutLabel       matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        ListofSavedModulesTextArea   matlab.ui.control.TextArea
        ListofAvailableModulesTextAreaLabel  matlab.ui.control.Label
        CloseButton                  matlab.ui.control.Button
        NeuromodToolboxLabel         matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        ManageModulesWindowLabel     matlab.ui.control.Label
        CurrentModulesPanel          matlab.ui.container.Panel
        Panel                        matlab.ui.container.Panel
        ListBox_5                    matlab.ui.control.ListBox
        Panel_1                      matlab.ui.container.Panel
        ListBox_4                    matlab.ui.control.ListBox
        Panel_2                      matlab.ui.container.Panel
        ListBox_2                    matlab.ui.control.ListBox
        Panel_3                      matlab.ui.container.Panel
        ListBox                      matlab.ui.control.ListBox
        Panel_5                      matlab.ui.container.Panel
        RestoreStandardButton        matlab.ui.control.Button
        SetasnewdefaultButton        matlab.ui.control.Button
        SwitchandApplytoMainWindowButton  matlab.ui.control.Button
        SelectedModuleDropDown       matlab.ui.control.DropDown
        SelectedModuleDropDownLabel  matlab.ui.control.Label
        CreateNewModuleButton        matlab.ui.control.Button
    end

    
    properties (Access = public)
        Mainapp = []; 
        Titles = [];
        Module = [];
        AppliedSwitch = 0;
        AppliedToMainapp = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            % Moce this window in the middle of thescreen
            movegui(app.ManageModulesWindowUIFigure, 'center');

            app.Mainapp = Mainapp;

            app.ListBox_5.Items = app.Mainapp.ListBox_6.Items;
            app.ListBox_4.Items = app.Mainapp.ListBox_3.Items;
            app.ListBox_2.Items = app.Mainapp.ListBox_2.Items;
            app.ListBox.Items = app.Mainapp.ListBox.Items;

            app.Panel.Title = app.Mainapp.MainPlotAnalysisModulePanel.Title;
            app.Panel_1.Title = app.Mainapp.ContinousDataModulePanel.Title;
            app.Panel_2.Title = app.Mainapp.EventDataModulePanel.Title;
            app.Panel_3.Title = app.Mainapp.SpikeModulePanel_2.Title;

            app.Titles{1} = app.Panel.Title;
            app.Titles{2} = app.Panel_1.Title;
            app.Titles{3} = app.Panel_2.Title;
            app.Titles{4} = app.Panel_3.Title;

            [app.Module,app] = All_Module_Items(app,0,[]);

            texttoshow = ["To add a new module click the button above directing you in the All_Module_Items.m function. There you have to add a new cell to the existing structure, entering title and items to show in your new module. Lastly add the function that should be executed when the user clicks the RUN button of your module.";"";"All modules found in All_Module_Items.m function:";""];
        
            for i = 1:length(app.Module)
                texttoshow = [texttoshow;convertCharsToStrings(app.Module{i}.Title)];
                app.SelectedModuleDropDown.Items{i} = app.Module{i}.Title;
            end

            texttoshow = [texttoshow;"";"To switch modules, select a module on the left that you want to replace with the module selected in dropdown menu below. Then click the 'Switch selected main window module with above selected modules' button to switch."];
            app.ListofSavedModulesTextArea.Value = texttoshow; 

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: SwitchandApplytoMainWindowButton
        function SwitchandApplytoMainWindowButtonPushed(app, event)
            [app] = Change_Modules_Apply_Module_Change_To_MainWindow(app);          
        end

        % Button pushed function: SetasnewdefaultButton
        function SetasnewdefaultButtonPushed(app, event)
            Change_Modules_Set_as_new_Default(app)
        end

        % Button pushed function: RestoreStandardButton
        function RestoreStandardButtonPushed(app, event)
            Change_Modules_Restore_Default(app);
        end

        % Value changed function: SelectCheckBox_2
        function SelectCheckBox_2ValueChanged(app, event)
            value = app.SelectCheckBox_2.Value;
            
        end

        % Button pushed function: CreateNewModuleButton
        function CreateNewModuleButtonPushed(app, event)
            Path = strcat(app.Mainapp.executableFolder,'\Modules\1. Main_Window_Functions\Manage_Modules_Functions\All_Module_Items.m');
            
            % Open the file in the editor
            edit(Path);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManageModulesWindowUIFigure = uifigure('Visible', 'off');
            app.ManageModulesWindowUIFigure.Name = 'Manage Modules Window';
            app.ManageModulesWindowUIFigure.Icon = 'Logo.png';
            app.Panel_5 = uipanel(app.ManageModulesWindowUIFigure);
            app.CreateNewModuleButton = uibutton(app.Panel_5, 'push');
            app.CreateNewModuleButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewModuleButtonPushed, true);
            app.CreateNewModuleButton.Text = 'Create New Module';
            app.SelectedModuleDropDownLabel = uilabel(app.Panel_5);
            app.SelectedModuleDropDownLabel.Text = 'Selected Module';
            app.SelectedModuleDropDown = uidropdown(app.Panel_5);
            app.SelectedModuleDropDown.Items = {};
            app.SelectedModuleDropDown.Value = {};
            app.SwitchandApplytoMainWindowButton = uibutton(app.Panel_5, 'push');
            app.SwitchandApplytoMainWindowButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchandApplytoMainWindowButtonPushed, true);
            app.SwitchandApplytoMainWindowButton.Text = {'<< Switch and '; 'Apply to Main Window'};
            app.SetasnewdefaultButton = uibutton(app.Panel_5, 'push');
            app.SetasnewdefaultButton.ButtonPushedFcn = createCallbackFcn(app, @SetasnewdefaultButtonPushed, true);
            app.SetasnewdefaultButton.Text = 'Set as new default';
            app.RestoreStandardButton = uibutton(app.Panel_5, 'push');
            app.RestoreStandardButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreStandardButtonPushed, true);
            app.RestoreStandardButton.Text = 'Restore Standard';
            app.CurrentModulesPanel = uipanel(app.ManageModulesWindowUIFigure);
            app.CurrentModulesPanel.Title = 'Current Modules';
            app.Panel_3 = uipanel(app.CurrentModulesPanel);
            app.Panel_3.Title = 'asd';
            app.ListBox = uilistbox(app.Panel_3);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.Panel_2 = uipanel(app.CurrentModulesPanel);
            app.Panel_2.Title = 'asdsa';
            app.ListBox_2 = uilistbox(app.Panel_2);
            app.ListBox_2.Items = {};
            app.ListBox_2.Value = {};
            app.Panel_1 = uipanel(app.CurrentModulesPanel);
            app.Panel_1.Title = 'dasd';
            app.ListBox_4 = uilistbox(app.Panel_1);
            app.ListBox_4.Items = {};
            app.ListBox_4.Value = {};
            app.Panel = uipanel(app.CurrentModulesPanel);
            app.Panel.Title = 'dasda';
            app.ListBox_5 = uilistbox(app.Panel);
            app.ListBox_5.Items = {};
            app.ListBox_5.Value = {};
            app.ManageModulesWindowLabel = uilabel(app.ManageModulesWindowUIFigure);
            app.ManageModulesWindowLabel.Text = 'Manage Modules Window';
            app.Image = uiimage(app.ManageModulesWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.ManageModulesWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.ManageModulesWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ListofAvailableModulesTextAreaLabel = uilabel(app.ManageModulesWindowUIFigure);
            app.ListofAvailableModulesTextAreaLabel.Text = 'List of Available Modules';
            app.ListofSavedModulesTextArea = uitextarea(app.ManageModulesWindowUIFigure);
            app.Label_2 = uilabel(app.ManageModulesWindowUIFigure);
            app.Label_2.Text = '>>';
            app.SelecttoSwitchoutLabel = uilabel(app.ManageModulesWindowUIFigure);
            app.SelecttoSwitchoutLabel.Text = {'Select to'; 'Switch out'};
            app.SelectCheckBox_5 = uicheckbox(app.ManageModulesWindowUIFigure);
            app.SelectCheckBox_5.Text = ' Select';
            app.SelectCheckBox_4 = uicheckbox(app.ManageModulesWindowUIFigure);
            app.SelectCheckBox_4.Text = ' Select';
            app.SelectCheckBox_3 = uicheckbox(app.ManageModulesWindowUIFigure);
            app.SelectCheckBox_3.Text = ' Select';
            app.SelectCheckBox_2 = uicheckbox(app.ManageModulesWindowUIFigure);
            app.SelectCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SelectCheckBox_2ValueChanged, true);
            app.SelectCheckBox_2.Text = ' Select';
            % Show the figure after all components are created
            app.ManageModulesWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Manage_Modules_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ManageModulesWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ManageModulesWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManageModulesWindowUIFigure)
        end
    end
end