classdef Max_Time_Span_Selection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChannelSpacingSelectionUIFigure  matlab.ui.Figure
        NeuromodToolboxLabel          matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        ChangeMaxTimeSpanWindowLabel  matlab.ui.control.Label
        MaxTimeSpansLabel_2           matlab.ui.control.Label
        MaxTimeSpansEditField         matlab.ui.control.EditField
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        WhatToDo = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, WhatToDo)
            movegui(app.ChannelSpacingSelectionUIFigure, 'center');

            app.Mainapp = Mainapp;
            app.WhatToDo = WhatToDo;

            if strcmp(app.WhatToDo,"Max Time")
                app.MaxTimeSpansLabel_2.Text = "Max Time Span [s]";
            elseif strcmp(app.WhatToDo,"Change Time")
                app.MaxTimeSpansLabel_2.Text = "New Tme Span [s]";
            end
        end

        % Value changed function: MaxTimeSpansEditField
        function MaxTimeSpansEditFieldValueChanged(app, event)
            value = app.MaxTimeSpansEditField.Value;
            if strcmp(app.WhatToDo,"Max Time")
                app.Mainapp.TimeLimit = [app.Mainapp.TimeLimit(1),str2double(value)];
            elseif strcmp(app.WhatToDo,"Change Time")
                if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        if app.Mainapp.CurrentTimePoints+(round(str2double(value)*app.Mainapp.Data.Info.DownsampledSampleRate)) <= length(app.Mainapp.Data.Time)
                            app.Mainapp.TimeRangeViewBox.Value = strcat(value,'s');
                        else 
                            Difference = (length(app.Mainapp.Data.Time)-app.Mainapp.CurrentTimePoints+(round(str2double(value)*app.Mainapp.Data.Info.DownsampledSampleRate)));
                            secs = num2str(floor(Difference/app.Mainapp.Data.Info.DownsampledSampleRate));
                            app.Mainapp.TimeRangeViewBox.Value = strcat(value,'s');
                        end
                    else
                        if app.Mainapp.CurrentTimePoints+(round(str2double(value)*app.Mainapp.Data.Info.NativeSamplingRate)) <= length(app.Mainapp.Data.Time)
                            app.Mainapp.TimeRangeViewBox.Value = strcat(value,'s');
                        else
                            Difference = (length(app.Mainapp.Data.Time)-app.Mainapp.CurrentTimePoints+(round(str2double(value)*app.Mainapp.Data.Info.NativeSamplingRate)));
                            secs = num2str(floor(Difference/app.Mainapp.Data.Info.NativeSamplingRate));
                            app.Mainapp.TimeRangeViewBox.Value = strcat(value,'s');
                        end
                    end
                else
                    if app.Mainapp.CurrentTimePoints+(round(str2double(value)*app.Mainapp.Data.Info.NativeSamplingRate)) <= length(app.Mainapp.Data.Time)
                        app.Mainapp.TimeRangeViewBox.Value = strcat(value,'s');
                    else
                        Difference = (length(app.Mainapp.Data.Time)-app.Mainapp.CurrentTimePoints+(round(str2double(value)*app.Mainapp.Data.Info.NativeSamplingRate)));
                        secs = num2str(floor(Difference/app.Mainapp.Data.Info.NativeSamplingRate));
                        app.Mainapp.TimeRangeViewBox.Value = strcat(value,'s');
                    end
                end
            end

            % if strcmp(app.WhatToDo,"Max Time")
            %     msgbox("Succesfully Changed Time Limit")
            % elseif strcmp(app.WhatToDo,"Change Time")
            %     msgbox("Succesfully Changed Plotted Time")
            % end
            
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Subsequent","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChannelSpacingSelectionUIFigure = uifigure('Visible', 'off');
            app.ChannelSpacingSelectionUIFigure.Name = 'Channel Spacing Selection';
            app.ChannelSpacingSelectionUIFigure.Icon = 'Logo.png';
            app.MaxTimeSpansEditField = uieditfield(app.ChannelSpacingSelectionUIFigure, 'text');
            app.MaxTimeSpansEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTimeSpansEditFieldValueChanged, true);
            app.MaxTimeSpansLabel_2 = uilabel(app.ChannelSpacingSelectionUIFigure);
            app.MaxTimeSpansLabel_2.Text = 'Max Time Span [s]';
            app.ChangeMaxTimeSpanWindowLabel = uilabel(app.ChannelSpacingSelectionUIFigure);
            app.ChangeMaxTimeSpanWindowLabel.Text = 'Change Max. Time Span Window';
            app.Image = uiimage(app.ChannelSpacingSelectionUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.ChannelSpacingSelectionUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            % Show the figure after all components are created
            app.ChannelSpacingSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Max_Time_Span_Selection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ChannelSpacingSelectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ChannelSpacingSelectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChannelSpacingSelectionUIFigure)
        end
    end
end