classdef Set_LowPassFilterSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SetLowPassFilterSettingsUIFigure  matlab.ui.Figure
        NeuromodToolboxLabel           matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        SetLowPassFilterSettingsLabel  matlab.ui.control.Label
        MaxTimeSpansLabel_2            matlab.ui.control.Label
        MaxTimeSpansEditField          matlab.ui.control.EditField
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        WhatToDo = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, WhatToDo)
            movegui(app.SetLowPassFilterSettingsUIFigure, 'center');

            app.Mainapp = Mainapp;
            app.WhatToDo = WhatToDo;

            if strcmp(app.WhatToDo,"FilterOrder")
                app.MaxTimeSpansLabel_2.Text = "Filter Order (int)";
            elseif strcmp(app.WhatToDo,"Cutoff")
                app.MaxTimeSpansLabel_2.Text = "New Cutoff Frequency [Hz]";
            end
        end

        % Value changed function: MaxTimeSpansEditField
        function MaxTimeSpansEditFieldValueChanged(app, event)
            if strcmp(app.WhatToDo,"FilterOrder")
                app.Mainapp.FilterSettings.FilterOrder = app.MaxTimeSpansEditField.Value;
            elseif strcmp(app.WhatToDo,"Cutoff")
                app.Mainapp.FilterSettings.Cutoff = app.MaxTimeSpansEditField.Value;
            end
            
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SetLowPassFilterSettingsUIFigure = uifigure('Visible', 'off');
            app.SetLowPassFilterSettingsUIFigure.Name = 'Set Low Pass Filter Settings';
            app.SetLowPassFilterSettingsUIFigure.Icon = 'Logo.png';
            app.MaxTimeSpansEditField = uieditfield(app.SetLowPassFilterSettingsUIFigure, 'text');
            app.MaxTimeSpansEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTimeSpansEditFieldValueChanged, true);
            app.MaxTimeSpansLabel_2 = uilabel(app.SetLowPassFilterSettingsUIFigure);
            app.MaxTimeSpansLabel_2.Text = 'Max Time Span [s]';
            app.SetLowPassFilterSettingsLabel = uilabel(app.SetLowPassFilterSettingsUIFigure);
            app.SetLowPassFilterSettingsLabel.Text = 'Set Low Pass Filter Settings';
            app.Image = uiimage(app.SetLowPassFilterSettingsUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.SetLowPassFilterSettingsUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            % Show the figure after all components are created
            app.SetLowPassFilterSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Set_LowPassFilterSettings(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SetLowPassFilterSettingsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SetLowPassFilterSettingsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SetLowPassFilterSettingsUIFigure)
        end
    end
end