classdef TDT_LFP_or_AP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TDTDataSelectionUIFigure  matlab.ui.Figure
        InformationLabel          matlab.ui.control.Label
        ExtractLFPSignalButton    matlab.ui.control.Button
        ExtractAPSignalButton     matlab.ui.control.Button
        TextArea                  matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        SelectedRecordings = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function startupFcn(app)
            % Moce this window in the middle of thescreen
            movegui(app.TDTDataSelectionUIFigure, 'center');            %% Predefine variables for the app to work
            
            app = Utility_Change_Light_Dark_Mode(app,"TDTSelectLFPorAP");

            TexttoShow = strcat("Found action potential and LFP data for TDT tank data. Only one can be loaded at a time. Please either 'AP Signal' or 'LFP Signal' to proceed with either one of these.");

            app.TextArea.Value = TexttoShow;
        end

        % Button pushed function: ExtractAPSignalButton
        function ExtractAPSignalButtonPushed(app, event)
            app.SelectedRecordings = 2; % 2 for AP

            uiresume(app.TDTDataSelectionUIFigure);
        end

        % Button pushed function: ExtractLFPSignalButton
        function ExtractLFPSignalButtonPushed(app, event)
            app.SelectedRecordings = 1; % 1 for LFP

            uiresume(app.TDTDataSelectionUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TDTDataSelectionUIFigure = uifigure('Visible', 'off');
            app.TDTDataSelectionUIFigure.Name = 'TDT Data Selection';
            app.TDTDataSelectionUIFigure.Icon = 'Logo.png';
            app.TextArea = uitextarea(app.TDTDataSelectionUIFigure);
            app.ExtractAPSignalButton = uibutton(app.TDTDataSelectionUIFigure, 'push');
            app.ExtractAPSignalButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractAPSignalButtonPushed, true);
            app.ExtractAPSignalButton.Text = 'Extract AP Signal';
            app.ExtractLFPSignalButton = uibutton(app.TDTDataSelectionUIFigure, 'push');
            app.ExtractLFPSignalButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractLFPSignalButtonPushed, true);
            app.ExtractLFPSignalButton.Text = 'Extract LFP Signal';
            app.InformationLabel = uilabel(app.TDTDataSelectionUIFigure);
            app.InformationLabel.Text = 'Information';
            % Show the figure after all components are created
            app.TDTDataSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TDT_LFP_or_AP

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TDTDataSelectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.TDTDataSelectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TDTDataSelectionUIFigure)
        end
    end
end