classdef Extract_Data_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExtractDataWindowUIFigure    matlab.ui.Figure
        Menu_2                       matlab.ui.container.Menu
        TrajectoryExplorerMenu       matlab.ui.container.Menu
        StartTrajectoryExplorerMenu  matlab.ui.container.Menu
        LoadSavedTrajectoryMenu      matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        ExtractDataWindowLabel       matlab.ui.control.Label
        NeuromodLabel                matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        ExtractDataButton            matlab.ui.control.Button
        ExtractionOptionsPanel       matlab.ui.container.Panel
        AddProbeInformationButton    matlab.ui.control.Button
        FileTypeDropDown             matlab.ui.control.DropDown
        FileTypeLabel                matlab.ui.control.Label
        AutoDetectedRecordingSystemLabel  matlab.ui.control.Label
        RecordingSystemDropDown      matlab.ui.control.DropDown
        SelectDataFolderButton       matlab.ui.control.Button
        AdditionalAmplificationFactorEditField  matlab.ui.control.EditField
        AmplificationFactorLabel     matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        CloseButton                  matlab.ui.control.Button
        FolderContentsLabel          matlab.ui.control.Label
        TextArea_3                   matlab.ui.control.TextArea
        InformationLabel             matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        TextArea_4                   matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    % Define app properties shared across all app functions
    properties (Access = public)
        ProbeInfoandPath = []; % Info structure holding all relevant informations for this window

        ProbeTrajectoryInfo = [];

        ProbeLayoutWindow = []; % when opened here this gets populated
        Mainapp = [];
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            % Moce this window in the middle of thescreen
            movegui(app.ExtractDataWindowUIFigure, 'center');            %% Predefine variables for the app to work

            app.Mainapp = Mainapp; % Save Mainapp in this app as object; Data can be accesed via app.Mainapp.Data

            %cd(app.Mainapp.executableFolder); % CD in GUI path to make selecting data easier when saved in Raw Data folder

            app.Mainapp.CheckSuccesful_Extraction = 0; % 0 if extraction wasnt succesfully yet

            app.ExtractDataButton.Enable = "on"; % Folder has to be selected for the extraction button to become enabled
            app.TextArea.Value = ["Please select a folder containing your recording data. Supported are formats recorded with the Open Ephys GUI, Intan RHX data acquisition software (and legacy RHD software) as well as Spike2 and Cheetah software. This includes binary, .nwb and Open Ephys data formats from the Open Ephys GUI recorded with Neuropixels, Open Ephys and Intan acquisition boards; .dat and .rhd files from the Intan RHX and RHD software; .smrx files for Spike2 and .ncs for Neuralynx Cheetah files. Folder contents, file types and recording system will be shown automatically if folder contains one of the supported file types.";"Please make sure that the standard folder structure created by the recording software is NOT changed and the recording folder only contains files and folder created by the recording software OR this GUI!";"";"After selecting a folder, specify probe information. Required fields are channel number and channelspacing to specify the probe geometry. Active channel specify the amount and identity of probe channels you recorded from. The number of channels in your recording has to be the same as the number of elements in your active channel selection!";"After setting the probe information you can start the data extraction.";"";"Note: For Open Ephys data the expected folder structure is Date/Record Node/experiment/recordings/.... Multiple recordings within the same sessions can be concatonated or loaded individually. Please make sure only one recording session is present in the selected file, independent of the file format and recording system!";];
            app.TextArea_3.Value = "Please select a folder";
            pause(0.2);

            %% Dynamically add menu points based on savefile found in standard folder
  
            % Create parent menu
            parentMenu = uimenu(app.ExtractDataWindowUIFigure, 'Text', '   Load Saved Probe Information   ');
            
            % Create the secondary menu 
            filesMenu = uimenu(parentMenu, 'Text', 'Files in Standard Directory');

            % Create the second secondary menu 
            loadMenu = uimenu(parentMenu, 'Text', 'Manually Select File');

            % Extract filenames from standard folder
            DefaultFolder = strcat(app.Mainapp.executableFolder,'\Probe Layouts\Saved Probe Layouts\');
            
            app.ProbeInfoandPath.selectedFolder = [];

            if ~exist(DefaultFolder,"dir")
                disp(strcat("Standard Folder",DefaultFolder ," was not found. Please load probe information manually. No quick load from menu available!"));
            else
                [stringArray] = Utility_Extract_Contents_of_Folder(DefaultFolder);
                % exclude emoty and non - .mat parts
                stringArray = stringArray(stringArray ~= "");
                fileNames = stringArray(contains(stringArray,".mat"))';

                for i = 1:length(fileNames)
                    uimenu(filesMenu, 'Text', fileNames(i), 'MenuSelectedFcn', @(src,event) Menu_Extract_Data_Callback(app, fileNames(i), DefaultFolder , "Extract Data"));
                end
                
                uimenu(loadMenu, 'Text', 'Manually Select File', 'MenuSelectedFcn', @(src,event) Menu_Extract_Data_Load_Manually_Callback(app, "Extract Data"));
                
            end
        end

        % Button pushed function: ExtractDataButton
        function ExtractDataButtonPushed(app, event)
            if ~isfield(app.ProbeInfoandPath,'ChannelSpacing')
                msgbox("Error: Please first set a Channelspacing using the 'Probe Information' Button.");
                return;
            else
                if isempty(app.ProbeInfoandPath.ChannelSpacing)
                    msgbox("Error: Please first set a Channelspacing using the 'Probe Information' Button.");
                    return;
                end
            end
            if isempty(app.ProbeInfoandPath.selectedFolder)
                msgbox("Error: Please select a folder first.");
                return;
            end

            if ~isfield(app.ProbeInfoandPath,'NrChannel')
                msgbox("Error: Please first set a channel number using the 'Probe Information' Button.");
                return;
            else
                if isempty(app.ProbeInfoandPath.NrChannel)
                    msgbox("Error: Please first set a channel number using the 'Probe Information' Button.");
                    return;
                end
            end
            if ~isfield(app.ProbeInfoandPath,'ActiveChannel')
                msgbox("Error: Please first set active channel using the 'Probe Information' Button.");
                return;
            else
                if isempty(app.ProbeInfoandPath.NrChannel)
                    msgbox("Error: Please first set active channel using the 'Probe Information' Button.");
                    return;
                end
            end

            app.TextArea_3.Value = "Loading Data, please wait until this window closes.";
            pause(0.01);

            %% Extract Data
            tic
            if ~isempty(app.ProbeInfoandPath.selectedFolder)

                %% Prepare GUI
                % Function to reset Main Window when new Data is loaded
                % i.e resetting plot, TimeRange of plot, channel
                % selection...AND to delete old data structure if already present
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Initial");

                %% Extract Data
                [Data,HeaderInfo,SampleRate,RecordingType,Time] = Manage_Dataset_Module_Extract_Raw_Recording_Main(app.RecordingSystemDropDown.Value,app.FileTypeDropDown.Value,app.ProbeInfoandPath.selectedFolder,app.TextArea,app.Mainapp.executableFolder,app.AdditionalAmplificationFactorEditField.Value,app.ProbeInfoandPath.NrChannel,app.ProbeInfoandPath.NumberChannelRows);
                
                if isempty(Data)
                    app.TextArea.Value = "No Amplifier Channel Data found! Data Extraction cannot be finished.";
                    app.TextArea_3.Value = app.ProbeInfoandPath.selectedFolder;
                    pause(0.1);
                    return;
                end

                %% Apply/save Header Infos

                % Use some header infos and the data structure to define all necessary variables for this toolbox
                % Variable definitions necessary:
                % app.Data = Data.Raw and or Data.Preprocessed, depending on what is available;
                % app.Data.Time = Time;
                % app.Data.Info = HeaderInfo;
                % app.Data.Info.num_data_points = size(app.Data.Raw,2);
                % app.Data.Info.NrChannel = size(app.Data.Raw,1);
                % app.Data.Info.Data_Path = SelectedFolder;
                % app.Data.Info.NativeSamplingRate = SampleRate;
                % app.Data.Info.RecordingType = RecordingType;
                
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"VariableDefinition",Data,HeaderInfo,SampleRate,app.ProbeInfoandPath.selectedFolder,RecordingType,0,Time,app.ProbeInfoandPath);
    
                %% Apply ChannelOrder
                
                if ~isempty(app.ProbeInfoandPath.Channelorder)
                    [app.Mainapp.Data] = Manage_Dataset_Module_Apply_ChannelOrder(app.Mainapp.Data,app.ProbeInfoandPath.Channelorder);
                end

                if app.Mainapp.Data.Info.ProbeInfo.FlipLoadedData == 1
                    [app.Mainapp.Data.Raw] = Manage_Dataset_Module_Apply_DataFlip(app.Mainapp.Data.Raw);
                end
             
                %% 
            else
                % If the user hasnt selected a folder yet, display that and
                % return
                stringtoshow = "Error. No folder selected";
                f = msgbox(stringtoshow);
                return;
            end
            
            %% Initialize Infos for Main Window based on extracted and saved variables (previous call of this function)
            [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Extracting");
            toc
            %% Get all necessary Infos from Main Window to plot, set time scale based on time window AND plot data
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            
            %% Plot twice! Since This is setting the plotobject again, but in the reversed, 'proper' order. Otherwise channel name display when clickin on data plot will show Ch1 for Ch64 without updating the plot
            Utility_Initialize_Clicks_Plots(app.Mainapp,"Static");
            
            %% Open Probe View
            app.Mainapp.ProbeViewWindowHandle = Probe_View_Window(app.Mainapp,'MainWindow');

            [app.Mainapp] = Utility_Set_ToolTips(app.Mainapp,app.Mainapp.ShowToolTipsSetting,"ProbeViewMainWindow");

            % Close this window when Extraction succesfull
            close(app.ExtractDataWindowUIFigure);

        end

        % Button pushed function: SelectDataFolderButton
        function SelectDataFolderButtonPushed(app, event)
            app.ProbeInfoandPath.selectedFolder = [];
           
            PathToOpen = strcat(app.Mainapp.executableFolder,'\Recording Data\Raw Recordings\');

            [app.ProbeInfoandPath,ProbeInfoText,FolderContentsText] = Manage_Dataset_Module_Show_ProbeInfo_and_Path(app,app.ProbeInfoandPath,"FolderSelection",PathToOpen);
            
            if strcmp(app.ProbeTrajectoryInfo,"Loaded")
                ProbeInfoText(end+1) = "";
                ProbeInfoText(end+2) = " Succesfully loaded Probe Trajectory.";
            end

            if ~isempty(ProbeInfoText)
                app.TextArea.Value = ProbeInfoText;
            else
                app.TextArea.Value = '';
            end

            if ~isempty(FolderContentsText)
                app.TextArea_3.Value = FolderContentsText;
            else
                app.TextArea_3.Value = '';
            end

        end

        % Button pushed function: AddProbeInformationButton
        function AddProbeInformationButtonPushed(app, event)
            
            app.ProbeLayoutWindow = Probe_Layout_Window(app.Mainapp,app);
            
            [app.Mainapp] = Utility_Set_ToolTips(app.Mainapp,app.Mainapp.ShowToolTipsSetting,"SetProbeInfoWindow");

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                if ~isempty(app.ProbeLayoutWindow)
                    delete(app.ProbeLayoutWindow);
                end
    
                app.Mainapp.ExtractDataWindow = [];
    
                delete(app);
            catch ME
                delete(app);
            end
        end

        % Value changed function: RecordingSystemDropDown
        function RecordingSystemDropDownValueChanged(app, event)
          
        end

        % Value changed function: FileTypeDropDown
        function FileTypeDropDownValueChanged(app, event)
           
            
        end

        % Value changed function: AdditionalAmplificationFactorEditField
        function AdditionalAmplificationFactorEditFieldValueChanged(app, event)
            
        end

        % Menu selected function: StartTrajectoryExplorerMenu
        function StartTrajectoryExplorerMenuSelected(app, event)
            neuropixels_trajectory_explorer;
        end

        % Menu selected function: LoadSavedTrajectoryMenu
        function LoadSavedTrajectoryMenuSelected(app, event)
            PathToSave = strcat(app.Mainapp.executableFolder,'\Probe Layouts\Trajectories\');
            
            if isfolder(PathToSave)
                [fileName, filePath] = uigetfile(fullfile(PathToSave,'*.mat*'), 'Select a File to Load');
            else
                [fileName, filePath] = uigetfile('*.mat*', 'Select a File to Load');
            end
            
            % Check if the user selected a file or canceled the dialog
            if isequal(fileName, 0)
                disp('File selection canceled.');
            else
               
                fullFileName = fullfile(filePath, fileName);

                % Load the file (assuming it's a .mat file)
                LoadedProbeTrajectoryInfo = load(fullFileName);
                Areas = LoadedProbeTrajectoryInfo.probe_areas; 

                app.ProbeInfoandPath.ProbeTrajectoryInfo.AreaNamesLong = Areas{1}.name;
                app.ProbeInfoandPath.ProbeTrajectoryInfo.AreaNamesShort = Areas{1}.acronym;
                
                app.ProbeInfoandPath.ProbeTrajectoryInfo.AreaTipDistance = Areas{1}.tip_distance;
                
                disp('Probe Trajectory Info successfully loaded.');
                
                app.ProbeTrajectoryInfo = "Loaded";
                if length(app.TextArea.Value)==1
                    TextAreaContents = app.TextArea.Value{1};
                    TextAreaContents = strcat(TextAreaContents," "," Succesfully loaded Probe Trajectory.");
                else
                    TextAreaContents = app.TextArea.Value;
                    TextAreaContents{end+1} = ' Succesfully loaded Probe Trajectory.';
                end

                app.TextArea.Value = TextAreaContents;
            end

        end

        % Close request function: ExtractDataWindowUIFigure
        function ExtractDataWindowUIFigureCloseRequest(app, event)
            try
                if ~isempty(app.ProbeLayoutWindow)
                    delete(app.ProbeLayoutWindow);
                end
    
                app.Mainapp.ExtractDataWindow = [];
    
                delete(app);

            catch ME
                delete(app);
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ExtractDataWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {520, 520};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {251, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExtractDataWindowUIFigure = uifigure('Visible', 'off');
            app.ExtractDataWindowUIFigure.Name = 'Extract Data Window';
            app.ExtractDataWindowUIFigure.Icon = 'Logo.png';
            app.ExtractDataWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @ExtractDataWindowUIFigureCloseRequest, true);
            app.ExtractDataWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu_2 = uimenu(app.ExtractDataWindowUIFigure);
            app.TrajectoryExplorerMenu = uimenu(app.ExtractDataWindowUIFigure);
            app.TrajectoryExplorerMenu.Text = '   Trajectory Explorer   ';
            app.StartTrajectoryExplorerMenu = uimenu(app.TrajectoryExplorerMenu);
            app.StartTrajectoryExplorerMenu.MenuSelectedFcn = createCallbackFcn(app, @StartTrajectoryExplorerMenuSelected, true);
            app.StartTrajectoryExplorerMenu.Text = 'Start Trajectory Explorer (requires to download Allen mouse brain atlas)';
            app.LoadSavedTrajectoryMenu = uimenu(app.TrajectoryExplorerMenu);
            app.LoadSavedTrajectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSavedTrajectoryMenuSelected, true);
            app.LoadSavedTrajectoryMenu.Text = 'Load Saved Trajectory';
            app.GridLayout = uigridlayout(app.ExtractDataWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ExtractionOptionsPanel = uipanel(app.LeftPanel);
            app.ExtractionOptionsPanel.Title = 'Extraction Options';
            app.AmplificationFactorLabel = uilabel(app.ExtractionOptionsPanel);
            app.AmplificationFactorLabel.Text = {'Additional Headstage'; 'Amplification:'};
            app.AdditionalAmplificationFactorEditField = uieditfield(app.ExtractionOptionsPanel, 'text');
            app.AdditionalAmplificationFactorEditField.ValueChangedFcn = createCallbackFcn(app, @AdditionalAmplificationFactorEditFieldValueChanged, true);
            app.SelectDataFolderButton = uibutton(app.ExtractionOptionsPanel, 'push');
            app.SelectDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataFolderButtonPushed, true);
            app.SelectDataFolderButton.Text = 'Select Data Folder';
            app.RecordingSystemDropDown = uidropdown(app.ExtractionOptionsPanel);
            app.RecordingSystemDropDown.Items = {};
            app.RecordingSystemDropDown.ValueChangedFcn = createCallbackFcn(app, @RecordingSystemDropDownValueChanged, true);
            app.RecordingSystemDropDown.Value = {};
            app.AutoDetectedRecordingSystemLabel = uilabel(app.ExtractionOptionsPanel);
            app.AutoDetectedRecordingSystemLabel.Text = {'Auto Detected'; 'Recording System'};
            app.FileTypeLabel = uilabel(app.ExtractionOptionsPanel);
            app.FileTypeLabel.Text = 'File Type';
            app.FileTypeDropDown = uidropdown(app.ExtractionOptionsPanel);
            app.FileTypeDropDown.Items = {};
            app.FileTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FileTypeDropDownValueChanged, true);
            app.FileTypeDropDown.Value = {};
            app.AddProbeInformationButton = uibutton(app.ExtractionOptionsPanel, 'push');
            app.AddProbeInformationButton.ButtonPushedFcn = createCallbackFcn(app, @AddProbeInformationButtonPushed, true);
            app.AddProbeInformationButton.Text = {'Add Probe'; 'Information'};
            app.ExtractDataButton = uibutton(app.LeftPanel, 'push');
            app.ExtractDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractDataButtonPushed, true);
            app.ExtractDataButton.Text = 'Extract Data';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodLabel = uilabel(app.LeftPanel);
            app.NeuromodLabel.Text = 'Neuromod ';
            app.ExtractDataWindowLabel = uilabel(app.LeftPanel);
            app.ExtractDataWindowLabel.Text = 'Extract Data Window';
            app.RightPanel = uipanel(app.GridLayout);
            app.TextArea_4 = uitextarea(app.RightPanel);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Enable = 'off';
            app.TextArea_4.Visible = 'off';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Editable = 'off';
            app.InformationLabel = uilabel(app.RightPanel);
            app.InformationLabel.Text = 'Information';
            app.TextArea_3 = uitextarea(app.RightPanel);
            app.TextArea_3.Editable = 'off';
            app.FolderContentsLabel = uilabel(app.RightPanel);
            app.FolderContentsLabel.Text = 'Folder Contents';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.ExtractDataWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Extract_Data_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ExtractDataWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ExtractDataWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExtractDataWindowUIFigure)
        end
    end
end