classdef Probe_Layout_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProbeLayoutWindowUIFigure       matlab.ui.Figure
        SaveProbeInformationButton      matlab.ui.control.Button
        SetProbeInformationandContinueButton  matlab.ui.control.Button
        NeuromodLabel                   matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ProbeLayoutWindowLabel          matlab.ui.control.Label
        SetProbeInformationPanel        matlab.ui.container.Panel
        SaveActiveChannelSelectionButton  matlab.ui.control.Button
        LoadActiveChannelSelectionButton  matlab.ui.control.Button
        ActiveChannelLabel              matlab.ui.control.Label
        ChannelOrderLabel               matlab.ui.control.Label
        ChannelOrderFormat1234LeaveemptyfornocostumorderEditField_3  matlab.ui.control.TextArea
        ChannelOrderFormat1234LeaveemptyfornocostumorderLabel_2  matlab.ui.control.Label
        HorizontalOffsetumEditField     matlab.ui.control.EditField
        HorizontalOffsetumEditFieldLabel  matlab.ui.control.Label
        SaveChannelOrderButton          matlab.ui.control.Button
        LoadChannelOrderButton          matlab.ui.control.Button
        NrChannelEditField              matlab.ui.control.EditField
        NrChannelperRowEditFieldLabel   matlab.ui.control.Label
        VerticalOffsetumEditField       matlab.ui.control.EditField
        VerticalOffsetumEditFieldLabel  matlab.ui.control.Label
        ChannelRowsDropDown             matlab.ui.control.DropDown
        ChannelRowsDropDownLabel        matlab.ui.control.Label
        ChannelOrderFormat1234LeaveemptyfornocostumorderEditField  matlab.ui.control.TextArea
        ChannelOrderFormat1234LeaveemptyfornocostumorderLabel  matlab.ui.control.Label
        ChannelSpacingumEditField       matlab.ui.control.EditField
        ChannelSpacingumLabel           matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        SaveforKilosortPanel            matlab.ui.container.Panel
        ChannelmapNameEditField         matlab.ui.control.EditField
        ChannelmapNameEditFieldLabel    matlab.ui.control.Label
        SaveChannelmapasmatforKilosortButton  matlab.ui.control.Button
        InteractiveProbeViewLabel       matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        ExtractRecordingWindow = [];
        Load_Data_Window_Info = [];
        FirstZoomChannel = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, ExtractRecordingWindow)
            % Moce this window in the middle of thescreen
            movegui(app.ProbeLayoutWindowUIFigure, 'center');

            app.Mainapp = Mainapp;
            app.ExtractRecordingWindow = ExtractRecordingWindow;

            % Clear any previous plots and hold the plot for multiple elements
            cla(app.UIAxes);
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            % Turn off the box around app.UIAxes
            box(app.UIAxes, 'off');
            app.UIAxes.FontSize = 10;
            %yTicks = get(Figure, 'YTickLabel'); % Current y-tick values
            set(app.UIAxes, 'XTickLabel',[]);

        end

        % Button pushed function: LoadChannelOrderButton
        function LoadChannelOrderButtonPushed(app, event)
            cd(app.Mainapp.executableFolder);
            %% Load a .mat file containing a vector with numbers indicating the true position of a channel on the probe
            % See ReadMe file in the ChannelOrder folder of the GUI 
            % stringtoshow = "Please select a .mat file containing your channel order (numbers).";
            % f = msgbox(stringtoshow);
            % Use uiget to to select a .mat file
            [file, path] = uigetfile('*.mat', 'Select a .mat file');
            
            % Check if the user cancels the operation
            if isequal(file,0) || isequal(path,0)
                disp('Operation canceled.');
                return;
            end
            
            % Load the selected .mat file and save in the mainapp
            tempChOrder = load(fullfile(path, file));
            % Get the field names of the structure
            fieldNames = fieldnames(tempChOrder);
            fieldName = fieldNames{1};

            app.Load_Data_Window_Info.Channelorder = tempChOrder.(fieldName);

            %% Display the channelorder in the Window 

            %tempChOrder = app.Load_Data_Window_Info.Channelorder;
            texttoshow = sprintf('%d, ', app.Load_Data_Window_Info.Channelorder);
            % Remove the trailing comma and whitespace
            texttoshow(end-1:end) = [];

            app.ChannelOrderFormat1234LeaveemptyfornocostumorderEditField.Value = texttoshow;

        end

        % Value changed function: ChannelSpacingumEditField
        function ChannelSpacingumEditFieldValueChanged(app, event)
            Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),app.ChannelOrderFormat1234LeaveemptyfornocostumorderEditField.Value,app.ChannelOrderFormat1234LeaveemptyfornocostumorderEditField_3.Value,app.FirstZoomChannel,1)

            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)
                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                
            end
        end

        % Button pushed function: SetProbeInformationandContinueButton
        function SetProbeInformationandContinueButtonPushed(app, event)
            if isempty(app.ChannelSpacingumEditField.Value)
                msgbox("Error: Please first input a channel spacing for your probe design! Returning")
                return;
            end

            app.ExtractRecordingWindow.Load_Data_Window_Info.ChannelSpacing = str2double(app.ChannelSpacingumEditField.Value);

            if isempty(app.ChannelOrderFormat1234LeaveemptyfornocostumorderEditField.Value)
                app.ExtractRecordingWindow.Load_Data_Window_Info.Channelorder = [];
            else
                % Split the string by commas
                split_array = strsplit(app.ChannelOrderFormat1234LeaveemptyfornocostumorderEditField.Value{1}, ',');
                
                % Convert each element to double
                app.ExtractRecordingWindow.Load_Data_Window_Info.Channelorder = str2double(split_array);
            end

            delete(app);
        end

        % Callback function
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Callback function
        function SaveChannelmapasmatforKilosortButtonPushed(app, event)
            cd(app.Mainapp.executableFolder);

            if isempty(app.ChannelSpacingumEditField.Value)
                stringtoshow = "Error. Please first define a channel spacing on the left side of this window";
                msgbox(stringtoshow);
                return;
            end
            if isempty(app.ChannelmapNameEditField.Value)
                stringtoshow = "Error. Please first define a channel name on the right side of this window";
                msgbox(stringtoshow);
                return;
            else
                name = app.ChannelmapNameEditField.Value;
            end

            if isempty(app.NrofProbeChannelEditField.Value)
                stringtoshow = "Error. Please first define a channel number on the right side of this window";
                msgbox(stringtoshow);
                return;
            end
            
            NrChannel = str2double(app.NrofProbeChannelEditField.Value);

            xcoords = zeros(NrChannel,1);
            ycoords = 0:str2double(app.ChannelSpacingumEditField.Value):str2double(app.ChannelSpacingumEditField.Value)*NrChannel-str2double(app.ChannelSpacingumEditField.Value);

            ycoords = ycoords';
            kcoords = zeros(NrChannel,1)+1;
            connected = true(NrChannel,1);
            chanMap = 1:1:NrChannel;
            chanMap = chanMap';
            chanMap0ind = 0:1:NrChannel-1;
            chanMap0ind = chanMap0ind';

            % Prompt user for file save location and name
            [file, path] = uiputfile('*.mat', 'Save as');
            
            if isequal(file,0) || isequal(path,0)
                disp('User canceled the operation.');
                return;
            end
    
            % Construct the full file path
            filepath = fullfile(path, file);
            % Save the variables to the .mat file
            save(filepath, 'xcoords','ycoords','kcoords','chanMap','chanMap0ind','connected');
            stringtoshow = ['Channelmap saved to: ' filepath];
            msgbox(stringtoshow);
        end

        % Button pushed function: SaveChannelOrderButton
        function SaveChannelOrderButtonPushed(app, event)
            cd(app.Mainapp.executableFolder);
            
            if ~isempty(app.ChannelOrderFormat1234LeaveemptyfornocostumorderEditField.Value)

                % Split the string by commas
                split_array = strsplit(app.ChannelOrderFormat1234LeaveemptyfornocostumorderEditField.Value{1}, ',');
                
                % Convert each element to double
                Channelorder = str2double(split_array);

                % Prompt user for file save location and name
                [file, path] = uiputfile('*.mat', 'Save as');
                
                if isequal(file,0) || isequal(path,0)
                    disp('User canceled the operation.');
                    return;
                end
        
                % Construct the full file path
                filepath = fullfile(path, file);
                % Save the variables to the .mat file
                save(filepath, 'Channelorder');
                stringtoshow = ['Channel Order saved to: ' filepath];
                msgbox(stringtoshow);
            else
                stringtoshow = 'No Channel Order specified in the text field "Channel Order"';
                msgbox(stringtoshow);
            end
        end

        % Button pushed function: SaveProbeInformationButton
        function SaveProbeInformationButtonPushed(app, event)
           if isempty(app.ChannelSpacingumEditField.Value)
                msgbox("Please first define a channelspacing! Returning.")
           end

           cd(strcat(app.ExtractRecordingWindow.Load_Data_Window_Info.Mainapp.executableFolder,'\Channel Maps\Saved Probe Information\'));

            % Prompt user for file save location and name
            [file, path] = uiputfile('*.mat', 'Save as');
            
            if isequal(file,0) || isequal(path,0)
                disp('User canceled the operation.');
                return;
            end

            Savefilepath = fullfile(path,file);
            DatatoSave.ChannelSpacing = app.ChannelSpacingumEditField.Value;
            DatatoSave.ChannelOrder = app.ChannelOrderFormat1234LeaveemptyfornocostumorderEditField.Value;

            save(Savefilepath,"DatatoSave");
            
            msgbox(strcat("Probe Information succesfully saved to: ",Savefilepath));
            disp(strcat("Probe Information succesfully saved to: ",Savefilepath));


        end

        % Value changed function: NrChannelEditField
        function NrChannelEditFieldValueChanged(app, event)
            Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),app.ChannelOrderFormat1234LeaveemptyfornocostumorderEditField.Value,app.ChannelOrderFormat1234LeaveemptyfornocostumorderEditField_3.Value,app.FirstZoomChannel,1)
            
            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)
                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed2(app, event)
            delete(app)
        end

        % Value changed function: HorizontalOffsetumEditField
        function HorizontalOffsetumEditFieldValueChanged(app, event)
            
        end

        % Value changed function: ChannelRowsDropDown
        function ChannelRowsDropDownValueChanged(app, event)
           
            Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),app.ChannelOrderFormat1234LeaveemptyfornocostumorderEditField.Value,app.ChannelOrderFormat1234LeaveemptyfornocostumorderEditField_3.Value,app.FirstZoomChannel,1)
            
            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)
                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                
            end
        end

        % Value changed function: VerticalOffsetumEditField
        function VerticalOffsetumEditFieldValueChanged(app, event)
            
            Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),app.ChannelOrderFormat1234LeaveemptyfornocostumorderEditField.Value,app.ChannelOrderFormat1234LeaveemptyfornocostumorderEditField_3.Value,app.FirstZoomChannel,1)
            
            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)
                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                
            end
        end

        % Button pushed function: SaveChannelmapasmatforKilosortButton
        function SaveChannelmapasmatforKilosortButtonPushed2(app, event)
            
        end

        % Value changing function: ChannelSpacingumEditField
        function ChannelSpacingumEditFieldValueChanging(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProbeLayoutWindowUIFigure = uifigure('Visible', 'off');
            app.ProbeLayoutWindowUIFigure.Name = 'Probe Layout Window';
            app.ProbeLayoutWindowUIFigure.Icon = 'Logo.png';
            app.UIAxes = uiaxes(app.ProbeLayoutWindowUIFigure);
            app.UIAxes.XTickLabel = '';
            app.InteractiveProbeViewLabel = uilabel(app.ProbeLayoutWindowUIFigure);
            app.InteractiveProbeViewLabel.Text = 'Interactive Probe View';
            app.SaveforKilosortPanel = uipanel(app.ProbeLayoutWindowUIFigure);
            app.SaveforKilosortPanel.Title = 'Save for Kilosort';
            app.SaveChannelmapasmatforKilosortButton = uibutton(app.SaveforKilosortPanel, 'push');
            app.SaveChannelmapasmatforKilosortButton.ButtonPushedFcn = createCallbackFcn(app, @SaveChannelmapasmatforKilosortButtonPushed2, true);
            app.SaveChannelmapasmatforKilosortButton.Text = {'Save Channelmap as '; '.mat for Kilosort'};
            app.ChannelmapNameEditFieldLabel = uilabel(app.SaveforKilosortPanel);
            app.ChannelmapNameEditFieldLabel.Text = 'Channelmap Name';
            app.ChannelmapNameEditField = uieditfield(app.SaveforKilosortPanel, 'text');
            app.CloseButton = uibutton(app.ProbeLayoutWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed2, true);
            app.CloseButton.Text = 'Close';
            app.SetProbeInformationPanel = uipanel(app.ProbeLayoutWindowUIFigure);
            app.SetProbeInformationPanel.Title = 'Set Probe Information';
            app.ChannelSpacingumLabel = uilabel(app.SetProbeInformationPanel);
            app.ChannelSpacingumLabel.Text = {'Channel Spacing '; '[um]'};
            app.ChannelSpacingumEditField = uieditfield(app.SetProbeInformationPanel, 'text');
            app.ChannelSpacingumEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSpacingumEditFieldValueChanged, true);
            app.ChannelSpacingumEditField.ValueChangingFcn = createCallbackFcn(app, @ChannelSpacingumEditFieldValueChanging, true);
            app.ChannelOrderFormat1234LeaveemptyfornocostumorderLabel = uilabel(app.SetProbeInformationPanel);
            app.ChannelOrderFormat1234LeaveemptyfornocostumorderLabel.Text = {'Format: 1,2,3,4'; 'Leave empty for no costum order'};
            app.ChannelOrderFormat1234LeaveemptyfornocostumorderEditField = uitextarea(app.SetProbeInformationPanel);
            app.ChannelRowsDropDownLabel = uilabel(app.SetProbeInformationPanel);
            app.ChannelRowsDropDownLabel.Text = 'Channel Rows';
            app.ChannelRowsDropDown = uidropdown(app.SetProbeInformationPanel);
            app.ChannelRowsDropDown.Items = {'1', '2'};
            app.ChannelRowsDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelRowsDropDownValueChanged, true);
            app.ChannelRowsDropDown.Value = '1';
            app.VerticalOffsetumEditFieldLabel = uilabel(app.SetProbeInformationPanel);
            app.VerticalOffsetumEditFieldLabel.Text = 'Vertical Offset [um]';
            app.VerticalOffsetumEditField = uieditfield(app.SetProbeInformationPanel, 'text');
            app.VerticalOffsetumEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalOffsetumEditFieldValueChanged, true);
            app.NrChannelperRowEditFieldLabel = uilabel(app.SetProbeInformationPanel);
            app.NrChannelperRowEditFieldLabel.Text = {'Nr Channel'; 'per Row'};
            app.NrChannelEditField = uieditfield(app.SetProbeInformationPanel, 'text');
            app.NrChannelEditField.ValueChangedFcn = createCallbackFcn(app, @NrChannelEditFieldValueChanged, true);
            app.LoadChannelOrderButton = uibutton(app.SetProbeInformationPanel, 'push');
            app.LoadChannelOrderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadChannelOrderButtonPushed, true);
            app.LoadChannelOrderButton.Text = {'Load Channel '; 'Order'};
            app.SaveChannelOrderButton = uibutton(app.SetProbeInformationPanel, 'push');
            app.SaveChannelOrderButton.ButtonPushedFcn = createCallbackFcn(app, @SaveChannelOrderButtonPushed, true);
            app.SaveChannelOrderButton.Text = {'Save Channel '; 'Order'};
            app.HorizontalOffsetumEditFieldLabel = uilabel(app.SetProbeInformationPanel);
            app.HorizontalOffsetumEditFieldLabel.Text = 'Horizontal Offset [um]';
            app.HorizontalOffsetumEditField = uieditfield(app.SetProbeInformationPanel, 'text');
            app.HorizontalOffsetumEditField.ValueChangedFcn = createCallbackFcn(app, @HorizontalOffsetumEditFieldValueChanged, true);
            app.ChannelOrderFormat1234LeaveemptyfornocostumorderLabel_2 = uilabel(app.SetProbeInformationPanel);
            app.ChannelOrderFormat1234LeaveemptyfornocostumorderLabel_2.Text = {'Format: 1,2,3,4'; 'Leave empty for all channel active'};
            app.ChannelOrderFormat1234LeaveemptyfornocostumorderEditField_3 = uitextarea(app.SetProbeInformationPanel);
            app.ChannelOrderLabel = uilabel(app.SetProbeInformationPanel);
            app.ChannelOrderLabel.Text = 'Channel Order';
            app.ActiveChannelLabel = uilabel(app.SetProbeInformationPanel);
            app.ActiveChannelLabel.Text = 'Active Channel';
            app.LoadActiveChannelSelectionButton = uibutton(app.SetProbeInformationPanel, 'push');
            app.LoadActiveChannelSelectionButton.Text = {'Load Active'; 'Channel Selection'};
            app.SaveActiveChannelSelectionButton = uibutton(app.SetProbeInformationPanel, 'push');
            app.SaveActiveChannelSelectionButton.Text = {'Save Active'; 'Channel Selection'};
            app.ProbeLayoutWindowLabel = uilabel(app.ProbeLayoutWindowUIFigure);
            app.ProbeLayoutWindowLabel.Text = 'Probe Layout Window';
            app.Image = uiimage(app.ProbeLayoutWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodLabel = uilabel(app.ProbeLayoutWindowUIFigure);
            app.NeuromodLabel.Text = 'Neuromod';
            app.SetProbeInformationandContinueButton = uibutton(app.ProbeLayoutWindowUIFigure, 'push');
            app.SetProbeInformationandContinueButton.ButtonPushedFcn = createCallbackFcn(app, @SetProbeInformationandContinueButtonPushed, true);
            app.SetProbeInformationandContinueButton.Text = {'Set Probe Information '; 'and Continue'};
            app.SaveProbeInformationButton = uibutton(app.ProbeLayoutWindowUIFigure, 'push');
            app.SaveProbeInformationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProbeInformationButtonPushed, true);
            app.SaveProbeInformationButton.Text = {'Save Probe '; 'Information'};
            % Show the figure after all components are created
            app.ProbeLayoutWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Probe_Layout_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ProbeLayoutWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ProbeLayoutWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProbeLayoutWindowUIFigure)
        end
    end
end