classdef Spike2_Select_Event_Channel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Spike2SelectEventChannelUIFigure  matlab.ui.Figure
        CloseButton                    matlab.ui.control.Button
        NeuromodToolboxLabel           matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        Spike2SelectEventChannelLabel  matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        EventChannelSelectionIntemptyfornonEditField  matlab.ui.control.EditField
        EventChannelSelectionIntemptyfornonEditFieldLabel  matlab.ui.control.Label
        ProceedButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        SpikeEventChannel = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SpikeEventChannel)
            movegui(app.Spike2SelectEventChannelUIFigure, 'center');

            app.SpikeEventChannel = SpikeEventChannel;
                       
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            % Step 1: Split the char array into individual strings using the comma as a delimiter
            if length(app.EventChannelSelectionIntemptyfornonEditField.Value)>1
                splitStrings = split(app.EventChannelSelectionIntemptyfornonEditField.Value, ',');
                
                % Step 2: Convert the split strings to numeric values
                app.SpikeEventChannel = str2double(splitStrings);
            else
                app.SpikeEventChannel = str2double(app.EventChannelSelectionIntemptyfornonEditField.Value);
            end

            
            % Resume execution to return the output
            uiresume(app.Spike2SelectEventChannelUIFigure);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Spike2SelectEventChannelUIFigure = uifigure('Visible', 'off');
            app.Spike2SelectEventChannelUIFigure.Name = 'Spike2 Select Event Channel';
            app.Spike2SelectEventChannelUIFigure.Icon = 'Logo.png';
            app.Panel = uipanel(app.Spike2SelectEventChannelUIFigure);
            app.ProceedButton = uibutton(app.Panel, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.EventChannelSelectionIntemptyfornonEditFieldLabel = uilabel(app.Panel);
            app.EventChannelSelectionIntemptyfornonEditFieldLabel.Text = {'Event Channel'; 'Selection; Int'; '(empty for non)'};
            app.EventChannelSelectionIntemptyfornonEditField = uieditfield(app.Panel, 'text');
            app.Spike2SelectEventChannelLabel = uilabel(app.Spike2SelectEventChannelUIFigure);
            app.Spike2SelectEventChannelLabel.Text = 'Spike2 Select Event Channel';
            app.Image = uiimage(app.Spike2SelectEventChannelUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.Spike2SelectEventChannelUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.Spike2SelectEventChannelUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.Spike2SelectEventChannelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spike2_Select_Event_Channel(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Spike2SelectEventChannelUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.Spike2SelectEventChannelUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Spike2SelectEventChannelUIFigure)
        end
    end
end