classdef Spectral_Power_Estimate_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectralDensityWindowUIFigure   matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        ChangePlotPropertiesMenu        matlab.ui.container.Menu
        PowerEstimatePlotMenu           matlab.ui.container.Menu
        FontSizeMenu                    matlab.ui.container.Menu
        BarColorMenu                    matlab.ui.container.Menu
        BackgorundColorMenu             matlab.ui.container.Menu
        XLabelMenu                      matlab.ui.container.Menu
        YLabelMenu                      matlab.ui.container.Menu
        RESETMenu                       matlab.ui.container.Menu
        SaveCostumAppearanceMenu        matlab.ui.container.Menu
        DeleteCostumAppearanceMenu      matlab.ui.container.Menu
        ExportDataasMenu                matlab.ui.container.Menu
        matMenu                         matlab.ui.container.Menu
        txtMenu                         matlab.ui.container.Menu
        csvMenu                         matlab.ui.container.Menu
        ChangeStandardSaveLocationMenu  matlab.ui.container.Menu
        CloseButton                     matlab.ui.control.Button
        NeuromodToolboxLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        PowerEstimateWindowLabel        matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        ChannelSelectionPreprocessedChannelEditField  matlab.ui.control.EditField
        ChannelSelectionPreprocessedChannelEditFieldLabel  matlab.ui.control.Label
        LockYLimCheckBox                matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        ExistflagSDE = [];
        PDLim = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.SpectralDensityWindowUIFigure, 'center');
            
            app.Mainapp = Mainapp;
            app.Mainapp.SpectralEstApp = app;

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.LivePowerEstimateWindow.FontSize;
            app.UIAxes.Color = app.Mainapp.PlotAppearance.LivePowerEstimateWindow.BackgroundColor;

            if ~isfield(app.Mainapp.Data,'Preprocessed') || ~isfield(app.Mainapp.Data.Info,'FilterMethod')
                f = msgbox("Error: No Preprocessed data found or data was not low pass filtered. Exiting");
                app.Mainapp.CSDApp = [];
                delete(app);
                return;
            end
            if isfield(app.Mainapp.Data.Info,'FilterMethod')
                if ~strcmp(app.Mainapp.Data.Info.FilterMethod,"Low-Pass")
                    f = msgbox("Error: No low pass filtered data found. Exiting");
                    app.Mainapp.CSDApp = [];
                    delete(app);
                    return;
                end
            end

            app.ExistflagSDE = 1;

           app.ChannelSelectionPreprocessedChannelEditField.Value = strcat(num2str(1),",",num2str(size(app.Mainapp.Data.Preprocessed,1)));

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
           
            CommaIndex = strfind(app.ChannelSelectionPreprocessedChannelEditField.Value,",");
            ChannelSelection(1) = str2double(app.ChannelSelectionPreprocessedChannelEditField.Value(1:CommaIndex-1));
            ChannelSelection(2) = str2double(app.ChannelSelectionPreprocessedChannelEditField.Value(CommaIndex+1:end));

            xtickangle(app.UIAxes, 45);
            
            app.Mainapp.SpectralEstApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                      
        end

        % Close request function: SpectralDensityWindowUIFigure
        function SpectralDensityWindowUIFigureCloseRequest(app, event)
            app.Mainapp.SpectralEstApp = [];
            app.ExistflagSDE = [];
            delete(app)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: 
        % ChannelSelectionPreprocessedChannelEditField
        function ChannelSelectionPreprocessedChannelEditFieldValueChanged(app, event)
            if isfield(app.Mainapp.Data,"Preprocessed")
                [app.ChannelSelectionPreprocessedChannelEditField.Value] = Utility_SimpleCheckInputs(app.ChannelSelectionPreprocessedChannelEditField.Value,"Two",strcat('1,',num2str(size(app.Mainapp.Data.Preprocessed,1))),1,0);
           else
                [app.ChannelSelectionPreprocessedChannelEditField.Value] = Utility_SimpleCheckInputs(app.ChannelSelectionPreprocessedChannelEditField.Value,"Two",strcat('1,',num2str(size(app.Mainapp.Data.Raw,1))),1,0);
           end
            app.Mainapp.SpectralEstApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Value changed function: LockYLimCheckBox
        function LockYLimCheckBoxValueChanged(app, event)
            value = app.LockYLimCheckBox.Value;
            
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.SpectralDensityWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.SpectralDensityWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_Spectral_Power_Estimate_Main_Window");
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_Spectral_Power_Estimate_Main_Window");
        end

        % Menu selected function: csvMenu
        function csvMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_Spectral_Power_Estimate_Main_Window");
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize',app.Mainapp,"LivePEWindowDataFontSize");
            waitfor(h);

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.LivePowerEstimateWindow.FontSize;
        end

        % Menu selected function: BarColorMenu
        function BarColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.LivePowerEstimateWindow.BarColor = selectedColor;
            end

            app.Mainapp.SpectralEstApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: BackgorundColorMenu
        function BackgorundColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.LivePowerEstimateWindow.BackgroundColor = selectedColor;
                app.UIAxes.Color = app.Mainapp.PlotAppearance.LivePowerEstimateWindow.BackgroundColor;
            end
        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'xlabel',app.Mainapp,"LivePEWindowXLabel");
            waitfor(h);

            app.Mainapp.SpectralEstApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'ylabel',app.Mainapp,"LivePEWindowYLabel");
            waitfor(h);

            app.Mainapp.SpectralEstApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("PowerEstimatePlot",app.Mainapp.PlotAppearance);

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.LivePowerEstimateWindow.FontSize;
            app.UIAxes.Color = app.Mainapp.PlotAppearance.LivePowerEstimateWindow.BackgroundColor;

            app.Mainapp.SpectralEstApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectralDensityWindowUIFigure = uifigure('Visible', 'off');
            app.SpectralDensityWindowUIFigure.Name = 'Spectral Density Window';
            app.SpectralDensityWindowUIFigure.Icon = 'Logo.png';
            app.SpectralDensityWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @SpectralDensityWindowUIFigureCloseRequest, true);
            app.SnapshotMenu = uimenu(app.SpectralDensityWindowUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.PlotMenu = uimenu(app.SpectralDensityWindowUIFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.ChangePlotPropertiesMenu = uimenu(app.PlotMenu);
            app.ChangePlotPropertiesMenu.Text = 'Change Plot Properties';
            app.PowerEstimatePlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.PowerEstimatePlotMenu.Text = 'Power Estimate Plot';
            app.FontSizeMenu = uimenu(app.PowerEstimatePlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.BarColorMenu = uimenu(app.PowerEstimatePlotMenu);
            app.BarColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BarColorMenuSelected, true);
            app.BarColorMenu.Text = 'Bar Color';
            app.BackgorundColorMenu = uimenu(app.PowerEstimatePlotMenu);
            app.BackgorundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenuSelected, true);
            app.BackgorundColorMenu.Text = 'Backgorund Color';
            app.XLabelMenu = uimenu(app.PowerEstimatePlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.PowerEstimatePlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.RESETMenu = uimenu(app.PowerEstimatePlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.SaveCostumAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SaveCostumAppearanceMenu.Text = 'Save Costum Appearance';
            app.DeleteCostumAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DeleteCostumAppearanceMenu.Text = 'Delete Costum Appearance';
            app.ExportDataasMenu = uimenu(app.SpectralDensityWindowUIFigure);
            app.ExportDataasMenu.Text = '   Export Data as   ';
            app.matMenu = uimenu(app.ExportDataasMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ExportDataasMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.csvMenu = uimenu(app.ExportDataasMenu);
            app.csvMenu.MenuSelectedFcn = createCallbackFcn(app, @csvMenuSelected, true);
            app.csvMenu.Text = '.csv';
            app.ChangeStandardSaveLocationMenu = uimenu(app.ExportDataasMenu);
            app.ChangeStandardSaveLocationMenu.Text = 'Change Standard Save Location';
            app.UIAxes = uiaxes(app.SpectralDensityWindowUIFigure);
            app.Panel = uipanel(app.SpectralDensityWindowUIFigure);
            app.LockYLimCheckBox = uicheckbox(app.Panel);
            app.LockYLimCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockYLimCheckBoxValueChanged, true);
            app.LockYLimCheckBox.Text = '   Lock YLim';
            app.LockYLimCheckBox.Value = true;
            app.ChannelSelectionPreprocessedChannelEditFieldLabel = uilabel(app.Panel);
            app.ChannelSelectionPreprocessedChannelEditFieldLabel.Text = {'Channel Selection'; '(Preprocessed Channel)'};
            app.ChannelSelectionPreprocessedChannelEditField = uieditfield(app.Panel, 'text');
            app.ChannelSelectionPreprocessedChannelEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionPreprocessedChannelEditFieldValueChanged, true);
            app.PowerEstimateWindowLabel = uilabel(app.SpectralDensityWindowUIFigure);
            app.PowerEstimateWindowLabel.Text = 'Power Estimate Window';
            app.Image = uiimage(app.SpectralDensityWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.SpectralDensityWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.SpectralDensityWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.SpectralDensityWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spectral_Power_Estimate_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SpectralDensityWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SpectralDensityWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectralDensityWindowUIFigure)
        end
    end
end