classdef Spike_Rate_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpikeRateWindowUIFigure       matlab.ui.Figure
        SnapshotMenu                  matlab.ui.container.Menu
        SaveSnapshotofMainWindowMenu  matlab.ui.container.Menu
        PNGMenu                       matlab.ui.container.Menu
        JPGMenu                       matlab.ui.container.Menu
        ExportDataasMenu              matlab.ui.container.Menu
        matMenu                       matlab.ui.container.Menu
        txtMenu                       matlab.ui.container.Menu
        csvMenu                       matlab.ui.container.Menu
        CloseButton                   matlab.ui.control.Button
        NeuromodToolboxLabel          matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        LiveSpikeRateWindowLabel      matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        DownsampleCheckBox            matlab.ui.control.CheckBox
        BinRangeSlider                matlab.ui.control.Slider
        BinSizeSliderLabel            matlab.ui.control.Label
        ChannelSelectionPreprocessedChannelLabel  matlab.ui.control.Label
        ChannelSelectionPreprocessedChannelEditField  matlab.ui.control.EditField
        LockYLimCheckBox              matlab.ui.control.CheckBox
        UIAxes                        matlab.ui.control.UIAxes
        ContextMenu3                  matlab.ui.container.ContextMenu
        ChangeTitleMenu               matlab.ui.container.Menu
        ChangeXLabelMenu              matlab.ui.container.Menu
        ChangeYLabelMenu              matlab.ui.container.Menu
        ChangeXlimMenu                matlab.ui.container.Menu
        ChangeYlimMenu                matlab.ui.container.Menu
        ChangeLegendMenu              matlab.ui.container.Menu
        ChangeFontsizeMenu            matlab.ui.container.Menu
        ContextMenu4                  matlab.ui.container.ContextMenu
        ChangeCutoffFrequencyMenu     matlab.ui.container.Menu
        ChangeFilterOrderMenu         matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        Existflag =  [];
        SpikeYLim = [];
        FilterOrder = 1;
        CutoffFreque = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.SpikeRateWindowUIFigure, 'center');

            app.Mainapp = Mainapp;
            app.Mainapp.PSTHApp = app;

            if ~isfield(app.Mainapp.Data,'Spikes') 
                f = msgbox("Error: No Spike data detected. Please first extract spikes from your dataset!");
                delete(app);
                return;
            else
                if isempty(app.Mainapp.Data.Spikes)
                    f = msgbox("Error: No Spike data detected. Please first extract spikes from your dataset!");
                delete(app);
                return;
                end
            end
    
            if strcmp(app.Mainapp.Data.Info.SpikeType,'Kilosort')
                app.ChannelSelectionPreprocessedChannelEditField.Enable = "off";
                app.ChannelSelectionPreprocessedChannelLabel.Text = "Kilosort Spikes only for all channel";
            end

            app.ChannelSelectionPreprocessedChannelEditField.Value = strcat(num2str(1),",",num2str(size(app.Mainapp.Data.Preprocessed,1)));
            app.Existflag = 1;
            
            if isfield(app.Mainapp.Data,'Spikes') 
                app.Mainapp.PSTHApp = app;
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            else

                msgbox("No Spike Data (GUI intern or Kilosort) found! Exiting.");
                delete(app);
            end
        end

        % Close request function: SpikeRateWindowUIFigure
        function SpikeRateWindowUIFigureCloseRequest(app, event)
            app.Mainapp.PSTHApp = [];
            delete(app)
        end

        % Value changed function: BinRangeSlider
        function BinRangeSliderValueChanged(app, event)
            app.Mainapp.PSTHApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Mainapp.PSTHApp = [];
            app.Existflag = 0;
            delete(app)
        end

        % Value changed function: LockYLimCheckBox
        function LockYLimCheckBoxValueChanged(app, event)
          
        end

        % Value changed function: 
        % ChannelSelectionPreprocessedChannelEditField
        function ChannelSelectionPreprocessedChannelEditFieldValueChanged(app, event)
           if isfield(app.Mainapp.Data,"Preprocessed")
                [app.ChannelSelectionPreprocessedChannelEditField.Value] = Utility_SimpleCheckInputs(app.ChannelSelectionPreprocessedChannelEditField.Value,"Two",strcat('1,',num2str(size(app.Mainapp.Data.Preprocessed,1))),1,0);
           else
                [app.ChannelSelectionPreprocessedChannelEditField.Value] = Utility_SimpleCheckInputs(app.ChannelSelectionPreprocessedChannelEditField.Value,"Two",strcat('1,',num2str(size(app.Mainapp.Data.Raw,1))),1,0);
           end
           
           app.Mainapp.PSTHApp = app;
           Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.SpikeRateWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.SpikeRateWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: ChangeTitleMenu
        function ChangeTitleMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'title');
        end

        % Menu selected function: ChangeXLabelMenu
        function ChangeXLabelMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'xlabel');
        end

        % Menu selected function: ChangeYLabelMenu
        function ChangeYLabelMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'ylabel');
        end

        % Menu selected function: ChangeXlimMenu
        function ChangeXlimMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'xlim');
        end

        % Menu selected function: ChangeYlimMenu
        function ChangeYlimMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'ylim');
        end

        % Menu selected function: ChangeLegendMenu
        function ChangeLegendMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'legend');
        end

        % Menu selected function: ChangeFontsizeMenu
        function ChangeFontsizeMenuSelected(app, event)
            
        end

        % Value changed function: DownsampleCheckBox
        function DownsampleCheckBoxValueChanged(app, event)
            app.Mainapp.PSTHApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: ChangeCutoffFrequencyMenu
        function ChangeCutoffFrequencyMenuSelected(app, event)
            Change_SpikeRate_Filter_Options_Window(app,"Cutoff");
        end

        % Menu selected function: ChangeFilterOrderMenu
        function ChangeFilterOrderMenuSelected(app, event)
            Change_SpikeRate_Filter_Options_Window(app,"FilterOrder");
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.Data,app.UIAxes,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_Spike_Rate_Main_Window");
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.Data,app.UIAxes,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_Spike_Rate_Main_Window");
        end

        % Menu selected function: csvMenu
        function csvMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.Data,app.UIAxes,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_Spike_Rate_Main_Window");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpikeRateWindowUIFigure = uifigure('Visible', 'off');
            app.SpikeRateWindowUIFigure.Name = 'Spike Rate Window';
            app.SpikeRateWindowUIFigure.Icon = 'Logo.png';
            app.SpikeRateWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @SpikeRateWindowUIFigureCloseRequest, true);
            app.SnapshotMenu = uimenu(app.SpikeRateWindowUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofMainWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofMainWindowMenu.Text = 'Save Snapshot of Main Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofMainWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofMainWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.ExportDataasMenu = uimenu(app.SpikeRateWindowUIFigure);
            app.ExportDataasMenu.Text = '   Export Data as   ';
            app.matMenu = uimenu(app.ExportDataasMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ExportDataasMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.csvMenu = uimenu(app.ExportDataasMenu);
            app.csvMenu.MenuSelectedFcn = createCallbackFcn(app, @csvMenuSelected, true);
            app.csvMenu.Text = '.csv';
            app.UIAxes = uiaxes(app.SpikeRateWindowUIFigure);
            app.Panel = uipanel(app.SpikeRateWindowUIFigure);
            app.LockYLimCheckBox = uicheckbox(app.Panel);
            app.LockYLimCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockYLimCheckBoxValueChanged, true);
            app.LockYLimCheckBox.Text = '   Lock YLim';
            app.LockYLimCheckBox.Value = true;
            app.ChannelSelectionPreprocessedChannelEditField = uieditfield(app.Panel, 'text');
            app.ChannelSelectionPreprocessedChannelEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionPreprocessedChannelEditFieldValueChanged, true);
            app.ChannelSelectionPreprocessedChannelLabel = uilabel(app.Panel);
            app.ChannelSelectionPreprocessedChannelLabel.Text = {'Channel Selection'; '(Preprocessed Channel)'};
            app.BinSizeSliderLabel = uilabel(app.Panel);
            app.BinSizeSliderLabel.Text = 'Bin Size';
            app.BinRangeSlider = uislider(app.Panel);
            app.BinRangeSlider.Limits = [0 50];
            app.BinRangeSlider.ValueChangedFcn = createCallbackFcn(app, @BinRangeSliderValueChanged, true);
            app.BinRangeSlider.Value = 30;
            app.DownsampleCheckBox = uicheckbox(app.Panel);
            app.DownsampleCheckBox.ValueChangedFcn = createCallbackFcn(app, @DownsampleCheckBoxValueChanged, true);
            app.DownsampleCheckBox.Text = '   Downsample';
            app.DownsampleCheckBox.Value = true;
            app.LiveSpikeRateWindowLabel = uilabel(app.SpikeRateWindowUIFigure);
            app.LiveSpikeRateWindowLabel.Text = 'Live Spike Rate Window';
            app.Image = uiimage(app.SpikeRateWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.SpikeRateWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.SpikeRateWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ContextMenu3 = uicontextmenu(app.SpikeRateWindowUIFigure);
            app.ChangeTitleMenu = uimenu(app.ContextMenu3);
            app.ChangeTitleMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeTitleMenuSelected, true);
            app.ChangeTitleMenu.Text = 'Change Title';
            app.ChangeXLabelMenu = uimenu(app.ContextMenu3);
            app.ChangeXLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeXLabelMenuSelected, true);
            app.ChangeXLabelMenu.Text = 'Change X Label';
            app.ChangeYLabelMenu = uimenu(app.ContextMenu3);
            app.ChangeYLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeYLabelMenuSelected, true);
            app.ChangeYLabelMenu.Text = 'Change Y Label';
            app.ChangeXlimMenu = uimenu(app.ContextMenu3);
            app.ChangeXlimMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeXlimMenuSelected, true);
            app.ChangeXlimMenu.Text = 'Change Xlim';
            app.ChangeYlimMenu = uimenu(app.ContextMenu3);
            app.ChangeYlimMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeYlimMenuSelected, true);
            app.ChangeYlimMenu.Text = 'Change Ylim';
            app.ChangeLegendMenu = uimenu(app.ContextMenu3);
            app.ChangeLegendMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeLegendMenuSelected, true);
            app.ChangeLegendMenu.Text = 'Change Legend';
            app.ChangeFontsizeMenu = uimenu(app.ContextMenu3);
            app.ChangeFontsizeMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeFontsizeMenuSelected, true);
            app.ChangeFontsizeMenu.Text = 'Change Fontsize';
            % Assign app.ContextMenu3
            app.UIAxes.ContextMenu = app.ContextMenu3;
            app.ContextMenu4 = uicontextmenu(app.SpikeRateWindowUIFigure);
            app.ChangeCutoffFrequencyMenu = uimenu(app.ContextMenu4);
            app.ChangeCutoffFrequencyMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeCutoffFrequencyMenuSelected, true);
            app.ChangeCutoffFrequencyMenu.Text = 'Change Cutoff Frequency';
            app.ChangeFilterOrderMenu = uimenu(app.ContextMenu4);
            app.ChangeFilterOrderMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeFilterOrderMenuSelected, true);
            app.ChangeFilterOrderMenu.Text = 'Change Filter Order';
            % Assign app.ContextMenu4
            app.DownsampleCheckBox.ContextMenu = app.ContextMenu4;
            % Show the figure after all components are created
            app.SpikeRateWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spike_Rate_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SpikeRateWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SpikeRateWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpikeRateWindowUIFigure)
        end
    end
end