classdef Artefact_Rejection_Continous_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ArtefactRejectionContinousUIFigure  matlab.ui.Figure
        AddArtefactRejectiontoPipelineButton  matlab.ui.control.Button
        InformationTextArea           matlab.ui.control.TextArea
        InformationTextAreaLabel      matlab.ui.control.Label
        CloseButton                   matlab.ui.control.Button
        NeuromodToolboxLabel          matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        PreprocessingWindowLabel      matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        TimeAroundArtefactsEditField  matlab.ui.control.EditField
        TimeAroundArtefactsEditFieldLabel  matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        DropDownLabel                 matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        PreproApp = [] % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PreproApp)
            %% Initiate GUI 
            movegui(app.ArtefactRejectionContinousUIFigure, 'center');
            app.PreproApp = PreproApp;


        end

        % Callback function: not associated with a component
        function ArtefactRejectionusingEventsButtonPushed(app, event)
            [app.TimeAroundArtefactsEditField.Value] = Utility_SimpleCheckInputs(app.TimeAroundArtefactsEditField.Value,"Two",'-0.005,0.005',0,1);
            
            commaindex = find(app.TimeAroundArtefactsEditField.Value==',');

            RejectionInfo.TimeBefore = str2double(app.TimeAroundArtefactsEditField.Value(1:commaindex(1)-1));
            RejectionInfo.TimeAfter = str2double(app.TimeAroundArtefactsEditField.Value(commaindex(1)+1:end));
            RejectionInfo.HFDetectionLimit = 6.5; % HF detection limit 3 std
            
            [~,NotToKeep] = Spike_Module_Spike_Detection(app.PreproApp.Mainapp.Data,'Quiroga Method','Individual Ch.',RejectionInfo.HFDetectionLimit,1,'3','200');
        
            ArtefactTimeIndicies = NotToKeep.SpikeTimes;
            ArtefactAmpIndicies = NotToKeep.SpikeAmps;
            ArtefactPositionindicies = NotToKeep.SpikePosition;
            
            NumUniqueArtefacts = length(unique(ArtefactTimeIndicies));

            app.InformationTextArea.Value = [strcat("Nr Artefacts: ",num2str(length(NotToKeep.SpikeTimes))),strcat("Nr Unique Artefact Indicies: ",num2str(NumUniqueArtefacts))];

            UniqueArtefactsIndicies = unique(ArtefactTimeIndicies);

            [Waveforms,BiggestSpikeIndicies] = Spikes_Module_Get_Waveforms(app.PreproApp.Mainapp.Data,UniqueArtefactsIndicies,ArtefactPositionindicies,"NormalWaveform");


            figure();plot(squeeze(Waveforms(1:10,:)))


            ChannelData = ArtefactSamples.Channel == 1;
            ChannelSamples = ArtefactSamples.Samples(ChannelData==1);

            [sortedSpikeTimes, sortIdx] = sort(SpikeTimes);
            ChannelSamples = ChannelSamples(sortIdx);
            
            [UniqueChannelSamples,b] = unique(ChannelSamples);
            DatatPlot = [];
            for i = 1:length(UniqueChannelSamples)
                DatatPlot(i,1:41) = app.Preproapp.PreproApp.Mainapp.Data.Preprocessed(1,UniqueChannelSamples(i)-20:UniqueChannelSamples(i)+20);
            end
            plot(app.UIAxes,DatatPlot(2,:))
            

            %% Unique spiketimes und keine sequences
            
           
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ArtefactRejectionContinousUIFigure = uifigure('Visible', 'off');
            app.ArtefactRejectionContinousUIFigure.Name = 'Artefact Rejection Continous';
            app.ArtefactRejectionContinousUIFigure.Icon = 'Logo.png';
            app.UIAxes = uiaxes(app.ArtefactRejectionContinousUIFigure);
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.ArtefactRejectionContinousUIFigure);
            app.DropDownLabel = uilabel(app.Panel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'Artefact Rejection using Events'};
            app.DropDown.Value = 'Artefact Rejection using Events';
            app.TimeAroundArtefactsEditFieldLabel = uilabel(app.Panel);
            app.TimeAroundArtefactsEditFieldLabel.Text = 'Time Around Artefacts';
            app.TimeAroundArtefactsEditField = uieditfield(app.Panel, 'text');
            app.TimeAroundArtefactsEditField.Value = '-0.005,0.005';
            app.PreprocessingWindowLabel = uilabel(app.ArtefactRejectionContinousUIFigure);
            app.PreprocessingWindowLabel.Text = 'Preprocessing Window';
            app.Image = uiimage(app.ArtefactRejectionContinousUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.ArtefactRejectionContinousUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.ArtefactRejectionContinousUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.InformationTextAreaLabel = uilabel(app.ArtefactRejectionContinousUIFigure);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.ArtefactRejectionContinousUIFigure);
            app.InformationTextArea.Value = {'Nr Artefacts:'; 'Threshold:'};
            app.AddArtefactRejectiontoPipelineButton = uibutton(app.ArtefactRejectionContinousUIFigure, 'push');
            app.AddArtefactRejectiontoPipelineButton.Text = {'Add Artefact Rejection'; 'to Pipeline'};
            % Show the figure after all components are created
            app.ArtefactRejectionContinousUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Artefact_Rejection_Continous_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ArtefactRejectionContinousUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ArtefactRejectionContinousUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ArtefactRejectionContinousUIFigure)
        end
    end
end