classdef Cut_Recording_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CutRecordingTimeUIFigure      matlab.ui.Figure
        NeuromodToolboxLabel          matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        CutRecordingTimeWindowLabel   matlab.ui.control.Label
        CloseButton                   matlab.ui.control.Button
        SaveselectionandaddtopipelineButton  matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        TimetocutbeforeendsEditField  matlab.ui.control.EditField
        TimetocutbeforeendsEditFieldLabel  matlab.ui.control.Label
        CutRecordingEndCheckBox       matlab.ui.control.CheckBox
        TimetocutfromstartsEditField  matlab.ui.control.EditField
        TimetocutLabel                matlab.ui.control.Label
        CutRecordingStartCheckBox     matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        PreprocessingApp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PreprocessingApp)
            movegui(app.CutRecordingTimeUIFigure, 'center');

            app.PreprocessingApp = PreprocessingApp;

        end

        % Button pushed function: SaveselectionandaddtopipelineButton
        function SaveselectionandaddtopipelineButtonPushed(app, event)
            if app.CutRecordingStartCheckBox.Value == 1
                app.PreprocessingApp.Info.CutStart = str2double(app.TimetocutfromstartsEditField.Value);
                [app.PreprocessingApp.Info,app.PreprocessingApp.PreprocessingSteps,texttoshow] = Preprocess_Module_Construct_Pipeline("CutStart",app.PreprocessingApp.Info,app.PreprocessingApp.PreprocessingSteps,0,[],[],[],[],[],[],app.PreprocessingApp.Mainapp.Data.Info.NativeSamplingRate);
                
            end
            if app.CutRecordingEndCheckBox.Value == 1
                app.PreprocessingApp.Info.CutEnd = str2double(app.TimetocutbeforeendsEditField.Value);
                [app.PreprocessingApp.Info,app.PreprocessingApp.PreprocessingSteps,texttoshow] = Preprocess_Module_Construct_Pipeline("CutEnd",app.PreprocessingApp.Info,app.PreprocessingApp.PreprocessingSteps,0,[],[],[],[],[],[],app.PreprocessingApp.Mainapp.Data.Info.NativeSamplingRate);
                
            end

            if ~isempty(texttoshow)
                app.PreprocessingApp.TextArea.Value = texttoshow;
            end

            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CutRecordingTimeUIFigure = uifigure('Visible', 'off');
            app.CutRecordingTimeUIFigure.Name = 'Cut Recording Time';
            app.CutRecordingTimeUIFigure.Icon = 'Logo.png';
            app.Panel = uipanel(app.CutRecordingTimeUIFigure);
            app.CutRecordingStartCheckBox = uicheckbox(app.Panel);
            app.CutRecordingStartCheckBox.Text = '  Cut Recording Start';
            app.TimetocutLabel = uilabel(app.Panel);
            app.TimetocutLabel.Text = {'Time to cut'; 'from start [s]'};
            app.TimetocutfromstartsEditField = uieditfield(app.Panel, 'text');
            app.CutRecordingEndCheckBox = uicheckbox(app.Panel);
            app.CutRecordingEndCheckBox.Text = '  Cut Recording End';
            app.TimetocutbeforeendsEditFieldLabel = uilabel(app.Panel);
            app.TimetocutbeforeendsEditFieldLabel.Text = {'Time to cut '; 'before end [s]'};
            app.TimetocutbeforeendsEditField = uieditfield(app.Panel, 'text');
            app.SaveselectionandaddtopipelineButton = uibutton(app.CutRecordingTimeUIFigure, 'push');
            app.SaveselectionandaddtopipelineButton.ButtonPushedFcn = createCallbackFcn(app, @SaveselectionandaddtopipelineButtonPushed, true);
            app.SaveselectionandaddtopipelineButton.Text = {'Save selection and '; 'add to pipeline'};
            app.CloseButton = uibutton(app.CutRecordingTimeUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.CutRecordingTimeWindowLabel = uilabel(app.CutRecordingTimeUIFigure);
            app.CutRecordingTimeWindowLabel.Text = 'Cut Recording Time Window';
            app.Image = uiimage(app.CutRecordingTimeUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.CutRecordingTimeUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            % Show the figure after all components are created
            app.CutRecordingTimeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cut_Recording_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CutRecordingTimeUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.CutRecordingTimeUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CutRecordingTimeUIFigure)
        end
    end
end