classdef Analyse_Event_Related_Signal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Analyse_Event_Related_SignalUIFigure  matlab.ui.Figure
        PleaseselectthekindofanalysisyouwanttoconductLabel  matlab.ui.control.Label
        TimeFrequencyPowerButton     matlab.ui.control.Button
        CurrentSourceDensityButton   matlab.ui.control.Button
        EventRelatedPotentialButton  matlab.ui.control.Button
        CloseButton                  matlab.ui.control.Button
        NeuromodToolboxLabel         matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        AnalyseEventRelatedSignalWindowLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Mainapp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            % Moce this window in the middle of thescreen
            movegui(app.Analyse_Event_Related_SignalUIFigure, 'center');

            app.Mainapp = Mainapp;

            if ~isfield(app.Mainapp.Data,'EventRelatedData')
                msgbox("Error: No event related data found. Please first extract it using the 'Extract Events and Data' window");
                delete(app);
            end
        end

        % Button pushed function: EventRelatedPotentialButton
        function EventRelatedPotentialButtonPushed(app, event)
            ERP_Analysis_Window(app);
        end

        % Button pushed function: CurrentSourceDensityButton
        function CurrentSourceDensityButtonPushed(app, event)
            CSD_Analysis_Window(app);
        end

        % Button pushed function: TimeFrequencyPowerButton
        function TimeFrequencyPowerButtonPushed(app, event)
            Time_Frequency_Power_Window(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Analyse_Event_Related_SignalUIFigure = uifigure('Visible', 'off');
            app.Analyse_Event_Related_SignalUIFigure.Name = 'Analyse_Event_Related_Signal';
            app.Analyse_Event_Related_SignalUIFigure.Icon = 'Logo.png';
            app.AnalyseEventRelatedSignalWindowLabel = uilabel(app.Analyse_Event_Related_SignalUIFigure);
            app.AnalyseEventRelatedSignalWindowLabel.Text = 'Analyse Event Related Signal Window';
            app.Image = uiimage(app.Analyse_Event_Related_SignalUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.Analyse_Event_Related_SignalUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.Analyse_Event_Related_SignalUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.EventRelatedPotentialButton = uibutton(app.Analyse_Event_Related_SignalUIFigure, 'push');
            app.EventRelatedPotentialButton.ButtonPushedFcn = createCallbackFcn(app, @EventRelatedPotentialButtonPushed, true);
            app.EventRelatedPotentialButton.Text = {'Event Related'; 'Potential'};
            app.CurrentSourceDensityButton = uibutton(app.Analyse_Event_Related_SignalUIFigure, 'push');
            app.CurrentSourceDensityButton.ButtonPushedFcn = createCallbackFcn(app, @CurrentSourceDensityButtonPushed, true);
            app.CurrentSourceDensityButton.Text = {'Current Source'; 'Density'};
            app.TimeFrequencyPowerButton = uibutton(app.Analyse_Event_Related_SignalUIFigure, 'push');
            app.TimeFrequencyPowerButton.ButtonPushedFcn = createCallbackFcn(app, @TimeFrequencyPowerButtonPushed, true);
            app.TimeFrequencyPowerButton.Text = {'Time-Frequency'; 'Power'};
            app.PleaseselectthekindofanalysisyouwanttoconductLabel = uilabel(app.Analyse_Event_Related_SignalUIFigure);
            app.PleaseselectthekindofanalysisyouwanttoconductLabel.Text = 'Please select the kind of analysis you want to conduct.';
            % Show the figure after all components are created
            app.Analyse_Event_Related_SignalUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analyse_Event_Related_Signal(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Analyse_Event_Related_SignalUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.Analyse_Event_Related_SignalUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Analyse_Event_Related_SignalUIFigure)
        end
    end
end