classdef CSD_Analysis_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CSDAnalysisUIFigure            matlab.ui.Figure
        SnapshotMenu                   matlab.ui.container.Menu
        SaveSnapshotofWindowMenu       matlab.ui.container.Menu
        PNGMenu                        matlab.ui.container.Menu
        JPGMenu                        matlab.ui.container.Menu
        PlotMenu                       matlab.ui.container.Menu
        Show2DimensionalPlotsMenu      matlab.ui.container.Menu
        Show3DimensionalPlotsMenu      matlab.ui.container.Menu
        ChangePlotPropertiesMenu       matlab.ui.container.Menu
        CSDPlotMenu                    matlab.ui.container.Menu
        FontSizeMenu                   matlab.ui.container.Menu
        LineWidthTriggerMenu           matlab.ui.container.Menu
        ColorTriggerMenu               matlab.ui.container.Menu
        BackgorundColorMenu            matlab.ui.container.Menu
        XLabelMenu                     matlab.ui.container.Menu
        YLabelMenu                     matlab.ui.container.Menu
        CLabelMenu                     matlab.ui.container.Menu
        RESETMenu                      matlab.ui.container.Menu
        SaveCostumAppearanceMenu       matlab.ui.container.Menu
        DeleteCostumAppearanceMenu     matlab.ui.container.Menu
        ExportDataasMenu               matlab.ui.container.Menu
        matMenu                        matlab.ui.container.Menu
        txtMenu                        matlab.ui.container.Menu
        csvMenu                        matlab.ui.container.Menu
        CSDParameterPanel              matlab.ui.container.Panel
        AutoClimButton                 matlab.ui.control.Button
        DataTypeDropDown               matlab.ui.control.DropDown
        DataTypeDropDownLabel          matlab.ui.control.Label
        EventNumberSelectionEditField  matlab.ui.control.EditField
        EventNumberSelectionEditFieldLabel  matlab.ui.control.Label
        ClimminmaxEditField            matlab.ui.control.EditField
        ClimminmaxEditFieldLabel       matlab.ui.control.Label
        SurfaceChannelDropDown_2       matlab.ui.control.DropDown
        SurfaceChannelDropDown_2Label  matlab.ui.control.Label
        HammWindowEditFieldLabel       matlab.ui.control.Label
        HammWindowEditField            matlab.ui.control.EditField
        CloseButton                    matlab.ui.control.Button
        NeuromodToolboxLabel           matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        CurrentSourceDensityWindowLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        EventRelatedWindow = []; % Description
        climCSD = [];
        EventTime = [];
        TimearoundEvent = [];
        ChannelSelection = [];
        TwoORThreeD = "TwoD"; % standard 2d plots when possible
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EventRelatedWindow)
            movegui(app.CSDAnalysisUIFigure, 'center');

            app.EventRelatedWindow = EventRelatedWindow;

            app.UIAxes.Color = app.EventRelatedWindow.Mainapp.PlotAppearance.CSDWindow.BackgroundColor;
            app.UIAxes.FontSize = app.EventRelatedWindow.Mainapp.PlotAppearance.CSDWindow.FontSize;

            if ~isfield(app.EventRelatedWindow.Mainapp.Data,'Events')
                msgbox("No events extracted. Please extract events first!. Exiting.");
                delete(app);
                return;
            end

            %% Fill Event Channel Selection, Event umber selection and channel selection fields

            if isfield(app.EventRelatedWindow.Mainapp.Data,'PreprocessedEventRelatedData')
                app.DataTypeDropDown.Items{1} = 'Raw Event Related Data';
                app.DataTypeDropDown.Items{2} = 'Preprocessed Event Related Data';
            else
                app.DataTypeDropDown.Items{1} = 'Raw Event Related Data';
            end

           % Surface Channel
           app.ChannelSelection(1) = 1;

            texttoshow = {};
            texttoshow{1} = 'Non';
            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,'Raw')
                for i = 1:size(app.EventRelatedWindow.Mainapp.Data.Raw,1)
                    texttoshow{i+1} = num2str(i);
                end
                app.ChannelSelection(2) = size(app.EventRelatedWindow.Mainapp.Data.Raw,1);
            else
                for i = 1:size(app.EventRelatedWindow.Mainapp.Data.Preprocessed,1)
                    texttoshow{i+1} = num2str(i);
                end
                app.ChannelSelection(2) = size(app.EventRelatedWindow.Mainapp.Data.Preprocessed,1);
            end

            app.SurfaceChannelDropDown_2.Items = texttoshow;

            texttoshow = ['1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,2))];
            app.EventNumberSelectionEditField.Value = texttoshow;

            %% Extract GUI Infos
            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie(1)-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie(1)+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            
            CSD.ChannelSpacing = app.EventRelatedWindow.Mainapp.Data.Info.ChannelSpacing;

            CSD.HammWindow = str2double(app.HammWindowEditField.Value);

            if strcmp(app.SurfaceChannelDropDown_2.Value,"Non")
                CSD.SurfaceChannel = 1;
            else
                CSD.SurfaceChannel = str2double(app.SurfaceChannelDropDown_2.Value);
            end

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end
            
            %% Plot CSD
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: EventNumberSelectionEditField
        function EventNumberSelectionEditFieldValueChanged(app, event)
            %% Check Proper Inputs

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [app.EventNumberSelectionEditField.Value] = Utility_SimpleCheckInputs(app.EventNumberSelectionEditField.Value,"Two",strcat('1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,2))),1,0);
            else
                [app.EventNumberSelectionEditField.Value] = Utility_SimpleCheckInputs(app.EventNumberSelectionEditField.Value,"Two",strcat('1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,2))),1,0);
            end
            
            value = app.EventNumberSelectionEditField.Value;
            CommaIndex = strfind(value,",");
            EventNr(1) = str2double(value(1:CommaIndex-1));
            EventNr(2) = str2double(value(CommaIndex+1:end));

            if EventNr(2) > size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,2)
                msgbox("More events selected than available for this event channel. Setting second limit to max event number");
                app.EventNumberSelectionEditField.Value = strcat(num2str(EventNr(1)),",",num2str(length(app.EventRelatedWindow.Mainapp.Data.Events{str2double(app.EventChannelSelectionDropDown.Value)})));
            end

            %% Extract Values to do calculations

            CSD.ChannelSpacing = app.EventRelatedWindow.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            if strcmp(app.SurfaceChannelDropDown_2.Value,"Non")
                CSD.SurfaceChannel = 1;
            else
                CSD.SurfaceChannel = str2double(app.SurfaceChannelDropDown_2.Value);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            end
        end

        % Value changed function: HammWindowEditField
        function HammWindowEditFieldValueChanged(app, event)
            %% Check Proper Inputs
            [app.HammWindowEditField.Value] = Utility_SimpleCheckInputs(app.HammWindowEditField.Value,"One",'5',0,0);

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));

            CSD.ChannelSpacing = app.EventRelatedWindow.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            if strcmp(app.SurfaceChannelDropDown_2.Value,"Non")
                CSD.SurfaceChannel = 1;
            else
                CSD.SurfaceChannel = str2double(app.SurfaceChannelDropDown_2.Value);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            end
        end

        % Value changed function: SurfaceChannelDropDown_2
        function SurfaceChannelDropDown_2ValueChanged(app, event)
            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));

            CSD.ChannelSpacing = app.EventRelatedWindow.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            if strcmp(app.SurfaceChannelDropDown_2.Value,"Non")
                CSD.SurfaceChannel = 1;
            else
                CSD.SurfaceChannel = str2double(app.SurfaceChannelDropDown_2.Value);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            end
        end

        % Value changed function: ClimminmaxEditField
        function ClimminmaxEditFieldValueChanged(app, event)
            [app.ClimminmaxEditField.Value] = Utility_SimpleCheckInputs(app.ClimminmaxEditField.Value,"Two",strcat(''),0,1);

            ErrorCLIM = 0;
            if ~isempty(app.ClimminmaxEditField.Value)
                
                if ErrorCLIM == 0
                    value = app.ClimminmaxEditField.Value;
                    indicesep = find(value == ',');
                    climcostumERP(1,1) = str2double(value(1:indicesep-1));
                    climcostumERP(1,2) = str2double(value(indicesep+1:end));
        
                    app.UIAxes.CLim = climcostumERP;
                end
            end
            
        end

        % Button pushed function: AutoClimButton
        function AutoClimButtonPushed(app, event)
            if ~isempty(app.climCSD)
                app.UIAxes.CLim = app.climCSD;
                app.ClimminmaxEditField.Value = "";
            end
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                texttoshow = ['1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,2))];
                app.EventNumberSelectionEditField.Value = texttoshow;
            else
               texttoshow = ['1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,2))];
                app.EventNumberSelectionEditField.Value = texttoshow;
            end
            
            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));

            CSD.ChannelSpacing = app.EventRelatedWindow.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            if strcmp(app.SurfaceChannelDropDown_2.Value,"Non")
                CSD.SurfaceChannel = 1;
            else
                CSD.SurfaceChannel = str2double(app.SurfaceChannelDropDown_2.Value);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            end
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.CSDAnalysisUIFigure,fullfile(path,file))
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.CSDAnalysisUIFigure,fullfile(path,file))
        end

        % Menu selected function: Show2DimensionalPlotsMenu
        function Show2DimensionalPlotsMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            app.UIAxes.Color = app.EventRelatedWindow.Mainapp.PlotAppearance.CSDWindow.BackgroundColor;
            app.UIAxes.FontSize = app.EventRelatedWindow.Mainapp.PlotAppearance.CSDWindow.FontSize;

            app.TwoORThreeD = "TwoD";

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));

            CSD.ChannelSpacing = app.EventRelatedWindow.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            if strcmp(app.SurfaceChannelDropDown_2.Value,"Non")
                CSD.SurfaceChannel = 1;
            else
                CSD.SurfaceChannel = str2double(app.SurfaceChannelDropDown_2.Value);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            end

        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.Data,".mat",app.EventRelatedWindow.Mainapp.executableFolder,TimeRangePlot,StartTime,"Current Source Density Analysis");
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.Data,".txt",app.EventRelatedWindow.Mainapp.executableFolder,TimeRangePlot,StartTime,"Current Source Density Analysis");
        end

        % Menu selected function: csvMenu
        function csvMenuSelected(app, event)
            TimeRangePlot = app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.Data,".csv",app.EventRelatedWindow.Mainapp.executableFolder,TimeRangePlot,StartTime,"Current Source Density Analysis");
        end

        % Menu selected function: Show3DimensionalPlotsMenu
        function Show3DimensionalPlotsMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            app.UIAxes.Color = app.EventRelatedWindow.Mainapp.PlotAppearance.CSDWindow.BackgroundColor;
            app.UIAxes.FontSize = app.EventRelatedWindow.Mainapp.PlotAppearance.CSDWindow.FontSize;

            app.TwoORThreeD = "ThreeD";

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));

            CSD.ChannelSpacing = app.EventRelatedWindow.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            if strcmp(app.SurfaceChannelDropDown_2.Value,"Non")
                CSD.SurfaceChannel = 1;
            else
                CSD.SurfaceChannel = str2double(app.SurfaceChannelDropDown_2.Value);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            end
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize',app.EventRelatedWindow.Mainapp,"CSDWindowDataFontSize");
            waitfor(h);

            app.UIAxes.FontSize = app.EventRelatedWindow.Mainapp.PlotAppearance.CSDWindow.FontSize;
           
        end

        % Menu selected function: LineWidthTriggerMenu
        function LineWidthTriggerMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth',app.EventRelatedWindow.Mainapp,"CSDEventLineWidth");
            waitfor(h);

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));

            CSD.ChannelSpacing = app.EventRelatedWindow.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            if strcmp(app.SurfaceChannelDropDown_2.Value,"Non")
                CSD.SurfaceChannel = 1;
            else
                CSD.SurfaceChannel = str2double(app.SurfaceChannelDropDown_2.Value);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            end

        end

        % Menu selected function: ColorTriggerMenu
        function ColorTriggerMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.EventRelatedWindow.Mainapp.PlotAppearance.CSDWindow.TriggerColor = selectedColor;
            end

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));

            CSD.ChannelSpacing = app.EventRelatedWindow.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            if strcmp(app.SurfaceChannelDropDown_2.Value,"Non")
                CSD.SurfaceChannel = 1;
            else
                CSD.SurfaceChannel = str2double(app.SurfaceChannelDropDown_2.Value);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            end
        end

        % Menu selected function: BackgorundColorMenu
        function BackgorundColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.EventRelatedWindow.Mainapp.PlotAppearance.CSDWindow.TriggerColor = selectedColor;
                app.UIAxes.Color = app.EventRelatedWindow.Mainapp.PlotAppearance.CSDWindow.BackgroundColor;
            end
        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'xlabel',app.EventRelatedWindow.Mainapp,"CSDXLabel");
            waitfor(h);

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));

            CSD.ChannelSpacing = app.EventRelatedWindow.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            if strcmp(app.SurfaceChannelDropDown_2.Value,"Non")
                CSD.SurfaceChannel = 1;
            else
                CSD.SurfaceChannel = str2double(app.SurfaceChannelDropDown_2.Value);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            end
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'ylabel',app.EventRelatedWindow.Mainapp,"CSDYLabel");
            waitfor(h);

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));

            CSD.ChannelSpacing = app.EventRelatedWindow.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            if strcmp(app.SurfaceChannelDropDown_2.Value,"Non")
                CSD.SurfaceChannel = 1;
            else
                CSD.SurfaceChannel = str2double(app.SurfaceChannelDropDown_2.Value);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            end
        end

        % Menu selected function: CLabelMenu
        function CLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'clabel',app.EventRelatedWindow.Mainapp,"CSDCLabel");
            waitfor(h);

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));

            CSD.ChannelSpacing = app.EventRelatedWindow.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            if strcmp(app.SurfaceChannelDropDown_2.Value,"Non")
                CSD.SurfaceChannel = 1;
            else
                CSD.SurfaceChannel = str2double(app.SurfaceChannelDropDown_2.Value);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            end
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
            [app.EventRelatedWindow.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("CSDPlot",app.EventRelatedWindow.Mainapp.PlotAppearance);

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));

            CSD.ChannelSpacing = app.EventRelatedWindow.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            if strcmp(app.SurfaceChannelDropDown_2.Value,"Non")
                CSD.SurfaceChannel = 1;
            else
                CSD.SurfaceChannel = str2double(app.SurfaceChannelDropDown_2.Value);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance);
            end
        end

        % Menu selected function: SaveCostumAppearanceMenu
        function SaveCostumAppearanceMenuSelected(app, event)
            app.EventRelatedWindow.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.EventRelatedWindow.Mainapp.PlotAppearance,app.EventRelatedWindow.Mainapp.executableFolder,"Save");
        end

        % Menu selected function: DeleteCostumAppearanceMenu
        function DeleteCostumAppearanceMenuSelected(app, event)
            app.EventRelatedWindow.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.EventRelatedWindow.Mainapp.PlotAppearance,app.EventRelatedWindow.Mainapp.executableFolder,"Delete");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CSDAnalysisUIFigure = uifigure('Visible', 'off');
            app.CSDAnalysisUIFigure.Name = 'Event CSD Window';
            app.CSDAnalysisUIFigure.Icon = 'Logo.png';
            app.SnapshotMenu = uimenu(app.CSDAnalysisUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.PlotMenu = uimenu(app.CSDAnalysisUIFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.Show2DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show2DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show2DimensionalPlotsMenuSelected, true);
            app.Show2DimensionalPlotsMenu.Text = 'Show 2 Dimensional Plots';
            app.Show3DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show3DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show3DimensionalPlotsMenuSelected, true);
            app.Show3DimensionalPlotsMenu.Text = 'Show 3 Dimensional Plots';
            app.ChangePlotPropertiesMenu = uimenu(app.PlotMenu);
            app.ChangePlotPropertiesMenu.Text = 'Change Plot Properties';
            app.CSDPlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.CSDPlotMenu.Text = 'CSD Plot';
            app.FontSizeMenu = uimenu(app.CSDPlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.LineWidthTriggerMenu = uimenu(app.CSDPlotMenu);
            app.LineWidthTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthTriggerMenuSelected, true);
            app.LineWidthTriggerMenu.Text = 'LineWidth Trigger';
            app.ColorTriggerMenu = uimenu(app.CSDPlotMenu);
            app.ColorTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorTriggerMenuSelected, true);
            app.ColorTriggerMenu.Text = 'Color Trigger';
            app.BackgorundColorMenu = uimenu(app.CSDPlotMenu);
            app.BackgorundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenuSelected, true);
            app.BackgorundColorMenu.Text = 'Backgorund Color';
            app.XLabelMenu = uimenu(app.CSDPlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.CSDPlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.CLabelMenu = uimenu(app.CSDPlotMenu);
            app.CLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @CLabelMenuSelected, true);
            app.CLabelMenu.Text = 'CLabel';
            app.RESETMenu = uimenu(app.CSDPlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.SaveCostumAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SaveCostumAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCostumAppearanceMenuSelected, true);
            app.SaveCostumAppearanceMenu.Text = 'Save Costum Appearance';
            app.DeleteCostumAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DeleteCostumAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCostumAppearanceMenuSelected, true);
            app.DeleteCostumAppearanceMenu.Text = 'Delete Costum Appearance';
            app.ExportDataasMenu = uimenu(app.CSDAnalysisUIFigure);
            app.ExportDataasMenu.Text = '   Export Data as   ';
            app.matMenu = uimenu(app.ExportDataasMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ExportDataasMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.csvMenu = uimenu(app.ExportDataasMenu);
            app.csvMenu.MenuSelectedFcn = createCallbackFcn(app, @csvMenuSelected, true);
            app.csvMenu.Text = '.csv';
            app.UIAxes = uiaxes(app.CSDAnalysisUIFigure);
            app.CurrentSourceDensityWindowLabel = uilabel(app.CSDAnalysisUIFigure);
            app.CurrentSourceDensityWindowLabel.Text = 'Current Source Density Window';
            app.Image = uiimage(app.CSDAnalysisUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.CSDAnalysisUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.CSDAnalysisUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.CSDParameterPanel = uipanel(app.CSDAnalysisUIFigure);
            app.CSDParameterPanel.Title = 'CSD Parameter';
            app.HammWindowEditField = uieditfield(app.CSDParameterPanel, 'text');
            app.HammWindowEditField.ValueChangedFcn = createCallbackFcn(app, @HammWindowEditFieldValueChanged, true);
            app.HammWindowEditField.Value = '5';
            app.HammWindowEditFieldLabel = uilabel(app.CSDParameterPanel);
            app.HammWindowEditFieldLabel.Text = 'Hamm Window';
            app.SurfaceChannelDropDown_2Label = uilabel(app.CSDParameterPanel);
            app.SurfaceChannelDropDown_2Label.Text = 'Surface Channel';
            app.SurfaceChannelDropDown_2 = uidropdown(app.CSDParameterPanel);
            app.SurfaceChannelDropDown_2.Items = {'Non'};
            app.SurfaceChannelDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SurfaceChannelDropDown_2ValueChanged, true);
            app.SurfaceChannelDropDown_2.Value = 'Non';
            app.ClimminmaxEditFieldLabel = uilabel(app.CSDParameterPanel);
            app.ClimminmaxEditFieldLabel.Text = 'Clim [min max]';
            app.ClimminmaxEditField = uieditfield(app.CSDParameterPanel, 'text');
            app.ClimminmaxEditField.ValueChangedFcn = createCallbackFcn(app, @ClimminmaxEditFieldValueChanged, true);
            app.EventNumberSelectionEditFieldLabel = uilabel(app.CSDParameterPanel);
            app.EventNumberSelectionEditFieldLabel.Text = {'Event Number'; 'Selection'};
            app.EventNumberSelectionEditField = uieditfield(app.CSDParameterPanel, 'text');
            app.EventNumberSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @EventNumberSelectionEditFieldValueChanged, true);
            app.DataTypeDropDownLabel = uilabel(app.CSDParameterPanel);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.CSDParameterPanel);
            app.DataTypeDropDown.Items = {};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = {};
            app.AutoClimButton = uibutton(app.CSDParameterPanel, 'push');
            app.AutoClimButton.ButtonPushedFcn = createCallbackFcn(app, @AutoClimButtonPushed, true);
            app.AutoClimButton.Text = 'Auto Clim';
            % Show the figure after all components are created
            app.CSDAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CSD_Analysis_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CSDAnalysisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.CSDAnalysisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CSDAnalysisUIFigure)
        end
    end
end