classdef Time_Frequency_Power_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TFAnalysisUIFigure              matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        Show2DimensionalPlotsMenu       matlab.ui.container.Menu
        Show3DimensionalPlotsMenu       matlab.ui.container.Menu
        TFPlotMenu                      matlab.ui.container.Menu
        FontSizeMenu                    matlab.ui.container.Menu
        LineWidthTriggerMenu            matlab.ui.container.Menu
        ColorTriggerMenu                matlab.ui.container.Menu
        BackgorundColorMenu             matlab.ui.container.Menu
        XLabelMenu                      matlab.ui.container.Menu
        YLabelMenu                      matlab.ui.container.Menu
        CLabelMenu                      matlab.ui.container.Menu
        RESETMenu                       matlab.ui.container.Menu
        SaveCostumeAppearanceMenu       matlab.ui.container.Menu
        DeleteCostumeAppearanceMenu     matlab.ui.container.Menu
        ExportDataMenu                  matlab.ui.container.Menu
        matMenu                         matlab.ui.container.Menu
        txtMenu                         matlab.ui.container.Menu
        csvMenu                         matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        AnalysisTypeLabel               matlab.ui.control.Label
        EventandChannelSelectionLabel   matlab.ui.control.Label
        AnalysisTypePanel               matlab.ui.container.Panel
        WaveletTypeDropDown             matlab.ui.control.DropDown
        WaveletTypeDropDownLabel        matlab.ui.control.Label
        PhaseindependentCheckBox        matlab.ui.control.CheckBox
        AddonsLabel                     matlab.ui.control.Label
        PhaselockedCheckBox             matlab.ui.control.CheckBox
        NonphaselockedCheckBox          matlab.ui.control.CheckBox
        PlottedDataLabel                matlab.ui.control.Label
        IntertrialPhaseClusteringCheckBox  matlab.ui.control.CheckBox
        TimeFrequencyCheckBox           matlab.ui.control.CheckBox
        WaveletAnalysisParameterPanel_2  matlab.ui.container.Panel
        WaveletAnalysisParameterLabel   matlab.ui.control.Label
        ClimminmaxEditField             matlab.ui.control.EditField
        ClimminmaxLabel                 matlab.ui.control.Label
        AutoClimButton                  matlab.ui.control.Button
        FrequencyRangeminmaxstepsEditField  matlab.ui.control.EditField
        FrequRangeminstepsmaxLabel      matlab.ui.control.Label
        CycleWidthfromto23EditField     matlab.ui.control.EditField
        CycleWidthfromtoEditFieldLabel  matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        EventandChannelSelectionPanel   matlab.ui.container.Panel
        DataTypeDropDown                matlab.ui.control.DropDown
        DataTypeDropDownLabel           matlab.ui.control.Label
        ChannelSelectionDropDown        matlab.ui.control.EditField
        ChannelLabel                    matlab.ui.control.Label
        EventNumberSelectionEditField   matlab.ui.control.EditField
        TriggerNumberSelectionMatlabExpressionsLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        EventRelatedWindow = []; % Description
        climsTF = [];
        PlotSelection = [];
        TFInfos = [];
        TwoORThreeD = "TwoD"; % standard 2d plots when possible
        Mainapp = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EventRelatedWindow, Mainapp)
            movegui(app.TFAnalysisUIFigure, 'center');
            
            app.Mainapp = Mainapp;

            app = Utility_Change_Light_Dark_Mode(app,"EventTF");

            app.UIAxes.Color = app.Mainapp.PlotAppearance.TFWindow.BackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.TFWindow.FontSize;

            if ~isfield(app.Mainapp.Data,'Events')
                msgbox("No events extracted. Please extract events first!. Exiting.");
                delete(app);
                return;
            end

            %% Fill Event Channel Selection, Event umber selection and channel selection fields
            
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                app.DataTypeDropDown.Items{1} = 'Raw Event Data';
                app.DataTypeDropDown.Items{2} = 'Preprocessed Event Data';
            else
                app.DataTypeDropDown.Items{1} = 'Raw Event Data';
            end
            
            if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                app.EventNumberSelectionEditField.Value = strcat('1:',num2str(size(app.Mainapp.Data.EventRelatedData,2)));
            else
                app.EventNumberSelectionEditField.Value = strcat('1:',num2str(size(app.Mainapp.Data.PreprocessedEventRelatedData,2)));
            end
                        
            app.TFInfos.PlottedData = "TF";
            app.TFInfos.Addons = "Total";

            % Extract Cell with eventindicies of selected event channel
            spaceindicie = find(app.Mainapp.Data.Info.EventRelatedDataTimeRange == ' ');
            app.TFInfos.Timearoundevent(1) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie(1)));
            app.TFInfos.Timearoundevent(2) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie(1)+1:end));
            
            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.WaveletTypeDropDown.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                end
            end

            app = Utility_Change_Light_Dark_Mode(app,"EventTF");
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Mainapp.EventLFPTF = [];
            
            try
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event Time Frequency Power Window')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end

            end

            delete(app);
        end

        % Value changed function: EventNumberSelectionEditField
        function EventNumberSelectionEditFieldValueChanged(app, event)
            if contains(app.EventNumberSelectionEditField.Value,',')
                if ~contains(app.EventNumberSelectionEditField.Value,'[') || ~contains(app.EventNumberSelectionEditField.Value,']')
                    app.EventNumberSelectionEditField.Value = strcat('[',app.EventNumberSelectionEditField.Value,']');
                end
            end
            
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Data')
                AlleventNr = size(app.Mainapp.Data.EventRelatedData,2);
                try
                    EventNr = eval(app.EventNumberSelectionEditField.Value);
                catch
                    EventNr = 1:AlleventNr;
                    app.EventNumberSelectionEditField.Value = strcat(num2str(min(EventNr)),':',num2str(max(EventNr)));
                end
    
                if length(EventNr)>AlleventNr || sum(EventNr>AlleventNr) ~= 0 || isempty(EventNr)
                    msgbox("Error: Trigger range exceeds existing number of triggers! Plotting all trigger.")
                    EventNr = 1:AlleventNr;
                    app.EventNumberSelectionEditField.Value = strcat(num2str(min(EventNr)),':',num2str(max(EventNr)));
                end
            else
                AlleventNr = size(app.Mainapp.Data.PreprocessedEventRelatedData,2);
                try
                    EventNr = eval(app.EventNumberSelectionEditField.Value);
                catch
                    EventNr = 1:AlleventNr;
                    app.EventNumberSelectionEditField.Value = strcat(num2str(min(EventNr)),':',num2str(max(EventNr)));
                end
    
                if length(EventNr)>AlleventNr || sum(EventNr>AlleventNr) ~= 0 || isempty(EventNr)
                    msgbox("Error: Trigger range exceeds existing number of triggers! Plotting all trigger.")
                    EventNr = 1:AlleventNr;
                    app.EventNumberSelectionEditField.Value = strcat(num2str(min(EventNr)),':',num2str(max(EventNr)));
                end
            end

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.WaveletTypeDropDown.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                end
            end

            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.String = app.Mainapp.PlotAppearance.TFWindow.CLabel;
            cb.Label.Rotation = 270;
            %cb.FontSize =  app.Mainapp.PlotAppearance.TFWindow.FontSize;  
            cb.Label.Color = 'k';        % Sets the color of the label text
        end

        % Value changed function: ClimminmaxEditField
        function ClimminmaxEditFieldValueChanged(app, event)
            [app.ClimminmaxEditField.Value] = Utility_SimpleCheckInputs(app.ClimminmaxEditField.Value,"Two",strcat('1,',num2str(size(app.Mainapp.Data.EventRelatedData,1))),0,1);

            value = app.ClimminmaxEditField.Value;
            indicesep = find(value == ',');
            climcostumTF(1,1) = str2double(value(1:indicesep-1));
            climcostumTF(1,2) = str2double(value(indicesep+1:end));

            app.UIAxes.CLim = climcostumTF;
        end

        % Button pushed function: AutoClimButton
        function AutoClimButtonPushed(app, event)
            if ~isempty(app.climsTF)
                app.UIAxes.CLim = app.climsTF;
            end
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.TFAnalysisUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.TFAnalysisUIFigure,fullfile(path,file)) 
        end

        % Value changed function: ChannelSelectionDropDown
        function ChannelSelectionDropDownValueChanged(app, event)
            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.WaveletTypeDropDown.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                end
            end
        end

        % Value changed function: IntertrialPhaseClusteringCheckBox
        function IntertrialPhaseClusteringCheckBoxValueChanged(app, event)
            app.TimeFrequencyCheckBox.Value = 0;
            app.TFInfos.PlottedData = "ITPC";

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.WaveletTypeDropDown.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                end
            end

            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.String = app.Mainapp.PlotAppearance.TFWindow.CLabel;
            cb.Label.Rotation = 270;
            %cb.FontSize =  app.Mainapp.PlotAppearance.TFWindow.FontSize;  
            cb.Label.Color = 'k';        % Sets the color of the label text
        end

        % Value changed function: TimeFrequencyCheckBox
        function TimeFrequencyCheckBoxValueChanged(app, event)
            app.IntertrialPhaseClusteringCheckBox.Value = 0;
            app.TFInfos.PlottedData = "TF";

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.WaveletTypeDropDown.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                end
            end

            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.String = app.Mainapp.PlotAppearance.TFWindow.CLabel;
            cb.Label.Rotation = 270;
            %cb.FontSize =  app.Mainapp.PlotAppearance.TFWindow.FontSize;  
            cb.Label.Color = 'k';        % Sets the color of the label text
        end

        % Value changed function: PhaseindependentCheckBox
        function PhaseindependentCheckBoxValueChanged(app, event)
           app.PhaselockedCheckBox.Value = 0;
           app.NonphaselockedCheckBox.Value = 0;
            app.TFInfos.Addons = "Total";
            
            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.WaveletTypeDropDown.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                end
            end

            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.String = app.Mainapp.PlotAppearance.TFWindow.CLabel;
            cb.Label.Rotation = 270;
            %cb.FontSize =  app.Mainapp.PlotAppearance.TFWindow.FontSize;  
            cb.Label.Color = 'k';        % Sets the color of the label text
        end

        % Value changed function: PhaselockedCheckBox
        function PhaselockedCheckBoxValueChanged(app, event)
            app.PhaseindependentCheckBox.Value = 0;
            app.NonphaselockedCheckBox.Value = 0;
            app.TFInfos.Addons = "PhaseLocked";

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.WaveletTypeDropDown.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                end
            end

            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.String = app.Mainapp.PlotAppearance.TFWindow.CLabel;
            cb.Label.Rotation = 270;
            %cb.FontSize =  app.Mainapp.PlotAppearance.TFWindow.FontSize;  
            cb.Label.Color = 'k';        % Sets the color of the label text
        end

        % Value changed function: NonphaselockedCheckBox
        function NonphaselockedCheckBoxValueChanged(app, event)
            app.PhaselockedCheckBox.Value = 0;
            app.PhaseindependentCheckBox.Value = 0;
            app.TFInfos.Addons = "NonPhaseLocked";

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.WaveletTypeDropDown.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                end
            end

            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.String = app.Mainapp.PlotAppearance.TFWindow.CLabel;
            cb.Label.Rotation = 270;
            %cb.FontSize =  app.Mainapp.PlotAppearance.TFWindow.FontSize;  
            cb.Label.Color = 'k';        % Sets the color of the label text
        end

        % Value changed function: FrequencyRangeminmaxstepsEditField
        function FrequencyRangeminmaxstepsEditFieldValueChanged(app, event)

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.WaveletTypeDropDown.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                end
            end

            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.String = app.Mainapp.PlotAppearance.TFWindow.CLabel;
            cb.Label.Rotation = 270;
            %cb.FontSize =  app.Mainapp.PlotAppearance.TFWindow.FontSize;  
            cb.Label.Color = 'k';        % Sets the color of the label text
        end

        % Value changed function: CycleWidthfromto23EditField
        function CycleWidthfromto23EditFieldValueChanged(app, event)
            [app.CycleWidthfromto23EditField.Value] = Utility_SimpleCheckInputs(app.CycleWidthfromto23EditField.Value,"Two",'5,9',0,0);

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.WaveletTypeDropDown.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                end
            end

            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.String = app.Mainapp.PlotAppearance.TFWindow.CLabel;
            cb.Label.Rotation = 270;
            %cb.FontSize =  app.Mainapp.PlotAppearance.TFWindow.FontSize;  
            cb.Label.Color = 'k';        % Sets the color of the label text
        end

        % Value changed function: WaveletTypeDropDown
        function WaveletTypeDropDownValueChanged(app, event)
            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.WaveletTypeDropDown.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                end
            end
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            value = app.DataTypeDropDown.Value;
            
            if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                app.EventNumberSelectionEditField.Value = strcat('1:',num2str(size(app.Mainapp.Data.EventRelatedData,2)));
            else
                app.EventNumberSelectionEditField.Value = strcat('1:',num2str(size(app.Mainapp.Data.PreprocessedEventRelatedData,2)));
            end

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.WaveletTypeDropDown.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                end
            end

            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.String = app.Mainapp.PlotAppearance.TFWindow.CLabel;
            cb.Label.Rotation = 270;
            cb.FontSize =  app.Mainapp.PlotAppearance.TFWindow.FontSize;  
            cb.Label.Color = 'k';        % Sets the color of the label text
        end

        % Menu selected function: Show2DimensionalPlotsMenu
        function Show2DimensionalPlotsMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            
            app.UIAxes.Color = app.Mainapp.PlotAppearance.TFWindow.BackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.TFWindow.FontSize;

            app.TwoORThreeD = "TwoD";

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.WaveletTypeDropDown.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                end
            end
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;
            
            if app.TimeFrequencyCheckBox.Value == 1
                AddonName = "Time Frequency Power";
            elseif app.IntertrialPhaseClusteringCheckBox.Value == 1
                AddonName = "Intertrial Phase Clustering";
            end

            if app.PhaseindependentCheckBox.Value == 1
                AddonName = strcat("Phase Independent ",AddonName);
            elseif app.PhaselockedCheckBox.Value == 1
                AddonName = strcat("Phase Locked ",AddonName);
            elseif app.NonphaselockedCheckBox.Value == 1
                AddonName = strcat("Non Phase Locked ",AddonName);
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,AddonName);
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
           TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;
            
            if app.TimeFrequencyCheckBox.Value == 1
                AddonName = "Time Frequency Power";
            elseif app.IntertrialPhaseClusteringCheckBox.Value == 1
                AddonName = "Intertrial Phase Clustering";
            end

            if app.PhaseindependentCheckBox.Value == 1
                AddonName = strcat("Phase Independent ",AddonName);
            elseif app.PhaselockedCheckBox.Value == 1
                AddonName = strcat("Phase Locked ",AddonName);
            elseif app.NonphaselockedCheckBox.Value == 1
                AddonName = strcat("Non Phase Locked ",AddonName);
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,AddonName);
        end

        % Menu selected function: csvMenu
        function csvMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;
            
            if app.TimeFrequencyCheckBox.Value == 1
                AddonName = "Time Frequency Power";
            elseif app.IntertrialPhaseClusteringCheckBox.Value == 1
                AddonName = "Intertrial Phase Clustering";
            end

            if app.PhaseindependentCheckBox.Value == 1
                AddonName = strcat("Phase Independent ",AddonName);
            elseif app.PhaselockedCheckBox.Value == 1
                AddonName = strcat("Phase Locked ",AddonName);
            elseif app.NonphaselockedCheckBox.Value == 1
                AddonName = strcat("Non Phase Locked ",AddonName);
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,AddonName);
        end

        % Menu selected function: Show3DimensionalPlotsMenu
        function Show3DimensionalPlotsMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            app.UIAxes.Color = app.Mainapp.PlotAppearance.TFWindow.BackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.TFWindow.FontSize;
            
            app.TwoORThreeD = "ThreeD";

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.WaveletTypeDropDown.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                end
            end
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize',app.Mainapp,"TFWindowDataFontSize");
            waitfor(h);

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.TFWindow.FontSize;
        end

        % Menu selected function: LineWidthTriggerMenu
        function LineWidthTriggerMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth',app.Mainapp,"TFEventLineWidth");
            waitfor(h);

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.WaveletTypeDropDown.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                end
            end
        end

        % Menu selected function: ColorTriggerMenu
        function ColorTriggerMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.TFWindow.TriggerColor = selectedColor;
            end

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.WaveletTypeDropDown.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                end
            end
        end

        % Menu selected function: BackgorundColorMenu
        function BackgorundColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.TFWindow.BackgroundColor = selectedColor;
                app.UIAxes.Color = app.Mainapp.PlotAppearance.TFWindow.BackgroundColor;
            end
        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'xlabel',app.Mainapp,"TFXLabel");
            waitfor(h);

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.WaveletTypeDropDown.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                end
            end
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'ylabel',app.Mainapp,"TFYLabel");
            waitfor(h);

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.WaveletTypeDropDown.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                end
            end
        end

        % Menu selected function: CLabelMenu
        function CLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'clabel',app.Mainapp,"TFCLabel");
            waitfor(h);

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.WaveletTypeDropDown.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                end
            end
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("TFPlot",app.Mainapp.PlotAppearance);

            app.UIAxes.Color = app.Mainapp.PlotAppearance.TFWindow.BackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.TFWindow.FontSize;
            
            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.WaveletTypeDropDown.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                    end
                end
            end
        end

        % Menu selected function: SaveCostumeAppearanceMenu
        function SaveCostumeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Save");
        end

        % Menu selected function: DeleteCostumeAppearanceMenu
        function DeleteCostumeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Delete");
        end

        % Close request function: TFAnalysisUIFigure
        function TFAnalysisUIFigureCloseRequest(app, event)
            app.Mainapp.EventLFPTF = [];

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event Time Frequency Power Window')
                        app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available
 
            end

            delete(app);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TFAnalysisUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {707, 707};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TFAnalysisUIFigure = uifigure('Visible', 'off');
            app.TFAnalysisUIFigure.Name = 'Time Frequency Power Window';
            app.TFAnalysisUIFigure.Icon = 'Logo.png';
            app.TFAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @TFAnalysisUIFigureCloseRequest, true);
            app.TFAnalysisUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.SnapshotMenu = uimenu(app.TFAnalysisUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.PlotMenu = uimenu(app.TFAnalysisUIFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.Show2DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show2DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show2DimensionalPlotsMenuSelected, true);
            app.Show2DimensionalPlotsMenu.Text = 'Show 2 Dimensional Plots';
            app.Show3DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show3DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show3DimensionalPlotsMenuSelected, true);
            app.Show3DimensionalPlotsMenu.Text = 'Show 3 Dimensional Plots';
            app.TFPlotMenu = uimenu(app.PlotMenu);
            app.TFPlotMenu.Text = 'TF Plot';
            app.FontSizeMenu = uimenu(app.TFPlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.LineWidthTriggerMenu = uimenu(app.TFPlotMenu);
            app.LineWidthTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthTriggerMenuSelected, true);
            app.LineWidthTriggerMenu.Text = 'LineWidth Trigger';
            app.ColorTriggerMenu = uimenu(app.TFPlotMenu);
            app.ColorTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorTriggerMenuSelected, true);
            app.ColorTriggerMenu.Text = 'Color Trigger';
            app.BackgorundColorMenu = uimenu(app.TFPlotMenu);
            app.BackgorundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenuSelected, true);
            app.BackgorundColorMenu.Text = 'Backgorund Color';
            app.XLabelMenu = uimenu(app.TFPlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.TFPlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.CLabelMenu = uimenu(app.TFPlotMenu);
            app.CLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @CLabelMenuSelected, true);
            app.CLabelMenu.Text = 'CLabel';
            app.RESETMenu = uimenu(app.TFPlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.SaveCostumeAppearanceMenu = uimenu(app.PlotMenu);
            app.SaveCostumeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCostumeAppearanceMenuSelected, true);
            app.SaveCostumeAppearanceMenu.Text = 'Save Costume Appearance';
            app.DeleteCostumeAppearanceMenu = uimenu(app.PlotMenu);
            app.DeleteCostumeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCostumeAppearanceMenuSelected, true);
            app.DeleteCostumeAppearanceMenu.Text = 'Delete Costume Appearance';
            app.ExportDataMenu = uimenu(app.TFAnalysisUIFigure);
            app.ExportDataMenu.Text = '   Export Data   ';
            app.matMenu = uimenu(app.ExportDataMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ExportDataMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.csvMenu = uimenu(app.ExportDataMenu);
            app.csvMenu.MenuSelectedFcn = createCallbackFcn(app, @csvMenuSelected, true);
            app.csvMenu.Text = '.csv';
            app.GridLayout = uigridlayout(app.TFAnalysisUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.EventandChannelSelectionPanel = uipanel(app.RightPanel);
            app.EventandChannelSelectionPanel.Title = 'Event and Channel Selection';
            app.TriggerNumberSelectionMatlabExpressionsLabel = uilabel(app.EventandChannelSelectionPanel);
            app.TriggerNumberSelectionMatlabExpressionsLabel.Text = {'Trigger Number '; 'Selection '; '(Matlab Expressions)'};
            app.EventNumberSelectionEditField = uieditfield(app.EventandChannelSelectionPanel, 'text');
            app.EventNumberSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @EventNumberSelectionEditFieldValueChanged, true);
            app.ChannelLabel = uilabel(app.EventandChannelSelectionPanel);
            app.ChannelLabel.Enable = 'off';
            app.ChannelLabel.Visible = 'off';
            app.ChannelLabel.Text = {'Channel Selection'; '[from,to]'};
            app.ChannelSelectionDropDown = uieditfield(app.EventandChannelSelectionPanel, 'text');
            app.ChannelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionDropDownValueChanged, true);
            app.ChannelSelectionDropDown.Editable = 'off';
            app.ChannelSelectionDropDown.Enable = 'off';
            app.ChannelSelectionDropDown.Visible = 'off';
            app.DataTypeDropDownLabel = uilabel(app.EventandChannelSelectionPanel);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.EventandChannelSelectionPanel);
            app.DataTypeDropDown.Items = {''};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = '';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.WaveletAnalysisParameterPanel_2 = uipanel(app.RightPanel);
            app.WaveletAnalysisParameterPanel_2.Title = 'Wavelet Analysis Parameter';
            app.CycleWidthfromtoEditFieldLabel = uilabel(app.WaveletAnalysisParameterPanel_2);
            app.CycleWidthfromtoEditFieldLabel.Text = {'Cycle Width'; '(from , to)'};
            app.CycleWidthfromto23EditField = uieditfield(app.WaveletAnalysisParameterPanel_2, 'text');
            app.CycleWidthfromto23EditField.ValueChangedFcn = createCallbackFcn(app, @CycleWidthfromto23EditFieldValueChanged, true);
            app.CycleWidthfromto23EditField.Value = '5,9';
            app.FrequRangeminstepsmaxLabel = uilabel(app.WaveletAnalysisParameterPanel_2);
            app.FrequRangeminstepsmaxLabel.Text = {'Frequ. Range'; '(min,steps,max)'};
            app.FrequencyRangeminmaxstepsEditField = uieditfield(app.WaveletAnalysisParameterPanel_2, 'text');
            app.FrequencyRangeminmaxstepsEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyRangeminmaxstepsEditFieldValueChanged, true);
            app.FrequencyRangeminmaxstepsEditField.Value = '10,80,120';
            app.AutoClimButton = uibutton(app.WaveletAnalysisParameterPanel_2, 'push');
            app.AutoClimButton.ButtonPushedFcn = createCallbackFcn(app, @AutoClimButtonPushed, true);
            app.AutoClimButton.Text = 'Auto Clim';
            app.ClimminmaxLabel = uilabel(app.WaveletAnalysisParameterPanel_2);
            app.ClimminmaxLabel.Text = {'Clim '; '[min max]'};
            app.ClimminmaxEditField = uieditfield(app.WaveletAnalysisParameterPanel_2, 'text');
            app.ClimminmaxEditField.ValueChangedFcn = createCallbackFcn(app, @ClimminmaxEditFieldValueChanged, true);
            app.WaveletAnalysisParameterLabel = uilabel(app.WaveletAnalysisParameterPanel_2);
            app.WaveletAnalysisParameterLabel.Text = 'Wavelet Analysis Parameter';
            app.AnalysisTypePanel = uipanel(app.RightPanel);
            app.AnalysisTypePanel.Title = 'Analysis Type';
            app.TimeFrequencyCheckBox = uicheckbox(app.AnalysisTypePanel);
            app.TimeFrequencyCheckBox.ValueChangedFcn = createCallbackFcn(app, @TimeFrequencyCheckBoxValueChanged, true);
            app.TimeFrequencyCheckBox.Text = 'Time Frequency';
            app.TimeFrequencyCheckBox.Value = true;
            app.IntertrialPhaseClusteringCheckBox = uicheckbox(app.AnalysisTypePanel);
            app.IntertrialPhaseClusteringCheckBox.ValueChangedFcn = createCallbackFcn(app, @IntertrialPhaseClusteringCheckBoxValueChanged, true);
            app.IntertrialPhaseClusteringCheckBox.Text = {'Intertrial Phase'; 'Clustering'};
            app.PlottedDataLabel = uilabel(app.AnalysisTypePanel);
            app.PlottedDataLabel.Text = 'Plotted Data';
            app.NonphaselockedCheckBox = uicheckbox(app.AnalysisTypePanel);
            app.NonphaselockedCheckBox.ValueChangedFcn = createCallbackFcn(app, @NonphaselockedCheckBoxValueChanged, true);
            app.NonphaselockedCheckBox.Text = 'Non-phase locked';
            app.PhaselockedCheckBox = uicheckbox(app.AnalysisTypePanel);
            app.PhaselockedCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhaselockedCheckBoxValueChanged, true);
            app.PhaselockedCheckBox.Text = 'Phase locked';
            app.AddonsLabel = uilabel(app.AnalysisTypePanel);
            app.AddonsLabel.Text = 'Addons';
            app.PhaseindependentCheckBox = uicheckbox(app.AnalysisTypePanel);
            app.PhaseindependentCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhaseindependentCheckBoxValueChanged, true);
            app.PhaseindependentCheckBox.Text = 'Phase independent';
            app.PhaseindependentCheckBox.Value = true;
            app.WaveletTypeDropDownLabel = uilabel(app.AnalysisTypePanel);
            app.WaveletTypeDropDownLabel.Text = 'Wavelet Type';
            app.WaveletTypeDropDown = uidropdown(app.AnalysisTypePanel);
            app.WaveletTypeDropDown.Items = {'Moorlet Wavelets'};
            app.WaveletTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveletTypeDropDownValueChanged, true);
            app.WaveletTypeDropDown.Value = 'Moorlet Wavelets';
            app.EventandChannelSelectionLabel = uilabel(app.RightPanel);
            app.EventandChannelSelectionLabel.Text = 'Event and Channel Selection';
            app.AnalysisTypeLabel = uilabel(app.RightPanel);
            app.AnalysisTypeLabel.Text = 'Analysis Type';
            % Show the figure after all components are created
            app.TFAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Time_Frequency_Power_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TFAnalysisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TFAnalysisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TFAnalysisUIFigure)
        end
    end
end