classdef Extract_Events_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExtractEventsWindowUIFigure     matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        ExtractEventRelatedDataLabel    matlab.ui.control.Label
        EventExtractionSettingsLabel    matlab.ui.control.Label
        NrInputChinfolderEditField      matlab.ui.control.EditField
        NrInputChannelfoundinFolderLabel  matlab.ui.control.Label
        LoadCostumeTriggerIdentityButton  matlab.ui.control.Button
        RecordingSystem                 matlab.ui.control.EditField
        RecordingSystemLabel            matlab.ui.control.Label
        EventExtractionSettingsPanel    matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        EventTypeDropDown               matlab.ui.control.DropDown
        TriggerTypeDropDownLabel        matlab.ui.control.Label
        InputChannelSelectionLabel      matlab.ui.control.Label
        TriggerTresholdVLabel           matlab.ui.control.Label
        AnalogThresholdVEditField       matlab.ui.control.EditField
        InputChannelSelectionEditField  matlab.ui.control.EditField
        SelectEventChannelTypeLabel     matlab.ui.control.Label
        FileTypeDropDown                matlab.ui.control.DropDown
        FilePathLabel                   matlab.ui.control.Label
        PlotInputChannelButton          matlab.ui.control.Button
        StartEventExtractionButton      matlab.ui.control.Button
        SetFoldermanuallyButton         matlab.ui.control.Button
        InfoandFilesinPathLabel         matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        ExtractEventRelatedDataPanel    matlab.ui.container.Panel
        InformationLabel                matlab.ui.control.Label
        TextArea_3                      matlab.ui.control.TextArea
        ExtractEventRelatedDataButton   matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        EventChanneltoUseDropDown       matlab.ui.control.DropDown
        EventChanneltoUseDropDownLabel  matlab.ui.control.Label
        DatatoUseDropDown               matlab.ui.control.DropDown
        DatatoUseDropDownLabel          matlab.ui.control.Label
        TimeWindowAfterEventssEditField  matlab.ui.control.EditField
        TimeWindowAfterEventssEditFieldLabel  matlab.ui.control.Label
        TimeWindowBeforeEventssEditField  matlab.ui.control.EditField
        TimeWindowBeforeEventssEditFieldLabel  matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = [] % Description
        Path = [];
        EventInfo = [];
        RHDAllChannelData = [];
        ExtractedRHDEventsFlag = 0;
        FilesinPath = [];
        TimearoundEvent = [];
        maxInputSelection = [];
        AdditionalEventInfo = [];

        ShowChannelPlotWindow = [];

        CostumeChannelIdentityInfo = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            % Moce this window in the middle of thescreen
            movegui(app.ExtractEventsWindowUIFigure, 'center');
            
            app.Mainapp = Mainapp;

            app = Utility_Change_Light_Dark_Mode(app,"ExtractEvents");
            
            app.Path = app.Mainapp.Data.Info.Data_Path;

            %% Initiate Event Extraction Panel -- Basic Parameters

            app.TextArea.Value = app.Path;
            pause(0.2);

            if strcmp(app.Mainapp.Data.Info.RecordingType,"IntanDat")            
                app.RecordingSystem.Value = "Intan .dat files";
                app.TextArea_2.Value = "Intan Event Data is extracted. Please wait until this text area shows infos about the found events.";
                app.TextArea.Value = strcat("Extracting Events from Path: ",app.Path);
                pause(0.1);
            elseif strcmp(app.Mainapp.Data.Info.RecordingType,"IntanRHD")            
                app.RecordingSystem.Value = "Intan .rhd files";
                app.TextArea_2.Value = "Intan Event Data is extracted. Please wait until this text area shows infos about the found events.";
                app.TextArea.Value = strcat("Extracting Events from Path: ",app.Path);
                pause(0.1);
            elseif strcmp(app.Mainapp.Data.Info.RecordingType,"Neuralynx") %%%% Not implemented yet
                app.SelectEventChannelTypeLabel.Text = "Nlx event type";
                app.TextArea_2.Value = "Neuralynx Event Data is extracted. Please wait until this text area shows infos about the found events.";
                app.TextArea.Value = strcat("Extracting Events from Path: ",app.Path);
                pause(0.1);
                app.FileTypeDropDown.Items = {'Events.nev'};
                app.AnalogThresholdVEditField.Value = "";
                app.AnalogThresholdVEditField.Enable = "off";
                app.InputChannelSelectionEditField.Enable="off";
                app.RecordingSystem.Value = "Neuralynx files";
            elseif strcmp(app.Mainapp.Data.Info.RecordingType,"Open Ephys")
                app.SelectEventChannelTypeLabel.Text = "Record Node";
                app.InputChannelSelectionLabel.Text = "Event Line Nr. to Extract";
                app.TextArea_2.Value = "Open Ephys Event Data is extracted. Please wait until this text area shows infos about the found events.";
                app.TextArea.Value = strcat("Extracting Events from Path: ",app.Path);
                pause(0.1);
                app.TriggerTresholdVLabel.Text = "State (Either 1 or 0)";
                app.RecordingSystem.Value = "Open Ephys files";
                app.AnalogThresholdVEditField.Value = "1";
                app.InputChannelSelectionLabel.Text = "Event Lines";
            elseif strcmp(app.Mainapp.Data.Info.RecordingType,"Spike2")
                app.RecordingSystem.Value = "Spike2 Recording";
                app.InputChannelSelectionLabel.Text = "Data Channel containing Events";
                app.TextArea_2.Value = "Spike2 Event Data is extracted. Please wait until this text area shows infos about the found events.";
                app.TextArea.Value = strcat("Extracting Events from Path: ",app.Path);
                pause(0.1);
            end

            if ~strcmp(app.Mainapp.Data.Info.RecordingType,"IntanDat") && ~strcmp(app.Mainapp.Data.Info.RecordingType,"IntanRHD") && ~strcmp(app.Mainapp.Data.Info.RecordingType,"Spike2")
                app.EventTypeDropDown.Enable = "off";
            end

            %% Function to determine which event channel area vailable in folder based on names of folder components

            [app.EventInfo,FileEndingsExist,FilePaths,texttoshow,Info] = Extract_Events_Module_Determine_Available_EventChannel(app.Mainapp.Data,app.Path,app.FileTypeDropDown.Value);
            app.AdditionalEventInfo = Info;
            Extract_Events_Module_Set_Up_Window(app,app.Mainapp.Data,app.EventInfo,app.Path,FilePaths,FileEndingsExist,texttoshow,"Initial",Info);
            
            if isfield(app.Mainapp.Data.Info,'CutStart') || isfield(app.Mainapp.Data.Info,'CutEnd')
                app.TextArea.Value = [app.TextArea.Value;" ";"Warning: Time of Recording was cut! Potentially, not all events present in the data can be extracted!"];
            end
            %% Initiate Event Related Data Panel

            texttoshow = {};
            if ~isfield(app.Mainapp.Data,'Events') 
                
                app.ExtractEventRelatedDataButton.Enable = "off";
                app.TextArea_3.Value = "No event channel data extracted. Please first extract events in the panel above.";
            else
               
                
                app.ExtractEventRelatedDataButton.Enable = "on";

                for i = 1:length(app.Mainapp.Data.Events)
                    texttoshow{i} = convertStringsToChars(app.Mainapp.Data.Info.EventChannelNames{i});
                end
    
                app.EventChanneltoUseDropDown.Items = texttoshow;
    
                texttoshow = {};
                n = 1;
                if isfield(app.Mainapp.Data,'Raw')
                    texttoshow{n} = 'Raw';
                    n = n+1;
                end
    
                if isfield(app.Mainapp.Data,'Preprocessed')
                    texttoshow{n} = 'Preprocessed';
                end
    
                app.DatatoUseDropDown.Items = texttoshow;
    
                if ~isfield(app.Mainapp.Data,'Events')
                    app.TextArea_3.Value = "No events extracted. Please extract events first in the above panel.";
                else

                    if isfield(app.Mainapp.Data,'EventRelatedData')
                        app.TextArea_3.Value = "Event Related Data found.";
        
                        app.TextArea_3.Value = [app.TextArea_3.Value;"Number of trigger:";size(app.Mainapp.Data.EventRelatedData,2);"Channel:";app.Mainapp.Data.Info.EventRelatedDataChannel;"Time Range:";app.Mainapp.Data.Info.EventRelatedDataTimeRange;"Data Type:";app.Mainapp.Data.Info.EventRelatedDataType];
                    else
                        app.TextArea_3.Value = "No Event Related Data found. Use this panel to extract event related data.";
                    end
                end
            end
            
            
            if iscell(app.TextArea_2.Value)
                try
                    TextToShow = cell(size(app.TextArea_2.Value,1)+1,1);

                    for i = 1:length(app.TextArea_2.Value)
                        TextToShow{i+1}=app.TextArea_2.Value{i};
                    end
                    
                    TextToShow{1} = 'This window automatically searches for event channel in the raw recording that was selected when amplifier data was extracted. First select the input channel type on the left, i.e. digital or analog event input channel. Those types can contain one or more continous event channel signals with individual triggers (i.e. Intan recordings) or sample numbers of each trigger (Open Ephys). The "Input Channel Selection" below that is automatically populated with numbers from 1 to the number of event channel found of the type specified before. In this field you can delete channel numbers so that triggers are not extracted from all found event channel. In doubt, click the "Plot Input Channel" button to view the signal within each event channel. If the path to recording containing your events changed and was not found automatically, you can specify a new folder to search for with the "Set Folder Manually" button.';
                    
                    app.TextArea_2.Value = TextToShow;                       
                end
            end
           
            % Save all selected event channelnr for comparisons later
            app.maxInputSelection = app.InputChannelSelectionEditField.Value;
        end

        % Value changed function: FileTypeDropDown
        function FileTypeDropDownValueChanged(app, event)
            app.TextArea_2.Value = "Event Data is extracted. Please wait until this text area shows infos about the found events.";
            pause(0.1);
            [app.EventInfo,FileEndingsExist,FilePaths,texttoshow,Info] = Extract_Events_Module_Determine_Available_EventChannel(app.Mainapp.Data,app.Path,app.FileTypeDropDown.Value);
            app.AdditionalEventInfo = Info;
            Extract_Events_Module_Set_Up_Window(app,app.Mainapp.Data,app.EventInfo,app.Path,FilePaths,FileEndingsExist,texttoshow,"ChangedEventChannelType",Info);

            app.maxInputSelection = app.InputChannelSelectionEditField.Value;

            if iscell(app.TextArea_2.Value)
                try
                    TextToShow = cell(size(app.TextArea_2.Value,1)+1,1);

                    for i = 1:length(app.TextArea_2.Value)
                        TextToShow{i+1}=app.TextArea_2.Value{i};
                    end
                    
                    TextToShow{1} = 'This window automatically searches for event channel in the raw recording that was selected when amplifier data was extracted. First select the input channel type on the left, i.e. digital or analog event input channel. Those types can contain one or more continous event channel signals with individual triggers (i.e. Intan recordings) or sample numbers of each trigger (Open Ephys). The "Input Channel Selection" below that is automatically populated with numbers from 1 to the number of event channel found of the type specified before. In this field you can delete channel numbers so that triggers are not extracted from all found event channel. In doubt, click the "Plot Input Channel" button to view the signal within each event channel. If the path to recording containing your events changed and was not found automatically, you can specify a new folder to search for with the "Set Folder Manually" button.';
                    
                    app.TextArea_2.Value = TextToShow;                       
                end
            end

            if ~isempty(app.CostumeChannelIdentityInfo)
                if iscell(app.TextArea_2.Value)
                    try
                                               
                        TextToShow{1} = 'Costume trigger identities where loaded. PLEASE REMEMBER that only a single event channel of the type specified can be loaded! Please adjust the "Input Channel Selection" field accordingly.';
                        
                        app.TextArea_2.Value = TextToShow;                       
                    end
                end
    
                if length(strsplit(app.InputChannelSelectionEditField.Value,',')) > 1
                    app.InputChannelSelectionEditField.BackgroundColor = [1, 0, 0];
                end
            end

        end

        % Button pushed function: SetFoldermanuallyButton
        function SetFoldermanuallyButtonPushed(app, event)
            if ~isempty(app.EventInfo)
                app.EventInfo = [];
            end
            %% Get user selcted folder and save as app.selectedFolder
            % Prompt the user to select a folder
            NewPath = uigetdir(app.Mainapp.executableFolder,'Navigate to - and select the Group folder containing all the animals');
            if NewPath == 0
                disp('No folder selected. Exiting.');
                return;
            end

            app.TextArea_2.Value = "";

            app.Path = NewPath;
            
            app.InputChannelSelectionEditField.BackgroundColor = [1, 1, 1];

            %% Function to determine which event channel area vailable in folder based on names of folder components

            [app.EventInfo,FileEndingsExist,FilePaths,texttoshow,Info] = Extract_Events_Module_Determine_Available_EventChannel(app.Mainapp.Data,app.Path,app.FileTypeDropDown.Value);
            app.AdditionalEventInfo = Info;
            Extract_Events_Module_Set_Up_Window(app,app.Mainapp.Data,app.EventInfo,app.Path,FilePaths,FileEndingsExist,texttoshow,"Initial",Info);

            app.maxInputSelection = app.InputChannelSelectionEditField.Value;
        end

        % Button pushed function: PlotInputChannelButton
        function PlotInputChannelButtonPushed(app, event)
            
            if isempty(app.EventInfo)
                msgbox("Error: No Event Channel found.");
                return;
            end

            %% For Single Intan RHD Files
            if strcmp(app.Mainapp.Data.Info.RecordingType,"IntanRHD") && app.ExtractedRHDEventsFlag == 0
            
                [RhdFilePaths] = LoadIntanRHDFiles(app.Path);
                LastDashIndex = find(RhdFilePaths == '\');
                RHDPath = RhdFilePaths(1:LastDashIndex(end));
                RHDFiles = RhdFilePaths(LastDashIndex(end)+1:end);

                app.TextArea_2.Value = "Extracting Event Input Channel from RHD file";

                [~,app.RHDAllChannelData.board_adc_data,~,app.RHDAllChannelData.board_dig_in_data,~,~,~,app.RHDAllChannelData.aux_input_data,~] = Intan_RHD2000_Data_Extraction (RHDFiles,RHDPath,"Extracting",app.TextArea_2);
            
                app.ExtractedRHDEventsFlag = 1;

                app.TextArea_2.Value = "Event Channel Extraction to show Event Channel Plots finished.";

            end

            app.ShowChannelPlotWindow = Show_Event_Channel_Window(app.Mainapp,app);
        end

        % Button pushed function: StartEventExtractionButton
        function StartEventExtractionButtonPushed(app, event)
            
            if isempty(app.EventInfo) 
                msgbox("Error: No Event Channel found.");
                return;
            end
            
            if ~isempty(app.CostumeChannelIdentityInfo)
                if length(strsplit(app.InputChannelSelectionEditField.Value,',')) > 1
                   msgbox("Error: Only ONE event channel allowed when costume trigger identities where loaded! Please adjust the 'Input Channel Selection' field accordingly.");
                   return;
                end
            end

            if strcmp(app.Mainapp.Data.Info.RecordingType,"IntanDat") || strcmp(app.Mainapp.Data.Info.RecordingType,"Spike2") || strcmp(app.Mainapp.Data.Info.RecordingType,"IntanRHD")
                app.EventInfo.EventType = app.EventTypeDropDown.Value;
            end

            % Split the string based on the delimiter ','
            IndividualStrings = strsplit(app.InputChannelSelectionEditField.Value, ',');
            
            % Convert the cell array of strings to a numeric vector
            InputChannelSelection = str2double(IndividualStrings);
            SelectedNode = [];
            if strcmp(app.Mainapp.Data.Info.RecordingType,"Open Ephys")
                for i = 1:length(app.FileTypeDropDown.Items)
                    if strcmp(app.FileTypeDropDown.Items{i},app.FileTypeDropDown.Value)
                        SelectedNode = i;
                    end
                end
                startTimestamp = app.AdditionalEventInfo.startTimestamp{SelectedNode};
                [app.Mainapp.Data,~,app.RHDAllChannelData,app.ExtractedRHDEventsFlag] = Extract_Events_Module_Main_Function(app.Mainapp.Data,app.EventInfo,app.Path,app.Mainapp.Data.Info.RecordingType,app.FileTypeDropDown.Value,app.AnalogThresholdVEditField.Value,InputChannelSelection,app.ExtractedRHDEventsFlag,app.TextArea_2,app.RHDAllChannelData,app.Mainapp.executableFolder,startTimestamp);
            else
                startTimestamp = [];
                [app.Mainapp.Data,~,app.RHDAllChannelData,app.ExtractedRHDEventsFlag] = Extract_Events_Module_Main_Function(app.Mainapp.Data,app.EventInfo,app.Path,app.Mainapp.Data.Info.RecordingType,app.FileTypeDropDown.Value,app.AnalogThresholdVEditField.Value,InputChannelSelection,app.ExtractedRHDEventsFlag,app.TextArea_2,app.RHDAllChannelData,app.Mainapp.executableFolder,startTimestamp);
            end
            
            if ~isempty(app.CostumeChannelIdentityInfo) && isfield(app.Mainapp.Data,'Events')
                if ~isempty(app.Mainapp.Data.Events)
                    [app.Mainapp.Data] = Extract_Events_Module_Costume_Trigger_Identity(app.Mainapp.Data,app.CostumeChannelIdentityInfo);
                    app.TextArea_2.Value{end+1} = strcat('Trigger for event channel where divided in ',num2str(length(app.CostumeChannelIdentityInfo.UniqueIdentities)),' individual channel according to the loaded trigger identity file.');
                    for i = 1:length(app.CostumeChannelIdentityInfo.UniqueIdentities)
                        app.TextArea_2.Value{end+1} = convertStringsToChars(strcat("Channel ",app.CostumeChannelIdentityInfo.UniqueIdentities(i)," contains ",num2str(sum(app.CostumeChannelIdentityInfo.AllIdentities==app.CostumeChannelIdentityInfo.UniqueIdentities(i)))," trigger."));
                    end

                end

                app.CostumeChannelIdentityInfo = [];
            end

            if isfield(app.Mainapp.Data.Info,'EventChannelNames')  % If events succesfully extracted
                %% Activate and Initiate Event related data extraction panel
            
                for i = 1:length(app.Mainapp.Data.Events)
                    texttoshow{i} = convertStringsToChars(app.Mainapp.Data.Info.EventChannelNames{i});
                end
            
                app.EventChanneltoUseDropDown.Items = texttoshow;
            
                texttoshow = {};
                n = 1;
                if isfield(app.Mainapp.Data,'Raw')
                    texttoshow{n} = 'Raw';
                    n = n+1;
                end
            
                if isfield(app.Mainapp.Data,'Preprocessed')
                    texttoshow{n} = 'Preprocessed';
                end
            
                app.DatatoUseDropDown.Items = texttoshow;
            
                app.TextArea_3.Value = "No Event Related Data found. Use this panel to extract event related data.";


                app.ExtractEventRelatedDataButton.Enable = "on";
                
            else % If no events extracted
                                        
                
                app.ExtractEventRelatedDataButton.Enable = "off";
                app.TextArea_3.Value = "No events extracted. Please extract events first in the panel above.";
                
            end

            %% Show new Info Header entries in main window
            
            Utility_Show_Info_Loaded_Data(app.Mainapp);
            
            [app.Mainapp] = Organize_Set_MainWindow_Dropdown(app.Mainapp,app.Mainapp.Data);

            KeepSpikes = 0;
            KeepEvents = 0;
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Plotspikes,"Spikes")
                KeepSpikes = 1;
            end
            if isfield(app.Mainapp.Data,'Events') && strcmp(app.Mainapp.PlotEvents,"Events")
                KeepEvents = 1;
            end

            Organize_Reset_Main_Plot(app.Mainapp,0,KeepEvents,KeepSpikes,0,app.Mainapp.CurrentTimePoints);
            
            app.Mainapp = Utility_Change_Light_Dark_Mode(app.Mainapp,"MainWindow");
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            if ~isempty(app.ShowChannelPlotWindow)
                delete(app.ShowChannelPlotWindow);
            end

            app.Mainapp.EventExtractionWindow = [];
            delete(app);
        end

        % Value changed function: EventChanneltoUseDropDown
        function EventChanneltoUseDropDownValueChanged(app, event)
            value = app.EventChanneltoUseDropDown.Value;
            EventChannelNr = [];
            for i = 1:length(app.Mainapp.Data.Info.EventChannelNames)
                if strcmp(app.EventChanneltoUseDropDown.Value,app.Mainapp.Data.Info.EventChannelNames{i})
                    EventChannelNr = i;
                end
            end

            app.TextArea_3.Value = ["Number of trigger for channel ",app.EventChanneltoUseDropDown.Value," ",num2str(length(app.Mainapp.Data.Events{EventChannelNr}))];

        end

        % Value changed function: TimeWindowBeforeEventssEditField
        function TimeWindowBeforeEventssEditFieldValueChanged(app, event)
            [app.TimeWindowBeforeEventssEditField.Value] = Utility_SimpleCheckInputs(app.TimeWindowBeforeEventssEditField.Value,"One",'0.2',0,0);
            value = app.TimeWindowBeforeEventssEditField.Value;
            app.TimearoundEvent(1) = str2double(value);
        end

        % Value changed function: TimeWindowAfterEventssEditField
        function TimeWindowAfterEventssEditFieldValueChanged(app, event)
            [app.TimeWindowAfterEventssEditField.Value] = Utility_SimpleCheckInputs(app.TimeWindowAfterEventssEditField.Value,"One",'0.5',0,0);
            value = app.TimeWindowAfterEventssEditField.Value;
            app.TimearoundEvent(2) = str2double(value);
        end

        % Button pushed function: ExtractEventRelatedDataButton
        function ExtractEventRelatedDataButtonPushed(app, event)
            [app.Mainapp.Data,app.TimearoundEvent] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChanneltoUseDropDown.Value,app.TimeWindowBeforeEventssEditField.Value,app.TimeWindowAfterEventssEditField.Value,app.DatatoUseDropDown.Value);

            if isfield(app.Mainapp.Data,'EventRelatedData') 
                if ~isempty(app.Mainapp.Data.EventRelatedData)
                     app.TextArea_3.Value = strcat("Success. Event Related Data can now be Analysed. Found ",num2str(size(app.Mainapp.Data.EventRelatedData,1))," Channel, ",num2str(size(app.Mainapp.Data.EventRelatedData,2))," Trigger and ",num2str(size(app.Mainapp.Data.EventRelatedData,3))," Time Points.");
                     Utility_Show_Info_Loaded_Data(app.Mainapp);
                else
                    app.TextArea_3.Value = "No Event Related Data could be Extracted.";
                end
            end
        end

        % Value changed function: AnalogThresholdVEditField
        function AnalogThresholdVEditFieldValueChanged(app, event)
            [app.AnalogThresholdVEditField.Value] = Utility_SimpleCheckInputs(app.AnalogThresholdVEditField.Value,"One",'1',0,1);
        end

        % Value changed function: InputChannelSelectionEditField
        function InputChannelSelectionEditFieldValueChanged(app, event)
            commafind = find(app.InputChannelSelectionEditField.Value==',');

            if isempty(commafind)
                SelectedChannel = str2double(app.InputChannelSelectionEditField.Value);
                if isnan(SelectedChannel)
                    msgbox("Wrong format. Enter either single number or comma seperated numbers!")
                    app.InputChannelSelectionEditField.Value = event.PreviousValue;
                    return;
                end
            else
                strArray = strsplit(app.InputChannelSelectionEditField.Value, ',');
                numericVector = str2double(strArray);
                if sum(isnan(numericVector))>0
                    msgbox("Wrong format. Enter either single number or comma seperated numbers!")
                    app.InputChannelSelectionEditField.Value = event.PreviousValue;
                    return;
                else
                    SelectedChannel = numericVector;
                end
            end
            
            if ~strcmp(app.Mainapp.Data.Info.RecordingType,"Spike2")

                commafind = find(app.maxInputSelection==',');
                if isempty(commafind)
                    MaxSelectedChannel = str2double(app.maxInputSelection);
                else
                    strArray = strsplit(app.maxInputSelection, ',');
                    numericVector = str2double(strArray);
                    MaxSelectedChannel = numericVector;
                end

                if ~all(ismember(SelectedChannel,MaxSelectedChannel))
                    msgbox("Channel Selection exceeds max number of found channel! Value is autoadjusted to previous one.")
                    app.InputChannelSelectionEditField.Value = event.PreviousValue;
                    return;

                end

            end
            
            if ~isempty(app.CostumeChannelIdentityInfo)
                if length(strsplit(app.InputChannelSelectionEditField.Value,',')) == 1
                    app.InputChannelSelectionEditField.BackgroundColor = [0, 1, 0];
                else
                    app.InputChannelSelectionEditField.BackgroundColor = [1, 0, 0];
                end
            end
        end

        % Close request function: ExtractEventsWindowUIFigure
        function ExtractEventsWindowUIFigureCloseRequest(app, event)
            if ~isempty(app.ShowChannelPlotWindow)
                delete(app.ShowChannelPlotWindow);
            end
            
            app.Mainapp.EventExtractionWindow = [];
            delete(app);
            
        end

        % Button pushed function: LoadCostumeTriggerIdentityButton
        function LoadCostumeTriggerIdentityButtonPushed(app, event)
            if ~isempty(app.CostumeChannelIdentityInfo)
                app.CostumeChannelIdentityInfo = [];
                app.InputChannelSelectionEditField.BackgroundColor = [1, 1, 1];
            end
            
            CostumeTriggerIdentityWindow = Load_Costume_Trigger_Identity_Window(app.Mainapp, app);
    
            uiwait(CostumeTriggerIdentityWindow.LoadCostumeTriggerIdentityUIFigure);
            % Wait for the app to close
            
            if isvalid(CostumeTriggerIdentityWindow)
                CostumeTriggerIdentityData = CostumeTriggerIdentityWindow.EventInfo;
                delete(CostumeTriggerIdentityWindow)
            else
                msgbox("Load Costume Trigger Identity Window closed.")
                app.CostumeChannelIdentityInfo = [];
                app.InputChannelSelectionEditField.BackgroundColor = [1, 1, 1];
                return
            end
            
            app.CostumeChannelIdentityInfo = CostumeTriggerIdentityData;

            if iscell(app.TextArea_2.Value)
                try
                    TextToShow = cell(size(app.TextArea_2.Value,1),1);

                    for i = 2:length(app.TextArea_2.Value)
                        TextToShow{i}=app.TextArea_2.Value{i};
                    end
                    
                    TextToShow{1} = 'Costume trigger identities where loaded. PLEASE REMEMBER that only a single event channel of the type specified can be loaded! Please adjust the "Input Channel Selection" field accordingly.';
                    
                    app.TextArea_2.Value = TextToShow;                       
                end
            end

            if length(strsplit(app.InputChannelSelectionEditField.Value,',')) > 1
                app.InputChannelSelectionEditField.BackgroundColor = [1, 0, 0];
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ExtractEventsWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {707, 707};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExtractEventsWindowUIFigure = uifigure('Visible', 'off');
            app.ExtractEventsWindowUIFigure.Name = 'Extract Events Window';
            app.ExtractEventsWindowUIFigure.Icon = 'Logo.png';
            app.ExtractEventsWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @ExtractEventsWindowUIFigureCloseRequest, true);
            app.ExtractEventsWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ExtractEventsWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ExtractEventRelatedDataPanel = uipanel(app.RightPanel);
            app.ExtractEventRelatedDataPanel.Title = 'Extract Event Related Data';
            app.Panel_2 = uipanel(app.ExtractEventRelatedDataPanel);
            app.TimeWindowBeforeEventssEditFieldLabel = uilabel(app.Panel_2);
            app.TimeWindowBeforeEventssEditFieldLabel.Text = {'Time Window '; 'Before Events [s]'};
            app.TimeWindowBeforeEventssEditField = uieditfield(app.Panel_2, 'text');
            app.TimeWindowBeforeEventssEditField.ValueChangedFcn = createCallbackFcn(app, @TimeWindowBeforeEventssEditFieldValueChanged, true);
            app.TimeWindowBeforeEventssEditField.Value = '0.2';
            app.TimeWindowAfterEventssEditFieldLabel = uilabel(app.Panel_2);
            app.TimeWindowAfterEventssEditFieldLabel.Text = {'Time Window '; 'After Events [s]'};
            app.TimeWindowAfterEventssEditField = uieditfield(app.Panel_2, 'text');
            app.TimeWindowAfterEventssEditField.ValueChangedFcn = createCallbackFcn(app, @TimeWindowAfterEventssEditFieldValueChanged, true);
            app.TimeWindowAfterEventssEditField.Value = '0.7';
            app.DatatoUseDropDownLabel = uilabel(app.Panel_2);
            app.DatatoUseDropDownLabel.Text = 'Data to Use';
            app.DatatoUseDropDown = uidropdown(app.Panel_2);
            app.DatatoUseDropDown.Items = {''};
            app.DatatoUseDropDown.Value = '';
            app.EventChanneltoUseDropDownLabel = uilabel(app.Panel_2);
            app.EventChanneltoUseDropDownLabel.Text = {'Event Channel'; 'to Use'};
            app.EventChanneltoUseDropDown = uidropdown(app.Panel_2);
            app.EventChanneltoUseDropDown.Items = {''};
            app.EventChanneltoUseDropDown.ValueChangedFcn = createCallbackFcn(app, @EventChanneltoUseDropDownValueChanged, true);
            app.EventChanneltoUseDropDown.Value = '';
            app.ExtractEventRelatedDataButton = uibutton(app.ExtractEventRelatedDataPanel, 'push');
            app.ExtractEventRelatedDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractEventRelatedDataButtonPushed, true);
            app.ExtractEventRelatedDataButton.Text = {'Extract Event '; 'Related Data'};
            app.TextArea_3 = uitextarea(app.ExtractEventRelatedDataPanel);
            app.InformationLabel = uilabel(app.ExtractEventRelatedDataPanel);
            app.InformationLabel.Text = 'Information';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea_2 = uitextarea(app.RightPanel);
            app.InfoandFilesinPathLabel = uilabel(app.RightPanel);
            app.InfoandFilesinPathLabel.Text = 'Info and Files in Path';
            app.SetFoldermanuallyButton = uibutton(app.RightPanel, 'push');
            app.SetFoldermanuallyButton.ButtonPushedFcn = createCallbackFcn(app, @SetFoldermanuallyButtonPushed, true);
            app.SetFoldermanuallyButton.Text = {'Set Folder '; 'Manually'};
            app.StartEventExtractionButton = uibutton(app.RightPanel, 'push');
            app.StartEventExtractionButton.ButtonPushedFcn = createCallbackFcn(app, @StartEventExtractionButtonPushed, true);
            app.StartEventExtractionButton.Text = {'Start Event '; 'Extraction'};
            app.PlotInputChannelButton = uibutton(app.RightPanel, 'push');
            app.PlotInputChannelButton.ButtonPushedFcn = createCallbackFcn(app, @PlotInputChannelButtonPushed, true);
            app.PlotInputChannelButton.Text = {'Plot Input '; 'Channel'};
            app.FilePathLabel = uilabel(app.RightPanel);
            app.FilePathLabel.Text = 'File Path';
            app.EventExtractionSettingsPanel = uipanel(app.RightPanel);
            app.EventExtractionSettingsPanel.Title = 'Event Extraction Settings';
            app.Panel = uipanel(app.EventExtractionSettingsPanel);
            app.FileTypeDropDown = uidropdown(app.Panel);
            app.FileTypeDropDown.Items = {'Digital Inputs', 'Analog Input', 'AUX Inputs', 'DIN Inputs'};
            app.FileTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FileTypeDropDownValueChanged, true);
            app.FileTypeDropDown.Value = 'Digital Inputs';
            app.SelectEventChannelTypeLabel = uilabel(app.Panel);
            app.SelectEventChannelTypeLabel.Text = 'Select Event Channel Type';
            app.InputChannelSelectionEditField = uieditfield(app.Panel, 'text');
            app.InputChannelSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @InputChannelSelectionEditFieldValueChanged, true);
            app.AnalogThresholdVEditField = uieditfield(app.Panel, 'text');
            app.AnalogThresholdVEditField.ValueChangedFcn = createCallbackFcn(app, @AnalogThresholdVEditFieldValueChanged, true);
            app.AnalogThresholdVEditField.Value = '1';
            app.TriggerTresholdVLabel = uilabel(app.Panel);
            app.TriggerTresholdVLabel.Text = 'Trigger Treshold [V]';
            app.InputChannelSelectionLabel = uilabel(app.Panel);
            app.InputChannelSelectionLabel.Text = {'Input Channel'; 'Selection'};
            app.TriggerTypeDropDownLabel = uilabel(app.Panel);
            app.TriggerTypeDropDownLabel.Text = 'Trigger Type';
            app.EventTypeDropDown = uidropdown(app.Panel);
            app.EventTypeDropDown.Items = {'Rising Edge', 'Falling Edge'};
            app.EventTypeDropDown.Value = 'Rising Edge';
            app.RecordingSystemLabel = uilabel(app.RightPanel);
            app.RecordingSystemLabel.Enable = 'off';
            app.RecordingSystemLabel.Visible = 'off';
            app.RecordingSystemLabel.Text = 'Recording System';
            app.RecordingSystem = uieditfield(app.RightPanel, 'text');
            app.RecordingSystem.Editable = 'off';
            app.RecordingSystem.Enable = 'off';
            app.RecordingSystem.Visible = 'off';
            app.LoadCostumeTriggerIdentityButton = uibutton(app.RightPanel, 'push');
            app.LoadCostumeTriggerIdentityButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCostumeTriggerIdentityButtonPushed, true);
            app.LoadCostumeTriggerIdentityButton.Text = {'Load Costume Trigger'; 'Identity'};
            app.NrInputChannelfoundinFolderLabel = uilabel(app.RightPanel);
            app.NrInputChannelfoundinFolderLabel.Enable = 'off';
            app.NrInputChannelfoundinFolderLabel.Visible = 'off';
            app.NrInputChannelfoundinFolderLabel.Text = {'Nr. Input Channel'; 'found in Folder'};
            app.NrInputChinfolderEditField = uieditfield(app.RightPanel, 'text');
            app.NrInputChinfolderEditField.Editable = 'off';
            app.NrInputChinfolderEditField.Enable = 'off';
            app.NrInputChinfolderEditField.Visible = 'off';
            app.EventExtractionSettingsLabel = uilabel(app.RightPanel);
            app.EventExtractionSettingsLabel.Text = 'Event Extraction Settings';
            app.ExtractEventRelatedDataLabel = uilabel(app.RightPanel);
            app.ExtractEventRelatedDataLabel.Text = 'Extract Event Related Data';
            % Show the figure after all components are created
            app.ExtractEventsWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Extract_Events_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ExtractEventsWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ExtractEventsWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExtractEventsWindowUIFigure)
        end
    end
end