classdef Show_Event_Channel_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Show_Input_Channel_WindowUIFigure  matlab.ui.Figure
        DowsampledSampleRateHzEditField  matlab.ui.control.EditField
        DowsampledSampleRateHzEditFieldLabel  matlab.ui.control.Label
        CloseButton                    matlab.ui.control.Button
        NeuromodToolboxLabel           matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        ShowAllInputChannelLabel       matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        SelectInputChannelNumberLabel  matlab.ui.control.Label
        FileTypeDropDown_2             matlab.ui.control.DropDown
        SelectInputChannelTypeLabel    matlab.ui.control.Label
        FileTypeDropDown               matlab.ui.control.DropDown
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        EWapp = [];
        ChannelWithStuff = [];
        EventChannelNames = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, EWapp)
            % Moce this window in the middle of thescreen
            movegui(app.Show_Input_Channel_WindowUIFigure, 'center');

            app.Mainapp = Mainapp;
            app.EWapp = EWapp;
            
            app.DowsampledSampleRateHzEditField.Value = "1000";

            ChannelPlot = 0;

            %% Determine for which kind of event channels data is present. First one that has data is plotted
            if strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"IntanDat") || strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"IntanRHD")
                if isempty(app.EWapp.EventInfo)
                    ChannelPlot = 0;
                else
                    fields = fieldnames(app.EWapp.EventInfo);
                    Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,fields{1},app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder);
                    ChannelPlot = 1;
                    app.ChannelWithStuff = fields{1};
                end
            end

            if strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"Spike2")
                Placeholder = {};
                app.FileTypeDropDown.Items = Placeholder;
                app.FileTypeDropDown.Items{1} = 'Spike 2 Event Channel';
                app.FileTypeDropDown.Value = 'Spike 2 Event Channel';
                ChannelRange = str2double(strsplit(app.EWapp.EventInfo.EventChannel,','));
                app.FileTypeDropDown_2.Items = Placeholder;
                for i = 1:length(ChannelRange)
                    app.FileTypeDropDown_2.Items{i} = num2str(ChannelRange(i));
                end

                if isempty(app.EWapp.EventInfo)
                    ChannelPlot = 0;
                else
                    fields = app.EWapp.EventInfo.EventChannel;
                    Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,fields,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder);
                    ChannelPlot = 1;
                end
            end

            if strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"Open Ephys")
                
                app.SelectInputChannelTypeLabel.Text = "Recording Node";
                app.SelectInputChannelNumberLabel.Text = "Input Line Number";
                %% Populate selection dropdowns
                % Input Channel Type
                addpath(genpath("."));
                [stringArray] = Utility_Extract_Contents_of_Folder(app.EWapp.Path);
                FolderIndicieWithEphysData = [];
                for foldercontents = 1:length(stringArray)
                    if contains(stringArray(foldercontents),'Record Node')
                        if isfolder(strcat(app.EWapp.Path,'\',stringArray(foldercontents)))
                            FolderIndicieWithEphysData = [FolderIndicieWithEphysData,foldercontents];
                        end
                    end
                end

                AvailableNode = stringArray(FolderIndicieWithEphysData);
                app.FileTypeDropDown.Items = cellstr(AvailableNode);
                % Input Channel Number
                differentEventnames = unique(app.EWapp.EventInfo{1}.line);
                
                for i = 1:length(differentEventnames)
                    ChannNames{i} = num2str(differentEventnames(i)); 
                end

                app.FileTypeDropDown_2.Items = ChannNames;

                %% Extract and Show Channel
                [app.EWapp.EventInfo,FileEndingsExist,FilePaths,texttoshow,Info] = Extract_Events_Module_Determine_Available_EventChannel(app.EWapp.Mainapp.Data,app.EWapp.Path,app.FileTypeDropDown.Value);
            
                Extract_Events_Module_Set_Up_Window(app.EWapp,app.EWapp.Mainapp.Data,app.EWapp.EventInfo,app.EWapp.Path,FilePaths,FileEndingsExist,texttoshow,"Initial",Info);
                
                NodeWithData = app.FileTypeDropDown.Items{Info.AvailabelNodes{1}};
                
                app.FileTypeDropDown.Value = NodeWithData;

                if isempty(app.EWapp.EventInfo)
                    ChannelPlot = 0;
                else
                    SelectedNode = [];
                    for i = 1:length(app.FileTypeDropDown.Items)
                        if strcmp(app.FileTypeDropDown.Items{i},app.FileTypeDropDown.Value)
                            SelectedNode = i;
                            break;
                        end
                    end
                    
                    if isempty(SelectedNode)
                        msgbox("Selected Node could not be identified.");
                    end

                    Nodenr = find(AvailableNode==app.FileTypeDropDown.Value);
                    OpenEphysEventInfo.RecordNode = Nodenr;
                    OpenEphysEventInfo.LineNumbers = differentEventnames;
                    OpenEphysEventInfo.SelectedLineNumbers = app.FileTypeDropDown_2.Value;
                    OpenEphysEventInfo.SelectedLineNode = SelectedNode;
                    OpenEphysEventInfo.Info = Info;

                    Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,OpenEphysEventInfo,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder);
                    
                    ChannelPlot = 1;
                end
            end

            if strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"Neuralynx")

                app.SelectInputChannelNumberLabel.Text = "Input Line Number";
                app.FileTypeDropDown_2.Enable="off";
                FilesIndex = {};
                [stringarray] = Utility_Extract_Contents_of_Folder(app.EWapp.Path);
                FilesIndex = endsWith(stringarray, ".nev");

                if sum(FilesIndex)==0
                    ChannelPlot = 0; 
                    msgbox("No Input Channel found in selected folder");
                    delete(app);
                end

                Filename = strcat(app.EWapp.Path,'\',stringarray{FilesIndex==1});
                
                [event] = Extract_Events_Module_Extract_Events_Neuralynx(Filename,app.EWapp.Path);
                
                NumChannel = length(unique({event.type}));
                eventypes = unique({event.type});

                Placeholder = {};
                app.FileTypeDropDown.Items = Placeholder;
                for i = 1:length(eventypes)
                    app.FileTypeDropDown.Items{i} = eventypes{i};
                end

                % Just Select samples of the eventtypoe selected
                eventcell = {event.type};
                selectedfielytypeindicies = zeros(1,length({event.type}))+1;
                for i = 1:length({event.type})
                    if strcmp(eventcell{i},app.FileTypeDropDown.Value)
                        selectedfielytypeindicies(i) = 1;
                    else
                        selectedfielytypeindicies(i) = 0;
                    end
                end
            
                Eventsamples = double(cell2mat({event.sample}));
                Eventsamples(selectedfielytypeindicies==0) = [];

                if ~isempty(Eventsamples) 
                    for i = 1:length(Eventsamples)
                        app.EWapp.EventInfo.Samples{1}(i) = double(Eventsamples(i));
                    end
                    % Event 1 always sample 1
                    if app.EWapp.EventInfo.Samples{1}(1) == 1
                        app.EWapp.EventInfo.Samples{1}(1) = [];
                        msgbox("Warning: First sample is always 1 ans gets deleted.");
                    end

                    Zerosamples = app.EWapp.EventInfo.Samples{1} == 0;
                
                    if sum(Zerosamples) > 0
                        app.EWapp.EventInfo.Samples{1}(Zerosamples==1) = [];
                        msgbox("Warning: Indice of zero detected and deleted.")
                    end
                else
                     app.EWapp.EventInfo.Samples = [];
                     msgbox("Warning: No event samples found");
                     return;
                end

                Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,app.EWapp.EventInfo,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder);
                ChannelPlot = 1; 
            end


            if ChannelPlot == 0 
                msgbox("No Input Channel found in selected folder");
                delete(app);
            end
        end

        % Value changed function: FileTypeDropDown
        function FileTypeDropDownValueChanged(app, event)
            %% Determine for which kind of event channels data is present. First one that has data is plotted
            if strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"IntanDat") || strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"IntanRHD")
                if ~isempty(app.EWapp.EventInfo)
                    fields = fieldnames(app.EWapp.EventInfo);
                    foundchannel = 0;
                    if strcmp(app.FileTypeDropDown.Value,"Digital Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"DIChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    elseif strcmp(app.FileTypeDropDown.Value,"Analog Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"ADCChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    elseif strcmp(app.FileTypeDropDown.Value,"AUX Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"AUXChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    elseif strcmp(app.FileTypeDropDown.Value,"DIN Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"DINChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    end

                    if foundchannel == 0
                        msgbox(strcat("No event data for ",app.FileTypeDropDown.Value," found"));
                        app.FileTypeDropDown.Value = event.PreviousValue;
                        return;
                    end
                    
                    Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,Fieldnametopass,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder);
                                      
                else
                    msgbox("No Event Channel for Selection available!");
                end

            elseif strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"Open Ephys")
                
                %% Extract and Show Channel
                [app.EWapp.EventInfo,FileEndingsExist,FilePaths,texttoshow,Info] = Extract_Events_Module_Determine_Available_EventChannel(app.EWapp.Mainapp.Data,app.EWapp.Path,app.FileTypeDropDown.Value);
            
                Extract_Events_Module_Set_Up_Window(app.EWapp,app.EWapp.Mainapp.Data,app.EWapp.EventInfo,app.EWapp.Path,FilePaths,FileEndingsExist,texttoshow,"Initial",Info);
                                
                [stringArray] = Utility_Extract_Contents_of_Folder(app.EWapp.Path);
                FolderIndicieWithEphysData = [];
                for foldercontents = 1:length(stringArray)
                    if contains(stringArray(foldercontents),'Record Node')
                        if isfolder(strcat(app.EWapp.Path,'\',stringArray(foldercontents)))
                            FolderIndicieWithEphysData = [FolderIndicieWithEphysData,foldercontents];
                        end
                    end
                end

                AvailableNode = stringArray(FolderIndicieWithEphysData);

                if isempty(app.EWapp.EventInfo)
                    msgbox("Selected Node contains no events.");
                else
                    SelectedNode = [];
                    for i = 1:length(app.FileTypeDropDown.Items)
                        if strcmp(app.FileTypeDropDown.Items{i},app.FileTypeDropDown.Value)
                            SelectedNode = i;
                            break;
                        end
                    end

                    if isempty(SelectedNode)
                        msgbox("Selected Node contains no events.");
                    end
                    
                    % Input Channel Number
                    differentEventnames = unique(app.EWapp.EventInfo{1}.line);
                
                    Nodenr = find(AvailableNode==app.FileTypeDropDown.Value);
                    OpenEphysEventInfo.RecordNode = Nodenr;
                    OpenEphysEventInfo.LineNumbers = differentEventnames;
                    OpenEphysEventInfo.SelectedLineNumbers = app.FileTypeDropDown_2.Value;
                    OpenEphysEventInfo.SelectedLineNode = SelectedNode;
                    OpenEphysEventInfo.Info = Info;

                    Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,OpenEphysEventInfo,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder);
                end

            elseif strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"Neuralynx")

                FilesIndex = {};
                [stringarray] = Utility_Extract_Contents_of_Folder(app.EWapp.Path);
                FilesIndex = endsWith(stringarray, ".nev");

                if sum(FilesIndex)==0
                    ChannelPlot = 0; 
                    msgbox("No Input Channel found in selected folder");
                    return;
                end

                Filename = strcat(app.EWapp.Path,'\',stringarray{FilesIndex==1});
                
                [event] = Extract_Events_Module_Extract_Events_Neuralynx(Filename,app.EWapp.Path);
                
                % Just Select samples of the eventtypoe selected
                eventcell = {event.type};
                selectedfielytypeindicies = zeros(1,length({event.type}))+1;
                for i = 1:length({event.type})
                    if strcmp(eventcell{i},app.FileTypeDropDown.Value)
                        selectedfielytypeindicies(i) = 1;
                    else
                        selectedfielytypeindicies(i) = 0;
                    end
                end
            
                Eventsamples = double(cell2mat({event.sample}));
                Eventsamples(selectedfielytypeindicies==0) = [];

                if ~isempty(Eventsamples) 
                    for i = 1:length(Eventsamples)
                        app.EWapp.EventInfo.Samples{1}(i) = double(Eventsamples(i));
                    end
                    % Event 1 always sample 1
                    if app.EWapp.EventInfo.Samples{1}(1) == 1
                        app.EWapp.EventInfo.Samples{1}(1) = [];
                        msgbox("Warning: First sample is always 1 ans gets deleted.");
                    end

                    Zerosamples = app.EWapp.EventInfo.Samples{1} == 0;
                
                    if sum(Zerosamples) > 0
                        app.EWapp.EventInfo.Samples{1}(Zerosamples==1) = [];
                        msgbox("Warning: Indice of zero detected and deleted.")
                    end
                else
                     app.EWapp.EventInfo.Samples = [];
                     msgbox("Warning: No event samples found");
                     return;
                end

                Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,app.EWapp.EventInfo,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder);
                     
            end
        end

        % Value changed function: FileTypeDropDown_2
        function FileTypeDropDown_2ValueChanged(app, event)
            %% Determine for which kind of event channels data is present. First one that has data is plotted
            if strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"IntanDat") || strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"IntanRHD")
                if ~isempty(app.EWapp.EventInfo)
                    fields = fieldnames(app.EWapp.EventInfo);

                    foundchannel = 0;
                    if strcmp(app.FileTypeDropDown.Value,"Digital Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"DIChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    elseif strcmp(app.FileTypeDropDown.Value,"Analog Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"ADCChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    elseif strcmp(app.FileTypeDropDown.Value,"AUX Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"AUXChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    elseif strcmp(app.FileTypeDropDown.Value,"DIN Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"DINChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    end

                    if foundchannel == 0
                        msgbox(strcat("No event data for ",app.FileTypeDropDown_2.Value," found"));
                        app.FileTypeDropDown_2.Value = event.PreviousValue;
                        return;
                    end
                    
                    Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,Fieldnametopass,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder);
                                      
                    return;
                else
                    msgbox("No Input Channel found in selected folder!");
                end
            elseif strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"Open Ephys")
                
                %% Extract and Show Channel
                [app.EWapp.EventInfo,FileEndingsExist,FilePaths,texttoshow,Info] = Extract_Events_Module_Determine_Available_EventChannel(app.EWapp.Mainapp.Data,app.EWapp.Path,app.FileTypeDropDown.Value);
            
                Extract_Events_Module_Set_Up_Window(app.EWapp,app.EWapp.Mainapp.Data,app.EWapp.EventInfo,app.EWapp.Path,FilePaths,FileEndingsExist,texttoshow,"Initial",Info);
                                
                [stringArray] = Utility_Extract_Contents_of_Folder(app.EWapp.Path);
                FolderIndicieWithEphysData = [];
                for foldercontents = 1:length(stringArray)
                    if contains(stringArray(foldercontents),'Record Node')
                        if isfolder(strcat(app.EWapp.Path,'\',stringArray(foldercontents)))
                            FolderIndicieWithEphysData = [FolderIndicieWithEphysData,foldercontents];
                        end
                    end
                end

                AvailableNode = stringArray(FolderIndicieWithEphysData);

                if isempty(app.EWapp.EventInfo)
                    msgbox("Selected Node contains no events.");
                else
                    SelectedNode = [];
                    for i = 1:length(app.FileTypeDropDown.Items)
                        if strcmp(app.FileTypeDropDown.Items{i},app.FileTypeDropDown.Value)
                            SelectedNode = i;
                            break;
                        end
                    end

                    if isempty(SelectedNode)
                        msgbox("Selected Node contains no events.");
                    end
                    
                    % Input Channel Number
                    differentEventnames = unique(app.EWapp.EventInfo{1}.line);
                
                    Nodenr = find(AvailableNode==app.FileTypeDropDown.Value);
                    OpenEphysEventInfo.RecordNode = Nodenr;
                    OpenEphysEventInfo.LineNumbers = differentEventnames;
                    OpenEphysEventInfo.SelectedLineNumbers = app.FileTypeDropDown_2.Value;
                    OpenEphysEventInfo.SelectedLineNode = SelectedNode;
                    OpenEphysEventInfo.Info = Info;

                    Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,OpenEphysEventInfo,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder);
                end

            elseif strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"Spike2")
                fields = app.EWapp.EventInfo.EventChannel;
                Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,fields,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder);
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: DowsampledSampleRateHzEditField
        function DowsampledSampleRateHzEditFieldValueChanged(app, event)
            [app.DowsampledSampleRateHzEditField.Value] = Utility_SimpleCheckInputs(app.DowsampledSampleRateHzEditField.Value,"One",'1000',0,0);

            downsamplerate = str2double(app.DowsampledSampleRateHzEditField.Value);
            if downsamplerate>app.EWapp.Mainapp.Data.Info.NativeSamplingRate
                msgbox("Error: Can not be higher than native samplerate!")
                return;
            end

            %% Determine for which kind of event channels data is present. First one that has data is plotted
            if strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"IntanDat") || strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"IntanRHD")
                if ~isempty(app.EWapp.EventInfo)
                    fields = fieldnames(app.EWapp.EventInfo);
                    foundchannel = 0;
                    if strcmp(app.FileTypeDropDown.Value,"Digital Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"DIChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    elseif strcmp(app.FileTypeDropDown.Value,"Analog Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"ADCChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    elseif strcmp(app.FileTypeDropDown.Value,"AUX Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"AUXChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    elseif strcmp(app.FileTypeDropDown.Value,"DIN Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"DINChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    end

                    if foundchannel == 0
                        msgbox(strcat("No event data for ",app.FileTypeDropDown.Value," found"));
                        app.FileTypeDropDown.Value = event.PreviousValue;
                        return;
                    end
                    
                    Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,Fieldnametopass,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder);
                                      
                else
                    msgbox("No Event Channel for Selection available!");
                end

            elseif strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"Open Ephys")
                
                %% Extract and Show Channel
                [app.EWapp.EventInfo,FileEndingsExist,FilePaths,texttoshow,Info] = Extract_Events_Module_Determine_Available_EventChannel(app.EWapp.Mainapp.Data,app.EWapp.Path,app.FileTypeDropDown.Value);
            
                Extract_Events_Module_Set_Up_Window(app.EWapp,app.EWapp.Mainapp.Data,app.EWapp.EventInfo,app.EWapp.Path,FilePaths,FileEndingsExist,texttoshow,"Initial",Info);
                                
                [stringArray] = Utility_Extract_Contents_of_Folder(app.EWapp.Path);
                FolderIndicieWithEphysData = [];
                for foldercontents = 1:length(stringArray)
                    if contains(stringArray(foldercontents),'Record Node')
                        if isfolder(strcat(app.EWapp.Path,'\',stringArray(foldercontents)))
                            FolderIndicieWithEphysData = [FolderIndicieWithEphysData,foldercontents];
                        end
                    end
                end

                AvailableNode = stringArray(FolderIndicieWithEphysData);

                if isempty(app.EWapp.EventInfo)
                    msgbox("Selected Node contains no events.");
                else
                    SelectedNode = [];
                    for i = 1:length(app.FileTypeDropDown.Items)
                        if strcmp(app.FileTypeDropDown.Items{i},app.FileTypeDropDown.Value)
                            SelectedNode = i;
                            break;
                        end
                    end

                    if isempty(SelectedNode)
                        msgbox("Selected Node contains no events.");
                    end
                    
                    % Input Channel Number
                    differentEventnames = unique(app.EWapp.EventInfo{1}.line);
                
                    Nodenr = find(AvailableNode==app.FileTypeDropDown.Value);
                    OpenEphysEventInfo.RecordNode = Nodenr;
                    OpenEphysEventInfo.LineNumbers = differentEventnames;
                    OpenEphysEventInfo.SelectedLineNumbers = app.FileTypeDropDown_2.Value;
                    OpenEphysEventInfo.SelectedLineNode = SelectedNode;
                    OpenEphysEventInfo.Info = Info;

                    Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,OpenEphysEventInfo,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder);
                end

            elseif strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"Neuralynx")

                FilesIndex = {};
                [stringarray] = Utility_Extract_Contents_of_Folder(app.EWapp.Path);
                FilesIndex = endsWith(stringarray, ".nev");

                if sum(FilesIndex)==0
                    ChannelPlot = 0; 
                    msgbox("No Input Channel found in selected folder");
                    return;
                end

                Filename = strcat(app.EWapp.Path,'\',stringarray{FilesIndex==1});
                
                [event] = Extract_Events_Module_Extract_Events_Neuralynx(Filename,app.EWapp.Path);
                
                % Just Select samples of the eventtypoe selected
                eventcell = {event.type};
                selectedfielytypeindicies = zeros(1,length({event.type}))+1;
                for i = 1:length({event.type})
                    if strcmp(eventcell{i},app.FileTypeDropDown.Value)
                        selectedfielytypeindicies(i) = 1;
                    else
                        selectedfielytypeindicies(i) = 0;
                    end
                end
            
                Eventsamples = double(cell2mat({event.sample}));
                Eventsamples(selectedfielytypeindicies==0) = [];

                if ~isempty(Eventsamples) 
                    for i = 1:length(Eventsamples)
                        app.EWapp.EventInfo.Samples{1}(i) = double(Eventsamples(i));
                    end
                    % Event 1 always sample 1
                    if app.EWapp.EventInfo.Samples{1}(1) == 1
                        app.EWapp.EventInfo.Samples{1}(1) = [];
                        msgbox("Warning: First sample is always 1 ans gets deleted.");
                    end

                    Zerosamples = app.EWapp.EventInfo.Samples{1} == 0;
                
                    if sum(Zerosamples) > 0
                        app.EWapp.EventInfo.Samples{1}(Zerosamples==1) = [];
                        msgbox("Warning: Indice of zero detected and deleted.")
                    end
                else
                     app.EWapp.EventInfo.Samples = [];
                     msgbox("Warning: No event samples found");
                     return;
                end

                Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,app.EWapp.EventInfo,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder);
                     
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Show_Input_Channel_WindowUIFigure = uifigure('Visible', 'off');
            app.Show_Input_Channel_WindowUIFigure.Name = 'Show_Input_Channel_Window';
            app.Show_Input_Channel_WindowUIFigure.Icon = 'Logo.png';
            app.UIAxes = uiaxes(app.Show_Input_Channel_WindowUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.Show_Input_Channel_WindowUIFigure);
            app.FileTypeDropDown = uidropdown(app.Panel);
            app.FileTypeDropDown.Items = {'Digital Inputs', 'Analog Inputs', 'AUX Inputs', 'DIN Inputs'};
            app.FileTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FileTypeDropDownValueChanged, true);
            app.FileTypeDropDown.Value = 'Digital Inputs';
            app.SelectInputChannelTypeLabel = uilabel(app.Panel);
            app.SelectInputChannelTypeLabel.Text = {'Select Input Channel'; ' Type'};
            app.FileTypeDropDown_2 = uidropdown(app.Panel);
            app.FileTypeDropDown_2.Items = {};
            app.FileTypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FileTypeDropDown_2ValueChanged, true);
            app.FileTypeDropDown_2.Value = {};
            app.SelectInputChannelNumberLabel = uilabel(app.Panel);
            app.SelectInputChannelNumberLabel.Text = {'Select Input Channel'; ' Number'};
            app.ShowAllInputChannelLabel = uilabel(app.Show_Input_Channel_WindowUIFigure);
            app.ShowAllInputChannelLabel.Text = 'Show All Input Channel';
            app.Image = uiimage(app.Show_Input_Channel_WindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.Show_Input_Channel_WindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.Show_Input_Channel_WindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.DowsampledSampleRateHzEditFieldLabel = uilabel(app.Show_Input_Channel_WindowUIFigure);
            app.DowsampledSampleRateHzEditFieldLabel.Text = {'Dowsampled'; 'Sample Rate [Hz]'};
            app.DowsampledSampleRateHzEditField = uieditfield(app.Show_Input_Channel_WindowUIFigure, 'text');
            app.DowsampledSampleRateHzEditField.ValueChangedFcn = createCallbackFcn(app, @DowsampledSampleRateHzEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.Show_Input_Channel_WindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Show_Event_Channel_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Show_Input_Channel_WindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.Show_Input_Channel_WindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Show_Input_Channel_WindowUIFigure)
        end
    end
end