classdef Preprocessing_Events_Channel_Rejection_and_Interpolation_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChannelRejectionWindowUIFigure  matlab.ui.Figure
        ChannelSpacingLabel             matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        SaveasnewDatasetButton          matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        NeuromodToolboxLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        TrialRejectionWindowLabel       matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        RejectChannelFormat11or110EditField  matlab.ui.control.EditField
        RejectChannelFormat11or110EditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Add a property to store the names of the vectors
        TextHandle = []; % Store the handle to the displayed text
        EventTime = []; % Store the handle to the displayed text
        Mainapp = [];
        EventsRelatedApp = [];
    end
        
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, EventsRelatedApp)
            movegui(app.ChannelRejectionWindowUIFigure, 'center');

            app.Mainapp = Mainapp;
            app.EventsRelatedApp = EventsRelatedApp;

            %% Fill GUI fields

            EventRelatedDataTimeRange = [];
                 
            spaceindicie = find(app.Mainapp.Data.Info.EventRelatedDataTimeRange == ' ');
            EventRelatedDataTimeRange(1) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie(1)));
            EventRelatedDataTimeRange(2) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie(1)+1:end));

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')
                app.EventTime = 0-EventRelatedDataTimeRange(1):1/app.Mainapp.Data.Info.NativeSamplingRate:EventRelatedDataTimeRange(2);
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    app.EventTime = 0-EventRelatedDataTimeRange(1):1/app.Mainapp.Data.Info.DownsampledSampleRate:EventRelatedDataTimeRange(2);
                else
                    app.EventTime = 0-EventRelatedDataTimeRange(1):1/app.Mainapp.Data.Info.NativeSamplingRate:EventRelatedDataTimeRange(2);
                end
            end

            app.UIAxes.FontSize = 10;
            app.UIAxes.YTick = [];
            app.UIAxes.YLabel = [];
            xlabel(app.UIAxes, 'Time [s]');

            %% Plot with standard values on startup

            %% Check ChannelSelection
            Input = app.RejectChannelFormat11or110EditField.Value;
            Error = 0;
            if isempty(Input) == 0 % If Trialselection field filled (empty = All Trials)
            
                CommaTest = find(Input == ',');
                
                if isempty(CommaTest) || ~isempty(find(Input == '.')) || length(CommaTest) ~= 1 || any(isletter(Input)) % If no Comma or Point
                    f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                    app.RejectChannelFormat11or110EditField.Value = "1,1"; 
                    Error = 1;
                end
                
                if Error == 0
            
                    Inputvalue(1,1) = str2double(Input(1:CommaTest(1)-1));
                    Inputvalue(1,2) = str2double(Input(CommaTest+1:end));
                   
                    if isnan(Inputvalue(1)) || isnan(Inputvalue(2))
                        f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                        app.RejectChannelFormat11or110EditField.Value = "1,1";
                        Error = 1;
                    end
            
                    if Error == 0
                        if Inputvalue(1) > Inputvalue(2)
                            f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                            app.RejectChannelFormat11or110EditField.Value = "1,1";
                            Error = 1;
                        end
                        
                        if Inputvalue(1) <= 0 || Inputvalue(2) <= 0
                            f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                            app.RejectChannelFormat11or110EditField.Value = "1,1"; 
                            Error = 1;
                        end
                    end
                end
            end

            %% Get Timewindow and trial information from GUI

            indicesep = find(app.RejectChannelFormat11or110EditField.Value == ',');
            RejectChannel(1,1) = str2double(app.RejectChannelFormat11or110EditField.Value(1:indicesep(1)-1));
            RejectChannel(1,2) = str2double(app.RejectChannelFormat11or110EditField.Value(indicesep+1:end));

            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All");
            else
                [~] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data.EventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All");
            end
        end

        % Button pushed function: SaveasnewDatasetButton
        function SaveasnewDatasetButtonPushed(app, event)
            %% Check ChannelSelection
            Input = app.RejectChannelFormat11or110EditField.Value;
            Error = 0;
            if isempty(Input) == 0 % If Trialselection field filled (empty = All Trials)
            
                CommaTest = find(Input == ',');
                
                if isempty(CommaTest) || ~isempty(find(Input == '.')) || length(CommaTest) ~= 1 || any(isletter(Input)) % If no Comma or Point
                    f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                    app.RejectChannelFormat11or110EditField.Value = "1,1"; 
                    Error = 1;
                end
                
                if Error == 0
            
                    Inputvalue(1,1) = str2double(Input(1:CommaTest(1)-1));
                    Inputvalue(1,2) = str2double(Input(CommaTest+1:end));
                   
                    if isnan(Inputvalue(1)) || isnan(Inputvalue(2))
                        f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                        app.RejectChannelFormat11or110EditField.Value = "1,1";
                        Error = 1;
                    end
            
                    if Error == 0
                        if Inputvalue(1) > Inputvalue(2)
                            f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                            app.RejectChannelFormat11or110EditField.Value = "1,1";
                            Error = 1;
                        end
                        
                        if Inputvalue(1) <= 0 || Inputvalue(2) <= 0
                            f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                            app.RejectChannelFormat11or110EditField.Value = "1,1"; 
                            Error = 1;
                        end
                    end
                end
            end

            %% Get Timewindow and trial information from GUI

            indicesep = find(app.RejectChannelFormat11or110EditField.Value == ',');
            RejectChannel(1,1) = str2double(app.RejectChannelFormat11or110EditField.Value(1:indicesep(1)-1));
            RejectChannel(1,2) = str2double(app.RejectChannelFormat11or110EditField.Value(indicesep+1:end));

            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [app.Mainapp.Data.PreprocessedEventRelatedData] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All");
            else
                [app.Mainapp.Data.PreprocessedEventRelatedData] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data.EventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All");
            end

            Trials = [1,size(app.Mainapp.Data.PreprocessedEventRelatedData,2)];
            
            [app.Mainapp.Data] = Preprocessing_Events_Add_Preprocessing_Info(app.Mainapp.Data,'Channel Rejection',RejectChannel,Trials,[]);

            Utility_Show_Info_Loaded_Data(app.Mainapp);

            Preprocessing_Events_PopulateInfoText(app.EventsRelatedApp.TextArea,app.Mainapp.Data);

            msgbox("Changes were succesfully applied to the dataset.");
        end

        % Value changed function: RejectChannelFormat11or110EditField
        function RejectChannelFormat11or110EditFieldValueChanged(app, event)
            app.UIAxes.NextPlot = "replace";
            cla(app.UIAxes);
            app.UIAxes.NextPlot = "add";
             
            %% Plot with standard values on startup

            %% Check ChannelSelection
            Input = app.RejectChannelFormat11or110EditField.Value;
            Error = 0;
            if isempty(Input) == 0 % If Trialselection field filled (empty = All Trials)
            
                CommaTest = find(Input == ',');
                
                if isempty(CommaTest) || ~isempty(find(Input == '.')) || length(CommaTest) ~= 1 || any(isletter(Input)) % If no Comma or Point
                    f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                    app.RejectChannelFormat11or110EditField.Value = "1,1"; 
                    Error = 1;
                end
                
                if Error == 0
            
                    Inputvalue(1,1) = str2double(Input(1:CommaTest(1)-1));
                    Inputvalue(1,2) = str2double(Input(CommaTest+1:end));
                   
                    if isnan(Inputvalue(1)) || isnan(Inputvalue(2))
                        f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                        app.RejectChannelFormat11or110EditField.Value = "1,1";
                        Error = 1;
                    end
            
                    if Error == 0
                        if Inputvalue(1) > Inputvalue(2)
                            f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                            app.RejectChannelFormat11or110EditField.Value = "1,1";
                            Error = 1;
                        end
                        
                        if Inputvalue(1) <= 0 || Inputvalue(2) <= 0
                            f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                            app.RejectChannelFormat11or110EditField.Value = "1,1"; 
                            Error = 1;
                        end
                    end
                end
            end

            %% Get Timewindow and trial information from GUI

            indicesep = find(app.RejectChannelFormat11or110EditField.Value == ',');
            RejectChannel(1,1) = str2double(app.RejectChannelFormat11or110EditField.Value(1:indicesep(1)-1));
            RejectChannel(1,2) = str2double(app.RejectChannelFormat11or110EditField.Value(indicesep+1:end));
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All");
            else
                [~] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data.EventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All");
            end
        end

        % Callback function
        function ElectrodeofInterestDropDownValueChanged(app, event)

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            %% Check ChannelSelection
            Input = app.RejectChannelFormat11or110EditField.Value;
            Error = 0;
            if isempty(Input) == 0 % If Trialselection field filled (empty = All Trials)
            
                CommaTest = find(Input == ',');
                
                if isempty(CommaTest) || ~isempty(find(Input == '.')) || length(CommaTest) ~= 1 || any(isletter(Input)) % If no Comma or Point
                    f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                    app.RejectChannelFormat11or110EditField.Value = "1,1"; 
                    Error = 1;
                end
                
                if Error == 0
            
                    Inputvalue(1,1) = str2double(Input(1:CommaTest(1)-1));
                    Inputvalue(1,2) = str2double(Input(CommaTest+1:end));
                   
                    if isnan(Inputvalue(1)) || isnan(Inputvalue(2))
                        f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                        app.RejectChannelFormat11or110EditField.Value = "1,1";
                        Error = 1;
                    end
            
                    if Error == 0
                        if Inputvalue(1) > Inputvalue(2)
                            f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                            app.RejectChannelFormat11or110EditField.Value = "1,1";
                            Error = 1;
                        end
                        
                        if Inputvalue(1) <= 0 || Inputvalue(2) <= 0
                            f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                            app.RejectChannelFormat11or110EditField.Value = "1,1"; 
                            Error = 1;
                        end
                    end
                end
            end

            %% Get Timewindow and trial information from GUI

            indicesep = find(app.RejectChannelFormat11or110EditField.Value == ',');
            RejectChannel(1,1) = str2double(app.RejectChannelFormat11or110EditField.Value(1:indicesep(1)-1));
            RejectChannel(1,2) = str2double(app.RejectChannelFormat11or110EditField.Value(indicesep+1:end));
            
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All");
            else
                [~] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data.EventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChannelRejectionWindowUIFigure = uifigure('Visible', 'off');
            app.ChannelRejectionWindowUIFigure.Name = 'Channel Rejection Window';
            app.ChannelRejectionWindowUIFigure.Icon = 'Logo.png';
            app.UIAxes = uiaxes(app.ChannelRejectionWindowUIFigure);
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.ChannelRejectionWindowUIFigure);
            app.RejectChannelFormat11or110EditFieldLabel = uilabel(app.Panel);
            app.RejectChannelFormat11or110EditFieldLabel.Text = {'Reject Channel:'; '(Format: 1,1 or 1,10)'};
            app.RejectChannelFormat11or110EditField = uieditfield(app.Panel, 'text');
            app.RejectChannelFormat11or110EditField.ValueChangedFcn = createCallbackFcn(app, @RejectChannelFormat11or110EditFieldValueChanged, true);
            app.RejectChannelFormat11or110EditField.Value = '1,1';
            app.TrialRejectionWindowLabel = uilabel(app.ChannelRejectionWindowUIFigure);
            app.TrialRejectionWindowLabel.Text = 'Trial Rejection Window';
            app.Image = uiimage(app.ChannelRejectionWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.ChannelRejectionWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.ChannelRejectionWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SaveasnewDatasetButton = uibutton(app.ChannelRejectionWindowUIFigure, 'push');
            app.SaveasnewDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasnewDatasetButtonPushed, true);
            app.SaveasnewDatasetButton.Text = {'Save as new'; 'Dataset'};
            app.Slider = uislider(app.ChannelRejectionWindowUIFigure);
            app.Slider.Limits = [0.001 0.1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 0.001;
            app.ChannelSpacingLabel = uilabel(app.ChannelRejectionWindowUIFigure);
            app.ChannelSpacingLabel.Text = {'Channel '; 'Spacing'};
            % Show the figure after all components are created
            app.ChannelRejectionWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preprocessing_Events_Channel_Rejection_and_Interpolation_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ChannelRejectionWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ChannelRejectionWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChannelRejectionWindowUIFigure)
        end
    end
end