classdef Preprocessing_Events_Main_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PreprocessEventDataWindowUIFigure  matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        ChannelRejectionButton   matlab.ui.control.Button
        ArtefactRejectionButton  matlab.ui.control.Button
        TrialRejectionButton     matlab.ui.control.Button
        DeleteExistingPreprocessedEventDataButton  matlab.ui.control.Button
        TextArea                 matlab.ui.control.TextArea
        CloseButton              matlab.ui.control.Button
        NeuromodToolboxLabel     matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        PreprocessingEventRelatedDataLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Mainapp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.PreprocessEventDataWindowUIFigure, 'center');
            
            app.Mainapp = Mainapp;
    
            Preprocessing_Events_PopulateInfoText(app.TextArea,app.Mainapp.Data);
        end

        % Button pushed function: TrialRejectionButton
        function TrialRejectionButtonPushed(app, event)
            Preprocessing_Events_Trial_Rejection_Window(app.Mainapp,app);
        end

        % Button pushed function: ArtefactRejectionButton
        function ArtefactRejectionButtonPushed(app, event)
            Preprocessing_Events_Artefact_Rejection_Window(app.Mainapp,app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: ChannelRejectionButton
        function ChannelRejectionButtonPushed(app, event)
            Preprocessing_Events_Channel_Rejection_and_Interpolation_Window(app.Mainapp,app);
        end

        % Button pushed function: DeleteExistingPreprocessedEventDataButton
        function DeleteExistingPreprocessedEventDataButtonPushed(app, event)
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                fieldsToDelete = {'PreprocessedEventRelatedData'};
                
                app.Mainapp.Data = rmfield(app.Mainapp.Data, fieldsToDelete);

                fieldsToDelete = {'EventRelatedPreprocessing'};
                
                app.Mainapp.Data.Info = rmfield(app.Mainapp.Data.Info, fieldsToDelete);

                Utility_Show_Info_Loaded_Data(app.Mainapp);

                Preprocessing_Events_PopulateInfoText(app.TextArea,app.Mainapp.Data);

                msgbox("Existing preprocessed event data was deleted.");

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PreprocessEventDataWindowUIFigure = uifigure('Visible', 'off');
            app.PreprocessEventDataWindowUIFigure.Name = 'Preprocess Event Data Window';
            app.PreprocessEventDataWindowUIFigure.Icon = 'Logo.png';
            app.PreprocessingEventRelatedDataLabel = uilabel(app.PreprocessEventDataWindowUIFigure);
            app.PreprocessingEventRelatedDataLabel.Text = 'Preprocessing Event Related Data';
            app.Image = uiimage(app.PreprocessEventDataWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.PreprocessEventDataWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.PreprocessEventDataWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TextArea = uitextarea(app.PreprocessEventDataWindowUIFigure);
            app.DeleteExistingPreprocessedEventDataButton = uibutton(app.PreprocessEventDataWindowUIFigure, 'push');
            app.DeleteExistingPreprocessedEventDataButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteExistingPreprocessedEventDataButtonPushed, true);
            app.DeleteExistingPreprocessedEventDataButton.Text = {'Delete Existing'; 'Preprocessed Event Data'};
            app.Panel = uipanel(app.PreprocessEventDataWindowUIFigure);
            app.TrialRejectionButton = uibutton(app.Panel, 'push');
            app.TrialRejectionButton.ButtonPushedFcn = createCallbackFcn(app, @TrialRejectionButtonPushed, true);
            app.TrialRejectionButton.Text = 'Trial Rejection';
            app.ArtefactRejectionButton = uibutton(app.Panel, 'push');
            app.ArtefactRejectionButton.ButtonPushedFcn = createCallbackFcn(app, @ArtefactRejectionButtonPushed, true);
            app.ArtefactRejectionButton.Text = 'Artefact Rejection';
            app.ChannelRejectionButton = uibutton(app.Panel, 'push');
            app.ChannelRejectionButton.ButtonPushedFcn = createCallbackFcn(app, @ChannelRejectionButtonPushed, true);
            app.ChannelRejectionButton.Text = 'Channel Rejection';
            % Show the figure after all components are created
            app.PreprocessEventDataWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preprocessing_Events_Main_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PreprocessEventDataWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.PreprocessEventDataWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PreprocessEventDataWindowUIFigure)
        end
    end
end