classdef Spike_Detection_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpikeDetectionWindowUIFigure    matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        Wave_clus3SpikeSortingSettingsPanel  matlab.ui.container.Panel
        LoadExistingSpikeSortingButton  matlab.ui.control.Button
        ChangeSpikeSortingSettingsButton  matlab.ui.control.Button
        ManuallySelectFolderwithSortingResultsButton  matlab.ui.control.Button
        CreateNewSpikeSortingButton     matlab.ui.control.Button
        SpikeDetectionSettingsPanel     matlab.ui.container.Panel
        MeanDropDown                    matlab.ui.control.DropDown
        MeanandStdDropDownLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.EditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        DetectionMethodDropDown         matlab.ui.control.DropDown
        DetectionMethodDropDownLabel    matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        InformationLabel                matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        CloseButton                     matlab.ui.control.Button
        NeuromodLabel                   matlab.ui.control.Label
        SpikeDetectionWindowLabel       matlab.ui.control.Label
        SpikeFilteringSettingsPanel     matlab.ui.container.Panel
        StartSpikeDetectionButton       matlab.ui.control.Button
        VerticalSpikeOffsetToleranceSamplesEditField  matlab.ui.control.EditField
        VerticalSpikeOffsetToleranceSamplesEditFieldLabel  matlab.ui.control.Label
        MinDepthofArtefactmEditField    matlab.ui.control.EditField
        MinDepthofArtefactmEditFieldLabel  matlab.ui.control.Label
        FilterVerticalSpikeArtefactsCheckBox  matlab.ui.control.CheckBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        SpikeSortingPath = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.SpikeDetectionWindowUIFigure, 'center');

            app.Mainapp = Mainapp;

            %% Check if high pass filtered data is available. If not, display error message and return
            if ~isfield(app.Mainapp.Data,'Preprocessed')
                f = msgbox("Error: No preprocessed Data detected. Spike Detection on Raw Data wont work! High pass filter and open this window again!");
                delete(app);
                return;
            else
                if ~isfield(app.Mainapp.Data.Info,'FilterMethod')
                    f = msgbox("Error: No high pass filtered Data detected. Spike Detection on Raw Data wont work! High pass filter and open this window again!");
                    delete(app);
                    return;
                else
                    if ~strcmp(app.Mainapp.Data.Info.FilterMethod,"High-Pass")
                        f = msgbox("Error: No high pass filtered Data detected. Spike Detection on Raw Data wont work! High pass filter and open this window again!");
                        delete(app);
                        return;
                    end
                end
            end

            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,"Internal")
                SpikeText = strcat("Existing Spike Data was found with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd));
            else
                SpikeText = strcat("No Existing Internal Spike Data was found");
            end

            app.VerticalSpikeOffsetToleranceSamplesEditField.Enable = "off";
            app.MinDepthofArtefactmEditField.Enable = "off";

            % Check if spike sorting was already saved
            % Check if the folder exists
            app.SpikeSortingPath = strcat(app.Mainapp.Data.Info.Data_Path,'\Wave_Clus');
            
            cluster_class = [];

            if ~isfolder(app.SpikeSortingPath) % No sorting found
                textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx];
                app.TextArea.Value = textboxx;
            else
                textboxx = strcat("Spike Sorting Data found in: ",app.SpikeSortingPath);

                if isfile(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')))
                    load(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')));
                else
                    textboxx = [strcat("Path: ",app.SpikeSortingPath);"";"does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder"];
                    SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                    textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx];
                    app.TextArea.Value = textboxx;
                    return;
                end
                
                if ~isempty(cluster_class)
                    numclu = length(unique(cluster_class(:,1)));
                else
                    numclu = 1;
                end

                textboxx = [textboxx;" ";strcat("Number of Cluster: ",num2str(numclu));""];
                
                % Initialize a string array to store the output
                outputText = "";
                
                % Get all field names from the structure
                fields = fieldnames(par);

                % Loop through each field and concatenate the field name and value
                for i = 1:numel(fields)
                    fieldName = fields{i};
                    fieldValue = par.(fieldName);
                    
                    % Convert the field value to a string if it is not already
                    if isnumeric(fieldValue)
                        fieldValue = num2str(fieldValue);
                    elseif ischar(fieldValue)
                        fieldValue = string(fieldValue);
                    end
                    
                    % Concatenate the field name and value
                    outputText = outputText + fieldName + ": " + fieldValue + newline;
                end
                
                % Set the TextArea value in the app (assuming app.TextArea is the name of the TextArea)
                textboxx = [textboxx;outputText];
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx;outputText];

                app.TextArea.Value = textboxx;
                
            end
           
        end

        % Button pushed function: StartSpikeDetectionButton
        function StartSpikeDetectionButtonPushed(app, event)
            disp(strcat("Extracting Spikes. Please wait until the progress bar closes"));

            %% Spike Detection
            [app.Mainapp.Data,~] = Spike_Module_Spike_Detection(app.Mainapp.Data,app.DetectionMethodDropDown.Value,app.MeanDropDown.Value,str2double(app.ThresholdEditField.Value),app.FilterVerticalSpikeArtefactsCheckBox.Value,app.VerticalSpikeOffsetToleranceSamplesEditField.Value,app.MinDepthofArtefactmEditField.Value);

            %% Aftmeramth: Resetting Main window Plots and plot data again
            disp(strcat("Spike detection finished. They can now be visualized and analyzed in the main window"));

            app.Mainapp.UIAxes.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes,0,0);
            app.Mainapp.UIAxes.NextPlot = "add"; 
            app.Mainapp.UIAxes_2.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes_2,0,0);
            app.Mainapp.UIAxes_2.NextPlot = "add"; 
            app.Mainapp.UIAxes.Box = "off";

            %Take care of potentially changed backgroundcolor
            app.Mainapp.UIAxes.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainBackground;
            app.Mainapp.UIAxes_2.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeBackground;
            app.Mainapp.UIAxes.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.MainFontSize;
            app.Mainapp.UIAxes_2.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.TimeFontSize;

            if isfield(app.Mainapp.Data,'Spikes')
                if length(app.Mainapp.DropDown_2.Items)==2
                    if strcmp(app.Mainapp.DropDown_2.Items{2},'Events')
                        Placeholder = {};
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                        app.Mainapp.DropDown_2.Items{2} = 'Events';
                        app.Mainapp.DropDown_2.Items{3} = 'Spikes';
                    else
                        Placeholder = {};
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                        app.Mainapp.DropDown_2.Items{2} = 'Spikes';
                    end
                elseif length(app.Mainapp.DropDown_2.Items)==3
                    Placeholder = {};
                    app.Mainapp.DropDown_2.Items = Placeholder;
                    app.Mainapp.DropDown_2.Items{1} = 'Non';
                    app.Mainapp.DropDown_2.Items{2} = 'Events';
                    app.Mainapp.DropDown_2.Items{3} = 'Spikes';
                else
                    Placeholder = {};
                    app.Mainapp.DropDown_2.Items = Placeholder;
                    app.Mainapp.DropDown_2.Items{1} = 'Non';
                    app.Mainapp.DropDown_2.Items{2} = 'Spikes';
                end
            else
                if length(app.Mainapp.DropDown_2.Items)>=2
                    if strcmp(app.Mainapp.DropDown_2.Items{2},'Events')
                        Placeholder = {};
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                        app.Mainapp.DropDown_2.Items{2} = 'Events';
                    else
                        Placeholder = {};
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                    end
                else
                    Placeholder = {};
                    app.Mainapp.DropDown_2.Items = Placeholder;
                    app.Mainapp.DropDown_2.Items{1} = 'Non';
                end  
            end

            if strcmp(app.Mainapp.Plotspikes,"Spikes")
                SpikeHandles = findobj(app.Mainapp.UIAxes, 'Type', 'line', 'Tag', 'Spikes');
                if ~isempty(SpikeHandles)
                    delete(SpikeHandles(:));
                end
                if strcmp(app.Mainapp.PlotEvents,"Events")
                    app.Mainapp.DropDown_2.Value = "Events";
                else
                    app.Mainapp.DropDown_2.Value = "Non";
                end
                app.Mainapp.Plotspikes = "No";
            end

            Utility_Show_Info_Loaded_Data(app.Mainapp);

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);

            %% Fill GUI textbox with new data
            % Check if spike sorting was already saved
            % Check if the folder exists
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,"Internal")
                SpikeText = strcat("Existing Spike Data was found with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd));
            else
                SpikeText = strcat("No Existing Internal Spike Data was found");
            end

            app.SpikeSortingPath = strcat(app.Mainapp.Data.Info.Data_Path,'\Wave_Clus');
            
            if ~isfolder(app.SpikeSortingPath) % No sorting found
                textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx];
                app.TextArea.Value = textboxx;
            else
                textboxx = strcat("Spike Sorting Data found in: ",app.SpikeSortingPath);

                if isfile(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')))
                    load(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')));
                else
                    textboxx = [strcat("Path: ",app.SpikeSortingPath);"";"does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder"];
                    SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                    textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx];
                    app.TextArea.Value = textboxx;
                    return;
                end

                numclu = length(unique(cluster_class(:,1)));

                textboxx = [textboxx;" ";strcat("Number of Cluster: ",num2str(numclu));""];
                
                % Initialize a string array to store the output
                outputText = "";
                
                % Get all field names from the structure
                fields = fieldnames(par);

                % Loop through each field and concatenate the field name and value
                for i = 1:numel(fields)
                    fieldName = fields{i};
                    fieldValue = par.(fieldName);
                    
                    % Convert the field value to a string if it is not already
                    if isnumeric(fieldValue)
                        fieldValue = num2str(fieldValue);
                    elseif ischar(fieldValue)
                        fieldValue = string(fieldValue);
                    end
                    
                    % Concatenate the field name and value
                    outputText = outputText + fieldName + ": " + fieldValue + newline;
                end
                
                % Set the TextArea value in the app (assuming app.TextArea is the name of the TextArea)
                textboxx = [textboxx;outputText];
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx;outputText];

                app.TextArea.Value = textboxx;
                
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: DetectionMethodDropDown
        function DetectionMethodDropDownValueChanged(app, event)
            
        end

        % Value changed function: FilterVerticalSpikeArtefactsCheckBox
        function FilterVerticalSpikeArtefactsCheckBoxValueChanged(app, event)
            value = app.FilterVerticalSpikeArtefactsCheckBox.Value;
            if value == 1
                app.VerticalSpikeOffsetToleranceSamplesEditField.Enable = "on";
                app.MinDepthofArtefactmEditField.Enable = "on";
            else
                app.VerticalSpikeOffsetToleranceSamplesEditField.Enable = "off";
                app.MinDepthofArtefactmEditField.Enable = "off";
            end
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            [app.ThresholdEditField.Value] = Utility_SimpleCheckInputs(app.ThresholdEditField.Value,"One",'4',0,0);
            
        end

        % Value changed function: 
        % VerticalSpikeOffsetToleranceSamplesEditField
        function VerticalSpikeOffsetToleranceSamplesEditFieldValueChanged(app, event)
            [app.VerticalSpikeOffsetToleranceSamplesEditField.Value] = Utility_SimpleCheckInputs(app.VerticalSpikeOffsetToleranceSamplesEditField.Value,"One",'3',0,0);
            
        end

        % Value changed function: MinDepthofArtefactmEditField
        function MinDepthofArtefactmEditFieldValueChanged(app, event)
            [app.MinDepthofArtefactmEditField.Value] = Utility_SimpleCheckInputs(app.MinDepthofArtefactmEditField.Value,"One",'200',0,0);
            
        end

        % Button pushed function: CreateNewSpikeSortingButton
        function CreateNewSpikeSortingButtonPushed(app, event)
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                if isfield(app.Mainapp.Data,'Preprocessed')
                    if isfield(app.Mainapp.Data.Info,'FilterMethod')
                        if strcmp(app.Mainapp.Data.Info.FilterMethod,"High-Pass")
                            [app.Mainapp.Data] = Spike_Module_Internal_Spike_Sorting(app.Mainapp.Data,app.SpikeSortingPath,"Clustering");
                        else
                            msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                            return;
                        end
                    else
                        msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                        return;
                    end
                else
                    msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                    return;
                end
            else
                msgbox("No Internal Spike Data found. Please first Start Spike Detection!")
                return;
            end

            app.Mainapp.UIAxes.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes,0,0);
            app.Mainapp.UIAxes.NextPlot = "add"; 
            app.Mainapp.UIAxes_2.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes_2,0,0);
            app.Mainapp.UIAxes_2.NextPlot = "add"; 
            app.Mainapp.UIAxes.Box = "off";

            %Take care of potentially changed backgroundcolor
            app.Mainapp.UIAxes.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainBackground;
            app.Mainapp.UIAxes_2.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeBackground;
            app.Mainapp.UIAxes.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.MainFontSize;
            app.Mainapp.UIAxes_2.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.TimeFontSize;

            if isfield(app.Mainapp.Data,'Spikes')
                if length(app.Mainapp.DropDown_2.Items)==2
                    if strcmp(app.Mainapp.DropDown_2.Items{2},'Events')
                        Placeholder = {};
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                        app.Mainapp.DropDown_2.Items{2} = 'Events';
                        app.Mainapp.DropDown_2.Items{3} = 'Spikes';
                    else
                        Placeholder = {};
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                        app.Mainapp.DropDown_2.Items{2} = 'Spikes';
                    end
                elseif length(app.Mainapp.DropDown_2.Items)==3
                    Placeholder = {};
                    app.Mainapp.DropDown_2.Items = Placeholder;
                    app.Mainapp.DropDown_2.Items{1} = 'Non';
                    app.Mainapp.DropDown_2.Items{2} = 'Events';
                    app.Mainapp.DropDown_2.Items{3} = 'Spikes';
                else
                    Placeholder = {};
                    app.Mainapp.DropDown_2.Items = Placeholder;
                    app.Mainapp.DropDown_2.Items{1} = 'Non';
                    app.Mainapp.DropDown_2.Items{2} = 'Spikes';
                end
            else
                if length(app.Mainapp.DropDown_2.Items)>=2
                    if strcmp(app.Mainapp.DropDown_2.Items{2},'Events')
                        Placeholder = {};
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                        app.Mainapp.DropDown_2.Items{2} = 'Events';
                    else
                        Placeholder = {};
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                    end
                else
                    Placeholder = {};
                    app.Mainapp.DropDown_2.Items = Placeholder;
                    app.Mainapp.DropDown_2.Items{1} = 'Non';
                end  
            end

            if strcmp(app.Mainapp.Plotspikes,"Spikes")
                SpikeHandles = findobj(app.Mainapp.UIAxes, 'Type', 'line', 'Tag', 'Spikes');
                if ~isempty(SpikeHandles)
                    delete(SpikeHandles(:));
                end
                if strcmp(app.Mainapp.PlotEvents,"Events")
                    app.Mainapp.DropDown_2.Value = "Events";
                else
                    app.Mainapp.DropDown_2.Value = "Non";
                end
                app.Mainapp.Plotspikes = "No";
            end

            %% Update Textbox "Recording Information" in Main window with new preprocessing infos
            Utility_Show_Info_Loaded_Data(app.Mainapp);

            %% Fill GUI textbox with new data
            % Check if spike sorting was already saved
            % Check if the folder exists
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                SpikeText = strcat("Existing Spike Data was found with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd));
            else
                SpikeText = strcat("No Existing Internal Spike Data was found");
            end

            app.SpikeSortingPath = strcat(app.Mainapp.Data.Info.Data_Path,'\Wave_Clus');
            
            if ~isfolder(app.SpikeSortingPath) % No sorting found
                textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx];
                app.TextArea.Value = textboxx;
            else
                textboxx = strcat("Spike Sorting Data found in: ",app.SpikeSortingPath);

                if isfile(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')))
                    load(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')));
                else
                    textboxx = [strcat("Path: ",app.SpikeSortingPath);"";"does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder"];
                    SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                    textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx];
                    app.TextArea.Value = textboxx;
                    return;
                end

                numclu = length(unique(cluster_class(:,1)));

                textboxx = [textboxx;" ";strcat("Number of Cluster: ",num2str(numclu));""];
                
                % Initialize a string array to store the output
                outputText = "";
                
                % Get all field names from the structure
                fields = fieldnames(par);

                % Loop through each field and concatenate the field name and value
                for i = 1:numel(fields)
                    fieldName = fields{i};
                    fieldValue = par.(fieldName);
                    
                    % Convert the field value to a string if it is not already
                    if isnumeric(fieldValue)
                        fieldValue = num2str(fieldValue);
                    elseif ischar(fieldValue)
                        fieldValue = string(fieldValue);
                    end
                    
                    % Concatenate the field name and value
                    outputText = outputText + fieldName + ": " + fieldValue + newline;
                end
                
                % Set the TextArea value in the app (assuming app.TextArea is the name of the TextArea)
                textboxx = [textboxx;outputText];
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx;outputText];

                app.TextArea.Value = textboxx;
                
            end
        end

        % Button pushed function: 
        % ManuallySelectFolderwithSortingResultsButton
        function ManuallySelectFolderwithSortingResultsButtonPushed(app, event)
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                SpikeText = strcat("Existing Spike Data was found with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionThreshold));
            else
                SpikeText = strcat("No Existing Internal Spike Data was found");
            end

            folderPath = uigetdir();
            
            if isempty(folderPath)
                return;
            end

            % Check if spike sorting was already saved
            % Check if the folder exists
            app.SpikeSortingPath = strcat(folderPath);
            
            if ~isfolder(app.SpikeSortingPath) % No sorting found
                textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx];
                app.TextArea.Value = textboxx;
            else
                textboxx = strcat("Spike Sorting Data found in: ",app.SpikeSortingPath);

                if isfile(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')))
                    load(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')));
                else
                    textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                    SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                    textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx];
                    app.TextArea.Value = textboxx;
                    return;
                end

                numclu = length(unique(cluster_class(:,1)));

                textboxx = [textboxx;" ";strcat("Number of Cluster: ",num2str(numclu));""];
                
                % Initialize a string array to store the output
                outputText = "";
                
                % Get all field names from the structure
                fields = fieldnames(par);

                % Loop through each field and concatenate the field name and value
                for i = 1:numel(fields)
                    fieldName = fields{i};
                    fieldValue = par.(fieldName);
                    
                    % Convert the field value to a string if it is not already
                    if isnumeric(fieldValue)
                        fieldValue = num2str(fieldValue);
                    elseif ischar(fieldValue)
                        fieldValue = string(fieldValue);
                    end
                    
                    % Concatenate the field name and value
                    outputText = outputText + fieldName + ": " + fieldValue + newline;
                end
                
                % Set the TextArea value in the app (assuming app.TextArea is the name of the TextArea)
                textboxx = [textboxx;outputText];
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx;outputText];

                app.TextArea.Value = textboxx;

            end
        end

        % Button pushed function: ChangeSpikeSortingSettingsButton
        function ChangeSpikeSortingSettingsButtonPushed(app, event)
            set_parameters_ui();
        end

        % Button pushed function: LoadExistingSpikeSortingButton
        function LoadExistingSpikeSortingButtonPushed(app, event)
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                if isfield(app.Mainapp.Data,'Preprocessed')
                    if isfield(app.Mainapp.Data.Info,'FilterMethod')
                        if strcmp(app.Mainapp.Data.Info.FilterMethod,"High-Pass")
                            [app.Mainapp.Data] = Spike_Module_Internal_Spike_Sorting(app.Mainapp.Data,app.SpikeSortingPath,"Loading");
                        else
                            msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                            return;
                        end
                    else
                        msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                        return;
                    end
                else
                    msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                    return;
                end
            else
                [app.Mainapp.Data] = Spike_Module_Internal_Spike_Sorting(app.Mainapp.Data,app.SpikeSortingPath,"Loading");
            end

            app.Mainapp.UIAxes.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes,0,0);
            app.Mainapp.UIAxes.NextPlot = "add"; 
            app.Mainapp.UIAxes_2.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes_2,0,0);
            app.Mainapp.UIAxes_2.NextPlot = "add"; 
            app.Mainapp.UIAxes.Box = "off";

            %Take care of potentially changed backgroundcolor
            app.Mainapp.UIAxes.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainBackground;
            app.Mainapp.UIAxes_2.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeBackground;
            app.Mainapp.UIAxes.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.MainFontSize;
            app.Mainapp.UIAxes_2.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.TimeFontSize;

            if isfield(app.Mainapp.Data,'Spikes')
                if length(app.Mainapp.DropDown_2.Items)==2
                    if strcmp(app.Mainapp.DropDown_2.Items{2},'Events')
                        Placeholder = {};
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                        app.Mainapp.DropDown_2.Items{2} = 'Events';
                        app.Mainapp.DropDown_2.Items{3} = 'Spikes';
                    else
                        Placeholder = {};
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                        app.Mainapp.DropDown_2.Items{2} = 'Spikes';
                    end
                elseif length(app.Mainapp.DropDown_2.Items)==3
                    Placeholder = {};
                    app.Mainapp.DropDown_2.Items = Placeholder;
                    app.Mainapp.DropDown_2.Items{1} = 'Non';
                    app.Mainapp.DropDown_2.Items{2} = 'Events';
                    app.Mainapp.DropDown_2.Items{3} = 'Spikes';
                else
                    Placeholder = {};
                    app.Mainapp.DropDown_2.Items = Placeholder;
                    app.Mainapp.DropDown_2.Items{1} = 'Non';
                    app.Mainapp.DropDown_2.Items{2} = 'Spikes';
                end
            else
                if length(app.Mainapp.DropDown_2.Items)>=2
                    if strcmp(app.Mainapp.DropDown_2.Items{2},'Events')
                        Placeholder = {};
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                        app.Mainapp.DropDown_2.Items{2} = 'Events';
                    else
                        Placeholder = {};
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                    end
                else
                    Placeholder = {};
                    app.Mainapp.DropDown_2.Items = Placeholder;
                    app.Mainapp.DropDown_2.Items{1} = 'Non';
                end  
            end

            if strcmp(app.Mainapp.Plotspikes,"Spikes")
                SpikeHandles = findobj(app.Mainapp.UIAxes, 'Type', 'line', 'Tag', 'Spikes');
                if ~isempty(SpikeHandles)
                    delete(SpikeHandles(:));
                end
                if strcmp(app.Mainapp.PlotEvents,"Events")
                    app.Mainapp.DropDown_2.Value = "Events";
                else
                    app.Mainapp.DropDown_2.Value = "Non";
                end
                app.Mainapp.Plotspikes = "No";
            end

            %% Update Textbox "Recording Information" in Main window with new preprocessing infos
            Utility_Show_Info_Loaded_Data(app.Mainapp);

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);

            % Check if spike sorting was already saved
            % Check if the folder exists
            app.SpikeSortingPath = strcat(app.Mainapp.Data.Info.Data_Path,'\Wave_Clus');

            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                SpikeText = strcat("Existing Spike Data was found with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd));
            else
                SpikeText = strcat("No Existing Internal Spike Data was found");
            end
            
            %% Fill GUI textbox with new data
            if ~isfolder(app.SpikeSortingPath) % No sorting found
                textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx];
                app.TextArea.Value = textboxx;
            else
                textboxx = strcat("Spike Sorting Data found in: ",app.SpikeSortingPath);

                if isfile(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')))
                    load(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')));
                else
                    textboxx = [strcat("Path: ",app.SpikeSortingPath);"";"does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder"];
                    SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                    textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx];
                    app.TextArea.Value = textboxx;
                    return;
                end

                numclu = length(unique(cluster_class(:,1)));

                textboxx = [textboxx;" ";strcat("Number of Cluster: ",num2str(numclu));""];
                
                % Initialize a string array to store the output
                outputText = "";
                
                % Get all field names from the structure
                fields = fieldnames(par);

                % Loop through each field and concatenate the field name and value
                for i = 1:numel(fields)
                    fieldName = fields{i};
                    fieldValue = par.(fieldName);
                    
                    % Convert the field value to a string if it is not already
                    if isnumeric(fieldValue)
                        fieldValue = num2str(fieldValue);
                    elseif ischar(fieldValue)
                        fieldValue = string(fieldValue);
                    end
                    
                    % Concatenate the field name and value
                    outputText = outputText + fieldName + ": " + fieldValue + newline;
                end
                
                % Set the TextArea value in the app (assuming app.TextArea is the name of the TextArea)
                textboxx = [textboxx;outputText];
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx;outputText];

                app.TextArea.Value = textboxx;
                
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SpikeDetectionWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {688, 688};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpikeDetectionWindowUIFigure = uifigure('Visible', 'off');
            app.SpikeDetectionWindowUIFigure.Name = 'Spike Detection Window';
            app.SpikeDetectionWindowUIFigure.Icon = 'Logo.png';
            app.SpikeDetectionWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SpikeDetectionWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.SpikeFilteringSettingsPanel = uipanel(app.RightPanel);
            app.SpikeFilteringSettingsPanel.Title = 'Spike Filtering Settings';
            app.FilterVerticalSpikeArtefactsCheckBox = uicheckbox(app.SpikeFilteringSettingsPanel);
            app.FilterVerticalSpikeArtefactsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterVerticalSpikeArtefactsCheckBoxValueChanged, true);
            app.FilterVerticalSpikeArtefactsCheckBox.Text = {'      Filter Vertical '; '      Spike Artefacts'};
            app.MinDepthofArtefactmEditFieldLabel = uilabel(app.SpikeFilteringSettingsPanel);
            app.MinDepthofArtefactmEditFieldLabel.Text = {'Min. Depth of Artefact'; '[µm]'};
            app.MinDepthofArtefactmEditField = uieditfield(app.SpikeFilteringSettingsPanel, 'text');
            app.MinDepthofArtefactmEditField.ValueChangedFcn = createCallbackFcn(app, @MinDepthofArtefactmEditFieldValueChanged, true);
            app.MinDepthofArtefactmEditField.Value = '200';
            app.VerticalSpikeOffsetToleranceSamplesEditFieldLabel = uilabel(app.SpikeFilteringSettingsPanel);
            app.VerticalSpikeOffsetToleranceSamplesEditFieldLabel.Text = {'Vertical Spike Offset'; 'Tolerance [Samples]'};
            app.VerticalSpikeOffsetToleranceSamplesEditField = uieditfield(app.SpikeFilteringSettingsPanel, 'text');
            app.VerticalSpikeOffsetToleranceSamplesEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalSpikeOffsetToleranceSamplesEditFieldValueChanged, true);
            app.VerticalSpikeOffsetToleranceSamplesEditField.Value = '3';
            app.StartSpikeDetectionButton = uibutton(app.SpikeFilteringSettingsPanel, 'push');
            app.StartSpikeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartSpikeDetectionButtonPushed, true);
            app.StartSpikeDetectionButton.Text = {'Start Spike '; 'Detection'};
            app.SpikeDetectionWindowLabel = uilabel(app.RightPanel);
            app.SpikeDetectionWindowLabel.Text = 'Spike Detection Window';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TextArea = uitextarea(app.RightPanel);
            app.InformationLabel = uilabel(app.RightPanel);
            app.InformationLabel.Text = 'Information';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.SpikeDetectionSettingsPanel = uipanel(app.RightPanel);
            app.SpikeDetectionSettingsPanel.Title = 'Spike Detection Settings';
            app.DetectionMethodDropDownLabel = uilabel(app.SpikeDetectionSettingsPanel);
            app.DetectionMethodDropDownLabel.Text = 'Detection Method';
            app.DetectionMethodDropDown = uidropdown(app.SpikeDetectionSettingsPanel);
            app.DetectionMethodDropDown.Items = {'Quiroga Method', 'Threshold: Mean - Std', 'Threshold: Median - Std'};
            app.DetectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectionMethodDropDownValueChanged, true);
            app.DetectionMethodDropDown.Value = 'Quiroga Method';
            app.ThresholdEditFieldLabel = uilabel(app.SpikeDetectionSettingsPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.SpikeDetectionSettingsPanel, 'text');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = '4';
            app.MeanandStdDropDownLabel = uilabel(app.SpikeDetectionSettingsPanel);
            app.MeanandStdDropDownLabel.Text = 'Mean and Std';
            app.MeanDropDown = uidropdown(app.SpikeDetectionSettingsPanel);
            app.MeanDropDown.Items = {'All Channel', 'Individual Ch.'};
            app.MeanDropDown.Value = 'All Channel';
            app.Wave_clus3SpikeSortingSettingsPanel = uipanel(app.RightPanel);
            app.Wave_clus3SpikeSortingSettingsPanel.Title = 'Wave_clus 3 Spike Sorting Settings';
            app.CreateNewSpikeSortingButton = uibutton(app.Wave_clus3SpikeSortingSettingsPanel, 'push');
            app.CreateNewSpikeSortingButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewSpikeSortingButtonPushed, true);
            app.CreateNewSpikeSortingButton.Text = {'Create New'; 'Spike Sorting'};
            app.ManuallySelectFolderwithSortingResultsButton = uibutton(app.Wave_clus3SpikeSortingSettingsPanel, 'push');
            app.ManuallySelectFolderwithSortingResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ManuallySelectFolderwithSortingResultsButtonPushed, true);
            app.ManuallySelectFolderwithSortingResultsButton.Text = {'Manually Select'; 'Folder with Sorting'; 'Results'};
            app.ChangeSpikeSortingSettingsButton = uibutton(app.Wave_clus3SpikeSortingSettingsPanel, 'push');
            app.ChangeSpikeSortingSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeSpikeSortingSettingsButtonPushed, true);
            app.ChangeSpikeSortingSettingsButton.Text = {'Change Spike '; 'Sorting Settings'};
            app.LoadExistingSpikeSortingButton = uibutton(app.Wave_clus3SpikeSortingSettingsPanel, 'push');
            app.LoadExistingSpikeSortingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExistingSpikeSortingButtonPushed, true);
            app.LoadExistingSpikeSortingButton.Text = {'Load Existing '; 'Spike Sorting'};
            % Show the figure after all components are created
            app.SpikeDetectionWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spike_Detection_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SpikeDetectionWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SpikeDetectionWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpikeDetectionWindowUIFigure)
        end
    end
end