classdef Spike_Detection_and_Sorting_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpikeDetectionWindowUIFigure    matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        SpikeSortingLabel               matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        Panel                           matlab.ui.container.Panel
        StartSpikeDetectionButton       matlab.ui.control.Button
        TimeOffsettoCombineSpikeIndiciessEditField  matlab.ui.control.EditField
        TimeSpantoCombineSpikeIndiciesEditFieldLabel  matlab.ui.control.Label
        FilterDoubleSpikeIndiciesCheckBox  matlab.ui.control.CheckBox
        MinDepthofArtefactmEditField    matlab.ui.control.EditField
        MinDepthofArtefactmEditFieldLabel  matlab.ui.control.Label
        VerticalSpikeOffsetToleranceSamplesEditField  matlab.ui.control.EditField
        VerticalSpikeOffsetToleranceSamplesEditFieldLabel  matlab.ui.control.Label
        FilterVerticalSpikeArtefactsCheckBox  matlab.ui.control.CheckBox
        MeanDropDown                    matlab.ui.control.DropDown
        MeanandStdDropDownLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.EditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        DetectionMethodDropDown         matlab.ui.control.DropDown
        DetectionMethodLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        SimpleSpikeDetectionLabel       matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        CloseButton                     matlab.ui.control.Button
        NeuromodLabel                   matlab.ui.control.Label
        SpikeDetectionandSortingLabel   matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        FoldercontainingaSpikeInterfaceandorKilosortfolderLabel  matlab.ui.control.Label
        SetOutputFolderManuallyButton   matlab.ui.control.Button
        OnlyforWaveClus3Label           matlab.ui.control.Label
        TextArea_3                      matlab.ui.control.TextArea
        SortallChannelTogetherCheckBox  matlab.ui.control.CheckBox
        SortforIndividualChannelCheckBox  matlab.ui.control.CheckBox
        CheckBox                        matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        OptionsDropDown_2               matlab.ui.control.DropDown
        OptionsDropDown_2Label          matlab.ui.control.Label
        RunsviaPythonLabel              matlab.ui.control.Label
        RUNButton_2                     matlab.ui.control.Button
        SorterDropDown                  matlab.ui.control.DropDown
        SorterDropDownLabel             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        SpikeSortingPath = [];
        AutoSortingPathToBin = [];
        SpikeSortinBinPath = [];
        SpikeInterfaceParameter = [];
        SortingParameterWindow = [];
        ManualSelection = [];

        ParameterStructure = []; % For spike sorting settings mountainsort 5 und spykingcircus 2
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.SpikeDetectionWindowUIFigure, 'center');

            app.Mainapp = Mainapp;
            
            app.ManualSelection = 0;

            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,"Internal")
                SpikeText = strcat("Existing Spike Data was found with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd));
            else
                SpikeText = strcat("No Existing Internal Spike Data was found");
            end

            app.VerticalSpikeOffsetToleranceSamplesEditField.Enable = "off";
            app.MinDepthofArtefactmEditField.Enable = "off";

            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                SpikeText = strcat("Spike data already part of dataset with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd),". If you want to load spike sorting data make sure the threshold is the same. Otherwise current spike dta is overwritten when loading sorting results.");
            else
                SpikeText = strcat("No Existing Internal Spike Data was found");
            end
            
            SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
            SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
            textboxx = [SpikeText;"";SpikeExtractiontext;"";SpikeExtractiontext2;""];
            app.TextArea.Value = textboxx;
            
            app = Spike_Module_Check_Bin_Dat_Files(app,"Auto",[]);
            
        end

        % Button pushed function: StartSpikeDetectionButton
        function StartSpikeDetectionButtonPushed(app, event)
            %% Check if high pass filtered data is available. If not, display error message and return
            if ~isfield(app.Mainapp.Data,'Preprocessed')
                msgbox("Error: No high pass filtered data detected. Spike detection on raw data wont work. High pass filter data and open this window again!");
                disp("Error: No high pass filtered data detected. Spike detection on raw data wont work. High pass filter data and open this window again!");
                return;
            else
                if ~isfield(app.Mainapp.Data.Info,'FilterMethod')
                    msgbox("Error: No high pass filtered data detected. Spike detection on raw data wont work. High pass filter data and open this window again!");
                    disp("Error: No high pass filtered data detected. Spike detection on raw data wont work. High pass filter data and open this window again!");
                    return;
                else
                    if ~strcmp(app.Mainapp.Data.Info.FilterMethod,"High-Pass")
                        msgbox("Error: No high pass filtered data detected. Spike detection on raw data wont work. High pass filter data and open this window again!");
                        disp("Error: No high pass filtered data detected. Spike detection on raw data wont work. High pass filter data and open this window again!");
                        return;
                    end
                end
            end

            disp(strcat("Extracting Spikes. Please wait until the progress bar closes"));

            %% Spike Detection
            [app.Mainapp.Data,~] = Spike_Module_Spike_Detection(app.Mainapp.Data,app.DetectionMethodDropDown.Value,app.MeanDropDown.Value,str2double(app.ThresholdEditField.Value),app.FilterVerticalSpikeArtefactsCheckBox.Value,app.VerticalSpikeOffsetToleranceSamplesEditField.Value,app.MinDepthofArtefactmEditField.Value,app.FilterDoubleSpikeIndiciesCheckBox.Value,str2double(app.TimeOffsettoCombineSpikeIndiciessEditField.Value));

            %% Aftmeramth: Resetting Main window Plots and plot data again
            disp(strcat("Spike detection finished. They can now be visualized and analyzed in the main window"));

            app.Mainapp.UIAxes.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes,0,0);
            app.Mainapp.UIAxes.NextPlot = "add"; 
            app.Mainapp.UIAxes_2.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes_2,0,0);
            app.Mainapp.UIAxes_2.NextPlot = "add"; 
            app.Mainapp.UIAxes.Box = "off";

            %Take care of potentially changed backgroundcolor
            app.Mainapp.UIAxes.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainBackground;
            app.Mainapp.UIAxes_2.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeBackground;
            app.Mainapp.UIAxes.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.MainFontSize;
            app.Mainapp.UIAxes_2.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.TimeFontSize;

            if ~isfield(app.Mainapp.Data,'Events')
                if strcmp(app.Mainapp.PlotEvents,"Events")
                    app.Mainapp.PlotEvents = "No";
                end
                Placeholder = {};
                app.Mainapp.DropDown_2.Items = Placeholder;
                app.Mainapp.DropDown_2.Items{1} = 'Non';
            else
                Placeholder = {};
                app.Mainapp.DropDown_2.Items = Placeholder;
                app.Mainapp.DropDown_2.Items{1} = 'Non';
                app.Mainapp.DropDown_2.Items{2} = 'Events';
                if strcmp(app.Mainapp.PlotEvents,"Events")
                    app.Mainapp.DropDown_2.Value = 'Events';
                end
            end
        
            if ~isfield(app.Mainapp.Data,'Spikes')
                % if strcmp(app.Mainapp.Plotspikes,"Spikes")
                %     app.Mainapp.Plotspikes = "No";
                % end
            else
                app.Mainapp.DropDown_2.Items = Placeholder;
                app.Mainapp.DropDown_2.Items{1} = 'Non';
                if isfield(app.Mainapp.Data,'Events')
                    app.Mainapp.DropDown_2.Items{2} = 'Events';
                    app.Mainapp.DropDown_2.Items{3} = 'Spikes';
                else
                    app.Mainapp.DropDown_2.Items{2} = 'Spikes';
                end
                
                if strcmp(app.Mainapp.PlotEvents,"Events")
                    app.Mainapp.DropDown_2.Value = 'Events';
                else
                    app.Mainapp.DropDown_2.Value = 'Non';
                end
            end

            if strcmp(app.Mainapp.Plotspikes,"Spikes")
                SpikeHandles = findobj(app.Mainapp.UIAxes, 'Type', 'line', 'Tag', 'Spikes');
                if ~isempty(SpikeHandles)
                    delete(SpikeHandles(:));
                end
                app.Mainapp.Plotspikes = "No";
            end

            Utility_Show_Info_Loaded_Data(app.Mainapp);

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","SpikeExtraction",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);

            %% Fill GUI textbox with new data
            % Check if spike sorting was already saved
            % Check if the folder exists
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,"Internal")
                SpikeText = strcat("Existing Spike Data was found with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd));
            else
                SpikeText = strcat("No Existing Internal Spike Data was found");
            end

            app.SpikeSortingPath = strcat(app.Mainapp.Data.Info.Data_Path,'\Wave_Clus');
            
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                SpikeText = strcat("Spike data already part of dataset with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd),". If you want to load spike sorting data make sure the threshold is the same. Otherwise current spike dta is overwritten when loading sorting results.");
            else
                SpikeText = strcat("No Existing Internal Spike Data was found");
            end
            
            if ~isfolder(app.SpikeSortingPath) % No sorting found
                textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                    textboxx = [SpikeText;"";SpikeExtractiontext;"";SpikeExtractiontext2;"";textboxx];
                app.TextArea.Value = textboxx;
            else

                textboxx = strcat("Spike Sorting Data found in: ",app.SpikeSortingPath,". Select the 'Load Sorting Results' option and press 'RUN' to load spike sorting.");

                if isfile(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')))
                    load(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')));
                    SortingType = "AllChannel";
                    textboxx = [textboxx;"";"Type: Sorted all channel together."];
                else
                    if isfile(strcat(strcat(app.SpikeSortingPath,'\times_CH',num2str(1),'.mat')))
                        load(strcat(strcat(app.SpikeSortingPath,'\times_CH',num2str(1),'.mat')));
                        SortingType = "IndividualChannel";
                        textboxx = [textboxx;"";"Type: Sorted channel individually."];
                    else
                        textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                        SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                        SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                        textboxx = [SpikeText;"";SpikeExtractiontext;"";SpikeExtractiontext2;"";textboxx];
                        app.TextArea.Value = textboxx;
                        return;
                    end
                end

                if strcmp(SortingType,"AllChannel")
                    numclu = length(unique(cluster_class(:,1)));
    
                    textboxx = [textboxx;" ";strcat("Number of Cluster: ",num2str(numclu));""];
                    
                    % Initialize a string array to store the output
                    outputText = "";
                    
                    % Get all field names from the structure
                    fields = fieldnames(par);
    
                    % Loop through each field and concatenate the field name and value
                    for i = 1:numel(fields)
                        fieldName = fields{i};
                        fieldValue = par.(fieldName);
                        
                        % Convert the field value to a string if it is not already
                        if isnumeric(fieldValue)
                            fieldValue = num2str(fieldValue);
                        elseif ischar(fieldValue)
                            fieldValue = string(fieldValue);
                        end
                        
                        % Concatenate the field name and value
                        outputText = outputText + fieldName + ": " + fieldValue + newline;
                    end
                    
                    % Set the TextArea value in the app (assuming app.TextArea is the name of the TextArea)
                    textboxx = [textboxx;outputText];
                    SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                    SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                    textboxx = [SpikeText;"";SpikeExtractiontext;"";SpikeExtractiontext2;"";textboxx;outputText];
    
                    app.TextArea.Value = textboxx;
                else
                    if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                        SpikeText = strcat("Spike data already part of dataset with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd),". If you want to load spike sorting data make sure the threshold is the same. Otherwise current spike dta is overwritten when loading sorting results.");
                    else
                        SpikeText = strcat("No Existing Internal Spike Data was found");
                    end

                    SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                    SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                    textboxx = [SpikeText;"";textboxx;"";SpikeExtractiontext;"";SpikeExtractiontext2];

                    app.TextArea.Value = textboxx;
                end
            end

            if strcmp(app.SorterDropDown.Value,"WaveClus 3")
                app.TextArea_3.Value = strcat("Spike data found as part of the dataset.");
                app.Label.Text = "Spike data found";
                app.Label.FontColor = [0.47,0.67,0.19];
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Mainapp.SpikeExtractionWindow = [];
            if ~isempty(app.SortingParameterWindow)
                delete(app.SortingParameterWindow)
            end

            delete(app);
        end

        % Value changed function: DetectionMethodDropDown
        function DetectionMethodDropDownValueChanged(app, event)
            
        end

        % Value changed function: FilterVerticalSpikeArtefactsCheckBox
        function FilterVerticalSpikeArtefactsCheckBoxValueChanged(app, event)
            value = app.FilterVerticalSpikeArtefactsCheckBox.Value;
            if value == 1
                app.VerticalSpikeOffsetToleranceSamplesEditField.Enable = "on";
                app.MinDepthofArtefactmEditField.Enable = "on";
            else
                app.VerticalSpikeOffsetToleranceSamplesEditField.Enable = "off";
                app.MinDepthofArtefactmEditField.Enable = "off";
            end
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            [app.ThresholdEditField.Value] = Utility_SimpleCheckInputs(app.ThresholdEditField.Value,"One",'4',0,0);
            
        end

        % Value changed function: 
        % VerticalSpikeOffsetToleranceSamplesEditField
        function VerticalSpikeOffsetToleranceSamplesEditFieldValueChanged(app, event)
            [app.VerticalSpikeOffsetToleranceSamplesEditField.Value] = Utility_SimpleCheckInputs(app.VerticalSpikeOffsetToleranceSamplesEditField.Value,"One",'3',0,0);
            
        end

        % Value changed function: MinDepthofArtefactmEditField
        function MinDepthofArtefactmEditFieldValueChanged(app, event)
            [app.MinDepthofArtefactmEditField.Value] = Utility_SimpleCheckInputs(app.MinDepthofArtefactmEditField.Value,"One",'200',0,0);
            
        end

        % Value changed function: 
        % TimeOffsettoCombineSpikeIndiciessEditField
        function TimeOffsettoCombineSpikeIndiciessEditFieldValueChanged(app, event)
            
            [app.TimeOffsettoCombineSpikeIndiciessEditField.Value] = Utility_SimpleCheckInputs(app.TimeOffsettoCombineSpikeIndiciessEditField.Value,"One",'0.002',0,0);
            
        end

        % Value changed function: SortallChannelTogetherCheckBox
        function SortallChannelTogetherCheckBoxValueChanged(app, event)
            value = app.SortallChannelTogetherCheckBox.Value;
            if value == 1
                app.SortforIndividualChannelCheckBox.Value = 0;
            end
        end

        % Value changed function: SortforIndividualChannelCheckBox
        function SortforIndividualChannelCheckBoxValueChanged(app, event)
            value = app.SortforIndividualChannelCheckBox.Value;
            if value == 1
                app.SortallChannelTogetherCheckBox.Value = 0;
            end
        end

        % Close request function: SpikeDetectionWindowUIFigure
        function SpikeDetectionWindowUIFigureCloseRequest(app, event)
            app.Mainapp.SpikeExtractionWindow = [];
            if ~isempty(app.SortingParameterWindow)
                delete(app.SortingParameterWindow)
            end
            delete(app);
        end

        % Button pushed function: RUNButton_2
        function RUNButton_2Pushed(app, event)
            
            if ~strcmp(app.SorterDropDown.Value,"WaveClus 3")
                if isempty(app.ParameterStructure)
                    [app.ParameterStructure,~] = Spike_Module_ShowSpikeSortingSettings(app.SorterDropDown.Value,app.ParameterStructure,"ParameterNOTPresent");
                end
    
                if isempty(app.ParameterStructure)
                    app.ParameterStructure.SC2 = 0;
                end
    
                app.SpikeSortinBinPath = convertStringsToChars(app.SpikeSortinBinPath);
    
                if isempty(app.SpikeSortinBinPath) && strcmp(app.OptionsDropDown_2.Value,"Start New Spike Sorting")
                    msgbox("Error: No valid path containing a .bin file of your recording found. Please first export dataset as a .bin file using the 'Save for Sorting' option or specify the path to that file manually.")
                    return;
                end
    
                if strcmp(app.OptionsDropDown_2.Value,"Start New Spike Sorting")
    
                    [pythonPath] = Spike_Module_Check_Load_Conda_Python_exe(app.Mainapp.executableFolder);
                    SpikeInterfaceScriptPath = strcat(app.Mainapp.executableFolder,'\Modules\SpikeInterface\SpikeInterface_Sorting.py');
                    
                    if isempty(app.SpikeInterfaceParameter)
                        app.SpikeInterfaceParameter.OpenSpikeInterface = 1;
                        app.SpikeInterfaceParameter.Preprocess = 1;
                        app.SpikeInterfaceParameter.PlotTraces = 0;
                        app.SpikeInterfaceParameter.PlotSortingResults = 0;
                        app.SpikeInterfaceParameter.JustOpenSpikeInterfaceGUI = 0;
                        app.SpikeInterfaceParameter.LoadSorting = 0;
                        app.SpikeInterfaceParameter.MultipleRecordings = 0;
                        app.SpikeInterfaceParameter.Sorter = app.SorterDropDown.Value;
                        app.SpikeInterfaceParameter.KeepConsoleOpen = 0;
                    else
                        app.SpikeInterfaceParameter.JustOpenSpikeInterfaceGUI = 0;
                    end
    
                    % path
                    if app.ManualSelection == 0
                        dashindex = find(app.SpikeSortinBinPath=='\');
                        TempSpikeSortinBinPath = app.SpikeSortinBinPath(1:dashindex(end)-1);
                        file_path = TempSpikeSortinBinPath;
                    else
                        if app.SpikeInterfaceParameter.MultipleRecordings == 0
                            dashindex = find(app.SpikeSortinBinPath=='\');
                            TempSpikeSortinBinPath = app.SpikeSortinBinPath(1:dashindex(end)-1);
                            file_path = TempSpikeSortinBinPath;
                        else
                            file_path = app.SpikeSortinBinPath;
                        end
                    end
    
                    SampleRate = app.Mainapp.Data.Info.NativeSamplingRate;
                    NumChannel = size(app.Mainapp.Data.Raw,1);
                    ypitch = app.Mainapp.Data.Info.ChannelSpacing;
    
                    % Build the command string
                    VerChannelOffset = str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset);
                    HorChannelOffset = str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset);
                    NumberRows = str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows);
                    RowOffset = double(app.Mainapp.Data.Info.ProbeInfo.OffSetRows);
                    RowOffsetDistance = str2double(app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance);
    
                    if strcmp(app.SorterDropDown.Value,"Mountainsort 5")
                        % Loop over all fields
                        fields = fieldnames(app.ParameterStructure.MS5);
                        for i = 1:numel(fields)
                            field = fields{i}; % Get the field name
                            value = app.ParameterStructure.MS5.(field); % Get the field value
                            
                            if ischar(value) % Only process strings
                                % Remove leading and trailing single/double quotes
                                value = strip(value, '"');
                                value = strip(value, '''');
                                
                                % Check if the value is 'None'
                                if strcmpi(value, 'None')
                                    app.ParameterStructure.MS5.(field) = []; % Convert to empty
                                else
                                    app.ParameterStructure.MS5.(field) = value; % Assign the cleaned value back
                                end
                            end
                        end
                        % Convert Sorter Parameter into dictionary
                        SortingParameters = jsonencode(app.ParameterStructure.MS5);
                    elseif strcmp(app.SorterDropDown.Value,"SpykingCircus 2")
                        app.ParameterStructure.SC2 = Spike_Module_cleanStructureQuotes(app.ParameterStructure.SC2);
                        app.ParameterStructure.SC2 = Spike_Module_convertTrueFalseStrings(app.ParameterStructure.SC2);
                        % Loop over all fields
                        fields = fieldnames(app.ParameterStructure.SC2);
                        for i = 1:numel(fields)
                            field = fields{i}; % Get the field name
                            value = app.ParameterStructure.SC2.(field); % Get the field value
                            
                            if ischar(value) % Only process strings
                                % Remove leading and trailing single/double quotes
                                value = strip(value, '"');
                                value = strip(value, '''');
                                
                                % Check if the value is 'None'
                                if strcmpi(value, 'None')
                                    app.ParameterStructure.SC2.(field) = []; % Convert to empty
                                else
                                    app.ParameterStructure.SC2.(field) = value; % Assign the cleaned value back
                                end
                            end
                        end
                        % Convert Sorter Parameter into dictionary
                        SortingParameters = jsonencode(app.ParameterStructure.SC2);
                    elseif strcmp(app.SorterDropDown.Value,"Kilosort 4")
                        % Loop over all fields
                        fields = fieldnames(app.ParameterStructure.KS4);
                        for i = 1:numel(fields)
                            field = fields{i}; % Get the field name
                            value = app.ParameterStructure.KS4.(field); % Get the field value
                            
                            if ischar(value) % Only process strings
                                % Remove leading and trailing single/double quotes
                                value = strip(value, '"');
                                value = strip(value, '''');
                                
                                % Check if the value is 'None'
                                if strcmpi(value, 'None')
                                    app.ParameterStructure.KS4.(field) = []; % Convert to empty
                                else
                                    app.ParameterStructure.KS4.(field) = value; % Assign the cleaned value back
                                end
                            end
                        end

                        % Convert Sorter Parameter into dictionary
                        SortingParameters = jsonencode(app.ParameterStructure.KS4);
                    end
                    
                    % Save JSON to a temporary file
                    if isfile(fullfile(file_path, 'sorting_parameters.json'))
                        delete(fullfile(file_path, 'sorting_parameters.json'))
                    end
                    jsonFilePath = fullfile(file_path, 'sorting_parameters.json');
                    fid = fopen(jsonFilePath, 'w');
                    fwrite(fid, SortingParameters, 'char');
                    fclose(fid);
    
                    command = sprintf('"%s" "%s" "%s" %d "%s" %d %d %d %d %d %d %d %d %d %d %d %d %d %d %d', ...
                        pythonPath, SpikeInterfaceScriptPath, file_path, app.SpikeInterfaceParameter.MultipleRecordings, app.SorterDropDown.Value, ...
                        app.SpikeInterfaceParameter.Preprocess, app.SpikeInterfaceParameter.LoadSorting, app.SpikeInterfaceParameter.OpenSpikeInterface, ...
                        app.SpikeInterfaceParameter.PlotSortingResults, app.SpikeInterfaceParameter.JustOpenSpikeInterfaceGUI, SampleRate, NumChannel, ypitch, app.SpikeInterfaceParameter.KeepConsoleOpen, app.SpikeInterfaceParameter.PlotTraces,VerChannelOffset,HorChannelOffset,NumberRows,RowOffset,RowOffsetDistance);
                    
                    % Execute the Python script
                    [status, cmdout] = system(command);
                    
                    % Check status
    
                    if status == 0
                        disp('Python script executed successfully:');
                        disp(cmdout);
                        % Auto load results

                        dashindex = find(file_path=='\');
                        TempFilePath = file_path(1:dashindex(end)-1);

                        Spike_Module_Load_Sorting_Results_After_Sorting(app,app.SorterDropDown.Value,TempFilePath);

                    else
                        disp('Error executing Python script:');
                        disp(cmdout);
                    end
    
                elseif strcmp(app.OptionsDropDown_2.Value,"Load Sorting Results")
    
                    if isfield(app.Mainapp.Data.Info,'CutStart')
                        msgbox("Warning: Start time of current dataset was cut. Please ensure, that spike sorting results are based on the same dataset");
                    end
                
                    if isfield(app.Mainapp.Data.Info,'CutEnd') 
                        msgbox("Warning: End time of current dataset was cut. Please ensure, that spike sorting results are based on the same dataset");
                    end
                
                    cd(app.Mainapp.executableFolder);
                
                    app.Mainapp.LoadfromKilosortWindowWindow = Load_Spike_Sorting_Window(app.Mainapp);
    
                elseif strcmp(app.OptionsDropDown_2.Value,"Change Sorting Parameters")
                     
                    if isempty(app.ParameterStructure)
                        [app.ParameterStructure,~] = Spike_Module_ShowSpikeSortingSettings(app.SorterDropDown.Value,app.ParameterStructure,"ParameterNOTPresent");
                    end
    
                     app.SortingParameterWindow = SpikeInterface_SpikeSorting_Parameter(app.Mainapp,app);
                    
                     [app.Mainapp] = Utility_Set_ToolTips(app.Mainapp,app.Mainapp.ShowToolTipsSetting,"SortingParameterWindow");

                end
            end


            if strcmp(app.SorterDropDown.Value,"WaveClus 3")

                %% New Spike Sorting
                if strcmp(app.OptionsDropDown_2.Value,"Start New Spike Sorting")
                    if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                        if isfield(app.Mainapp.Data,'Preprocessed')
                            if isfield(app.Mainapp.Data.Info,'FilterMethod')
                                if strcmp(app.Mainapp.Data.Info.FilterMethod,"High-Pass")
                                    if app.SortallChannelTogetherCheckBox.Value == 1 
                                        SortingType = "AllChannelTogether";
                                    elseif app.SortforIndividualChannelCheckBox.Value == 1
                                        SortingType = "IndividualChannel";
                                    end
    
                                    [app.Mainapp.Data] = Spike_Module_Internal_Spike_Sorting(app.Mainapp.Data,app.SpikeSortingPath,"Clustering",SortingType,"WaveClus");
                                else
                                    msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                                    return;
                                end
                            else
                                msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                                return;
                            end
                        else
                            msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                            return;
                        end
                    else
                        msgbox("No Internal Spike Data found. Please first Start Spike Detection!")
                        return;
                    end
                 %% Change Sorting Parameters
                elseif strcmp(app.OptionsDropDown_2.Value,"Change Sorting Parameters")
                    set_parameters_ui();
                %% Load Sorting
                elseif strcmp(app.OptionsDropDown_2.Value,"Load Sorting Results")

                    if isfield(app.Mainapp.Data.Info,'CutStart')
                        msgbox("Warning: Start time of current dataset was cut. Please ensure, that spike sorting results are based on the same dataset");
                    end
                
                    if isfield(app.Mainapp.Data.Info,'CutEnd') 
                        msgbox("Warning: End time of current dataset was cut. Please ensure, that spike sorting results are based on the same dataset");
                    end
                
                    cd(app.Mainapp.executableFolder);
                
                    app.Mainapp.LoadfromKilosortWindowWindow = Load_Spike_Sorting_Window(app.Mainapp);

                end
    
                %% PostProcessing
                if strcmp(app.OptionsDropDown_2.Value,"Start New Spike Sorting") || strcmp(app.OptionsDropDown_2.Value,"Load Sorting Results")
                    
                    %% Mainapp plot
                    app.Mainapp.UIAxes.NextPlot = "replace"; 
                    plot(app.Mainapp.UIAxes,0,0);
                    app.Mainapp.UIAxes.NextPlot = "add"; 
                    app.Mainapp.UIAxes_2.NextPlot = "replace"; 
                    plot(app.Mainapp.UIAxes_2,0,0);
                    app.Mainapp.UIAxes_2.NextPlot = "add"; 
                    app.Mainapp.UIAxes.Box = "off";
        
                    %Take care of potentially changed backgroundcolor
                    app.Mainapp.UIAxes.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainBackground;
                    app.Mainapp.UIAxes_2.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeBackground;
                    app.Mainapp.UIAxes.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.MainFontSize;
                    app.Mainapp.UIAxes_2.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.TimeFontSize;
        
                    if ~isfield(app.Mainapp.Data,'Events')
                        if strcmp(app.Mainapp.PlotEvents,"Events")
                            app.Mainapp.PlotEvents = "No";
                        end
                        Placeholder = {};
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                    else
                        Placeholder = {};
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                        app.Mainapp.DropDown_2.Items{2} = 'Events';
                        if strcmp(app.Mainapp.PlotEvents,"Events")
                            app.Mainapp.DropDown_2.Value = 'Events';
                        end
                    end
                
                    if ~isfield(app.Mainapp.Data,'Spikes')
                        % if strcmp(app.Mainapp.Plotspikes,"Spikes")
                        %     app.Mainapp.Plotspikes = "No";
                        % end
                    else
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                        if isfield(app.Mainapp.Data,'Events')
                            app.Mainapp.DropDown_2.Items{2} = 'Events';
                            app.Mainapp.DropDown_2.Items{3} = 'Spikes';
                        else
                            app.Mainapp.DropDown_2.Items{2} = 'Spikes';
                        end
                        
                        if strcmp(app.Mainapp.PlotEvents,"Events")
                            app.Mainapp.DropDown_2.Value = 'Events';
                        else
                            app.Mainapp.DropDown_2.Value = 'Non';
                        end
                        
                    end
        
                    if strcmp(app.Mainapp.Plotspikes,"Spikes")
                        SpikeHandles = findobj(app.Mainapp.UIAxes, 'Type', 'line', 'Tag', 'Spikes');
                        if ~isempty(SpikeHandles)
                            delete(SpikeHandles(:));
                        end
                        app.Mainapp.Plotspikes = "No";
                    end
        
                    % Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
        
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","SpikeExtraction",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        
                    
                end
            end
        end

        % Button pushed function: SetOutputFolderManuallyButton
        function SetOutputFolderManuallyButtonPushed(app, event)
            if ~strcmp(app.SorterDropDown.Value,"WaveClus 3")
                app.ManualSelection = 1;
                app.SpikeSortinBinPath = [];
                
                % Prompt the user to select a file
                filepath = uigetdir();
                
                % Check if the user selected a file or canceled the dialog
                if isequal(filepath, 0)
                    disp('User canceled the file selection.');
                    % Display the selected file's full path
                    app.SpikeSortinBinPath = [];
                    app.Label.FontColor = [1.00,0.00,0.00];
                    
                    app.Label.Text = "Manual selection of exported .bin file " + ...
                        "NOT Succesfull";
                    return;
                  
                else
    
                    app = Spike_Module_Check_Bin_Dat_Files(app,"Manual",filepath);
    
                end
            %% Waveclus
            else 
                app.ManualSelection = 1;
                app.SpikeSortinBinPath = [];
                
                % Prompt the user to select a file
                filepath = uigetdir();
                
                % Check if the user selected a file or canceled the dialog
                if isequal(filepath, 0)
                    disp('User canceled the file selection.');
                    % Display the selected file's full path
                    app.SpikeSortinBinPath = [];
                    app.Label.FontColor = [1.00,0.00,0.00];
                    
                    app.Label.Text = "Manual selection of output file " + ...
                        "NOT Succesfull";
                    return;
                  
                else
                    
                    app.SpikeSortinBinPath = filepath;

                    app = Spike_Module_Detect_WaveClus_Sorting(app,app.SpikeSortinBinPath,0);
    
                end
            end
        end

        % Value changed function: SorterDropDown
        function SorterDropDownValueChanged(app, event)
            if ~strcmp(app.SorterDropDown.Value,"WaveClus 3")
                
                if isfield(app.SpikeInterfaceParameter,'Sorter')
                    if strcmp(app.SpikeInterfaceParameter.Sorter,"WaveClus 3")
                        app.SpikeInterfaceParameter = [];
                    end
                end

                app.SortallChannelTogetherCheckBox.Enable = "off";
                app.SortforIndividualChannelCheckBox.Enable = "off";
                app.SetOutputFolderManuallyButton.Text = "Manually Select Folder with exported .bin file";

                app.RunsviaPythonLabel.Text = "Runs via Python!";

                app = Spike_Module_Check_Bin_Dat_Files(app,"Auto",[]);
    
                app.ParameterStructure = [];
                if isempty(app.ParameterStructure)
                    [app.ParameterStructure,~] = Spike_Module_ShowSpikeSortingSettings(app.SorterDropDown.Value,app.ParameterStructure,"ParameterNOTPresent");
                end
            else
                app.SpikeInterfaceParameter.Sorter = app.SorterDropDown.Value;
                app.RunsviaPythonLabel.Text = "Runs via Matlab!";
                app.SortallChannelTogetherCheckBox.Enable = "on";
                app.SortforIndividualChannelCheckBox.Enable = "on";

                app.SetOutputFolderManuallyButton.Text = "Manually Select Folder to save results in.";

                app = Spike_Module_Detect_WaveClus_Sorting(app,app.Mainapp.Data.Info.Data_Path,0);
            end
            
        end

        % Value changed function: OptionsDropDown_2
        function OptionsDropDown_2ValueChanged(app, event)
            value = app.OptionsDropDown_2.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SpikeDetectionWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {814, 814};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpikeDetectionWindowUIFigure = uifigure('Visible', 'off');
            app.SpikeDetectionWindowUIFigure.Name = 'Spike Detection Window';
            app.SpikeDetectionWindowUIFigure.Icon = 'Logo.png';
            app.SpikeDetectionWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @SpikeDetectionWindowUIFigureCloseRequest, true);
            app.SpikeDetectionWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SpikeDetectionWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel_2 = uipanel(app.RightPanel);
            app.SorterDropDownLabel = uilabel(app.Panel_2);
            app.SorterDropDownLabel.Text = 'Sorter';
            app.SorterDropDown = uidropdown(app.Panel_2);
            app.SorterDropDown.Items = {'Mountainsort 5', 'SpykingCircus 2', 'Kilosort 4', 'WaveClus 3'};
            app.SorterDropDown.ValueChangedFcn = createCallbackFcn(app, @SorterDropDownValueChanged, true);
            app.SorterDropDown.Value = 'Mountainsort 5';
            app.RUNButton_2 = uibutton(app.Panel_2, 'push');
            app.RUNButton_2.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_2Pushed, true);
            app.RUNButton_2.Text = 'RUN';
            app.RunsviaPythonLabel = uilabel(app.Panel_2);
            app.RunsviaPythonLabel.Text = 'Runs via Python!';
            app.OptionsDropDown_2Label = uilabel(app.Panel_2);
            app.OptionsDropDown_2Label.Text = 'Options';
            app.OptionsDropDown_2 = uidropdown(app.Panel_2);
            app.OptionsDropDown_2.Items = {'Start New Spike Sorting', 'Change Sorting Parameters', 'Load Sorting Results'};
            app.OptionsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @OptionsDropDown_2ValueChanged, true);
            app.OptionsDropDown_2.Value = 'Start New Spike Sorting';
            app.Label = uilabel(app.Panel_2);
            app.CheckBox = uicheckbox(app.Panel_2);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Text = '';
            app.SortforIndividualChannelCheckBox = uicheckbox(app.Panel_2);
            app.SortforIndividualChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @SortforIndividualChannelCheckBoxValueChanged, true);
            app.SortforIndividualChannelCheckBox.Enable = 'off';
            app.SortforIndividualChannelCheckBox.Text = '    Sort for Individual Channel';
            app.SortallChannelTogetherCheckBox = uicheckbox(app.Panel_2);
            app.SortallChannelTogetherCheckBox.ValueChangedFcn = createCallbackFcn(app, @SortallChannelTogetherCheckBoxValueChanged, true);
            app.SortallChannelTogetherCheckBox.Enable = 'off';
            app.SortallChannelTogetherCheckBox.Text = '    Sort all Channel Together';
            app.SortallChannelTogetherCheckBox.Value = true;
            app.TextArea_3 = uitextarea(app.Panel_2);
            app.OnlyforWaveClus3Label = uilabel(app.Panel_2);
            app.OnlyforWaveClus3Label.Text = 'Only for WaveClus 3:';
            app.SetOutputFolderManuallyButton = uibutton(app.Panel_2, 'push');
            app.SetOutputFolderManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @SetOutputFolderManuallyButtonPushed, true);
            app.SetOutputFolderManuallyButton.Text = 'Manually Select Recording Folder';
            app.FoldercontainingaSpikeInterfaceandorKilosortfolderLabel = uilabel(app.Panel_2);
            app.FoldercontainingaSpikeInterfaceandorKilosortfolderLabel.Text = {'Folder containing a ''SpikeInterface'''; 'folder with a .bin file'};
            app.SpikeDetectionandSortingLabel = uilabel(app.RightPanel);
            app.SpikeDetectionandSortingLabel.Text = 'Spike Detection and Sorting';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TextArea = uitextarea(app.RightPanel);
            app.SimpleSpikeDetectionLabel = uilabel(app.RightPanel);
            app.SimpleSpikeDetectionLabel.Text = 'Simple Spike Detection';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.Panel = uipanel(app.RightPanel);
            app.DetectionMethodLabel = uilabel(app.Panel);
            app.DetectionMethodLabel.Text = {'Detection '; 'Method'};
            app.DetectionMethodDropDown = uidropdown(app.Panel);
            app.DetectionMethodDropDown.Items = {'Quiroga Method', 'Threshold: Mean - Std', 'Threshold: Median - Std'};
            app.DetectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectionMethodDropDownValueChanged, true);
            app.DetectionMethodDropDown.Value = 'Quiroga Method';
            app.ThresholdEditFieldLabel = uilabel(app.Panel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.Panel, 'text');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = '4';
            app.MeanandStdDropDownLabel = uilabel(app.Panel);
            app.MeanandStdDropDownLabel.Text = 'Mean and Std';
            app.MeanDropDown = uidropdown(app.Panel);
            app.MeanDropDown.Items = {'All Channel', 'Individual Ch.'};
            app.MeanDropDown.Value = 'All Channel';
            app.FilterVerticalSpikeArtefactsCheckBox = uicheckbox(app.Panel);
            app.FilterVerticalSpikeArtefactsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterVerticalSpikeArtefactsCheckBoxValueChanged, true);
            app.FilterVerticalSpikeArtefactsCheckBox.Text = {'      Filter Vertical '; '      Spike Artefacts'};
            app.VerticalSpikeOffsetToleranceSamplesEditFieldLabel = uilabel(app.Panel);
            app.VerticalSpikeOffsetToleranceSamplesEditFieldLabel.Text = {'Vertical Spike Offset'; 'Tolerance [Samples]'};
            app.VerticalSpikeOffsetToleranceSamplesEditField = uieditfield(app.Panel, 'text');
            app.VerticalSpikeOffsetToleranceSamplesEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalSpikeOffsetToleranceSamplesEditFieldValueChanged, true);
            app.VerticalSpikeOffsetToleranceSamplesEditField.Value = '3';
            app.MinDepthofArtefactmEditFieldLabel = uilabel(app.Panel);
            app.MinDepthofArtefactmEditFieldLabel.Text = {'Min. Depth of Artefact'; '[µm]'};
            app.MinDepthofArtefactmEditField = uieditfield(app.Panel, 'text');
            app.MinDepthofArtefactmEditField.ValueChangedFcn = createCallbackFcn(app, @MinDepthofArtefactmEditFieldValueChanged, true);
            app.MinDepthofArtefactmEditField.Value = '200';
            app.FilterDoubleSpikeIndiciesCheckBox = uicheckbox(app.Panel);
            app.FilterDoubleSpikeIndiciesCheckBox.Text = {'     Filter Spikes in '; '     Same Waveform'};
            app.FilterDoubleSpikeIndiciesCheckBox.Value = true;
            app.TimeSpantoCombineSpikeIndiciesEditFieldLabel = uilabel(app.Panel);
            app.TimeSpantoCombineSpikeIndiciesEditFieldLabel.Text = {'Time Span to Combine'; 'Spike Indicies'};
            app.TimeOffsettoCombineSpikeIndiciessEditField = uieditfield(app.Panel, 'text');
            app.TimeOffsettoCombineSpikeIndiciessEditField.ValueChangedFcn = createCallbackFcn(app, @TimeOffsettoCombineSpikeIndiciessEditFieldValueChanged, true);
            app.TimeOffsettoCombineSpikeIndiciessEditField.Value = '0.001';
            app.StartSpikeDetectionButton = uibutton(app.Panel, 'push');
            app.StartSpikeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartSpikeDetectionButtonPushed, true);
            app.StartSpikeDetectionButton.Text = {'Start Spike '; 'Detection'};
            app.TextArea_2 = uitextarea(app.RightPanel);
            app.TextArea_2.Value = {'Spike sorting with Mountainsort 5, SpykingCircus 2 and Kilosort 4 can be conducted fully automatically in this window using SpikeInterface and a costume python script. Just save your recording in the appropriate format using the ''Save for Spike Sorting'' window and click on the ''Run'' button in this window. This will open a python script and conduct your sorting automatically. All sorting parameters for the selected sorter can be changed by selecting ''Change Sorting Parameters'' in the ''Options'' dropdown menu and clicking ''RUN''. After sorting finsihed, you can load the results in this GUI using the ''Load Spike Sorting Results'' window (or selecting Load Sorting Results in the ''Options'' dropdown).'; ''; 'Necessary requirement is a .bin file from the ''Save for Spike Sorting'' window . If you saved it in the automatcally proposed folder, it should be found automatically here. If not, press the ''Manually Select Folder with exported .bin file'' button to select the folder containing a .bin file manually. Waveclus 3 does not need a .bin file, just extracted spikes from the spike detection above!'; ''; 'Alternatively, you can also run those sorters yourself outside of this GUI in their respective python GUI or library using the files exported from this GUI and load the results in here. When using Mountainsort 5 or Spykingcircus 2, you need to save the results in a format that phy can read with an SpikePositions.mat file saving the depth of each spike.'; ''; 'For all necessary python packages and steps to set up SpikeInterface see the manual or Readme file.'; 'To conduct spike sorting solely in Matlab you can use Wave_clus 3, this requires no additional installations. '; ''; 'Output folder are created automatically. It is recommended to not change the location or name of the output folders. Standard folder structures allow for autodetection of results, single click loading and a smooth autorun function looping over several recordings.'; ''};
            app.SpikeSortingLabel = uilabel(app.RightPanel);
            app.SpikeSortingLabel.Text = 'Spike Sorting';
            % Show the figure after all components are created
            app.SpikeDetectionWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spike_Detection_and_Sorting_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SpikeDetectionWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SpikeDetectionWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpikeDetectionWindowUIFigure)
        end
    end
end