classdef Kilosort_Loadfile_Format_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoadKilosortWindowUIFigure  matlab.ui.Figure
        InformationTextArea         matlab.ui.control.TextArea
        InformationTextAreaLabel    matlab.ui.control.Label
        CloseButton                 matlab.ui.control.Button
        NeuromodToolboxLabel        matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        LoadKilosortWindowLabel     matlab.ui.control.Label
        LoadButton                  matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        SelectAmplitudeScalingmanuallyButton  matlab.ui.control.Button
        EmptyfornonLabel            matlab.ui.control.Label
        AmplitudeScalingFactorEditField  matlab.ui.control.EditField
        AmplitudeScalingFactorEditFieldLabel  matlab.ui.control.Label
        SelectKilosortFoldermanuallyButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        Mainapp=[]; % Description
        Path = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.LoadKilosortWindowUIFigure, 'center');
            app.Mainapp = Mainapp;

            Kilosort4FoldertoSeach = strcat(app.Mainapp.Data.Info.Data_Path,'\Kilosort\kilosort4');
            Kilosort3FoldertoSeach = strcat(app.Mainapp.Data.Info.Data_Path,'\Kilosort\kilosort3');
          
            StringtoShow = [];

            if isfolder(Kilosort4FoldertoSeach) || isfolder(Kilosort3FoldertoSeach)
                if isfolder(Kilosort4FoldertoSeach)
                    [stringArray] = Utility_Extract_Contents_of_Folder(Kilosort4FoldertoSeach);
                    StringtoShow = strcat("Autosearched Path (Original Recording Folder): ", Kilosort4FoldertoSeach);
                    app.Path = Kilosort4FoldertoSeach;
                else
                    [stringArray] = Utility_Extract_Contents_of_Folder(Kilosort3FoldertoSeach);
                    StringtoShow = strcat("Autosearched Path (Original Recording Folder): ", Kilosort3FoldertoSeach);
                    app.Path = Kilosort3FoldertoSeach;
                end
            else
                if isfolder(app.Mainapp.Data.Info.Data_Path)
                    [stringArray] = Utility_Extract_Contents_of_Folder(app.Mainapp.Data.Info.Data_Path);
                    StringtoShow = strcat("Autosearched Path (Original Recording Folder): ", app.Mainapp.Data.Info.Data_Path);
                    app.Path = app.Mainapp.Data.Info.Data_Path;
                else
                    app.InformationTextArea.Value = "Autosearched path the recording was extracted from was not found or does not exist anymore. Please select a folder manually.";
                    stringArray = [];
                    app.Path = [];
                    return;
                end
            end

            % Filter files with .npy extension
            npyFiles = stringArray(endsWith(stringArray, '.npy'));

            if isempty(npyFiles)
                StringtoShow = ["Warning: No .npy kilosort output files found. Please select a different folder."; ""; StringtoShow];
            else
                StringtoShow = [StringtoShow; "";".npy kilosort output files found. Folder contents are:"; stringArray];
            end
                        
            if isfield(app.Mainapp.Data.Info,'KilosortScalingFactor')
                app.AmplitudeScalingFactorEditField.Value = num2str(app.Mainapp.Data.Info.KilosortScalingFactor);
                StringtoShow = [StringtoShow; "";"Scaling factor to convert int format of the kilosort output to mV found as part of the recording info."];
            else
                ScalingFactorPath32 = strcat(app.Mainapp.Data.Info.Data_Path,'\Kilosort\Scaling Factor int32.mat');
                ScalingFactorPath16 = strcat(app.Mainapp.Data.Info.Data_Path,'\Kilosort\Scaling Factor int16.mat');
                if isfile(ScalingFactorPath32) 
                    %% Check if selected mat file contains correct variable
                    variableName = 'scalingFactor';  % Variable you want to load
                    
                    % Get the list of variables in the file
                    variablesInFile = who('-file', ScalingFactorPath32);
                    
                    % Check if the desired variable exists
                    if ismember(variableName, variablesInFile)
                        load(ScalingFactorPath32, variableName);  % Load only the specific variable
                    else
                        msgbox(strcat("Variable ", variableName," does not exist in the manually selected file ",ScalingFactorPath32));
                        return;  % Exit if the variable does not exist
                    end

                    load(ScalingFactorPath32,'scalingFactor');
                    StringtoShow = [StringtoShow; "Scaling factor to convert int format of the kilosort output to mV found in 'Original_Recording_Path/Kilosort' saved as a .mat file."];
                    app.AmplitudeScalingFactorEditField.Value = num2str(scalingFactor);
                    app.Mainapp.Data.Info.KilosortScalingFactor = scalingFactor;
                elseif isfile(ScalingFactorPath16) 
                    %% Check if selected mat file contains correct variable
                    variableName = 'scalingFactor';  % Variable you want to load
                    
                    % Get the list of variables in the file
                    variablesInFile = who('-file', ScalingFactorPath16);
                    
                    % Check if the desired variable exists
                    if ismember(variableName, variablesInFile)
                        load(ScalingFactorPath16, variableName);  % Load only the specific variable
                    else
                        msgbox(strcat("Variable ", variableName," does not exist in the manually selected file ",ScalingFactorPath16));
                        return;  % Exit if the variable does not exist
                    end

                    load(ScalingFactorPath16,'scalingFactor');
                    StringtoShow = [StringtoShow; "Scaling factor to convert int format of the kilosort output to mV found in 'Original_Recording_Path/Kilosort' saved as a .mat file."];
                    app.AmplitudeScalingFactorEditField.Value = num2str(scalingFactor);
                    app.Mainapp.Data.Info.KilosortScalingFactor = scalingFactor;
                else
                    StringtoShow = [StringtoShow; "Scaling factor to convert int format of the kilosort output to mV was not found as part of the dataset or as a .mat file in 'Original_Recording_Path/Kilosort'. Select a .mat file with a variable called scalingfactor, enter the scalingfactor manually or leave it empty (for no scaling)"];      
                    app.AmplitudeScalingFactorEditField.Value = "";
                end

            end

            app.InformationTextArea.Value = StringtoShow;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if isempty(app.Path)
                app.InformationTextArea.Value = "No Path selected. Please first select a folder.";
                return;
            end

            if ~isfolder(app.Path)
                app.InformationTextArea.Value = "Selected Path does not exist. Please select an exisitng folder.";
                return;
            end
            
            if isempty(app.AmplitudeScalingFactorEditField.Value)
                KilosortScalingFactor = [];
            else
                KilosortScalingFactor = str2double(app.AmplitudeScalingFactorEditField.Value);
            end

            % Function to load all relevant npy and .mat files Kilosort outputs
            [app.Mainapp.Data] = Spike_Module_Load_Kilosort_Data(app.Mainapp.Data,"No",app.Path,KilosortScalingFactor);

            app.Mainapp.UIAxes.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes,0,0);
            app.Mainapp.UIAxes.NextPlot = "add"; 
            app.Mainapp.UIAxes_2.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes_2,0,0);
            app.Mainapp.UIAxes_2.NextPlot = "add"; 

            %Take care of potentially changed backgroundcolor
            app.Mainapp.UIAxes.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainBackground;
            app.Mainapp.UIAxes_2.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeBackground;
            app.Mainapp.UIAxes.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.MainFontSize;
            app.Mainapp.UIAxes_2.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.TimeFontSize;

            if ~isfield(app.Mainapp.Data,'Spikes')
                app.Mainapp.SpikeDataCheckBox_2.Value = 0;
                app.Mainapp.SpikeDataCheckBox_2.Enable = "off";
                app.Mainapp.Plotspikes = "Non";
            else
                app.Mainapp.SpikeDataCheckBox_2.Value = 0;
                app.Mainapp.SpikeDataCheckBox_2.Enable = "on";
                app.Mainapp.Plotspikes = "Non";
            end

            Utility_Show_Info_Loaded_Data(app.Mainapp);

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);

            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: SelectKilosortFoldermanuallyButton
        function SelectKilosortFoldermanuallyButtonPushed(app, event)
            msgbox("Please selecet a folder containing Kilosort Data");

            % Prompt user to select a folder
            folderPath = uigetdir('Select a folder');
            
            % Check if user cancels the dialog
            if folderPath == 0
                disp('Operation canceled by user');
                return;
            end

            StringtoShow = [];

            if isfolder(folderPath)
                [stringArray] = Utility_Extract_Contents_of_Folder(folderPath);
                StringtoShow = strcat("Manually selected path: ", folderPath);
                app.Path = folderPath;
            else
                if isfolder(app.Mainapp.Data.Info.Data_Path)
                    [stringArray] = Utility_Extract_Contents_of_Folder(app.Mainapp.Data.Info.Data_Path);
                    StringtoShow = strcat("Manually selected path: ", app.Mainapp.Data.Info.Data_Path);
                    app.Path = app.Mainapp.Data.Info.Data_Path;
                else
                    app.InformationTextArea.Value = "Manually selected path is not available. Please select a folder.";
                    stringArray = [];
                    app.Path = [];
                    return;
                end
            end

            % Filter files with .npy extension
            npyFiles = stringArray(endsWith(stringArray, '.npy'));

            if isempty(npyFiles)
                StringtoShow = ["Warning: No .npy kilosort output files found. Please select a different folder."; ""; StringtoShow];
            else
                StringtoShow = [StringtoShow; "";".npy kilosort output files found. Folder contents are:"; stringArray];
            end
                        
            if isfield(app.Mainapp.Data.Info,'KilosortScalingFactor')
                app.AmplitudeScalingFactorEditField.Value = num2str(app.Mainapp.Data.Info.KilosortScalingFactor);
                StringtoShow = [StringtoShow; "";"Scaling factor to convert int format of the kilosort output to mV found as part of the recording info."];
            else
                ScalingFactorPath32 = strcat(app.Mainapp.Data.Info.Data_Path,'\Kilosort\Scaling Factor int32.mat');
                ScalingFactorPath16 = strcat(app.Mainapp.Data.Info.Data_Path,'\Kilosort\Scaling Factor int16.mat');
                if isfile(ScalingFactorPath32) 

                    %% Check if selected mat file contains correct variable
                    variableName = 'scalingFactor';  % Variable you want to load
                    
                    % Get the list of variables in the file
                    variablesInFile = who('-file', ScalingFactorPath32);
                    
                    % Check if the desired variable exists
                    if ismember(variableName, variablesInFile)
                        load(ScalingFactorPath32, variableName);  % Load only the specific variable
                    else
                        msgbox(strcat("Variable ", variableName," does not exist in the manually selected file ",ScalingFactorPath32));
                        return;  % Exit if the variable does not exist
                    end

                    load(ScalingFactorPath32,'scalingFactor');
                    StringtoShow = [StringtoShow;""; "Scaling factor to convert int format of the kilosort output to mV found in 'Original_Recording_Path/Kilosort' saved as a .mat file."];
                    app.AmplitudeScalingFactorEditField.Value = num2str(scalingFactor);
                    app.Mainapp.Data.Info.KilosortScalingFactor = scalingFactor;
                elseif isfile(ScalingFactorPath16) 

                    %% Check if selected mat file contains correct variable
                    variableName = 'scalingFactor';  % Variable you want to load
                    
                    % Get the list of variables in the file
                    variablesInFile = who('-file', ScalingFactorPath16);
                    
                    % Check if the desired variable exists
                    if ismember(variableName, variablesInFile)
                        load(ScalingFactorPath16, variableName);  % Load only the specific variable
                    else
                        msgbox(strcat("Variable ", variableName," does not exist in the manually selected file ",ScalingFactorPath16));
                        return;  % Exit if the variable does not exist
                    end

                    load(ScalingFactorPath16,'scalingFactor');
                    StringtoShow = [StringtoShow;""; "Scaling factor to convert int format of the kilosort output to mV found in 'Original_Recording_Path/Kilosort' saved as a .mat file."];
                    app.AmplitudeScalingFactorEditField.Value = num2str(scalingFactor);
                    app.Mainapp.Data.Info.KilosortScalingFactor = scalingFactor;
                else
                    StringtoShow = [StringtoShow;""; "Scaling factor to convert int format of the kilosort output to mV was not found as part of the dataset or as a .mat file in 'Original_Recording_Path/Kilosort'. Select a different folder, enter the scalingfactor manually or leave it empty (for no scaling)"];      
                    app.AmplitudeScalingFactorEditField.Value = "";
                end

            end

            app.InformationTextArea.Value = StringtoShow;
        end

        % Button pushed function: SelectAmplitudeScalingmanuallyButton
        function SelectAmplitudeScalingmanuallyButtonPushed(app, event)
            % Prompt user to select a folder
            filePath = uigetfile('Select a folder');
            
            % Check if user cancels the dialog
            if filePath == 0
                disp('Operation canceled by user');
                return;
            end
            
            %% Check if selected mat file contains correct variable
            variableName = 'scalingFactor';  % Variable you want to load
            
            % Get the list of variables in the file
            variablesInFile = who('-file', filePath);
            
            % Check if the desired variable exists
            if ismember(variableName, variablesInFile)
                load(filePath, variableName);  % Load only the specific variable
            else
                msgbox(strcat("Variable ", variableName," does not exist in the manually selected file ",filePath));
                return;  % Exit if the variable does not exist
            end

            load(filePath,'scalingFactor');

            scalindicie = find(contains(app.InformationTextArea.Value, 'Scaling'));
            % cut out previous scaling info shown
            StringtoShow = app.InformationTextArea.Value(1:scalindicie(1)-1);
            % add new scaling info 
            StringtoShow = [StringtoShow; "Scaling factor to convert int format of the kilosort output to mV found in manually selected a .mat file."];


            app.AmplitudeScalingFactorEditField.Value = num2str(scalingFactor);
            app.Mainapp.Data.Info.KilosortScalingFactor = scalingFactor;

            app.InformationTextArea.Value = StringtoShow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoadKilosortWindowUIFigure = uifigure('Visible', 'off');
            app.LoadKilosortWindowUIFigure.Name = 'Load Kilosort Window';
            app.LoadKilosortWindowUIFigure.Icon = 'Logo.png';
            app.Panel = uipanel(app.LoadKilosortWindowUIFigure);
            app.SelectKilosortFoldermanuallyButton = uibutton(app.Panel, 'push');
            app.SelectKilosortFoldermanuallyButton.ButtonPushedFcn = createCallbackFcn(app, @SelectKilosortFoldermanuallyButtonPushed, true);
            app.SelectKilosortFoldermanuallyButton.Text = {'Select Kilosort '; 'Folder manually'};
            app.AmplitudeScalingFactorEditFieldLabel = uilabel(app.Panel);
            app.AmplitudeScalingFactorEditFieldLabel.Text = {'Amplitude'; 'Scaling Factor'};
            app.AmplitudeScalingFactorEditField = uieditfield(app.Panel, 'text');
            app.EmptyfornonLabel = uilabel(app.Panel);
            app.EmptyfornonLabel.Text = '(Empty for non)';
            app.SelectAmplitudeScalingmanuallyButton = uibutton(app.Panel, 'push');
            app.SelectAmplitudeScalingmanuallyButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAmplitudeScalingmanuallyButtonPushed, true);
            app.SelectAmplitudeScalingmanuallyButton.Text = {'Select Amplitude'; 'Scaling manually'};
            app.LoadButton = uibutton(app.LoadKilosortWindowUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LoadKilosortWindowLabel = uilabel(app.LoadKilosortWindowUIFigure);
            app.LoadKilosortWindowLabel.Text = 'Load Kilosort Window';
            app.Image = uiimage(app.LoadKilosortWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.LoadKilosortWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.LoadKilosortWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.InformationTextAreaLabel = uilabel(app.LoadKilosortWindowUIFigure);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.LoadKilosortWindowUIFigure);
            % Show the figure after all components are created
            app.LoadKilosortWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kilosort_Loadfile_Format_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.LoadKilosortWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.LoadKilosortWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoadKilosortWindowUIFigure)
        end
    end
end