classdef Kilosort_Savefile_Format_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KilosortSaveWindowUIFigure  matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        SelectFolderManuallyButton  matlab.ui.control.Button
        SaveFormatDropDown          matlab.ui.control.DropDown
        FormatDropDownLabel         matlab.ui.control.Label
        InformationTextArea         matlab.ui.control.TextArea
        InformationTextAreaLabel    matlab.ui.control.Label
        CloseButton                 matlab.ui.control.Button
        NeuromodToolboxLabel        matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        SaveforKilosortWindowLabel  matlab.ui.control.Label
        SaveButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        Mainapp=[]; % Description
        StandardFolder = [];
        SavePath = [];
        filename = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.KilosortSaveWindowUIFigure, 'center');
            app.Mainapp = Mainapp;

            KilosortFoldertoSeach = strcat(app.Mainapp.Data.Info.Data_Path,'\Kilosort\');
            dashindex = find(KilosortFoldertoSeach=='\');
            driveLetter = KilosortFoldertoSeach(1:dashindex(1)-1);

            StringtoShow = [];

            % Überprüfen, ob das Laufwerk existiert
            if ~isfolder(driveLetter)
                disp(['Drive ' driveLetter ' does not exist.']);
                StringtoShow = "Auto - selected of path failed since drive letter was not found. Please select a folder manually.";
                app.SavePath = [];
            else
                StringtoShow = ["Auto - selected save path: ";"";convertCharsToStrings(KilosortFoldertoSeach);"";"(Folder will be created when non existent)"];
                app.SavePath = KilosortFoldertoSeach;
                dahsindicie = find(app.Mainapp.Data.Info.Data_Path=='\');
                app.filename = strcat(app.Mainapp.Data.Info.Data_Path(dahsindicie(end)+1:end),".dat");
                StringtoShow = [StringtoShow;"";"Filename:";"";app.filename];
            end

            app.InformationTextArea.Value = StringtoShow;

            pause(0.1);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isempty(app.SavePath)
                StringtoShow = "Auto - selected of path failed since drive letter was not found. Please select a folder manually.";
                app.InformationTextArea.Value = StringtoShow;
                return;
            end

            if strcmp(app.SaveFormatDropDown.Value,'int16')
                KilosortSaveFormat = 'int16';
            elseif strcmp(app.SaveFormatDropDown.Value,'int32')
                KilosortSaveFormat = 'int32';
            end

            if isfolder(app.SavePath)
                Path = strcat(app.SavePath,app.filename);
                % Function to Save Raw Data as int16 in a .dat file
                [app.Mainapp.Data] = Spike_Module_Save_for_Kilosort(app.Mainapp.Data,"No",Path,KilosortSaveFormat);

            else
                % Create a folder
                % Create the folder
                [status, msg, msgID] = mkdir(app.SavePath);
            
                % Check the status and print an appropriate message
                if status == 1
                    fprintf('The folder "%s" has been successfully created.\n', app.SavePath);
                else
                    fprintf('Failed to create the folder "%s".\nError: %s\n', app.SavePath, msg);
                end

                Path = strcat(app.SavePath,app.filename);
                % Function to Save Raw Data as int16 in a .dat file
                [app.Mainapp.Data] = Spike_Module_Save_for_Kilosort(app.Mainapp.Data,"No",Path,KilosortSaveFormat);

            end
            
            Utility_Show_Info_Loaded_Data(app.Mainapp);

            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: SelectFolderManuallyButton
        function SelectFolderManuallyButtonPushed(app, event)
            % Open a dialog box for the user to select a folder
            selectedFolder = uigetdir;
            
            % Check if the user pressed the cancel button
            if selectedFolder == 0
                disp('User canceled folder selection');
                return;
            else
                disp(['Selected folder: ', selectedFolder]);
            end

            KilosortFoldertoSeach = strcat(selectedFolder,'\');
            dashindex = find(KilosortFoldertoSeach=='\');
            driveLetter = KilosortFoldertoSeach(1:dashindex(1)-1);

            StringtoShow = [];

            % Überprüfen, ob das Laufwerk existiert
            if ~isfolder(driveLetter)
                disp(['Drive ' driveLetter ' does not exist.']);
                StringtoShow = "Auto - selected of path failed since drive letter was not found. Please select a folder manually.";
                app.SavePath = [];
            else
                StringtoShow = ["Auto - selected save path: ";"";convertCharsToStrings(KilosortFoldertoSeach);"";"(Folder will be created when non existent)"];
                app.SavePath = KilosortFoldertoSeach;
                dahsindicie = find(app.Mainapp.Data.Info.Data_Path=='\');
                app.filename = strcat(app.Mainapp.Data.Info.Data_Path(dahsindicie(end)+1:end),".dat");
                StringtoShow = [StringtoShow;"";"Filename:";"";app.filename];
            end

            app.InformationTextArea.Value = StringtoShow;

            pause(0.1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KilosortSaveWindowUIFigure = uifigure('Visible', 'off');
            app.KilosortSaveWindowUIFigure.Name = 'Kilosort Save Window';
            app.KilosortSaveWindowUIFigure.Icon = 'Logo.png';
            app.SaveButton = uibutton(app.KilosortSaveWindowUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SaveforKilosortWindowLabel = uilabel(app.KilosortSaveWindowUIFigure);
            app.SaveforKilosortWindowLabel.Text = 'Save for Kilosort Window';
            app.Image = uiimage(app.KilosortSaveWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.KilosortSaveWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.KilosortSaveWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.InformationTextAreaLabel = uilabel(app.KilosortSaveWindowUIFigure);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.KilosortSaveWindowUIFigure);
            app.Panel = uipanel(app.KilosortSaveWindowUIFigure);
            app.FormatDropDownLabel = uilabel(app.Panel);
            app.FormatDropDownLabel.Text = 'Format';
            app.SaveFormatDropDown = uidropdown(app.Panel);
            app.SaveFormatDropDown.Items = {'int16', 'int32'};
            app.SaveFormatDropDown.Value = 'int32';
            app.SelectFolderManuallyButton = uibutton(app.Panel, 'push');
            app.SelectFolderManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderManuallyButtonPushed, true);
            app.SelectFolderManuallyButton.Text = 'Select Folder Manually';
            % Show the figure after all components are created
            app.KilosortSaveWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kilosort_Savefile_Format_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.KilosortSaveWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.KilosortSaveWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KilosortSaveWindowUIFigure)
        end
    end
end