classdef Continous_Waveform_Analysis_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpikeWaveformWindowUIFigure     matlab.ui.Figure
        NumWaveformsEditField_3         matlab.ui.control.EditField
        NumWaveformsEditField_3Label    matlab.ui.control.Label
        NumWaveformsEditField_2         matlab.ui.control.EditField
        NumWaveformsEditField_2Label    matlab.ui.control.Label
        NumWaveformsEditField           matlab.ui.control.EditField
        NumWaveformsEditFieldLabel      matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        NeuromodToolboxLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        KilosortAnalysisWindowLabel     matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        DeleteButton                    matlab.ui.control.Button
        DeleteUnitDropDown              matlab.ui.control.DropDown
        DeleteUnitDropDownLabel         matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        UnitstoShow123EditField_3       matlab.ui.control.EditField
        UnitstoShow123EditField_3Label  matlab.ui.control.Label
        UnitstoShow123EditField_2       matlab.ui.control.EditField
        UnitstoShow123EditField_2Label  matlab.ui.control.Label
        UnitstoShow123EditField         matlab.ui.control.EditField
        UnitstoShow123EditFieldLabel    matlab.ui.control.Label
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_1                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.SpikeWaveformWindowUIFigure, 'center');
            
            app.Mainapp = Mainapp;
            
            app.NumWaveformsEditField.Value = "20";
            app.NumWaveformsEditField_2.Value = "20";
            app.NumWaveformsEditField_3.Value = "20";

            app.UnitstoShow123EditField.Value = "1,2";
            app.UnitstoShow123EditField_2.Value = "3,4";
            app.UnitstoShow123EditField_3.Value = "4,5";
            
            %% Prepare inputs
            commaindex = find(app.UnitstoShow123EditField.Value==',');
            for i = 1:length(commaindex)+1
                if i == 1
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(1:commaindex(1)-1));
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(1:commaindex(1)-1));
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(1:commaindex(1)-1));
                elseif i == length(commaindex)+1
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(commaindex(end)+1:end));
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(commaindex(end)+1:end));
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(commaindex(end)+1:end));
                else
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(commaindex(i-1)+1:commaindex(i)-1));
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(commaindex(i-1)+1:commaindex(i)-1));
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(commaindex(i-1)+1:commaindex(i)-1));
                end
                
            end

            Waves{1} = str2double(app.NumWaveformsEditField.Value);
            Waves{2} = str2double(app.NumWaveformsEditField_2.Value);
            Waves{3} = str2double(app.NumWaveformsEditField_3.Value);
            
            %% Prepare Data
            MaxNrUnits = 0;
            for i = 1:length(Units)
                if length(Units{i})>MaxNrUnits
                    MaxNrUnits = length(Units{i});
                end
            end

            colorMatrix = jet(MaxNrUnits);
            for nplots = 1:length(Units)
                na = strcat("UIAxes_",num2str(nplots));
                Figurename = app.(na);
                title(Figurename,strcat("Biggest Waveforms Unit(s) ",num2str(Units{nplots})));
                xlabel(Figurename,"Time [ms]")
                ylabel(Figurename,"Signal [mV]")
                Figurename.FontSize = 9;
            end
            
            % For waveform in ms
            Time = (0:1/app.Mainapp.Data.Info.NativeSamplingRate:(size(app.Mainapp.Data.Spikes.Waveforms,2)-1)/app.Mainapp.Data.Info.NativeSamplingRate)*1000; %IN MS

            for nplots = 1:length(Units)

                disp(strcat("Plot ",num2str(nplots)));
                na = strcat("UIAxes_",num2str(nplots));
                Figurename = app.(na);

                Waveformhandles = findobj(Figurename, 'Tag', 'Waveforms');
                MeanWaveformhandles = findobj(Figurename, 'Tag', 'MeanWaveforms');

                if length(MeanWaveformhandles)>length(Units{nplots})
                    delete(MeanWaveformhandles(length(Units{nplots})+1:end));
                end
                
                SumIndicies = 0;

                for nindividualunits = 1:length(Units{nplots}) % Loop over all units per plot
                    % Spike Indicies of the current unit
                    Indicies = app.Mainapp.Data.Spikes.SpikeCluster == Units{nplots}(nindividualunits);
                    SumIndicies = SumIndicies+sum(Indicies);

                    if nindividualunits == 1
                        CurrentNumberPlots(1) = 1;
                        CurrentNumberPlots(2) = sum(Indicies);
                    else
                        CurrentNumberPlots(1) = CurrentNumberPlots(2)+1;
                        CurrentNumberPlots(2) = CurrentNumberPlots(1)+sum(Indicies);
                    end

                    if sum(Indicies)>0
                        % Sort to get biggest waveforms (extract waveforms 1:nrwaveforms)
                        TempWaves = sort(app.Mainapp.Data.Spikes.Waveforms(Indicies,:));
                        % If as many or more waves found in cluster then
                        % selected
                        if size(TempWaves,1)>=Waves{nplots}

                            if isempty(Waveformhandles)
                                line(Figurename,Time,TempWaves(1:Waves{nplots},:),'Color',colorMatrix(nindividualunits,:), 'Tag', 'Waveforms');
                            else
                                if length(Waveformhandles)>= CurrentNumberPlots(1) && length(Waveformhandles) >= CurrentNumberPlots(2)
                                    set(Waveformhandles(CurrentNumberPlots(1):CurrentNumberPlots(2)),'XData',Time,'YData',TempWaves(1:Waves{nplots},:),'Color','k','LineWidth',1.5, 'Tag', 'Waveforms');
                                elseif length(Waveformhandles) < CurrentNumberPlots(1) 
                                    line(Figurename,Time,TempWaves(1:Waves{nplots},:),'Color',colorMatrix(nindividualunits,:), 'Tag', 'Waveforms');
                                elseif length(Waveformhandles) >= CurrentNumberPlots(1) && length(Waveformhandles) < CurrentNumberPlots(2)
                                    NrPlotted = CurrentNumberPlots(1):length(Waveformhandles);
                                    set(Waveformhandles(CurrentNumberPlots(1):length(Waveformhandles)),'XData',Time,'YData',TempWaves(1:length(NrPlotted),:),'Color','k','LineWidth',1.5, 'Tag', 'Waveforms');
                                    line(Figurename,Time,TempWaves(length(NrPlotted)+1:end,:),'Color',colorMatrix(nindividualunits,:), 'Tag', 'Waveforms');
                                end
                            end

                            if isempty(MeanWaveformhandles)
                                meanline = line(Figurename,Time,mean(TempWaves(1:Waves{nplots},:),1),'Color','k','LineWidth',1.5, 'Tag', 'MeanWaveforms');
                            else
                                set(MeanWaveformhandles(nindividualunits),'XData',Time,'YData',mean(TempWaves(1:Waves{nplots},:),1),'Color','k','LineWidth',1.5, 'Tag', 'MeanWaveforms');
                                meanline = MeanWaveformhandles(nindividualunits);
                            end

                        else % If less waveforms found for unit then waveforms selected

                            if isempty(Waveformhandles)
                                line(Figurename,Time,TempWaves,'Color',colorMatrix(nindividualunits,:), 'Tag', 'Waveforms');
                            else
                                if length(Waveformhandles)>= CurrentNumberPlots(1) && length(Waveformhandles) >= CurrentNumberPlots(2)
                                    set(Waveformhandles(CurrentNumberPlots(1):CurrentNumberPlots(2)),'XData',Time,'YData',TempWaves,'Color','k','LineWidth',1.5, 'Tag', 'Waveforms');
                                elseif length(Waveformhandles) < CurrentNumberPlots(1) 
                                    line(Figurename,Time,TempWaves,'Color',colorMatrix(nindividualunits,:), 'Tag', 'Waveforms');
                                elseif length(Waveformhandles) >= CurrentNumberPlots(1) && length(Waveformhandles) < CurrentNumberPlots(2)
                                    NrPlotted = CurrentNumberPlots(1):length(Waveformhandles);
                                    set(Waveformhandles(CurrentNumberPlots(1):length(Waveformhandles)),'XData',Time,'YData',TempWaves,'Color','k','LineWidth',1.5, 'Tag', 'Waveforms');
                                    line(Figurename,Time,TempWaves(length(NrPlotted)+1:end,:),'Color',colorMatrix(nindividualunits,:), 'Tag', 'Waveforms');
                                end
                            end

                            if isempty(MeanWaveformhandles)
                                meanline = line(Figurename,Time,mean(TempWaves,1),'Color','k','LineWidth',1.5, 'Tag', 'MeanWaveforms');
                            else
                                set(MeanWaveformhandles(nindividualunits),'XData',Time,'YData',mean(TempWaves,1),'Color','k','LineWidth',1.5, 'Tag', 'MeanWaveforms');
                                meanline = MeanWaveformhandles(nindividualunits);
                            end
                        end
                        uistack(meanline, 'top');
                        drawnow;
                    end
                end % individual units

                if length(Waveformhandles)>SumIndicies
                    delete(Waveformhandles(SumIndicies+1:end));
                end

            end % 3 plots

      
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: UnitstoShow123EditField
        function UnitstoShow123EditFieldValueChanged(app, event)
            %% Prepare inputs
            commaindex = find(app.UnitstoShow123EditField.Value==',');
            for i = 1:length(commaindex)+1
                if i == 1
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(1:commaindex(1)-1));
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(1:commaindex(1)-1));
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(1:commaindex(1)-1));
                elseif i == length(commaindex)+1
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(commaindex(end)+1:end));
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(commaindex(end)+1:end));
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(commaindex(end)+1:end));
                else
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(commaindex(i-1)+1:commaindex(i)-1));
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(commaindex(i-1)+1:commaindex(i)-1));
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(commaindex(i-1)+1:commaindex(i)-1));
                end
                
            end

            Waves{1} = str2double(app.NumWaveformsEditField.Value);
            Waves{2} = str2double(app.NumWaveformsEditField_2.Value);
            Waves{3} = str2double(app.NumWaveformsEditField_3.Value);
            
            %% Prepare Data
            MaxNrUnits = 0;
            for i = 1:length(Units)
                if length(Units{i})>MaxNrUnits
                    MaxNrUnits = length(Units{i});
                end
            end

            colorMatrix = jet(MaxNrUnits);
            for nplots = 1:length(Units)
                na = strcat("UIAxes_",num2str(nplots));
                Figurename = app.(na);
                title(Figurename,strcat("Biggest Waveforms Unit(s) ",num2str(Units{nplots})));
                xlabel(Figurename,"Time [ms]")
                ylabel(Figurename,"Signal [mV]")
                Figurename.FontSize = 8;
            end
            
            % For waveform in ms
            Time = (0:1/app.Mainapp.Data.Info.NativeSamplingRate:(size(app.Mainapp.Data.Spikes.Waveforms,2)-1)/app.Mainapp.Data.Info.NativeSamplingRate)*1000; %IN MS

            for nplots = 1:length(Units)

                disp(strcat("Plot ",num2str(nplots)));
                na = strcat("UIAxes_",num2str(nplots));
                Figurename = app.(na);

                Waveformhandles = findobj(Figurename, 'Tag', 'Waveforms');
                MeanWaveformhandles = findobj(Figurename, 'Tag', 'MeanWaveforms');

                if length(MeanWaveformhandles)>length(Units{nplots})
                    delete(MeanWaveformhandles(length(Units{nplots})+1:end));
                end
                
                SumIndicies = 0;

                for nindividualunits = 1:length(Units{nplots}) % Loop over all units per plot
                    % Spike Indicies of the current unit
                    Indicies = app.Mainapp.Data.Spikes.SpikeCluster == Units{nplots}(nindividualunits);
                    SumIndicies = SumIndicies+sum(Indicies);

                    if nindividualunits == 1
                        CurrentNumberPlots(1) = 1;
                        CurrentNumberPlots(2) = sum(Indicies);
                    else
                        CurrentNumberPlots(1) = CurrentNumberPlots(2)+1;
                        CurrentNumberPlots(2) = CurrentNumberPlots(1)+sum(Indicies);
                    end

                    if sum(Indicies)>0
                        % Sort to get biggest waveforms (extract waveforms 1:nrwaveforms)
                        TempWaves = sort(app.Mainapp.Data.Spikes.Waveforms(Indicies,:));
                        % If as many or more waves found in cluster then
                        % selected
                        if size(TempWaves,1)>=Waves{nplots}

                            if isempty(Waveformhandles)
                                line(Figurename,Time,TempWaves(1:Waves{nplots},:),'Color',colorMatrix(nindividualunits,:), 'Tag', 'Waveforms');
                            else
                                if length(Waveformhandles)>= CurrentNumberPlots(1) && length(Waveformhandles) >= CurrentNumberPlots(2)
                                    set(Waveformhandles(CurrentNumberPlots(1):CurrentNumberPlots(2)),'XData',Time,'YData',TempWaves(1:Waves{nplots},:),'Color','k','LineWidth',1.5, 'Tag', 'Waveforms');
                                elseif length(Waveformhandles) < CurrentNumberPlots(1) 
                                    line(Figurename,Time,TempWaves(1:Waves{nplots},:),'Color',colorMatrix(nindividualunits,:), 'Tag', 'Waveforms');
                                elseif length(Waveformhandles) >= CurrentNumberPlots(1) && length(Waveformhandles) < CurrentNumberPlots(2)
                                    NrPlotted = CurrentNumberPlots(1):length(Waveformhandles);
                                    set(Waveformhandles(CurrentNumberPlots(1):length(Waveformhandles)),'XData',Time,'YData',TempWaves(1:length(NrPlotted),:),'Color','k','LineWidth',1.5, 'Tag', 'Waveforms');
                                    line(Figurename,Time,TempWaves(length(NrPlotted)+1:end,:),'Color',colorMatrix(nindividualunits,:), 'Tag', 'Waveforms');
                                end
                            end

                            if isempty(MeanWaveformhandles)
                                meanline = line(Figurename,Time,mean(TempWaves(1:Waves{nplots},:),1),'Color','k','LineWidth',1.5, 'Tag', 'MeanWaveforms');
                            else
                                set(MeanWaveformhandles(nindividualunits),'XData',Time,'YData',mean(TempWaves(1:Waves{nplots},:),1),'Color','k','LineWidth',1.5, 'Tag', 'MeanWaveforms');
                                meanline = MeanWaveformhandles(nindividualunits);
                            end

                        else % If less waveforms found for unit then waveforms selected

                            if isempty(Waveformhandles)
                                line(Figurename,Time,TempWaves,'Color',colorMatrix(nindividualunits,:), 'Tag', 'Waveforms');
                            else
                                if length(Waveformhandles)>= CurrentNumberPlots(1) && length(Waveformhandles) >= CurrentNumberPlots(2)
                                    set(Waveformhandles(CurrentNumberPlots(1):CurrentNumberPlots(2)),'XData',Time,'YData',TempWaves,'Color','k','LineWidth',1.5, 'Tag', 'Waveforms');
                                elseif length(Waveformhandles) < CurrentNumberPlots(1) 
                                    line(Figurename,Time,TempWaves,'Color',colorMatrix(nindividualunits,:), 'Tag', 'Waveforms');
                                elseif length(Waveformhandles) >= CurrentNumberPlots(1) && length(Waveformhandles) < CurrentNumberPlots(2)
                                    NrPlotted = CurrentNumberPlots(1):length(Waveformhandles);
                                    set(Waveformhandles(CurrentNumberPlots(1):length(Waveformhandles)),'XData',Time,'YData',TempWaves,'Color','k','LineWidth',1.5, 'Tag', 'Waveforms');
                                    line(Figurename,Time,TempWaves(length(NrPlotted)+1:end,:),'Color',colorMatrix(nindividualunits,:), 'Tag', 'Waveforms');
                                end
                            end

                            if isempty(MeanWaveformhandles)
                                meanline = line(Figurename,Time,mean(TempWaves,1),'Color','k','LineWidth',1.5, 'Tag', 'MeanWaveforms');
                            else
                                set(MeanWaveformhandles(nindividualunits),'XData',Time,'YData',mean(TempWaves,1),'Color','k','LineWidth',1.5, 'Tag', 'MeanWaveforms');
                                meanline = MeanWaveformhandles(nindividualunits);
                            end
                        end
                        uistack(meanline, 'top');
                        drawnow;
                    end
                end % individual units

                if length(Waveformhandles)>SumIndicies
                    delete(Waveformhandles(SumIndicies+1:end));
                end
                
            end % 3 plots
            
        end

        % Value changed function: NumWaveformsEditField
        function NumWaveformsEditFieldValueChanged(app, event)
            %% Prepare inputs
            commaindex = find(app.UnitstoShow123EditField.Value==',');
            for i = 1:length(commaindex)+1
                if i == 1
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(1:commaindex(1)-1));
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(1:commaindex(1)-1));
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(1:commaindex(1)-1));
                elseif i == length(commaindex)+1
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(commaindex(end)+1:end));
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(commaindex(end)+1:end));
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(commaindex(end)+1:end));
                else
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(commaindex(i-1)+1:commaindex(i)-1));
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(commaindex(i-1)+1:commaindex(i)-1));
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(commaindex(i-1)+1:commaindex(i)-1));
                end
                
            end

            Waves{1} = str2double(app.NumWaveformsEditField.Value);
            Waves{2} = str2double(app.NumWaveformsEditField_2.Value);
            Waves{3} = str2double(app.NumWaveformsEditField_3.Value);
            
            %% Prepare Data
            MaxNrUnits = 0;
            for i = 1:length(Units)
                if length(Units{i})>MaxNrUnits
                    MaxNrUnits = length(Units{i});
                end
            end

            colorMatrix = jet(MaxNrUnits);
            for nplots = 1:length(Units)
                na = strcat("UIAxes_",num2str(nplots));
                Figurename = app.(na);
                title(Figurename,strcat("Biggest Waveforms Unit(s) ",num2str(Units{nplots})));
                xlabel(Figurename,"Time [ms]")
                ylabel(Figurename,"Signal [mV]")
                Figurename.FontSize = 8;
            end
            
            % For waveform in ms
            Time = (0:1/app.Mainapp.Data.Info.NativeSamplingRate:(size(app.Mainapp.Data.Spikes.Waveforms,2)-1)/app.Mainapp.Data.Info.NativeSamplingRate)*1000; %IN MS

            for nplots = 1:length(Units)

                disp(strcat("Plot ",num2str(nplots)));
                na = strcat("UIAxes_",num2str(nplots));
                Figurename = app.(na);

                Waveformhandles = findobj(Figurename, 'Tag', 'Waveforms');
                MeanWaveformhandles = findobj(Figurename, 'Tag', 'MeanWaveforms');

                if length(MeanWaveformhandles)>length(Units{nplots})
                    delete(MeanWaveformhandles(length(Units{nplots})+1:end));
                end
                
                SumIndicies = 0;

                for nindividualunits = 1:length(Units{nplots}) % Loop over all units per plot
                    % Spike Indicies of the current unit
                    Indicies = app.Mainapp.Data.Spikes.SpikeCluster == Units{nplots}(nindividualunits);
                    SumIndicies = SumIndicies+sum(Indicies);

                    if nindividualunits == 1
                        CurrentNumberPlots(1) = 1;
                        if sum(Indicies)<Waves{nplots}
                            CurrentNumberPlots(2) = sum(Indicies);
                        else
                            CurrentNumberPlots(2) = Waves{nplots};
                        end
                    else
                        CurrentNumberPlots(1) = CurrentNumberPlots(2)+1;
                        if sum(Indicies)<Waves{nplots}
                            CurrentNumberPlots(2) = CurrentNumberPlots(1)+sum(Indicies);
                        else
                            CurrentNumberPlots(2) =CurrentNumberPlots(1)+ Waves{nplots};
                        end
                    end

                    if sum(Indicies)>0
                        % Sort to get biggest waveforms (extract waveforms 1:nrwaveforms)
                        TempWaves = sort(app.Mainapp.Data.Spikes.Waveforms(Indicies,:));
                        % If as many or more waves found in cluster then
                        % selected
                        if size(TempWaves,1)>=Waves{nplots}

                            if isempty(Waveformhandles)
                                line(Figurename,Time,TempWaves(1:Waves{nplots},:),'Color',colorMatrix(nindividualunits,:), 'Tag', 'Waveforms');
                            else
                                if length(Waveformhandles)>= CurrentNumberPlots(1) && length(Waveformhandles) >= CurrentNumberPlots(2)
                                    %for i = 1:...
                                    set(Waveformhandles(CurrentNumberPlots(1):CurrentNumberPlots(2)),'XData',Time,'YData',TempWaves(1:Waves{nplots},:),'Color','k','LineWidth',1.5, 'Tag', 'Waveforms');
                                elseif length(Waveformhandles) < CurrentNumberPlots(1) 
                                    line(Figurename,Time,TempWaves(1:Waves{nplots},:),'Color',colorMatrix(nindividualunits,:), 'Tag', 'Waveforms');
                                elseif length(Waveformhandles) >= CurrentNumberPlots(1) && length(Waveformhandles) < CurrentNumberPlots(2)
                                    NrPlotted = CurrentNumberPlots(1):length(Waveformhandles);
                                    set(Waveformhandles(1),'XData',repmat(Time,length(NrPlotted),1),'YData',TempWaves(1:length(NrPlotted),:),'Color','k','LineWidth',1.5, 'Tag', 'Waveforms');
                                    line(Figurename,Time,TempWaves(length(NrPlotted)+1:end,:),'Color',colorMatrix(nindividualunits,:), 'Tag', 'Waveforms');
                                end
                            end

                            if isempty(MeanWaveformhandles)
                                meanline = line(Figurename,Time,mean(TempWaves(1:Waves{nplots},:),1),'Color','k','LineWidth',1.5, 'Tag', 'MeanWaveforms');
                            else
                                set(MeanWaveformhandles(nindividualunits),'XData',Time,'YData',mean(TempWaves(1:Waves{nplots},:),1),'Color','k','LineWidth',1.5, 'Tag', 'MeanWaveforms');
                                meanline = MeanWaveformhandles(nindividualunits);
                            end

                        else % If less waveforms found for unit then waveforms selected

                            if isempty(Waveformhandles)
                                line(Figurename,Time,TempWaves,'Color',colorMatrix(nindividualunits,:), 'Tag', 'Waveforms');
                            else
                                if length(Waveformhandles)>= CurrentNumberPlots(1) && length(Waveformhandles) >= CurrentNumberPlots(2)
                                    set(Waveformhandles(CurrentNumberPlots(1):CurrentNumberPlots(2)),'XData',Time,'YData',TempWaves,'Color','k','LineWidth',1.5, 'Tag', 'Waveforms');
                                elseif length(Waveformhandles) < CurrentNumberPlots(1) 
                                    line(Figurename,Time,TempWaves,'Color',colorMatrix(nindividualunits,:), 'Tag', 'Waveforms');
                                elseif length(Waveformhandles) >= CurrentNumberPlots(1) && length(Waveformhandles) < CurrentNumberPlots(2)
                                    NrPlotted = CurrentNumberPlots(1):length(Waveformhandles);
                                    set(Waveformhandles(CurrentNumberPlots(1):length(Waveformhandles)),'XData',Time,'YData',TempWaves,'Color','k','LineWidth',1.5, 'Tag', 'Waveforms');
                                    line(Figurename,Time,TempWaves(length(NrPlotted)+1:end,:),'Color',colorMatrix(nindividualunits,:), 'Tag', 'Waveforms');
                                end
                            end

                            if isempty(MeanWaveformhandles)
                                meanline = line(Figurename,Time,mean(TempWaves,1),'Color','k','LineWidth',1.5, 'Tag', 'MeanWaveforms');
                            else
                                set(MeanWaveformhandles(nindividualunits),'XData',Time,'YData',mean(TempWaves,1),'Color','k','LineWidth',1.5, 'Tag', 'MeanWaveforms');
                                meanline = MeanWaveformhandles(nindividualunits);
                            end
                        end
                        uistack(meanline, 'top');
                        drawnow;
                    end
                end % individual units

                if length(Waveformhandles)>SumIndicies
                    delete(Waveformhandles(SumIndicies+1:end));
                end
                
            end % 3 plots
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpikeWaveformWindowUIFigure = uifigure('Visible', 'off');
            app.SpikeWaveformWindowUIFigure.Name = 'Spike Waveform Window';
            app.SpikeWaveformWindowUIFigure.Icon = 'Logo.png';
            app.UIAxes_1 = uiaxes(app.SpikeWaveformWindowUIFigure);
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_2 = uiaxes(app.SpikeWaveformWindowUIFigure);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.SpikeWaveformWindowUIFigure);
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.SpikeWaveformWindowUIFigure);
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.SpikeWaveformWindowUIFigure);
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.SpikeWaveformWindowUIFigure);
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.SpikeWaveformWindowUIFigure);
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.SpikeWaveformWindowUIFigure);
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_9 = uiaxes(app.SpikeWaveformWindowUIFigure);
            zlabel(app.UIAxes_9, 'Z')
            app.Panel_2 = uipanel(app.SpikeWaveformWindowUIFigure);
            app.UnitstoShow123EditFieldLabel = uilabel(app.Panel_2);
            app.UnitstoShow123EditFieldLabel.Text = {'Units to Show'; '(1,2,3)'};
            app.UnitstoShow123EditField = uieditfield(app.Panel_2, 'text');
            app.UnitstoShow123EditField.ValueChangedFcn = createCallbackFcn(app, @UnitstoShow123EditFieldValueChanged, true);
            app.UnitstoShow123EditField_2Label = uilabel(app.Panel_2);
            app.UnitstoShow123EditField_2Label.Text = {'Units to Show'; '(1,2,3)'};
            app.UnitstoShow123EditField_2 = uieditfield(app.Panel_2, 'text');
            app.UnitstoShow123EditField_3Label = uilabel(app.Panel_2);
            app.UnitstoShow123EditField_3Label.Text = {'Units to Show'; '(1,2,3)'};
            app.UnitstoShow123EditField_3 = uieditfield(app.Panel_2, 'text');
            app.Panel = uipanel(app.SpikeWaveformWindowUIFigure);
            app.DeleteUnitDropDownLabel = uilabel(app.Panel);
            app.DeleteUnitDropDownLabel.Text = 'Delete Unit';
            app.DeleteUnitDropDown = uidropdown(app.Panel);
            app.DeleteUnitDropDown.Items = {''};
            app.DeleteUnitDropDown.Value = '';
            app.DeleteButton = uibutton(app.Panel, 'push');
            app.DeleteButton.Text = 'Delete';
            app.KilosortAnalysisWindowLabel = uilabel(app.SpikeWaveformWindowUIFigure);
            app.KilosortAnalysisWindowLabel.Text = 'Kilosort Analysis Window';
            app.Image = uiimage(app.SpikeWaveformWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.SpikeWaveformWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.SpikeWaveformWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.NumWaveformsEditFieldLabel = uilabel(app.SpikeWaveformWindowUIFigure);
            app.NumWaveformsEditFieldLabel.Text = 'Num Waveforms';
            app.NumWaveformsEditField = uieditfield(app.SpikeWaveformWindowUIFigure, 'text');
            app.NumWaveformsEditField.ValueChangedFcn = createCallbackFcn(app, @NumWaveformsEditFieldValueChanged, true);
            app.NumWaveformsEditField_2Label = uilabel(app.SpikeWaveformWindowUIFigure);
            app.NumWaveformsEditField_2Label.Text = 'Num Waveforms';
            app.NumWaveformsEditField_2 = uieditfield(app.SpikeWaveformWindowUIFigure, 'text');
            app.NumWaveformsEditField_3Label = uilabel(app.SpikeWaveformWindowUIFigure);
            app.NumWaveformsEditField_3Label.Text = 'Num Waveforms';
            app.NumWaveformsEditField_3 = uieditfield(app.SpikeWaveformWindowUIFigure, 'text');
            % Show the figure after all components are created
            app.SpikeWaveformWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Continous_Waveform_Analysis_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SpikeWaveformWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SpikeWaveformWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpikeWaveformWindowUIFigure)
        end
    end
end