classdef Events_Internal_Spike_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EventRelatedInternalSpikeAnalysisUIFigure  matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        Show2DimensionalPlotsMenu       matlab.ui.container.Menu
        Show3DimensionalPlotsMenu       matlab.ui.container.Menu
        ExportDataasMenu_2              matlab.ui.container.Menu
        SpikeRateMenu_2                 matlab.ui.container.Menu
        AllSpikesMenu                   matlab.ui.container.Menu
        OverTimeMenu                    matlab.ui.container.Menu
        matMenu                         matlab.ui.container.Menu
        txtMenu                         matlab.ui.container.Menu
        csvMenu                         matlab.ui.container.Menu
        OverChannelMenu                 matlab.ui.container.Menu
        matMenu_2                       matlab.ui.container.Menu
        txtMenu_2                       matlab.ui.container.Menu
        csvMenu_2                       matlab.ui.container.Menu
        SelectedUnitMenu                matlab.ui.container.Menu
        matMenu_3                       matlab.ui.container.Menu
        txtMenu_3                       matlab.ui.container.Menu
        csvMenu_3                       matlab.ui.container.Menu
        MainPlotMenu                    matlab.ui.container.Menu
        AllSpikesMenu_2                 matlab.ui.container.Menu
        matMenu_4                       matlab.ui.container.Menu
        txtMenu_4                       matlab.ui.container.Menu
        csvMenu_4                       matlab.ui.container.Menu
        SelectedUnitMenu_2              matlab.ui.container.Menu
        matMenu_5                       matlab.ui.container.Menu
        txtMenu_5                       matlab.ui.container.Menu
        csvMenu_5                       matlab.ui.container.Menu
        InformationLabel                matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        CloseButton                     matlab.ui.control.Button
        NeuromodToolboxLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        EventRelatedInternalSpikesWindowLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        ClustertoshowDropDown           matlab.ui.control.DropDown
        ClustertoShowDropDownLabel      matlab.ui.control.Label
        TimeWindowSpiketriggredLFPEditField  matlab.ui.control.EditField
        TimeWindowSpiketriggredLFPEditFieldLabel  matlab.ui.control.Label
        ChannelSelectionforPlottingEditField  matlab.ui.control.EditField
        ChannelSelectionforPlottingEditFieldLabel  matlab.ui.control.Label
        BaselineWindowStartStopinsEditField  matlab.ui.control.EditField
        BaselineWindowStartStopinsEditFieldLabel  matlab.ui.control.Label
        BaselineNormalizeCheckBox       matlab.ui.control.CheckBox
        AnalysisTypeDropDown            matlab.ui.control.DropDown
        AnalysisTypeDropDownLabel       matlab.ui.control.Label
        EventRangeEditField             matlab.ui.control.EditField
        EventRangeEditFieldLabel        matlab.ui.control.Label
        SpikeRateNumBinsEditField       matlab.ui.control.EditField
        SpikeRateNumBinsEditFieldLabel  matlab.ui.control.Label
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        ContextMenu3                    matlab.ui.container.ContextMenu
        ChangeTimeBinSizeMenu           matlab.ui.container.Menu
        ChangeTitleMenu                 matlab.ui.container.Menu
        ChangeXLabelMenu                matlab.ui.container.Menu
        ChangeYLabelMenu                matlab.ui.container.Menu
        ChangeXlimMenu                  matlab.ui.container.Menu
        ChangeYlimMenu                  matlab.ui.container.Menu
        ChangeLegendMenu                matlab.ui.container.Menu
        ChangeFontsizeMenu              matlab.ui.container.Menu
        ContextMenu3_2                  matlab.ui.container.ContextMenu
        ChangeTitleMenu_2               matlab.ui.container.Menu
        ChangeXLabelMenu_2              matlab.ui.container.Menu
        ChangeYLabelMenu_2              matlab.ui.container.Menu
        ChangeXlimMenu_2                matlab.ui.container.Menu
        ChangeYlimMenu_2                matlab.ui.container.Menu
        ChangeLegendMenu_2              matlab.ui.container.Menu
        ChangeFontsizeMenu_2            matlab.ui.container.Menu
        ContextMenu3_3                  matlab.ui.container.ContextMenu
        ChangeTitleMenu_3               matlab.ui.container.Menu
        ChangeXLabelMenu_3              matlab.ui.container.Menu
        ChangeYLabelMenu_3              matlab.ui.container.Menu
        ChangeXlimMenu_3                matlab.ui.container.Menu
        ChangeYlimMenu_3                matlab.ui.container.Menu
        ChangeLegendMenu_3              matlab.ui.container.Menu
        ChangeFontsizeMenu_3            matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        Settings = [];
        SpikeData =[];
        SpikeBinSettings = [];
        rgbMatrix = [];
        SaveSpikeTriggereAveragePrepro = 0;
        TwoORThreeD = "TwoD"; % standard 2d plots when possible
        TimearoundEvent = [];
        numCluster = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.Mainapp = Mainapp;

            movegui(app.EventRelatedInternalSpikeAnalysisUIFigure, 'center');

            % Reset Spike Rate plot in order to show new bin size
            yyaxis(app.UIAxes_3, 'left');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            app.UIAxes_3.FontSize = 10;
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")

            yyaxis(app.UIAxes_3, 'right');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")
            app.UIAxes_3.FontSize = 10;
            
            [app] = Spike_Module_Set_Up_Spike_Analysis_Windows(app,[],"EventWindow");

            app.BaselineNormalizeCheckBox.Enable = "off";
            app.BaselineWindowStartStopinsEditField.Enable = "off";
            
            [app.Mainapp.Data,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings);
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: SpikeRateNumBinsEditField
        function SpikeRateNumBinsEditFieldValueChanged(app, event)
            % Reset Spike Rate plot in order to show new bin size
            yyaxis(app.UIAxes_3, 'left');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            app.UIAxes_3.FontSize = 10;
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")

            yyaxis(app.UIAxes_3, 'right');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")
            app.UIAxes_3.FontSize = 10;

            [~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings);
            
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.EventRelatedInternalSpikeAnalysisUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.EventRelatedInternalSpikeAnalysisUIFigure,fullfile(path,file)) 
        end

        % Value changed function: EventRangeEditField
        function EventRangeEditFieldValueChanged(app, event)
            % Reset Spike Rate plot in order to show new bin size
            yyaxis(app.UIAxes_3, 'left');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            app.UIAxes_3.FontSize = 10;
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")

            yyaxis(app.UIAxes_3, 'right');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")
            app.UIAxes_3.FontSize = 10;

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings);
            
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end

            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,'Internal',0);
          
            if Error == 1
                return;
            end

            %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,~,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Internal",0,app.TimeWindowSpiketriggredLFPEditField.Value);
       
            app.Mainapp.CurrentPlotData = Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),"Non",app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot,app.Mainapp.CurrentPlotData);
       

        end

        % Menu selected function: ChangeTitleMenu
        function ChangeTitleMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'title');
        end

        % Menu selected function: ChangeXLabelMenu
        function ChangeXLabelMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'xlabel');
        end

        % Menu selected function: ChangeYLabelMenu
        function ChangeYLabelMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'ylabel');
        end

        % Menu selected function: ChangeXlimMenu
        function ChangeXlimMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'xlim');
        end

        % Menu selected function: ChangeYlimMenu
        function ChangeYlimMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'ylim');
        end

        % Menu selected function: ChangeLegendMenu
        function ChangeLegendMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'legend');
        end

        % Menu selected function: ChangeFontsizeMenu
        function ChangeFontsizeMenuSelected(app, event)
            
        end

        % Menu selected function: ChangeTitleMenu_2
        function ChangeTitleMenu_2Selected(app, event)
             Figure_Property_Change_Window(app.UIAxes_3,'title');
        end

        % Menu selected function: ChangeXLabelMenu_2
        function ChangeXLabelMenu_2Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_3,'xlabel');
        end

        % Menu selected function: ChangeYLabelMenu_2
        function ChangeYLabelMenu_2Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_3,'ylabel');
        end

        % Menu selected function: ChangeXlimMenu_2
        function ChangeXlimMenu_2Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_3,'xlim');
        end

        % Menu selected function: ChangeYlimMenu_2
        function ChangeYlimMenu_2Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_3,'ylim');
        end

        % Menu selected function: ChangeLegendMenu_2
        function ChangeLegendMenu_2Selected(app, event)
             Figure_Property_Change_Window(app.UIAxes_3,'legend');
        end

        % Menu selected function: ChangeFontsizeMenu_2
        function ChangeFontsizeMenu_2Selected(app, event)
            
        end

        % Menu selected function: ChangeTitleMenu_3
        function ChangeTitleMenu_3Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_5,'title');
        end

        % Menu selected function: ChangeXLabelMenu_3
        function ChangeXLabelMenu_3Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_5,'xlabel');
        end

        % Menu selected function: ChangeYLabelMenu_3
        function ChangeYLabelMenu_3Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_5,'ylabel');
        end

        % Menu selected function: ChangeXlimMenu_3
        function ChangeXlimMenu_3Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_5,'xlim');
        end

        % Menu selected function: ChangeYlimMenu_3
        function ChangeYlimMenu_3Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_5,'ylim');
        end

        % Menu selected function: ChangeLegendMenu_3
        function ChangeLegendMenu_3Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_5,'legend');
        end

        % Menu selected function: ChangeFontsizeMenu_3
        function ChangeFontsizeMenu_3Selected(app, event)
            
        end

        % Value changed function: AnalysisTypeDropDown
        function AnalysisTypeDropDownValueChanged(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            set(app.UIAxes, 'YDir','reverse');
            set(app.UIAxes,'xticklabel',{[]});

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings);
            
            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                app.BaselineNormalizeCheckBox.Enable = "on";
                app.BaselineWindowStartStopinsEditField.Enable = "on";
            else
                app.BaselineNormalizeCheckBox.Enable = "off";
                app.BaselineWindowStartStopinsEditField.Enable = "off";
            end

            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
       
        end

        % Value changed function: BaselineNormalizeCheckBox
        function BaselineNormalizeCheckBoxValueChanged(app, event)
            [~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings);
            
        end

        % Value changed function: BaselineWindowStartStopinsEditField
        function BaselineWindowStartStopinsEditFieldValueChanged(app, event)
            [~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings);
            
        end

        % Menu selected function: ChangeTimeBinSizeMenu
        function ChangeTimeBinSizeMenuSelected(app, event)
            BinSizeChangeWindow(app,"TimeBins");
        end

        % Value changed function: ChannelSelectionforPlottingEditField
        function ChannelSelectionforPlottingEditFieldValueChanged(app, event)
            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings);
            
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
        end

        % Value changed function: TimeWindowSpiketriggredLFPEditField
        function TimeWindowSpiketriggredLFPEditFieldValueChanged(app, event)
            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings);
            
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
        end

        % Menu selected function: Show2DimensionalPlotsMenu
        function Show2DimensionalPlotsMenuSelected(app, event)
            app.TwoORThreeD = "TwoD";

            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            set(app.UIAxes, 'YDir','reverse');
            set(app.UIAxes,'xticklabel',{[]});

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings);
            
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end

            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,'Internal',0);
          
            if Error == 1
                return;
            end
        end

        % Value changed function: ClustertoshowDropDown
        function ClustertoshowDropDownValueChanged(app, event)
            % delete saved unit specific data if no unit selected
            if strcmp(app.ClustertoshowDropDown.Value,"All") || strcmp(app.ClustertoshowDropDown.Value,"Non")
                if isfield(app.Mainapp.CurrentPlotData,'MainRateUnitXData')
                    app.Mainapp.CurrentPlotData = rmfield(app.Mainapp.CurrentPlotData, {'MainRateUnitXData', 'MainRateUnitYData','MainRateUnitCData', 'MainRateUnitType', 'MainRateUnitXTicks'});
                end
                if isfield(app.Mainapp.CurrentPlotData,'MainUnitXData')
                    app.Mainapp.CurrentPlotData = rmfield(app.Mainapp.CurrentPlotData, {'MainUnitXData', 'MainUnitYData','MainUnitCData', 'MainUnitType', 'MainUnitXTicks'});
                end
            end
            
            [~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings);
            
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal All Spikes Spike Rate over Time ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal All Spikes Spike Rate over Time ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: csvMenu
        function csvMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal All Spikes Spike Rate over Time ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: matMenu_2
        function matMenu_2Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal All Spikes Spike Rate over Channel ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: txtMenu_2
        function txtMenu_2Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;
        
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal All Spikes Spike Rate over Channel ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: csvMenu_2
        function csvMenu_2Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal All Spikes Spike Rate over Channel ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: matMenu_3
        function matMenu_3Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal Spike Rate Unit ",app.ClustertoshowDropDown.Value));
        end

        % Menu selected function: txtMenu_3
        function txtMenu_3Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal Spike Rate Unit ",app.ClustertoshowDropDown.Value));
        end

        % Menu selected function: csvMenu_3
        function csvMenu_3Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal Spike Rate Unit ",app.ClustertoshowDropDown.Value));
        end

        % Menu selected function: matMenu_4
        function matMenu_4Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal All Spikes ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: txtMenu_4
        function txtMenu_4Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal All Spikes ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: csvMenu_4
        function csvMenu_4Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal All Spikes ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: matMenu_5
        function matMenu_5Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal Unit Spikes ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: txtMenu_5
        function txtMenu_5Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal Unit Spikes ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: csvMenu_5
        function csvMenu_5Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal Unit Spikes ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: Show3DimensionalPlotsMenu
        function Show3DimensionalPlotsMenuSelected(app, event)
            app.TwoORThreeD = "ThreeD";

            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            set(app.UIAxes, 'YDir','reverse');
            set(app.UIAxes,'xticklabel',{[]});

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings);
            
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end

            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,'Internal',0);
          
            if Error == 1
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EventRelatedInternalSpikeAnalysisUIFigure = uifigure('Visible', 'off');
            app.EventRelatedInternalSpikeAnalysisUIFigure.Name = 'Event Related Internal Spike Analysis';
            app.EventRelatedInternalSpikeAnalysisUIFigure.Icon = 'Logo.png';
            app.SnapshotMenu = uimenu(app.EventRelatedInternalSpikeAnalysisUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.PlotMenu = uimenu(app.EventRelatedInternalSpikeAnalysisUIFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.Show2DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show2DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show2DimensionalPlotsMenuSelected, true);
            app.Show2DimensionalPlotsMenu.Text = 'Show 2 Dimensional Plots';
            app.Show3DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show3DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show3DimensionalPlotsMenuSelected, true);
            app.Show3DimensionalPlotsMenu.Text = 'Show 3 Dimensional Plots';
            app.ExportDataasMenu_2 = uimenu(app.EventRelatedInternalSpikeAnalysisUIFigure);
            app.ExportDataasMenu_2.Text = '   Export Data as   ';
            app.SpikeRateMenu_2 = uimenu(app.ExportDataasMenu_2);
            app.SpikeRateMenu_2.Text = 'Spike Rate';
            app.AllSpikesMenu = uimenu(app.SpikeRateMenu_2);
            app.AllSpikesMenu.Text = 'All Spikes';
            app.OverTimeMenu = uimenu(app.AllSpikesMenu);
            app.OverTimeMenu.Text = 'Over Time';
            app.matMenu = uimenu(app.OverTimeMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.OverTimeMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.csvMenu = uimenu(app.OverTimeMenu);
            app.csvMenu.MenuSelectedFcn = createCallbackFcn(app, @csvMenuSelected, true);
            app.csvMenu.Text = '.csv';
            app.OverChannelMenu = uimenu(app.AllSpikesMenu);
            app.OverChannelMenu.Text = 'Over Channel';
            app.matMenu_2 = uimenu(app.OverChannelMenu);
            app.matMenu_2.MenuSelectedFcn = createCallbackFcn(app, @matMenu_2Selected, true);
            app.matMenu_2.Text = '.mat';
            app.txtMenu_2 = uimenu(app.OverChannelMenu);
            app.txtMenu_2.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_2Selected, true);
            app.txtMenu_2.Text = '.txt';
            app.csvMenu_2 = uimenu(app.OverChannelMenu);
            app.csvMenu_2.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_2Selected, true);
            app.csvMenu_2.Text = '.csv';
            app.SelectedUnitMenu = uimenu(app.SpikeRateMenu_2);
            app.SelectedUnitMenu.Text = 'Selected Unit';
            app.matMenu_3 = uimenu(app.SelectedUnitMenu);
            app.matMenu_3.MenuSelectedFcn = createCallbackFcn(app, @matMenu_3Selected, true);
            app.matMenu_3.Text = '.mat';
            app.txtMenu_3 = uimenu(app.SelectedUnitMenu);
            app.txtMenu_3.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_3Selected, true);
            app.txtMenu_3.Text = '.txt';
            app.csvMenu_3 = uimenu(app.SelectedUnitMenu);
            app.csvMenu_3.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_3Selected, true);
            app.csvMenu_3.Text = '.csv';
            app.MainPlotMenu = uimenu(app.ExportDataasMenu_2);
            app.MainPlotMenu.Text = 'Main Plot';
            app.AllSpikesMenu_2 = uimenu(app.MainPlotMenu);
            app.AllSpikesMenu_2.Text = 'All Spikes';
            app.matMenu_4 = uimenu(app.AllSpikesMenu_2);
            app.matMenu_4.MenuSelectedFcn = createCallbackFcn(app, @matMenu_4Selected, true);
            app.matMenu_4.Text = '.mat';
            app.txtMenu_4 = uimenu(app.AllSpikesMenu_2);
            app.txtMenu_4.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_4Selected, true);
            app.txtMenu_4.Text = '.txt';
            app.csvMenu_4 = uimenu(app.AllSpikesMenu_2);
            app.csvMenu_4.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_4Selected, true);
            app.csvMenu_4.Text = '.csv';
            app.SelectedUnitMenu_2 = uimenu(app.MainPlotMenu);
            app.SelectedUnitMenu_2.Text = 'Selected Unit';
            app.matMenu_5 = uimenu(app.SelectedUnitMenu_2);
            app.matMenu_5.MenuSelectedFcn = createCallbackFcn(app, @matMenu_5Selected, true);
            app.matMenu_5.Text = '.mat';
            app.txtMenu_5 = uimenu(app.SelectedUnitMenu_2);
            app.txtMenu_5.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_5Selected, true);
            app.txtMenu_5.Text = '.txt';
            app.csvMenu_5 = uimenu(app.SelectedUnitMenu_2);
            app.csvMenu_5.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_5Selected, true);
            app.csvMenu_5.Text = '.csv';
            app.UIAxes_3 = uiaxes(app.EventRelatedInternalSpikeAnalysisUIFigure);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes = uiaxes(app.EventRelatedInternalSpikeAnalysisUIFigure);
            title(app.UIAxes, 'Title')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes_5 = uiaxes(app.EventRelatedInternalSpikeAnalysisUIFigure);
            xlabel(app.UIAxes_5, 'X')
            zlabel(app.UIAxes_5, 'Z')
            app.Panel = uipanel(app.EventRelatedInternalSpikeAnalysisUIFigure);
            app.SpikeRateNumBinsEditFieldLabel = uilabel(app.Panel);
            app.SpikeRateNumBinsEditFieldLabel.Text = {'Spike Rate'; 'Num Bins'};
            app.SpikeRateNumBinsEditField = uieditfield(app.Panel, 'text');
            app.SpikeRateNumBinsEditField.ValueChangedFcn = createCallbackFcn(app, @SpikeRateNumBinsEditFieldValueChanged, true);
            app.SpikeRateNumBinsEditField.Value = '100';
            app.EventRangeEditFieldLabel = uilabel(app.Panel);
            app.EventRangeEditFieldLabel.Text = 'Event Range';
            app.EventRangeEditField = uieditfield(app.Panel, 'text');
            app.EventRangeEditField.ValueChangedFcn = createCallbackFcn(app, @EventRangeEditFieldValueChanged, true);
            app.AnalysisTypeDropDownLabel = uilabel(app.Panel);
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type';
            app.AnalysisTypeDropDown = uidropdown(app.Panel);
            app.AnalysisTypeDropDown.Items = {'Spike Map', 'Spike Rate Heatmap', 'Spike Triggered Average'};
            app.AnalysisTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisTypeDropDownValueChanged, true);
            app.AnalysisTypeDropDown.Value = 'Spike Map';
            app.BaselineNormalizeCheckBox = uicheckbox(app.Panel);
            app.BaselineNormalizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @BaselineNormalizeCheckBoxValueChanged, true);
            app.BaselineNormalizeCheckBox.Text = '  Baseline Normalize';
            app.BaselineWindowStartStopinsEditFieldLabel = uilabel(app.Panel);
            app.BaselineWindowStartStopinsEditFieldLabel.Text = {'Baseline Window'; '[Start,Stop] in s'};
            app.BaselineWindowStartStopinsEditField = uieditfield(app.Panel, 'text');
            app.BaselineWindowStartStopinsEditField.ValueChangedFcn = createCallbackFcn(app, @BaselineWindowStartStopinsEditFieldValueChanged, true);
            app.ChannelSelectionforPlottingEditFieldLabel = uilabel(app.Panel);
            app.ChannelSelectionforPlottingEditFieldLabel.Text = {'Channel Selection'; 'for Plotting'};
            app.ChannelSelectionforPlottingEditField = uieditfield(app.Panel, 'text');
            app.ChannelSelectionforPlottingEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionforPlottingEditFieldValueChanged, true);
            app.TimeWindowSpiketriggredLFPEditFieldLabel = uilabel(app.Panel);
            app.TimeWindowSpiketriggredLFPEditFieldLabel.Text = {'Time Window '; 'Spike triggred LFP'};
            app.TimeWindowSpiketriggredLFPEditField = uieditfield(app.Panel, 'text');
            app.TimeWindowSpiketriggredLFPEditField.ValueChangedFcn = createCallbackFcn(app, @TimeWindowSpiketriggredLFPEditFieldValueChanged, true);
            app.TimeWindowSpiketriggredLFPEditField.Value = '-0.005,0.2';
            app.ClustertoShowDropDownLabel = uilabel(app.Panel);
            app.ClustertoShowDropDownLabel.Text = 'Cluster to Show';
            app.ClustertoshowDropDown = uidropdown(app.Panel);
            app.ClustertoshowDropDown.Items = {};
            app.ClustertoshowDropDown.ValueChangedFcn = createCallbackFcn(app, @ClustertoshowDropDownValueChanged, true);
            app.ClustertoshowDropDown.Value = {};
            app.EventRelatedInternalSpikesWindowLabel = uilabel(app.EventRelatedInternalSpikeAnalysisUIFigure);
            app.EventRelatedInternalSpikesWindowLabel.Text = 'Event Related Internal Spikes Window';
            app.Image = uiimage(app.EventRelatedInternalSpikeAnalysisUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.EventRelatedInternalSpikeAnalysisUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.EventRelatedInternalSpikeAnalysisUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TextArea = uitextarea(app.EventRelatedInternalSpikeAnalysisUIFigure);
            app.InformationLabel = uilabel(app.EventRelatedInternalSpikeAnalysisUIFigure);
            app.InformationLabel.Text = 'Information';
            app.ContextMenu3 = uicontextmenu(app.EventRelatedInternalSpikeAnalysisUIFigure);
            app.ChangeTimeBinSizeMenu = uimenu(app.ContextMenu3);
            app.ChangeTimeBinSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeTimeBinSizeMenuSelected, true);
            app.ChangeTimeBinSizeMenu.Text = 'Change Time Bin Size';
            app.ChangeTitleMenu = uimenu(app.ContextMenu3);
            app.ChangeTitleMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeTitleMenuSelected, true);
            app.ChangeTitleMenu.Text = 'Change Title';
            app.ChangeXLabelMenu = uimenu(app.ContextMenu3);
            app.ChangeXLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeXLabelMenuSelected, true);
            app.ChangeXLabelMenu.Text = 'Change X Label';
            app.ChangeYLabelMenu = uimenu(app.ContextMenu3);
            app.ChangeYLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeYLabelMenuSelected, true);
            app.ChangeYLabelMenu.Text = 'Change Y Label';
            app.ChangeXlimMenu = uimenu(app.ContextMenu3);
            app.ChangeXlimMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeXlimMenuSelected, true);
            app.ChangeXlimMenu.Text = 'Change Xlim';
            app.ChangeYlimMenu = uimenu(app.ContextMenu3);
            app.ChangeYlimMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeYlimMenuSelected, true);
            app.ChangeYlimMenu.Text = 'Change Ylim';
            app.ChangeLegendMenu = uimenu(app.ContextMenu3);
            app.ChangeLegendMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeLegendMenuSelected, true);
            app.ChangeLegendMenu.Text = 'Change Legend';
            app.ChangeFontsizeMenu = uimenu(app.ContextMenu3);
            app.ChangeFontsizeMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeFontsizeMenuSelected, true);
            app.ChangeFontsizeMenu.Text = 'Change Fontsize';
            % Assign app.ContextMenu3
            app.UIAxes.ContextMenu = app.ContextMenu3;
            app.ContextMenu3_2 = uicontextmenu(app.EventRelatedInternalSpikeAnalysisUIFigure);
            app.ChangeTitleMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeTitleMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeTitleMenu_2Selected, true);
            app.ChangeTitleMenu_2.Text = 'Change Title';
            app.ChangeXLabelMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeXLabelMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeXLabelMenu_2Selected, true);
            app.ChangeXLabelMenu_2.Text = 'Change X Label';
            app.ChangeYLabelMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeYLabelMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeYLabelMenu_2Selected, true);
            app.ChangeYLabelMenu_2.Text = 'Change Y Label';
            app.ChangeXlimMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeXlimMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeXlimMenu_2Selected, true);
            app.ChangeXlimMenu_2.Text = 'Change Xlim';
            app.ChangeYlimMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeYlimMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeYlimMenu_2Selected, true);
            app.ChangeYlimMenu_2.Text = 'Change Ylim';
            app.ChangeLegendMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeLegendMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeLegendMenu_2Selected, true);
            app.ChangeLegendMenu_2.Text = 'Change Legend';
            app.ChangeFontsizeMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeFontsizeMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeFontsizeMenu_2Selected, true);
            app.ChangeFontsizeMenu_2.Text = 'Change Fontsize';
            % Assign app.ContextMenu3_2
            app.UIAxes_3.ContextMenu = app.ContextMenu3_2;
            app.ContextMenu3_3 = uicontextmenu(app.EventRelatedInternalSpikeAnalysisUIFigure);
            app.ChangeTitleMenu_3 = uimenu(app.ContextMenu3_3);
            app.ChangeTitleMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ChangeTitleMenu_3Selected, true);
            app.ChangeTitleMenu_3.Text = 'Change Title';
            app.ChangeXLabelMenu_3 = uimenu(app.ContextMenu3_3);
            app.ChangeXLabelMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ChangeXLabelMenu_3Selected, true);
            app.ChangeXLabelMenu_3.Text = 'Change X Label';
            app.ChangeYLabelMenu_3 = uimenu(app.ContextMenu3_3);
            app.ChangeYLabelMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ChangeYLabelMenu_3Selected, true);
            app.ChangeYLabelMenu_3.Text = 'Change Y Label';
            app.ChangeXlimMenu_3 = uimenu(app.ContextMenu3_3);
            app.ChangeXlimMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ChangeXlimMenu_3Selected, true);
            app.ChangeXlimMenu_3.Text = 'Change Xlim';
            app.ChangeYlimMenu_3 = uimenu(app.ContextMenu3_3);
            app.ChangeYlimMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ChangeYlimMenu_3Selected, true);
            app.ChangeYlimMenu_3.Text = 'Change Ylim';
            app.ChangeLegendMenu_3 = uimenu(app.ContextMenu3_3);
            app.ChangeLegendMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ChangeLegendMenu_3Selected, true);
            app.ChangeLegendMenu_3.Text = 'Change Legend';
            app.ChangeFontsizeMenu_3 = uimenu(app.ContextMenu3_3);
            app.ChangeFontsizeMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ChangeFontsizeMenu_3Selected, true);
            app.ChangeFontsizeMenu_3.Text = 'Change Fontsize';
            % Assign app.ContextMenu3_3
            app.UIAxes_5.ContextMenu = app.ContextMenu3_3;
            % Show the figure after all components are created
            app.EventRelatedInternalSpikeAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Events_Internal_Spike_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EventRelatedInternalSpikeAnalysisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EventRelatedInternalSpikeAnalysisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EventRelatedInternalSpikeAnalysisUIFigure)
        end
    end
end