classdef Autorun_Manager_Select_Template < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectAutorunTemplateWindowUIFigure  matlab.ui.Figure
        NeuromodToolboxLabel         matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        SelectAutorunTemplateWindowLabel  matlab.ui.control.Label
        ProceedButton                matlab.ui.control.Button
        SelectTemplateDropDown       matlab.ui.control.DropDown
        SelectTemplateDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        AutorunApp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, AutorunApp)
            movegui(app.SelectAutorunTemplateWindowUIFigure, 'center');

            app.AutorunApp = AutorunApp;
            
            PathToSearch = strcat(app.AutorunApp.Mainapp.executableFolder,'\Modules\MISC\Default Autorun Configs (do not edit!)\');

            [stringArray] = Utility_Extract_Contents_of_Folder(PathToSearch);

            for i = 1:length(stringArray)
                app.SelectTemplateDropDown.Items{i}= convertStringsToChars(stringArray(i));
            end
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            app.AutorunApp.SelectedTemplate = app.SelectTemplateDropDown.Value;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectAutorunTemplateWindowUIFigure = uifigure('Visible', 'off');
            app.SelectAutorunTemplateWindowUIFigure.Name = 'Select Autorun Template Window';
            app.SelectAutorunTemplateWindowUIFigure.Icon = 'Logo.png';
            app.SelectTemplateDropDownLabel = uilabel(app.SelectAutorunTemplateWindowUIFigure);
            app.SelectTemplateDropDownLabel.Text = 'Select Template';
            app.SelectTemplateDropDown = uidropdown(app.SelectAutorunTemplateWindowUIFigure);
            app.ProceedButton = uibutton(app.SelectAutorunTemplateWindowUIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.SelectAutorunTemplateWindowLabel = uilabel(app.SelectAutorunTemplateWindowUIFigure);
            app.SelectAutorunTemplateWindowLabel.Text = 'Select Autorun Template Window';
            app.Image = uiimage(app.SelectAutorunTemplateWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.SelectAutorunTemplateWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            % Show the figure after all components are created
            app.SelectAutorunTemplateWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Autorun_Manager_Select_Template(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SelectAutorunTemplateWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SelectAutorunTemplateWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectAutorunTemplateWindowUIFigure)
        end
    end
end