classdef Channel_Spacing_Selection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChannelSpacingSelectionUIFigure  matlab.ui.Figure
        NewSpacingLimitsminmaxLabel   matlab.ui.control.Label
        SpacingLimitsminmaxEditField  matlab.ui.control.EditField
    end

    
    properties (Access = public)
        PreviousWindow = []
        Mainapp = []; % Description
        Window = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PreviousWindow, Window)
            movegui(app.ChannelSpacingSelectionUIFigure, 'center');
            
            app.PreviousWindow = PreviousWindow;
            app.Window = Window;

            if isprop(PreviousWindow,'Mainapp')
                app.Mainapp = app.PreviousWindow.Mainapp;
            else
                app.Mainapp = PreviousWindow;
            end

            if strcmp(Window,"MainWindow")
                app.NewSpacingLimitsminmaxLabel.Text = "New Spacing Limits [min,max]";
            elseif strcmp(Window,"Grid_Trace_Window_Scaling")
                app.NewSpacingLimitsminmaxLabel.Text = "New Y Limits [min,max]";
            elseif strcmp(Window,"ProbeViewColumnSelection")
                app.NewSpacingLimitsminmaxLabel.Text = "Enter the columns in the probe you want to activate [Matlab expressions]";
            elseif strcmp(Window,"ProbeViewRowSelection")
                app.NewSpacingLimitsminmaxLabel.Text = "Enter the rows in the probe you want to activate [Matlab expressions]";
            end

            app = Utility_Change_Light_Dark_Mode(app,"ChangeChannelSpacing");
        end

        % Value changed function: SpacingLimitsminmaxEditField
        function SpacingLimitsminmaxEditFieldValueChanged(app, event)
            if strcmp(app.Window,"MainWindow")
                [CorrectedValue] = Utility_SimpleCheckInputs(app.SpacingLimitsminmaxEditField.Value,"Two",app.PreviousWindow.Slider.Limits,0,1);
                if ~strcmp(CorrectedValue,app.SpacingLimitsminmaxEditField.Value)
                    msgbox("Error: Wrong format. Please enter as [lower limit, upper limit]")
                    return;
                end
            end

            if strcmp(app.Window,"MainWindow") || strcmp(app.Window,"Grid_Trace_Window_Scaling")
               
                splitStr = split(app.SpacingLimitsminmaxEditField.Value, ',');
                
                % Convert the substrings to numbers
                Minslider = str2double(splitStr{1});
                Maxslider = str2double(splitStr{2});
            else
                Timing = str2double(app.SpacingLimitsminmaxEditField.Value);
            end

            if strcmp(app.Window,"MainWindow")
                app.PreviousWindow.Slider.Limits = [Minslider,Maxslider];
            elseif strcmp(app.Window,"Grid_Trace_Window_Scaling")
                app.Mainapp.PlotAppearance.MainWindow.Data.GridViewWindowScaling = [Minslider,Maxslider];
            elseif strcmp(app.Window,"Grid_Trace_Window_Time")
                app.Mainapp.PlotAppearance.MainWindow.Data.GridViewWindowTime = Timing;
            end

            if strcmp(app.Window,"ProbeViewColumnSelection")
                try
                    NewColums = eval(app.SpacingLimitsminmaxEditField.Value);

                    NumChannel = length(unique(app.Mainapp.Data.Info.ProbeInfo.ycoords));
                    NumRows = length(unique(app.Mainapp.Data.Info.ProbeInfo.xcoords));

                    NewActiveChannel = [];
                    for i = 1:length(NewColums)
                        StartPoint = (NewColums(i)-1)*NumRows+1;
                        NewActiveChannel = [NewActiveChannel,StartPoint:1:StartPoint+NumRows-1];
                    end
                    NewActiveChannel = sort(NewActiveChannel);

                    DeleteIndicie = [];
                    for i = 1:length(NewActiveChannel)
                        if sum(NewActiveChannel(i),app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)==0
                            DeleteIndicie = [DeleteIndicie,i];
                        end
                    end


                    if ~isempty(DeleteIndicie)
                        NewActiveChannel(DeleteIndicie) = [];
                    end
                    
                    NewActiveChannel(~ismember(NewActiveChannel,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)) = [];

                    app.Mainapp.ActiveChannel = NewActiveChannel;

                catch
                    msgbox("Error: Wrong format. Please enter as [lower limit, upper limit]")
                    return;
                end
            end
            if strcmp(app.Window,"ProbeViewRowSelection")
                try
                    NewRows = eval(app.SpacingLimitsminmaxEditField.Value);

                    NumRows = length(unique(app.Mainapp.Data.Info.ProbeInfo.xcoords));

                    NewActiveChannel = [];
                    for i = 1:length(NewRows)
                        NewActiveChannel = [NewActiveChannel,NewRows(i):NumRows:numel(app.Mainapp.Data.Info.ProbeInfo.xcoords)];
                    end
                    NewActiveChannel = sort(NewActiveChannel);

                    DeleteIndicie = [];
                    for i = 1:length(NewActiveChannel)
                        if sum(NewActiveChannel(i),app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)==0
                            DeleteIndicie = [DeleteIndicie,i];
                        end
                    end


                    if ~isempty(DeleteIndicie)
                        NewActiveChannel(DeleteIndicie) = [];
                    end
                    
                    NewActiveChannel(~ismember(NewActiveChannel,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)) = [];

                    app.Mainapp.ActiveChannel = NewActiveChannel;

                catch
                    msgbox("Error: Wrong format. Please enter as [lower limit, upper limit]")
                    return;
                end
            end

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChannelSpacingSelectionUIFigure = uifigure('Visible', 'off');
            app.ChannelSpacingSelectionUIFigure.Name = 'Channel Spacing Selection';
            app.ChannelSpacingSelectionUIFigure.Icon = 'Logo.png';
            app.SpacingLimitsminmaxEditField = uieditfield(app.ChannelSpacingSelectionUIFigure, 'text');
            app.SpacingLimitsminmaxEditField.ValueChangedFcn = createCallbackFcn(app, @SpacingLimitsminmaxEditFieldValueChanged, true);
            app.NewSpacingLimitsminmaxLabel = uilabel(app.ChannelSpacingSelectionUIFigure);
            app.NewSpacingLimitsminmaxLabel.Text = {'New Spacing Limits'; '[min,max]'};
            % Show the figure after all components are created
            app.ChannelSpacingSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Channel_Spacing_Selection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ChannelSpacingSelectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ChannelSpacingSelectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChannelSpacingSelectionUIFigure)
        end
    end
end