classdef Controls_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ControlsWindowsUIFigure  matlab.ui.Figure
        ProbeViewWindowLabel     matlab.ui.control.Label
        MainWindowLabel          matlab.ui.control.Label
        CloseButton              matlab.ui.control.Button
        ProbeViewWindowPanel     matlab.ui.container.Panel
        TextArea_2               matlab.ui.control.TextArea
        MainWindowPanel          matlab.ui.container.Panel
        TextArea                 matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        Mainapp = [] % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.ControlsWindowsUIFigure, 'center');            %% Predefine variables for the app to work
            
            app.Mainapp = Mainapp;
            app = Utility_Change_Light_Dark_Mode(app,"ControlsWindow");

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ControlsWindowsUIFigure = uifigure('Visible', 'off');
            app.ControlsWindowsUIFigure.Name = 'Controls Windows';
            app.ControlsWindowsUIFigure.Icon = 'Logo.png';
            app.MainWindowPanel = uipanel(app.ControlsWindowsUIFigure);
            app.MainWindowPanel.Title = 'Main Window';
            app.TextArea = uitextarea(app.MainWindowPanel);
            app.TextArea.Value = {'Hover over GUI elements to show tooltips (when enabled).'; ''; 'Click on data plot to show channel and time point. Use the ''Probe View Window'' to select channel being displayed.'; ''; 'Use the left and right arrow keys to jump in time and arrow up and arrow down to change the scale of data traces being plotted (click somewhere in the main window excpet of buttons or edit fields if it should not work)'; ''; 'Scroll data plot to zoom in and out at the mouse pointer.'; ''; 'Click time plot to jump in recording time.'; ''; 'Enter time span to plot in the ''Current Time Span'' edit field to change the time span of the data plot. '; ''; 'Use the ''Data Line Spacing'' slider to change the distance between plots and height of data lines. Right click to enter new limits.'; ''; 'Right Click the ''Play'' button to change the update speed of the Movie mode.'};
            app.ProbeViewWindowPanel = uipanel(app.ControlsWindowsUIFigure);
            app.ProbeViewWindowPanel.Title = 'Probe View Window';
            app.TextArea_2 = uitextarea(app.ProbeViewWindowPanel);
            app.TextArea_2.Value = {'Click on the probe on the left of this window or in empty space: jump with zoomed channel on the right to this position.'; ''; 'Click on active channel on the right: activate/deactivate'; 'channel for selected window'; ''; 'Scroll to change zoomed channel window on the right. '; ''; 'General remark about channel selection: '; ''; 'In every window in which you can not select analysis channel directly the channel selection of the probe view window is used for the analysis in the corresponding window. If a window contains a dedicated field to input channel, this field is used for the channel selection in that window.'; ''; 'To avoid computational overhead and make the probe view window more responsive, you can select for which window the channel selection changes are applied, so that not all plots have to be updated.'; ''};
            app.CloseButton = uibutton(app.ControlsWindowsUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.MainWindowLabel = uilabel(app.ControlsWindowsUIFigure);
            app.MainWindowLabel.Text = 'Main Window';
            app.ProbeViewWindowLabel = uilabel(app.ControlsWindowsUIFigure);
            app.ProbeViewWindowLabel.Text = 'Probe View Window';
            % Show the figure after all components are created
            app.ControlsWindowsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Controls_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ControlsWindowsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ControlsWindowsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ControlsWindowsUIFigure)
        end
    end
end