classdef Static_Power_Spectrum_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StaticSpectrumWindowUIFigure  matlab.ui.Figure
        SnapshotMenu                  matlab.ui.container.Menu
        SaveSnapshotofWindowMenu      matlab.ui.container.Menu
        PNGMenu                       matlab.ui.container.Menu
        JPGMenu                       matlab.ui.container.Menu
        SVGMenu                       matlab.ui.container.Menu
        PlotMenu_2                    matlab.ui.container.Menu
        ChangePlotTypeMenu            matlab.ui.container.Menu
        IndividualChannelViewMenu     matlab.ui.container.Menu
        DepthViewMenu                 matlab.ui.container.Menu
        Show2DimensionalPlotsMenu     matlab.ui.container.Menu
        Show3DimensionalPlotsMenu     matlab.ui.container.Menu
        GridTraceViewMenu             matlab.ui.container.Menu
        LinesMenu                     matlab.ui.container.Menu
        ColorplotMenu                 matlab.ui.container.Menu
        ChangePlotPropertiesMenu      matlab.ui.container.Menu
        DataPlotMenu                  matlab.ui.container.Menu
        FontSizeMenu                  matlab.ui.container.Menu
        LineWidthDataMenu             matlab.ui.container.Menu
        ColorDataMenu                 matlab.ui.container.Menu
        BackgorundColorMenu           matlab.ui.container.Menu
        XLabelMenu                    matlab.ui.container.Menu
        YLabelMenu                    matlab.ui.container.Menu
        RESETMenu                     matlab.ui.container.Menu
        GridViewPlotMenu              matlab.ui.container.Menu
        LineWidthDataMenu_2           matlab.ui.container.Menu
        ColorDataMenu_2               matlab.ui.container.Menu
        RESETMenu_2                   matlab.ui.container.Menu
        SaveCustomeAppearanceMenu     matlab.ui.container.Menu
        DeleteCustomeAppearanceMenu   matlab.ui.container.Menu
        ExportDataMenu                matlab.ui.container.Menu
        matMenu                       matlab.ui.container.Menu
        txtMenu                       matlab.ui.container.Menu
        xlsxMenu                      matlab.ui.container.Menu
        CloseButton                   matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        TextArea_2                    matlab.ui.control.TextArea
        StaticSpectrumOptionsLabel    matlab.ui.control.Label
        StaticSpectrumOptionsPanel    matlab.ui.container.Panel
        CostumeWindowSizeCheckBox     matlab.ui.control.CheckBox
        FrequencyRangeHzEditField_2   matlab.ui.control.EditField
        FrequencyRangeHzLabel_2       matlab.ui.control.Label
        ChannelDropDown               matlab.ui.control.DropDown
        ChannelDropDownLabel          matlab.ui.control.Label
        DataSourceDropDown            matlab.ui.control.DropDown
        DataTypeDropDown_2Label       matlab.ui.control.Label
        DataTypeDropDown              matlab.ui.control.DropDown
        MethodLabel                   matlab.ui.control.Label
        FrequencyRangeHzEditField     matlab.ui.control.EditField
        FrequencyRangeHzLabel         matlab.ui.control.Label
        Grid_Traces_View_Panel        matlab.ui.container.Panel
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        BandPower = []
        TwoORThreeD = "TwoD"; % standard 2d plots when possible
        TextArea = [];

        ActiveChannelContSpectrum = [];

        ChannelGrid = [];
        ChannelAxes = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            %% Initiate GUI 
            app.Mainapp = Mainapp;
            
            movegui(app.StaticSpectrumWindowUIFigure, 'center');

            app.Grid_Traces_View_Panel.Enable = "off";
            app.Grid_Traces_View_Panel.Visible = "off";

            if str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)<=2 && strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")
                warning("Plotappearance was set to display Band Power Across Grid, which is not possible with less or equal then 2 channel rows. Auto-changing to Individual Channel View.")
                app.Mainapp.PlotAppearance.SpectrumWindow.PlotType = "Band Power Individual Channel ";
            end
            
            app = Utility_Change_Light_Dark_Mode(app,"ContSpectrum");
            
            if isfield(app.Mainapp.Data, 'Raw') && isfield(app.Mainapp.Data, 'Preprocessed')
                app.DataSourceDropDown.Items = ["Raw Data","Preprocessed Data"];
                app.DataSourceDropDown.Value = "Raw Data";
            elseif ~isfield(app.Mainapp.Data, 'Preprocessed') && isfield(app.Mainapp.Data, 'Raw')
                app.DataSourceDropDown.Items = "Raw Data";
            elseif isfield(app.Mainapp.Data, 'Preprocessed') && ~isfield(app.Mainapp.Data, 'Raw')
                app.DataSourceDropDown.Items = "Preprocessed Data";
            end
            
            app.FrequencyRangeHzEditField_2.Enable = "off";
            app.FrequencyRangeHzEditField_2.Value   = num2str(app.Mainapp.Data.Info.NativeSamplingRate);
            WindowSize = [];

            app.ActiveChannelContSpectrum = sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);

            % Generate Channel nr to be shown based on NRChannel saved
            AllChannels = {};
            if isfield(app.Mainapp.Data,'Raw')
                N = size(app.Mainapp.Data.Raw,1);  
            elseif isfield(app.Mainapp.Data,'Preprocessed')
                N = size(app.Mainapp.Data.Preprocessed,1);  
            end
            
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
              
                for i = 1:N
                    AllChannels{i} = num2str(TempActiveChannel(i));
                end
            else
                for i = 1:N
                    AllChannels{i} = num2str(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(i));
                end
            end

            app.ChannelDropDown.Items = AllChannels;

            SelectedChannel = str2double(app.ChannelDropDown.Value);              
            
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
            else
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
            end         

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";

                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.FrequencyRangeHzEditField_2.Value,app.CostumeWindowSizeCheckBox.Value);

                [app.Mainapp.PowerSpecResults,app.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);

                % app.TextArea.Value = "Ready";
                % pause(0.01);


            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                % app.TextArea.Value = "Ready";
                % pause(0.01);

                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";

                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                
                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                cb.Label.Color = 'k';        % Sets the color of the label text
                
                % app.TextArea.Value = "Ready";
                % pause(0.01);
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")

                % if str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)<=2
                %     msgbox("At least 3 channel rows required! Returning");
                %     return;
                % end
                
                app.Grid_Traces_View_Panel.Visible = "on";
                app.Grid_Traces_View_Panel.Enable = "on";
                app.Button_2.Visible = "off";
                app.Button_2.Enable = "off";

                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
                
                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                
                if isempty(app.ChannelAxes)
                    app = Module_MainWindow_Initialize_Grid_Trace_Panel(app,app.Mainapp,"StaticEventSpectrum");
                end         
                
                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',0,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims);
  
            end

            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
            % app.TextArea.Value = "Ready";
            % pause(0.01);

            app = Utility_Change_Light_Dark_Mode(app,"ContSpectrum");
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.Mainapp.ContStaticSpectrumWindow = [];
    
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Static Spectrum Window')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end
            end
            delete(app);
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            if strcmp(app.DataTypeDropDown.Value,"Mean over all Channel")
                app.ChannelDropDown.Enable = "off";
            else
                app.ChannelDropDown.Enable = "on";
            end

            AllChannels = {};
            N = size(app.Mainapp.Data.Raw,1);  
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                for i = 1:N
                    AllChannels{i} = num2str(TempActiveChannel(i));
                end
            else
                for i = 1:N
                    AllChannels{i} = num2str(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(i));
                end
            end
            app.ChannelDropDown.Items = AllChannels;
            
            SelectedChannel = str2double(app.ChannelDropDown.Value);              
            
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
            else
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
            end

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")

                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.FrequencyRangeHzEditField_2.Value,app.CostumeWindowSizeCheckBox.Value);

                [app.Mainapp.PowerSpecResults,app.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")

                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                
                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                cb.Label.Color = 'k';        % Sets the color of the label text

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")

                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                
                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',0,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims);
            
            end
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)

            SelectedChannel = str2double(app.ChannelDropDown.Value);              
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
            else
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
            end
   
            [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.FrequencyRangeHzEditField_2.Value,app.CostumeWindowSizeCheckBox.Value);
        end

        % Value changed function: DataSourceDropDown
        function DataSourceDropDownValueChanged(app, event)
            app.UIAxes_2.NextPlot = "replace";
            cla(app.UIAxes_2)
            app.UIAxes_2.NextPlot = "add";
            
            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
            
            app = Utility_Change_Light_Dark_Mode(app,"ContSpectrum");
            
            if strcmp(app.DataSourceDropDown.Value,"Raw Data")
                app.FrequencyRangeHzEditField_2.Value = num2str(app.Mainapp.Data.Info.NativeSamplingRate);
            elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,"DownsampledSampleRate")
                    app.FrequencyRangeHzEditField_2.Value = num2str(app.Mainapp.Data.Info.DownsampledSampleRate);
                else
                    app.FrequencyRangeHzEditField_2.Value = num2str(app.Mainapp.Data.Info.NativeSamplingRate);
                end
            end

            AllChannels = {};
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                for i = 1:length(TempActiveChannel)
                    AllChannels{i} = num2str(TempActiveChannel(i));
                end
            else
                for i = 1:length(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)
                    AllChannels{i} = num2str(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(i));
                end
            end
            app.ChannelDropDown.Items = AllChannels;
            
            SelectedChannel = str2double(app.ChannelDropDown.Value);              
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
            else
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
            end

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")

                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.FrequencyRangeHzEditField_2.Value,app.CostumeWindowSizeCheckBox.Value);

                [app.Mainapp.PowerSpecResults,app.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")

                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                
                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                cb.Label.Color = 'k';        % Sets the color of the label text
                
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")

                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                
                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',0,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims);
            
            end

        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.StaticSpectrumWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.StaticSpectrumWindowUIFigure,fullfile(path,file)) 
        end

        % Value changed function: FrequencyRangeHzEditField
        function FrequencyRangeHzEditFieldValueChanged(app, event)
            [app.FrequencyRangeHzEditField.Value] = Utility_SimpleCheckInputs(app.FrequencyRangeHzEditField.Value,"Two",'0,100',0,0);

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";
                app.FrequencyRangeHzEditField.Enable = "on";

                SelectedChannel = str2double(app.ChannelDropDown.Value);              
                if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                    TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
                else
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
                end
 
                set(app.UIAxes, 'YDir', 'normal');

                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.FrequencyRangeHzEditField_2.Value,app.CostumeWindowSizeCheckBox.Value);

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";

                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);

                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                cb.Label.Color = 'k';        % Sets the color of the label text
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")

                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                DataSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                DataSpectrum = DataSpectrum(Channelrange,:);
                
                Ylims = [min(DataSpectrum,[],'all') max(DataSpectrum,[],'all')];

                % EventRelatedData is channel by time
                Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,DataSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',0,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims);
  
            end

        end

        % Menu selected function: Show2DimensionalPlotsMenu
        function Show2DimensionalPlotsMenuSelected(app, event)
    
            app.Mainapp.PlotAppearance.SpectrumWindow.PlotType = "Band Power over Depth";
            
            app.Grid_Traces_View_Panel.Visible = "off";
                app.Grid_Traces_View_Panel.Enable = "off";
                
            if ~isempty(app.ChannelAxes)
                for k = 1:numel(app.ChannelAxes)
                    if isvalid(app.ChannelAxes{k})  
                        delete(app.ChannelAxes{k});
                    end
                end
                app.ChannelAxes = [];
            end
            if ~isempty(app.ChannelGrid)
                delete(app.ChannelGrid)
            end

            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            
            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;

            app.TwoORThreeD = "TwoD";

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";
                
                SelectedChannel = str2double(app.ChannelDropDown.Value);              
                if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                    TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
                else
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
                end

                set(app.UIAxes, 'YDir', 'normal');

                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.FrequencyRangeHzEditField_2.Value,app.CostumeWindowSizeCheckBox.Value);

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";

                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
            end
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Static_Spectrum",0);
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Static_Spectrum",0);
        end

        % Menu selected function: Show3DimensionalPlotsMenu
        function Show3DimensionalPlotsMenuSelected(app, event)
            app.Mainapp.PlotAppearance.SpectrumWindow.PlotType = "Band Power over Depth";
            
            app.Grid_Traces_View_Panel.Visible = "off";
                app.Grid_Traces_View_Panel.Enable = "off";
                
            if ~isempty(app.ChannelAxes)
                for k = 1:numel(app.ChannelAxes)
                    if isvalid(app.ChannelAxes{k})  
                        delete(app.ChannelAxes{k});
                    end
                end
                app.ChannelAxes = [];
            end
            if ~isempty(app.ChannelGrid)
                delete(app.ChannelGrid)
            end
            
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            
            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;

            app.TwoORThreeD = "ThreeD";

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";
                
                SelectedChannel = str2double(app.ChannelDropDown.Value);              
                if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                    TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
                else
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
                end
    
                set(app.UIAxes, 'YDir', 'normal');

                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.FrequencyRangeHzEditField_2.Value,app.CostumeWindowSizeCheckBox.Value);

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";

                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
            end
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize',app.Mainapp,"SpectrumFontSize");
            waitfor(h);
            
            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                
                SelectedChannel = str2double(app.ChannelDropDown.Value);              
                if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                    TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
                else
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
                end
       
                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.FrequencyRangeHzEditField_2.Value,app.CostumeWindowSizeCheckBox.Value);
            end

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
            app.UIAxes_2.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
        end

        % Menu selected function: LineWidthDataMenu
        function LineWidthDataMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth',app.Mainapp,"SpectrumLineWidth");
            waitfor(h);
            
            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                
                SelectedChannel = str2double(app.ChannelDropDown.Value);              
                if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                    TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
                else
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
                end
    
                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.FrequencyRangeHzEditField_2.Value,app.CostumeWindowSizeCheckBox.Value);
            end
          
        end

        % Menu selected function: ColorDataMenu
        function ColorDataMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 

            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3

                app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumColor = selectedColor;

                if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                    
                    SelectedChannel = str2double(app.ChannelDropDown.Value);              
                    if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                        TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                        [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
                    else
                        [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
                    end
         
                    [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.FrequencyRangeHzEditField_2.Value,app.CostumeWindowSizeCheckBox.Value);
                end
            end
        end

        % Menu selected function: BackgorundColorMenu
        function BackgorundColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 

            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor = selectedColor;
                app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
                app.UIAxes_2.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            end
        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'xlabel',app.Mainapp,"SpectrumWindowDataXlabel");
            waitfor(h);
            
            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                
                SelectedChannel = str2double(app.ChannelDropDown.Value);              
                if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                    TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
                else
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
                end
  
                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.FrequencyRangeHzEditField_2.Value,app.CostumeWindowSizeCheckBox.Value);
            end
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'ylabel',app.Mainapp,"SpectrumWindowDataYlabel");
            waitfor(h);
            
            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                
                SelectedChannel = str2double(app.ChannelDropDown.Value);              
                if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                    TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
                else
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
                end
    
                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.FrequencyRangeHzEditField_2.Value,app.CostumeWindowSizeCheckBox.Value);
            end
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
        app.UIAxes.NextPlot = "replace"; 
        plot(app.UIAxes,0,0);
        app.UIAxes.NextPlot = "add"; 

        app.UIAxes_2.NextPlot = "replace"; 
        plot(app.UIAxes_2,0,0);
        app.UIAxes_2.NextPlot = "add"; 

        TempPlotType = app.Mainapp.PlotAppearance.SpectrumWindow.PlotType;
        TempGirdType = app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType;

        [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("SpectrumPlot",app.Mainapp.PlotAppearance);
        
        app.Mainapp.PlotAppearance.SpectrumWindow.PlotType = TempPlotType;
        app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType = TempGirdType;

        if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")   
            SelectedChannel = str2double(app.ChannelDropDown.Value);              
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
            else
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
            end
    
            [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.FrequencyRangeHzEditField_2.Value,app.CostumeWindowSizeCheckBox.Value);
            
            [app.Mainapp.PowerSpecResults,app.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);

        elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
    
            [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
            
            [app.Mainapp.PowerSpecResults,app.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
            
        end 
        end

        % Menu selected function: SaveCustomeAppearanceMenu
        function SaveCustomeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Save");
        end

        % Menu selected function: DeleteCustomeAppearanceMenu
        function DeleteCustomeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Delete");
        end

        % Close request function: StaticSpectrumWindowUIFigure
        function StaticSpectrumWindowUIFigureCloseRequest(app, event)
            try
                app.Mainapp.ContStaticSpectrumWindow = [];
    
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Static Spectrum Window')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end
            end

            delete(app)
        end

        % Value changed function: FrequencyRangeHzEditField_2
        function FrequencyRangeHzEditField_2ValueChanged(app, event)
            value = app.FrequencyRangeHzEditField_2.Value;
            SelectedChannel = str2double(app.ChannelDropDown.Value);              
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
            else
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
            end
   
            [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.FrequencyRangeHzEditField_2.Value,app.CostumeWindowSizeCheckBox.Value);
        end

        % Value changed function: CostumeWindowSizeCheckBox
        function CostumeWindowSizeCheckBoxValueChanged(app, event)
            value = app.CostumeWindowSizeCheckBox.Value;

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                if value == 1
                    app.FrequencyRangeHzEditField_2.Enable = "on";
                else
                    app.FrequencyRangeHzEditField_2.Enable = "off";
                end
                
                SelectedChannel = str2double(app.ChannelDropDown.Value);              
                if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                    TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
                else
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
                end
       
                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.FrequencyRangeHzEditField_2.Value,app.CostumeWindowSizeCheckBox.Value);
            end
        end

        % Menu selected function: SVGMenu
        function SVGMenuSelected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.StaticSpectrumWindowUIFigure, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: xlsxMenu
        function xlsxMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Static_Spectrum",0);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Utility_Open_UIAxes_as_Figure(app.Mainapp.Data,app.UIAxes,strcat("ConStaticSpectrum ",app.Mainapp.PlotAppearance.SpectrumWindow.PlotType));
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            Utility_Open_UIAxes_as_Figure(app.Mainapp.Data,app.UIAxes_2,"ConStaticSpectrum2");
        end

        % Menu selected function: IndividualChannelViewMenu
        function IndividualChannelViewMenuSelected(app, event)
            app.Mainapp.PlotAppearance.SpectrumWindow.PlotType = "Band Power Individual Channel ";
            
            app.Grid_Traces_View_Panel.Visible = "off";
            app.Grid_Traces_View_Panel.Enable = "off";

            if ~isempty(app.ChannelAxes)
                for k = 1:numel(app.ChannelAxes)
                    if isvalid(app.ChannelAxes{k})  
                        delete(app.ChannelAxes{k});
                    end
                end
                app.ChannelAxes = [];
            end
            if ~isempty(app.ChannelGrid)
                delete(app.ChannelGrid)
            end

            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            cla(app.UIAxes)
            
            PowerDepth_handles = findobj(app.UIAxes, 'Tag', 'PowerDepth');
            delete(PowerDepth_handles(1:end));

            PWelch_handles = findobj(app.UIAxes, 'Tag', 'Pwelch');
            delete(PWelch_handles(1:end));

            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
            
            app = Utility_Change_Light_Dark_Mode(app,"ContSpectrum");

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                
                cb = colorbar(app.UIAxes);   % Create a colorbar (if it exists)
                if ~isempty(cb)
                    delete(cb);                  % Delete the colorbar
                end
                
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";
                app.FrequencyRangeHzEditField.Enable = "on";
                app.FrequencyRangeHzEditField_2.Enable = "on";
                app.CostumeWindowSizeCheckBox.Enable = "on";

                SelectedChannel = str2double(app.ChannelDropDown.Value);                     
                if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                    TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
                else
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
                end
    
                set(app.UIAxes, 'YDir', 'normal');
                
                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.FrequencyRangeHzEditField_2.Value,app.CostumeWindowSizeCheckBox.Value);

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                
                app.CostumeWindowSizeCheckBox.Enable = "off";
 
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
                app.FrequencyRangeHzEditField_2.Enable = "off";

                %app.UIAxes.YScale = 'linear';

                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                
                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                cb.Label.Color = 'k';        % Sets the color of the label text
           
            end
        end

        % Menu selected function: LinesMenu
        function LinesMenuSelected(app, event)
            app.Mainapp.PlotAppearance.SpectrumWindow.PlotType = "Band Power Across Grid";
            app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType = "EventWindowLinesSpectrum";

            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            
            if ~isempty(app.ChannelAxes)
                for k = 1:numel(app.ChannelAxes)
                    if isvalid(app.ChannelAxes{k})  
                        delete(app.ChannelAxes{k});
                    end
                end
                app.ChannelAxes = [];
            end
            if ~isempty(app.ChannelGrid)
                delete(app.ChannelGrid)
            end

            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            cla(app.UIAxes)
            
            PowerDepth_handles = findobj(app.UIAxes, 'Tag', 'PowerDepth');
            delete(PowerDepth_handles(1:end));

            PWelch_handles = findobj(app.UIAxes, 'Tag', 'Pwelch');
            delete(PWelch_handles(1:end));

            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
            
            app = Utility_Change_Light_Dark_Mode(app,"ContSpectrum");

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                
                cb = colorbar(app.UIAxes);   % Create a colorbar (if it exists)
                if ~isempty(cb)
                    delete(cb);                  % Delete the colorbar
                end
                
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";
                app.FrequencyRangeHzEditField.Enable = "on";
                app.FrequencyRangeHzEditField_2.Enable = "on";
                app.CostumeWindowSizeCheckBox.Enable = "on";

                SelectedChannel = str2double(app.ChannelDropDown.Value);                     
                if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                    TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
                else
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
                end
    
                set(app.UIAxes, 'YDir', 'normal');
                
                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.FrequencyRangeHzEditField_2.Value,app.CostumeWindowSizeCheckBox.Value);

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                
                app.CostumeWindowSizeCheckBox.Enable = "off";
 
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
                app.FrequencyRangeHzEditField_2.Enable = "off";

                %app.UIAxes.YScale = 'linear';

                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                
                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                cb.Label.Color = 'k';        % Sets the color of the label text

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")

                app.CostumeWindowSizeCheckBox.Enable = "off";
 
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
                app.FrequencyRangeHzEditField_2.Enable = "off";

                % if str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)<=2
                %     msgbox("At least 3 channel rows required! Returning");
                %     return;
                % end
                
                app.Grid_Traces_View_Panel.Visible = "on";
                app.Grid_Traces_View_Panel.Enable = "on";
                app.Button_2.Visible = "off";
                app.Button_2.Enable = "off";

                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
    
                if isempty(app.ChannelAxes)
                    app = Module_MainWindow_Initialize_Grid_Trace_Panel(app,app.Mainapp,"StaticEventSpectrum");
                end         

                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                DataSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                DataSpectrum = DataSpectrum(Channelrange,:);
                
                Ylims = [min(DataSpectrum,[],'all') max(DataSpectrum,[],'all')];

                % EventRelatedData is channel by time
                Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,DataSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',0,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims);
  
            end
        end

        % Menu selected function: ColorplotMenu
        function ColorplotMenuSelected(app, event)
            app.Mainapp.PlotAppearance.SpectrumWindow.PlotType = "Band Power Across Grid";
            app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType = "EventWindowColorSpectrum";

            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            
            if ~isempty(app.ChannelAxes)
                for k = 1:numel(app.ChannelAxes)
                    if isvalid(app.ChannelAxes{k})  
                        delete(app.ChannelAxes{k});
                    end
                end
                app.ChannelAxes = [];
            end
            if ~isempty(app.ChannelGrid)
                delete(app.ChannelGrid)
            end

            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            cla(app.UIAxes)
            
            PowerDepth_handles = findobj(app.UIAxes, 'Tag', 'PowerDepth');
            delete(PowerDepth_handles(1:end));

            PWelch_handles = findobj(app.UIAxes, 'Tag', 'Pwelch');
            delete(PWelch_handles(1:end));

            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
            
            app = Utility_Change_Light_Dark_Mode(app,"ContSpectrum");

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                
                cb = colorbar(app.UIAxes);   % Create a colorbar (if it exists)
                if ~isempty(cb)
                    delete(cb);                  % Delete the colorbar
                end
                
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";
                app.FrequencyRangeHzEditField.Enable = "on";
                app.FrequencyRangeHzEditField_2.Enable = "on";
                app.CostumeWindowSizeCheckBox.Enable = "on";

                SelectedChannel = str2double(app.ChannelDropDown.Value);                     
                if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                    TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
                else
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
                end
    
                set(app.UIAxes, 'YDir', 'normal');
                
                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.FrequencyRangeHzEditField_2.Value,app.CostumeWindowSizeCheckBox.Value);

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                
                app.CostumeWindowSizeCheckBox.Enable = "off";
 
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
                app.FrequencyRangeHzEditField_2.Enable = "off";

                %app.UIAxes.YScale = 'linear';

                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                
                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                cb.Label.Color = 'k';        % Sets the color of the label text
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")
                
                app.CostumeWindowSizeCheckBox.Enable = "off";
 
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
                app.FrequencyRangeHzEditField_2.Enable = "off";
                
                % if str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)<=2
                %     msgbox("At least 3 channel rows required! Returning");
                %     return;
                % end
                
                app.Grid_Traces_View_Panel.Visible = "on";
                app.Grid_Traces_View_Panel.Enable = "on";
                app.Button_2.Visible = "off";
                app.Button_2.Enable = "off";

                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
    
                if isempty(app.ChannelAxes)
                    app = Module_MainWindow_Initialize_Grid_Trace_Panel(app,app.Mainapp,"StaticEventSpectrum");
                end         

                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                DataSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                DataSpectrum = DataSpectrum(Channelrange,:);
                
                Ylims = [min(DataSpectrum,[],'all') max(DataSpectrum,[],'all')];

                % EventRelatedData is channel by time
                Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,DataSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',0,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims);
  
            end
        end

        % Menu selected function: LineWidthDataMenu_2
        function LineWidthDataMenu_2Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth', app.Mainapp,"EventSpectrumGridDataLineWidth");
            waitfor(h);
            
            SelectedChannel = str2double(app.ChannelDropDown.Value);                     
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
            else
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
            end

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")

                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.FrequencyRangeHzEditField_2.Value,app.CostumeWindowSizeCheckBox.Value);

                [app.Mainapp.PowerSpecResults,app.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")

                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                
                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                cb.Label.Color = 'k';        % Sets the color of the label text
                
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")

                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                
                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',0,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims);
            
            end
        end

        % Menu selected function: ColorDataMenu_2
        function ColorDataMenu_2Selected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                 app.Mainapp.PlotAppearance.SpectrumWindow.Data.GridTraceDataColor = selectedColor;
            end

            SelectedChannel = str2double(app.ChannelDropDown.Value);                     
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
            else
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
            end

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")

                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.FrequencyRangeHzEditField_2.Value,app.CostumeWindowSizeCheckBox.Value);

                [app.Mainapp.PowerSpecResults,app.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")

                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                
                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                cb.Label.Color = 'k';        % Sets the color of the label text
                
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")

                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                
                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',0,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims);
            
            end
        end

        % Menu selected function: RESETMenu_2
        function RESETMenu_2Selected(app, event)
            app.UIAxes.NextPlot = "replace"; 
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add"; 
    
            TempPlotType = app.Mainapp.PlotAppearance.SpectrumWindow.PlotType;
            TempGirdType = app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType;
            
            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("SpectrumPlot",app.Mainapp.PlotAppearance);
    
            app.Mainapp.PlotAppearance.SpectrumWindow.PlotType = TempPlotType;
            app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType = TempGirdType;
            
            SelectedChannel = str2double(app.ChannelDropDown.Value);                     
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
            else
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
            end

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")

                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.FrequencyRangeHzEditField_2.Value,app.CostumeWindowSizeCheckBox.Value);

                [app.Mainapp.PowerSpecResults,app.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")

                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                
                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                cb.Label.Color = 'k';        % Sets the color of the label text
                
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")

                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                
                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',0,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims);
            
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StaticSpectrumWindowUIFigure = uifigure('Visible', 'off');
            app.StaticSpectrumWindowUIFigure.Name = 'Static Spectrum Window';
            app.StaticSpectrumWindowUIFigure.Icon = 'Logo.png';
            app.StaticSpectrumWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @StaticSpectrumWindowUIFigureCloseRequest, true);
            app.SnapshotMenu = uimenu(app.StaticSpectrumWindowUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.SVGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.SVGMenu.MenuSelectedFcn = createCallbackFcn(app, @SVGMenuSelected, true);
            app.SVGMenu.Text = 'SVG';
            app.PlotMenu_2 = uimenu(app.StaticSpectrumWindowUIFigure);
            app.PlotMenu_2.Text = '   Plot   ';
            app.ChangePlotTypeMenu = uimenu(app.PlotMenu_2);
            app.ChangePlotTypeMenu.Text = 'Change Plot Type';
            app.IndividualChannelViewMenu = uimenu(app.ChangePlotTypeMenu);
            app.IndividualChannelViewMenu.MenuSelectedFcn = createCallbackFcn(app, @IndividualChannelViewMenuSelected, true);
            app.IndividualChannelViewMenu.Text = 'Individual Channel View';
            app.DepthViewMenu = uimenu(app.ChangePlotTypeMenu);
            app.DepthViewMenu.Text = 'Depth View';
            app.Show2DimensionalPlotsMenu = uimenu(app.DepthViewMenu);
            app.Show2DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show2DimensionalPlotsMenuSelected, true);
            app.Show2DimensionalPlotsMenu.Text = 'Show 2 Dimensional Plots';
            app.Show3DimensionalPlotsMenu = uimenu(app.DepthViewMenu);
            app.Show3DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show3DimensionalPlotsMenuSelected, true);
            app.Show3DimensionalPlotsMenu.Text = 'Show 3 Dimensional Plots';
            app.GridTraceViewMenu = uimenu(app.ChangePlotTypeMenu);
            app.GridTraceViewMenu.Text = 'Grid Trace View';
            app.LinesMenu = uimenu(app.GridTraceViewMenu);
            app.LinesMenu.MenuSelectedFcn = createCallbackFcn(app, @LinesMenuSelected, true);
            app.LinesMenu.Text = 'Lines';
            app.ColorplotMenu = uimenu(app.GridTraceViewMenu);
            app.ColorplotMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorplotMenuSelected, true);
            app.ColorplotMenu.Text = 'Colorplot';
            app.ChangePlotPropertiesMenu = uimenu(app.PlotMenu_2);
            app.ChangePlotPropertiesMenu.Text = 'Change Plot Properties';
            app.DataPlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DataPlotMenu.Text = 'Data Plot';
            app.FontSizeMenu = uimenu(app.DataPlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.LineWidthDataMenu = uimenu(app.DataPlotMenu);
            app.LineWidthDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthDataMenuSelected, true);
            app.LineWidthDataMenu.Text = 'LineWidth Data';
            app.ColorDataMenu = uimenu(app.DataPlotMenu);
            app.ColorDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorDataMenuSelected, true);
            app.ColorDataMenu.Text = 'Color Data';
            app.BackgorundColorMenu = uimenu(app.DataPlotMenu);
            app.BackgorundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenuSelected, true);
            app.BackgorundColorMenu.Text = 'Backgorund Color';
            app.XLabelMenu = uimenu(app.DataPlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.DataPlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.RESETMenu = uimenu(app.DataPlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.GridViewPlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.GridViewPlotMenu.Text = 'Grid View Plot';
            app.LineWidthDataMenu_2 = uimenu(app.GridViewPlotMenu);
            app.LineWidthDataMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LineWidthDataMenu_2Selected, true);
            app.LineWidthDataMenu_2.Text = 'LineWidth Data';
            app.ColorDataMenu_2 = uimenu(app.GridViewPlotMenu);
            app.ColorDataMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ColorDataMenu_2Selected, true);
            app.ColorDataMenu_2.Text = 'Color Data';
            app.RESETMenu_2 = uimenu(app.GridViewPlotMenu);
            app.RESETMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RESETMenu_2Selected, true);
            app.RESETMenu_2.Text = 'RESET';
            app.SaveCustomeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SaveCustomeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCustomeAppearanceMenuSelected, true);
            app.SaveCustomeAppearanceMenu.Text = 'Save Custome Appearance';
            app.DeleteCustomeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DeleteCustomeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCustomeAppearanceMenuSelected, true);
            app.DeleteCustomeAppearanceMenu.Text = 'Delete Custome Appearance';
            app.ExportDataMenu = uimenu(app.StaticSpectrumWindowUIFigure);
            app.ExportDataMenu.Text = '   Export Data   ';
            app.matMenu = uimenu(app.ExportDataMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ExportDataMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.xlsxMenu = uimenu(app.ExportDataMenu);
            app.xlsxMenu.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenuSelected, true);
            app.xlsxMenu.Text = '.xlsx';
            app.UIAxes = uiaxes(app.StaticSpectrumWindowUIFigure);
            app.UIAxes_2 = uiaxes(app.StaticSpectrumWindowUIFigure);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.YTick = [];
            app.Grid_Traces_View_Panel = uipanel(app.StaticSpectrumWindowUIFigure);
            app.StaticSpectrumOptionsPanel = uipanel(app.StaticSpectrumWindowUIFigure);
            app.StaticSpectrumOptionsPanel.Title = 'Static Spectrum Options';
            app.FrequencyRangeHzLabel = uilabel(app.StaticSpectrumOptionsPanel);
            app.FrequencyRangeHzLabel.Text = {'Frequency Range [Hz]'; '(from,to)'};
            app.FrequencyRangeHzEditField = uieditfield(app.StaticSpectrumOptionsPanel, 'text');
            app.FrequencyRangeHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyRangeHzEditFieldValueChanged, true);
            app.FrequencyRangeHzEditField.Enable = 'off';
            app.FrequencyRangeHzEditField.Value = '0,100';
            app.MethodLabel = uilabel(app.StaticSpectrumOptionsPanel);
            app.MethodLabel.Text = 'Method';
            app.DataTypeDropDown = uidropdown(app.StaticSpectrumOptionsPanel);
            app.DataTypeDropDown.Items = {'Channel Individually', 'Mean over all Channel'};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = 'Channel Individually';
            app.DataTypeDropDown_2Label = uilabel(app.StaticSpectrumOptionsPanel);
            app.DataTypeDropDown_2Label.Text = 'Data Type';
            app.DataSourceDropDown = uidropdown(app.StaticSpectrumOptionsPanel);
            app.DataSourceDropDown.Items = {'Raw Data', 'Preprocessed Data'};
            app.DataSourceDropDown.ValueChangedFcn = createCallbackFcn(app, @DataSourceDropDownValueChanged, true);
            app.DataSourceDropDown.Value = 'Raw Data';
            app.ChannelDropDownLabel = uilabel(app.StaticSpectrumOptionsPanel);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.StaticSpectrumOptionsPanel);
            app.ChannelDropDown.Items = {''};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = '';
            app.FrequencyRangeHzLabel_2 = uilabel(app.StaticSpectrumOptionsPanel);
            app.FrequencyRangeHzLabel_2.Text = {'Window Size '; '[samples]'};
            app.FrequencyRangeHzEditField_2 = uieditfield(app.StaticSpectrumOptionsPanel, 'text');
            app.FrequencyRangeHzEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrequencyRangeHzEditField_2ValueChanged, true);
            app.CostumeWindowSizeCheckBox = uicheckbox(app.StaticSpectrumOptionsPanel);
            app.CostumeWindowSizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @CostumeWindowSizeCheckBoxValueChanged, true);
            app.CostumeWindowSizeCheckBox.Text = {'   Costume '; '   Window Size'};
            app.StaticSpectrumOptionsLabel = uilabel(app.StaticSpectrumWindowUIFigure);
            app.StaticSpectrumOptionsLabel.Text = 'Static Spectrum Options';
            app.TextArea_2 = uitextarea(app.StaticSpectrumWindowUIFigure);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Enable = 'off';
            app.TextArea_2.Visible = 'off';
            app.Button = uibutton(app.StaticSpectrumWindowUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'Open_Figure_Icon.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.StaticSpectrumWindowUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'Open_Figure_Icon.png';
            app.Button_2.Text = '';
            app.CloseButton = uibutton(app.StaticSpectrumWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.StaticSpectrumWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Static_Power_Spectrum_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.StaticSpectrumWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.StaticSpectrumWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StaticSpectrumWindowUIFigure)
        end
    end
end