classdef FieldTrip_Event_Analysis_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FieldTripEventAnalysisUIFigure  matlab.ui.Figure
        ConnectivityButton              matlab.ui.control.Button
        TimeFrequencyPowerPanel         matlab.ui.container.Panel
        TFMethodDropDown                matlab.ui.control.DropDown
        TFMethodDropDownLabel           matlab.ui.control.Label
        EventNumberSelectionEditField_5  matlab.ui.control.EditField
        TriggerNumberSelectionMatlabExpressionsLabel_4  matlab.ui.control.Label
        SlidingWindowLengthsLabel       matlab.ui.control.Label
        EventNumberSelectionEditField_4  matlab.ui.control.EditField
        BaselineCorrectCheckBox         matlab.ui.control.CheckBox
        EventNumberSelectionEditField_3  matlab.ui.control.EditField
        TriggerNumberSelectionMatlabExpressionsLabel_3  matlab.ui.control.Label
        EventNumberSelectionEditField_2  matlab.ui.control.EditField
        TriggerNumberSelectionMatlabExpressionsLabel_2  matlab.ui.control.Label
        TimeFrequencyPowerLabel         matlab.ui.control.Label
        MultipleChannelTimeFrequencyPowerButton  matlab.ui.control.Button
        SingleChannelTimeFrequencyPowerButton  matlab.ui.control.Button
        EventRelatedPotentialLabel      matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        EventRelatedPotentialPanel      matlab.ui.container.Panel
        MultipleERPPlotButton           matlab.ui.control.Button
        SingleERPPlotButton             matlab.ui.control.Button
        AnalysisParameterPanel          matlab.ui.container.Panel
        DataToExtractFromDropDown       matlab.ui.control.DropDown
        DataToExtractFromDropDownLabel  matlab.ui.control.Label
        EventChannelSelectionDropDown   matlab.ui.control.DropDown
        EventChannelSelectionDropDownLabel  matlab.ui.control.Label
        ChannelSelectionDropDown        matlab.ui.control.DropDown
        ChannelSelectionDropDownLabel   matlab.ui.control.Label
        DataTypeDropDown                matlab.ui.control.DropDown
        EventDataTypeDropDownLabel      matlab.ui.control.Label
        EventNumberSelectionEditField   matlab.ui.control.EditField
        TriggerNumberSelectionMatlabExpressionsLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        TimearoundEvent = [];
        Info = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.FieldTripEventAnalysisUIFigure, 'center');

            app.Mainapp = Mainapp;
            
            app = Utility_Change_Light_Dark_Mode(app,"FieldTripEventWindow");

            if isfield(app.Mainapp.Data,'Preprocessed')
                app.DataToExtractFromDropDown.Items{1} = 'Preprocessed Data';
                app.DataToExtractFromDropDown.Items{2} = 'Raw Data';
            else
                app.DataToExtractFromDropDown.Items{1} = 'Raw Data';
            end
            
            for i = 1:length(app.Mainapp.Data.Info.EventChannelNames)
                app.EventChannelSelectionDropDown.Items{i} = convertStringsToChars(app.Mainapp.Data.Info.EventChannelNames{i});
            end
            
            value = app.TFMethodDropDown.Value;
            if strcmp(value,"mtmconvol")
                app.EventNumberSelectionEditField_4.Value = '2';
            else
                app.EventNumberSelectionEditField_4.Value = '3';
            end
            value = app.TFMethodDropDown.Value;
            if strcmp(value,"mtmconvol")
                app.EventNumberSelectionEditField_4.Value = '2';
                app.SlidingWindowLengthsLabel.Text = "Sliding Window Length (s)";
            else
                app.EventNumberSelectionEditField_4.Value = '3';
                app.SlidingWindowLengthsLabel.Text = "Wavelet Width";
            end

            %% -------------------- Check if prepro event related data found and can be offered -------------------- 
            [texttoshow,Value] = Event_Module_Check_Event_Preprocessing(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,"Raw Event Related Data");
            app.DataTypeDropDown.Items = {};
            app.DataTypeDropDown.Items = texttoshow;
            app.DataTypeDropDown.Value = Value;

            %% -------------------- Set up time -------------------- 
            spaceindicie = strfind(app.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie(1)-1));
            app.TimearoundEvent(2) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie(1)+1:end));

            %% -------------------- Set UP Channel Selection -------------------- 

            AllChannels = {};
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                for i = 1:size(app.Mainapp.Data.Raw,1)  
                    AllChannels{i} = num2str(TempActiveChannel(i));
                end
            else
                for i = 1:size(app.Mainapp.Data.Raw,1)
                    AllChannels{i} = num2str(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(i));
                end
            end

            app.ChannelSelectionDropDown.Items = AllChannels;
            
            %% -------------------- Extract Event Related Data -------------------- 
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,app.DataTypeDropDown.Value);


            %% -------------------- Populate event channel selection -------------------- 
            [app.EventNumberSelectionEditField.Value] = Event_Module_Check_EventInput(app.EventNumberSelectionEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.DataTypeDropDown.Value,1);
            

            app.Info.TimeFrequenyPowerFreqs = app.EventNumberSelectionEditField_2.Value;
            app.Info.SlidingWindowLength = app.EventNumberSelectionEditField_4.Value;
            app.Info.BaseLineWindow = app.EventNumberSelectionEditField_3.Value;
            app.Info.BaselineNormalize = app.BaselineCorrectCheckBox.Value;
            app.Info.TFMethod = app.TFMethodDropDown.Value;
        end

        % Button pushed function: SingleERPPlotButton
        function SingleERPPlotButtonPushed(app, event)
            app.Info.TimeFrequenyPowerFreqs = app.EventNumberSelectionEditField_2.Value;
            app.Info.SlidingWindowLength = app.EventNumberSelectionEditField_4.Value;
            app.Info.BaseLineWindow = app.EventNumberSelectionEditField_3.Value;
            app.Info.BaselineNormalize = app.BaselineCorrectCheckBox.Value;
            app.Info.TFMethod = app.TFMethodDropDown.Value;
            app.Info.Trial = app.EventNumberSelectionEditField.Value;
            app.Info.TimeStepLength = app.EventNumberSelectionEditField_5.Value;

            Event_Module_FieldTrip_Event_Analysis(app.Mainapp.Data,app.DataToExtractFromDropDown.Value,"SingleERP",app.ChannelSelectionDropDown.Value,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.Info,app.DataTypeDropDown.Value,app.EventNumberSelectionEditField.Value);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: MultipleERPPlotButton
        function MultipleERPPlotButtonPushed(app, event)
            app.Info.TimeFrequenyPowerFreqs = app.EventNumberSelectionEditField_2.Value;
            app.Info.SlidingWindowLength = app.EventNumberSelectionEditField_4.Value;
            app.Info.BaseLineWindow = app.EventNumberSelectionEditField_3.Value;
            app.Info.BaselineNormalize = app.BaselineCorrectCheckBox.Value;
            app.Info.TFMethod = app.TFMethodDropDown.Value;
            app.Info.Trial = app.EventNumberSelectionEditField.Value;
            app.Info.TimeStepLength = app.EventNumberSelectionEditField_5.Value;

            Event_Module_FieldTrip_Event_Analysis(app.Mainapp.Data,app.DataToExtractFromDropDown.Value,"MultipleERP",app.ChannelSelectionDropDown.Value,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.Info,app.DataTypeDropDown.Value,app.EventNumberSelectionEditField.Value);
        end

        % Button pushed function: SingleChannelTimeFrequencyPowerButton
        function SingleChannelTimeFrequencyPowerButtonPushed(app, event)
            app.Info.TimeFrequenyPowerFreqs = app.EventNumberSelectionEditField_2.Value;
            app.Info.SlidingWindowLength = app.EventNumberSelectionEditField_4.Value;
            app.Info.BaseLineWindow = app.EventNumberSelectionEditField_3.Value;
            app.Info.BaselineNormalize = app.BaselineCorrectCheckBox.Value;
            app.Info.TFMethod = app.TFMethodDropDown.Value;
            app.Info.Trial = app.EventNumberSelectionEditField.Value;
            app.Info.TimeStepLength = app.EventNumberSelectionEditField_5.Value;
            
            Event_Module_FieldTrip_Event_Analysis(app.Mainapp.Data,app.DataToExtractFromDropDown.Value,"SingleChannelTimeFrequencyPower",app.ChannelSelectionDropDown.Value,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.Info,app.DataTypeDropDown.Value,app.EventNumberSelectionEditField.Value);
        end

        % Value changed function: TFMethodDropDown
        function TFMethodDropDownValueChanged(app, event)
            value = app.TFMethodDropDown.Value;
            if strcmp(value,"mtmconvol")
                app.EventNumberSelectionEditField_4.Value = '2';
                app.SlidingWindowLengthsLabel.Text = "Sliding Window Length (s)";
            else
                app.EventNumberSelectionEditField_4.Value = '3';
                app.SlidingWindowLengthsLabel.Text = "Wavelet Width";
            end
        end

        % Button pushed function: ConnectivityButton
        function ConnectivityButtonPushed(app, event)
            app.Info.TimeFrequenyPowerFreqs = app.EventNumberSelectionEditField_2.Value;
            app.Info.SlidingWindowLength = app.EventNumberSelectionEditField_4.Value;
            app.Info.BaseLineWindow = app.EventNumberSelectionEditField_3.Value;
            app.Info.BaselineNormalize = app.BaselineCorrectCheckBox.Value;
            app.Info.TFMethod = app.TFMethodDropDown.Value;
            app.Info.Trial = app.EventNumberSelectionEditField.Value;
            app.Info.TimeStepLength = app.EventNumberSelectionEditField_5.Value;

            Event_Module_FieldTrip_Event_Analysis(app.Mainapp.Data,app.DataToExtractFromDropDown.Value,"ConnectivityAnalysis",app.ChannelSelectionDropDown.Value,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.Info,app.DataTypeDropDown.Value,app.EventNumberSelectionEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FieldTripEventAnalysisUIFigure = uifigure('Visible', 'off');
            app.FieldTripEventAnalysisUIFigure.Name = 'FieldTrip Event Analysis';
            app.FieldTripEventAnalysisUIFigure.Icon = 'Logo.png';
            app.AnalysisParameterPanel = uipanel(app.FieldTripEventAnalysisUIFigure);
            app.AnalysisParameterPanel.Title = 'Analysis Parameter';
            app.TriggerNumberSelectionMatlabExpressionsLabel = uilabel(app.AnalysisParameterPanel);
            app.TriggerNumberSelectionMatlabExpressionsLabel.Text = {'Trial Selection'; '(Matlab Expressions)'};
            app.EventNumberSelectionEditField = uieditfield(app.AnalysisParameterPanel, 'text');
            app.EventDataTypeDropDownLabel = uilabel(app.AnalysisParameterPanel);
            app.EventDataTypeDropDownLabel.Text = 'Event Data Type';
            app.DataTypeDropDown = uidropdown(app.AnalysisParameterPanel);
            app.DataTypeDropDown.Items = {};
            app.DataTypeDropDown.Value = {};
            app.ChannelSelectionDropDownLabel = uilabel(app.AnalysisParameterPanel);
            app.ChannelSelectionDropDownLabel.Text = 'Channel Selection';
            app.ChannelSelectionDropDown = uidropdown(app.AnalysisParameterPanel);
            app.ChannelSelectionDropDown.Items = {};
            app.ChannelSelectionDropDown.Value = {};
            app.EventChannelSelectionDropDownLabel = uilabel(app.AnalysisParameterPanel);
            app.EventChannelSelectionDropDownLabel.Text = 'Event Channel Selection';
            app.EventChannelSelectionDropDown = uidropdown(app.AnalysisParameterPanel);
            app.EventChannelSelectionDropDown.Items = {};
            app.EventChannelSelectionDropDown.Value = {};
            app.DataToExtractFromDropDownLabel = uilabel(app.AnalysisParameterPanel);
            app.DataToExtractFromDropDownLabel.Text = 'Data To Extract From';
            app.DataToExtractFromDropDown = uidropdown(app.AnalysisParameterPanel);
            app.DataToExtractFromDropDown.Items = {};
            app.DataToExtractFromDropDown.Value = {};
            app.EventRelatedPotentialPanel = uipanel(app.FieldTripEventAnalysisUIFigure);
            app.EventRelatedPotentialPanel.Title = 'Event Related Potential';
            app.SingleERPPlotButton = uibutton(app.EventRelatedPotentialPanel, 'push');
            app.SingleERPPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SingleERPPlotButtonPushed, true);
            app.SingleERPPlotButton.Text = 'Single ERP Plot';
            app.MultipleERPPlotButton = uibutton(app.EventRelatedPotentialPanel, 'push');
            app.MultipleERPPlotButton.ButtonPushedFcn = createCallbackFcn(app, @MultipleERPPlotButtonPushed, true);
            app.MultipleERPPlotButton.Text = 'Multiple ERP Plot';
            app.CloseButton = uibutton(app.FieldTripEventAnalysisUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.EventRelatedPotentialLabel = uilabel(app.FieldTripEventAnalysisUIFigure);
            app.EventRelatedPotentialLabel.Text = 'Event Related Potential';
            app.TimeFrequencyPowerPanel = uipanel(app.FieldTripEventAnalysisUIFigure);
            app.TimeFrequencyPowerPanel.Title = 'Time Frequency Power';
            app.SingleChannelTimeFrequencyPowerButton = uibutton(app.TimeFrequencyPowerPanel, 'push');
            app.SingleChannelTimeFrequencyPowerButton.ButtonPushedFcn = createCallbackFcn(app, @SingleChannelTimeFrequencyPowerButtonPushed, true);
            app.SingleChannelTimeFrequencyPowerButton.Text = {'Single Channel'; 'Time Frequency'; 'Power'};
            app.MultipleChannelTimeFrequencyPowerButton = uibutton(app.TimeFrequencyPowerPanel, 'push');
            app.MultipleChannelTimeFrequencyPowerButton.Text = {'Multiple Channel'; 'Time Frequency'; 'Power'};
            app.TimeFrequencyPowerLabel = uilabel(app.TimeFrequencyPowerPanel);
            app.TimeFrequencyPowerLabel.Text = 'Time Frequency Power';
            app.TriggerNumberSelectionMatlabExpressionsLabel_2 = uilabel(app.TimeFrequencyPowerPanel);
            app.TriggerNumberSelectionMatlabExpressionsLabel_2.Text = {'Frequency Range'; '(from,to,steps)'};
            app.EventNumberSelectionEditField_2 = uieditfield(app.TimeFrequencyPowerPanel, 'text');
            app.EventNumberSelectionEditField_2.Value = '1,80,1';
            app.TriggerNumberSelectionMatlabExpressionsLabel_3 = uilabel(app.TimeFrequencyPowerPanel);
            app.TriggerNumberSelectionMatlabExpressionsLabel_3.Text = {'Baseline Window '; '(from, to) in s'};
            app.EventNumberSelectionEditField_3 = uieditfield(app.TimeFrequencyPowerPanel, 'text');
            app.EventNumberSelectionEditField_3.Value = '-0.2,0';
            app.BaselineCorrectCheckBox = uicheckbox(app.TimeFrequencyPowerPanel);
            app.BaselineCorrectCheckBox.Text = {'   Baseline '; '   Correct'};
            app.BaselineCorrectCheckBox.Value = true;
            app.EventNumberSelectionEditField_4 = uieditfield(app.TimeFrequencyPowerPanel, 'text');
            app.EventNumberSelectionEditField_4.Value = '5';
            app.SlidingWindowLengthsLabel = uilabel(app.TimeFrequencyPowerPanel);
            app.SlidingWindowLengthsLabel.Text = {'Sliding Window'; 'Length (s)'};
            app.TriggerNumberSelectionMatlabExpressionsLabel_4 = uilabel(app.TimeFrequencyPowerPanel);
            app.TriggerNumberSelectionMatlabExpressionsLabel_4.Text = {'Length Time'; 'Bins (s)'};
            app.EventNumberSelectionEditField_5 = uieditfield(app.TimeFrequencyPowerPanel, 'text');
            app.EventNumberSelectionEditField_5.Value = '0.001';
            app.TFMethodDropDownLabel = uilabel(app.TimeFrequencyPowerPanel);
            app.TFMethodDropDownLabel.Text = 'TF Method';
            app.TFMethodDropDown = uidropdown(app.TimeFrequencyPowerPanel);
            app.TFMethodDropDown.Items = {'Wavelet', 'mtmconvol'};
            app.TFMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @TFMethodDropDownValueChanged, true);
            app.TFMethodDropDown.Value = 'Wavelet';
            app.ConnectivityButton = uibutton(app.FieldTripEventAnalysisUIFigure, 'push');
            app.ConnectivityButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectivityButtonPushed, true);
            app.ConnectivityButton.Text = 'Connectivity';
            % Show the figure after all components are created
            app.FieldTripEventAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FieldTrip_Event_Analysis_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.FieldTripEventAnalysisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.FieldTripEventAnalysisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FieldTripEventAnalysisUIFigure)
        end
    end
end